classdef MatBaseX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        App_GridLayout                  matlab.ui.container.GridLayout
        MaterialsDatabase_Panel         matlab.ui.container.Panel
        MaterialsDatabase_GridLayout    matlab.ui.container.GridLayout
        CrystallographyViewer_Button    matlab.ui.control.Button
        MaterialsDatabaseEditor_Button  matlab.ui.control.Button
        XrayDataAndCalculationTools_Panel  matlab.ui.container.Panel
        XrayDataAndCalculationTools_GridLayout  matlab.ui.container.GridLayout
        XRayAbsorption_Button           matlab.ui.control.Button
        CalculatorXPSSensitivityFactor_Button  matlab.ui.control.Button
        CalculatorIMFP_Button           matlab.ui.control.Button
        XRayPhotoionization_Button      matlab.ui.control.Button
        AppPES_Panel                    matlab.ui.container.Panel
        AppPES_GridLayout               matlab.ui.container.GridLayout
        PES_CurveFitting_Button         matlab.ui.control.Button
        PES_NLayer_Simul_Button         matlab.ui.control.Button
        Version_Label                   matlab.ui.control.Label
        Author_Label                    matlab.ui.control.Label
        AppTitle                        matlab.ui.control.Label
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
    end

    % Private functions available to the app only
    methods (Access = private)
        % Open Location of New App
        function open_New_App_to_right_of_Main_App(app)
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBaseX-v1.3 (procopios.constantinou@psi.ch)';
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type', 'Figure');
            % Exclude those with 'MatBaseX' in the Name (title)
            matbaseFigs = findobj(hPlots, '-regexp', 'Name', 'MatBaseX');
            hPlotsToClose = setdiff(hPlots, matbaseFigs);
            % Close only the non-MatBaseX figures
            close(hPlotsToClose);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run MatBaseX;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 630;
            screen_size(4) = 600;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
        end

        % Button pushed function: MaterialsDatabaseEditor_Button
        function MaterialsDatabaseEditor_ButtonPushed(app, event)
            run MatBaseX_01_MaterialsDatabaseEditor;
            open_New_App_to_right_of_Main_App(app);
        end

        % Button pushed function: CrystallographyViewer_Button
        function CrystallographyViewer_ButtonPushed(app, event)
            run MatBaseX_02_CrystallographyViewer;
            open_New_App_to_right_of_Main_App(app);
        end

        % Button pushed function: XRayPhotoionization_Button
        function XRayPhotoionization_ButtonPushed(app, event)
            run MatBaseX_03_XrayPhotoionization;
            open_New_App_to_right_of_Main_App(app);
        end

        % Button pushed function: XRayAbsorption_Button
        function XRayAbsorption_ButtonPushed(app, event)
            run MatBaseX_04_XrayAbsorption;
            open_New_App_to_right_of_Main_App(app);
        end

        % Button pushed function: CalculatorIMFP_Button
        function CalculatorIMFP_ButtonPushed(app, event)
            run MatBaseX_05_IMFP;
            open_New_App_to_right_of_Main_App(app);
        end

        % Button pushed function: CalculatorXPSSensitivityFactor_Button
        function CalculatorXPSSensitivityFactor_ButtonPushed(app, event)
            run MatBaseX_06_XPSSensitivityFactor;
            open_New_App_to_right_of_Main_App(app);
        end

        % Button pushed function: PES_NLayer_Simul_Button
        function PES_NLayer_Simul_ButtonPushed(app, event)
            run MatBaseX_07_PES_NLayerSimulations;
            open_New_App_to_right_of_Main_App(app);
        end

        % Button pushed function: PES_CurveFitting_Button
        function PES_CurveFitting_ButtonPushed(app, event)
            run MatBaseX_08_PES_CurveFitter;
            open_New_App_to_right_of_Main_App(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.App_GridLayout = uigridlayout(app.UIFigure);
            app.AppButtons_GridLayout = uigridlayout(app.App_GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.AppTitle = uilabel(app.App_GridLayout);
            app.AppTitle.Text = 'MatBaseX Main Menu';
            app.Author_Label = uilabel(app.App_GridLayout);
            app.Author_Label.Text = 'Author: c0deta1ker';
            app.Version_Label = uilabel(app.App_GridLayout);
            app.Version_Label.Text = 'MatBase-v1.3';
            app.AppPES_Panel = uipanel(app.App_GridLayout);
            app.AppPES_Panel.Title = 'PES Simulations & Fitting';
            app.AppPES_GridLayout = uigridlayout(app.AppPES_Panel);
            app.AppPES_GridLayout.Tooltip = {'Navigate to all MatBase PES Curve Fitting Apps'};
            app.PES_NLayer_Simul_Button = uibutton(app.AppPES_GridLayout, 'push');
            app.PES_NLayer_Simul_Button.ButtonPushedFcn = createCallbackFcn(app, @PES_NLayer_Simul_ButtonPushed, true);
            app.PES_NLayer_Simul_Button.Icon = 'Icon_NLayerModeller01.jpeg';
            app.PES_NLayer_Simul_Button.Tooltip = {'Construct multi-layered models with uniform, constant material concentration and thickness. Leverage an extensive database for material properties, IMFP parameters, and photoionization cross-sections and asymmetry parameters. Accurately compute PES intensities using the Beer-Lambert law, modulated by electron IMFP based on your experimental setup. Graphically represent variations in photon energy and emission angles.'};
            app.PES_NLayer_Simul_Button.Text = 'PES - N-Layer Simulations';
            app.PES_CurveFitting_Button = uibutton(app.AppPES_GridLayout, 'push');
            app.PES_CurveFitting_Button.ButtonPushedFcn = createCallbackFcn(app, @PES_CurveFitting_ButtonPushed, true);
            app.PES_CurveFitting_Button.Icon = 'Icon_PESModelFitter.jpeg';
            app.PES_CurveFitting_Button.Tooltip = {'The go-to app for precise peak fitting of your XPS/PES data. Construct an initial model with various curve shapes (Gaussian, Lorentzian, Voigt, or Doniach-Sunjic) and define spin-orbit split components and branching ratios. Subtract the background using Polynomial, Shirley, or Tougaard methods, and enter lower/upper bound estimates of the fit parameters. Run optimization algorithms to achieve the best fit for your data.'};
            app.PES_CurveFitting_Button.Text = 'PES - Curve Fitting';
            app.XrayDataAndCalculationTools_Panel = uipanel(app.App_GridLayout);
            app.XrayDataAndCalculationTools_Panel.Tooltip = {'Navigate to all MatBase Databases'};
            app.XrayDataAndCalculationTools_Panel.Title = 'X-Ray Data and Calculation Tools';
            app.XrayDataAndCalculationTools_GridLayout = uigridlayout(app.XrayDataAndCalculationTools_Panel);
            app.XRayPhotoionization_Button = uibutton(app.XrayDataAndCalculationTools_GridLayout, 'push');
            app.XRayPhotoionization_Button.ButtonPushedFcn = createCallbackFcn(app, @XRayPhotoionization_ButtonPushed, true);
            app.XRayPhotoionization_Button.Icon = 'Icon_Photoionization.jpeg';
            app.XRayPhotoionization_Button.Tooltip = {'Extract binding energy spectra, photoionization cross-sections, and asymmetry parameters for elements 1-98 using the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018), and Cant (2022) formalisms. Easily find the best core-levels to probe by identifying maximum cross-sections and gain a comprehensive overview of core-level spectra!'};
            app.XRayPhotoionization_Button.Text = {'Photoionization Binding Energies'; '& Cross-Section Database'};
            app.CalculatorIMFP_Button = uibutton(app.XrayDataAndCalculationTools_GridLayout, 'push');
            app.CalculatorIMFP_Button.ButtonPushedFcn = createCallbackFcn(app, @CalculatorIMFP_ButtonPushed, true);
            app.CalculatorIMFP_Button.Icon = 'Icon_IMFP.jpeg';
            app.CalculatorIMFP_Button.Tooltip = {'Calculate the electron inelastic mean free path (IMFP) using all available formalsms; Optical (NIST), Universal, TPP-2M, S1, S2, S3, S4, and JTP methods. Conveniently access material parameters from the Materials Properties Database or manually input data for materials not included in the database.'};
            app.CalculatorIMFP_Button.Text = {'Electron Inelastic Mean Free '; 'Path Calculator'};
            app.CalculatorXPSSensitivityFactor_Button = uibutton(app.XrayDataAndCalculationTools_GridLayout, 'push');
            app.CalculatorXPSSensitivityFactor_Button.ButtonPushedFcn = createCallbackFcn(app, @CalculatorXPSSensitivityFactor_ButtonPushed, true);
            app.CalculatorXPSSensitivityFactor_Button.Icon = 'Icon_SensitivityFactorXPS.jpeg';
            app.CalculatorXPSSensitivityFactor_Button.Tooltip = {'Calculate the XPS Sensitivity Factor (SF) using all the IMFP, photoionization cross-sections, and asymmetry databases. Customize the experimental geometry, photon polarization, and formalisms for IMFP and cross-section determination. Select core-levels and generate sensitivity factor plots against photon energy and emission angle with a single click. Indispensable for precise, quantitative XPS analysis!'};
            app.CalculatorXPSSensitivityFactor_Button.Text = {'XPS Sensitivity '; 'Factor Calculator'};
            app.XRayAbsorption_Button = uibutton(app.XrayDataAndCalculationTools_GridLayout, 'push');
            app.XRayAbsorption_Button.ButtonPushedFcn = createCallbackFcn(app, @XRayAbsorption_ButtonPushed, true);
            app.XRayAbsorption_Button.Icon = 'Icon_XRayAbsorption.png';
            app.XRayAbsorption_Button.Tooltip = {'Extract x-ray absorption edges and scattering factors for elements 1-92, and derived compounds, using the Henke (1995) & NIST(2005) methods. Calculate photoelectric mass attenuation coefficients, and determine material properties like index of refraction, critical angle, transmission, and reflectance. Identify optimal edges for resonance spectroscopy!'};
            app.XRayAbsorption_Button.Text = {'X-Ray Absorption Edge &'; 'Scattering Factor Database'};
            app.MaterialsDatabase_Panel = uipanel(app.App_GridLayout);
            app.MaterialsDatabase_Panel.Tooltip = {'Navigate to all MatBase Databases'};
            app.MaterialsDatabase_Panel.Title = 'Material Properties Database';
            app.MaterialsDatabase_GridLayout = uigridlayout(app.MaterialsDatabase_Panel);
            app.MaterialsDatabaseEditor_Button = uibutton(app.MaterialsDatabase_GridLayout, 'push');
            app.MaterialsDatabaseEditor_Button.ButtonPushedFcn = createCallbackFcn(app, @MaterialsDatabaseEditor_ButtonPushed, true);
            app.MaterialsDatabaseEditor_Button.Icon = 'Icon_MaterialsProperties.jpeg';
            app.MaterialsDatabaseEditor_Button.Tooltip = {'Manage the Materials Properties Database - add, delete, edit, and export material entries to streamline workflow. Enable other apps to access defined material data. Save the database to keep it persistent for all future needs.'};
            app.MaterialsDatabaseEditor_Button.Text = {'Materials Database '; 'Editor'};
            app.CrystallographyViewer_Button = uibutton(app.MaterialsDatabase_GridLayout, 'push');
            app.CrystallographyViewer_Button.ButtonPushedFcn = createCallbackFcn(app, @CrystallographyViewer_ButtonPushed, true);
            app.CrystallographyViewer_Button.Icon = 'Icon_Crystallography.png';
            app.CrystallographyViewer_Button.Tooltip = {'View unit cells in real and reciprocal space, calculate Brillouin zones, and extract 2D slices for detailed analysis.'};
            app.CrystallographyViewer_Button.Text = {'Crystallography '; 'Viewer'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatBaseX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end