classdef MatBaseX_01_MaterialsDatabaseEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Global_GridLayout               matlab.ui.container.GridLayout
        Local_GridLayout                matlab.ui.container.GridLayout
        Material_TabGroup               matlab.ui.container.TabGroup
        Elements_Tab                    matlab.ui.container.Tab
        Element_GridLayout              matlab.ui.container.GridLayout
        Element_Edit_GridLayout         matlab.ui.container.GridLayout
        Element_EditSave_Button         matlab.ui.control.Button
        Element_EditCancel_Button       matlab.ui.control.Button
        GridLayout                      matlab.ui.container.GridLayout
        Element_Export_Button           matlab.ui.control.Button
        Element_Save_Button             matlab.ui.control.Button
        Element_EditEntry_Button        matlab.ui.control.Button
        Element_PhysicalProperties_Panel  matlab.ui.container.Panel
        Element_PhysicalProperties_GridLayout  matlab.ui.container.GridLayout
        Element_gamma_EditField         matlab.ui.control.NumericEditField
        gammadegreeEditFieldLabel       matlab.ui.control.Label
        Element_c_EditField             matlab.ui.control.NumericEditField
        cpmEditFieldLabel               matlab.ui.control.Label
        Element_beta_EditField          matlab.ui.control.NumericEditField
        betadegreeEditFieldLabel        matlab.ui.control.Label
        Element_b_EditField             matlab.ui.control.NumericEditField
        bpmEditFieldLabel               matlab.ui.control.Label
        Element_alpha_EditField         matlab.ui.control.NumericEditField
        alphadegreeEditFieldLabel       matlab.ui.control.Label
        Element_a_EditField             matlab.ui.control.NumericEditField
        apmEditFieldLabel               matlab.ui.control.Label
        Element_CrystalStructure_DropDown  matlab.ui.control.DropDown
        CrystalStructureLabel_4         matlab.ui.control.Label
        Element_SpecificHeat_EditField  matlab.ui.control.NumericEditField
        SpecificHeatJgKEditFieldLabel   matlab.ui.control.Label
        Element_MassDensity_EditField   matlab.ui.control.NumericEditField
        MassDensitygcm3Label            matlab.ui.control.Label
        Element_ElectronicProperties_Panel  matlab.ui.container.Panel
        Element_ElectronicProperties_GridLayout  matlab.ui.container.GridLayout
        Element_WorkFunction_EditField  matlab.ui.control.NumericEditField
        WorkFunctioneVEditFieldLabel    matlab.ui.control.Label
        Element_BandGap_EditField       matlab.ui.control.NumericEditField
        BandGapeVEditFieldLabel         matlab.ui.control.Label
        Element_Resistivity_EditField   matlab.ui.control.NumericEditField
        ResistivitymOhmEditFieldLabel   matlab.ui.control.Label
        Element_Properties_Panel        matlab.ui.container.Panel
        Element_Properties_GridLayout   matlab.ui.container.GridLayout
        Element_ElectronValency_EditField  matlab.ui.control.NumericEditField
        ElectronValencyLabel            matlab.ui.control.Label
        Element_AtomicMass_EditField    matlab.ui.control.NumericEditField
        AtomicMassamuLabel              matlab.ui.control.Label
        Element_AtomicZNumber_EditField  matlab.ui.control.NumericEditField
        AtomicZNumberEditFieldLabel     matlab.ui.control.Label
        Element_Stoichiometry_EditField  matlab.ui.control.NumericEditField
        StoichiometryLabel              matlab.ui.control.Label
        Element_OtherProperties_Panel   matlab.ui.container.Panel
        Element_OtherProperties_GridLayout  matlab.ui.container.GridLayout
        Element_MagneticSuceptibility_EditField  matlab.ui.control.NumericEditField
        MagneticSuceptibilitycckgLabel  matlab.ui.control.Label
        Element_MagneticType_EditField  matlab.ui.control.EditField
        MagneticTypeLabel               matlab.ui.control.Label
        Element_NeutronCrossSection_EditField  matlab.ui.control.NumericEditField
        NeutronCrossSectionbarnEditFieldLabel  matlab.ui.control.Label
        Element_NeutronNumber_EditField  matlab.ui.control.NumericEditField
        NeutronNumberLabel              matlab.ui.control.Label
        Element_Radioactive_EditField   matlab.ui.control.EditField
        RadioactiveEditFieldLabel       matlab.ui.control.Label
        Element_FirstIonisation_EditField  matlab.ui.control.NumericEditField
        FirstIonisationeVEditFieldLabel  matlab.ui.control.Label
        Element_ElectronAffinity_EditField  matlab.ui.control.NumericEditField
        ElectronAffinityeVEditFieldLabel  matlab.ui.control.Label
        Element_Electronegativity_EditField  matlab.ui.control.NumericEditField
        ElectronegativityPaulingscaleEditFieldLabel  matlab.ui.control.Label
        Element_Phase_EditField         matlab.ui.control.EditField
        PhaseatSTPEditFieldLabel        matlab.ui.control.Label
        Element_AtomicRadius_EditField  matlab.ui.control.NumericEditField
        AtomicRadiusfmEditFieldLabel    matlab.ui.control.Label
        Element_ElectronConfiguration_EditField  matlab.ui.control.EditField
        ElectronConfigurationEditField_2Label  matlab.ui.control.Label
        Element_AtomicTerm_EditField    matlab.ui.control.EditField
        AtomicTermEditField_2Label      matlab.ui.control.Label
        Element_Information_Panel       matlab.ui.container.Panel
        Element_Information_GridLayout  matlab.ui.container.GridLayout
        Element_Classification_EditField  matlab.ui.control.EditField
        ClassificationEditField_2Label  matlab.ui.control.Label
        Element_Formula_EditField       matlab.ui.control.EditField
        FormulaLabel                    matlab.ui.control.Label
        Element_Name_EditField          matlab.ui.control.EditField
        NameLabel                       matlab.ui.control.Label
        Element_IDNumber_Spinner        matlab.ui.control.Spinner
        IDNumberSpinnerLabel            matlab.ui.control.Label
        Element_Number_Label            matlab.ui.control.Label
        SelectedElement_DropDown        matlab.ui.control.DropDown
        Compounds_Tab                   matlab.ui.container.Tab
        Compound_GridLayout             matlab.ui.container.GridLayout
        Compound_New_GridLayout         matlab.ui.container.GridLayout
        Compound_NewSave_Button         matlab.ui.control.Button
        Compound_NewCancel_Button       matlab.ui.control.Button
        Compound_Edit_GridLayout        matlab.ui.container.GridLayout
        Compound_EditSave_Button        matlab.ui.control.Button
        Compound_EditCancel_Button      matlab.ui.control.Button
        Compound_Export_GridLayout      matlab.ui.container.GridLayout
        Compound_Export_Button          matlab.ui.control.Button
        Compound_Save_Button            matlab.ui.control.Button
        Compound_Delete_Button          matlab.ui.control.Button
        Compound_Add_Button             matlab.ui.control.Button
        Compound_EditEntry_Button       matlab.ui.control.Button
        Compound_PhysicalProperties_Panel  matlab.ui.container.Panel
        Compound_PhysicalProperties_GridLayout  matlab.ui.container.GridLayout
        Compound_gamma_EditField        matlab.ui.control.NumericEditField
        gammadegreeEditFieldLabel_2     matlab.ui.control.Label
        Compound_c_EditField            matlab.ui.control.NumericEditField
        cpmEditFieldLabel_2             matlab.ui.control.Label
        Compound_beta_EditField         matlab.ui.control.NumericEditField
        betadegreeEditFieldLabel_2      matlab.ui.control.Label
        Compound_b_EditField            matlab.ui.control.NumericEditField
        bpmEditFieldLabel_2             matlab.ui.control.Label
        Compound_alpha_EditField        matlab.ui.control.NumericEditField
        alphadegreeEditFieldLabel_2     matlab.ui.control.Label
        Compound_a_EditField            matlab.ui.control.NumericEditField
        apmEditFieldLabel_2             matlab.ui.control.Label
        Compound_CrystalStructure_DropDown  matlab.ui.control.DropDown
        CrystalStructureLabel_3         matlab.ui.control.Label
        Compound_SpecificHeat_EditField  matlab.ui.control.NumericEditField
        SpecificHeatJgKEditFieldLabel_2  matlab.ui.control.Label
        Compound_MassDensity_EditField  matlab.ui.control.NumericEditField
        MassDensitygcm3Label_2          matlab.ui.control.Label
        Compound_ElectronicProperties_Panel  matlab.ui.container.Panel
        Compound_ElectronicProperties_GridLayout  matlab.ui.container.GridLayout
        Compound_WorkFunction_EditField  matlab.ui.control.NumericEditField
        WorkFunctioneVEditFieldLabel_3  matlab.ui.control.Label
        Compound_BandGap_EditField      matlab.ui.control.NumericEditField
        BandGapeVEditFieldLabel_3       matlab.ui.control.Label
        Compound_Resistivity_EditField  matlab.ui.control.NumericEditField
        ResistivitymOhmEditFieldLabel_3  matlab.ui.control.Label
        Compound_Properties_Panel       matlab.ui.container.Panel
        Compound_Properties_GridLayout  matlab.ui.container.GridLayout
        Compound_ElectronValency_EditField  matlab.ui.control.NumericEditField
        ElectronValencyLabel_2          matlab.ui.control.Label
        Compound_AtomicMass_EditField   matlab.ui.control.NumericEditField
        AtomicMassamuLabel_3            matlab.ui.control.Label
        Compound_AverageZNumber_EditField  matlab.ui.control.NumericEditField
        AverageZNumberLabel             matlab.ui.control.Label
        Compound_Stoichiometry_EditField  matlab.ui.control.NumericEditField
        StoichiometryLabel_2            matlab.ui.control.Label
        Compounds_OtherProperties_Panel  matlab.ui.container.Panel
        Compounds_OtherProperties_GridLayout  matlab.ui.container.GridLayout
        Compound_Information_Panel      matlab.ui.container.Panel
        Compound_Information_GridLayout  matlab.ui.container.GridLayout
        Compound_Classification_EditField  matlab.ui.control.EditField
        ClassificationEditField_2Label_2  matlab.ui.control.Label
        Compound_Formula_EditField      matlab.ui.control.EditField
        FormulaLabel_2                  matlab.ui.control.Label
        Compound_Name_EditField         matlab.ui.control.EditField
        NameEditFieldLabel_2            matlab.ui.control.Label
        Compound_IDNumber_Spinner       matlab.ui.control.Spinner
        IDNumberSpinnerLabel_2          matlab.ui.control.Label
        Compound_Number_Label           matlab.ui.control.Label
        SelectedCompound_DropDown       matlab.ui.control.DropDown
        BUTTONS_GridLayout              matlab.ui.container.GridLayout
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        AppIcon_Image                   matlab.ui.control.Image
        RESET2ORIGINAL_Button           matlab.ui.control.Button
        UNDOALL_Button                  matlab.ui.control.Button
        SAVE_DATABASE_Button            matlab.ui.control.Button
        TRANSFER_Button                 matlab.ui.control.Button
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
        AppTitle                        matlab.ui.control.Label
        MAINMENU_GridLayout             matlab.ui.container.GridLayout
        MAINMENU_Button                 matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % -- Materials Database
        material_database           % Materials Properties Database
        material_database_raw       % Raw Materials Properties Database
        % -- Element Properties
        num_of_elements             % Total number of elements listed
        selected_element_name       % String of the element selected
        selected_element_data       % Data-structure of the element selected
        element_edit_state          % 0 or 1 that defines the edit state of element
        % -- Compound Properties
        num_of_compounds            % Total number of compounds listed
        selected_compound_name      % String of the compound selected
        selected_compound_data      % Data-structure of the compound selected
        compound_edit_state         % 0 or 1 that defines the edit state of compound
        new_compound_state          % 0 or 1 that defines the new state of compound
        new_compound_name           % String of the new compound name
        new_compound_data           % Data-structure of the new compound data
    end
    
    % Private functions available to the app only
    methods (Access = private)
        %% Open Figure Locations 
        % Open Location of MainMenu App
        function open_MainMenu_to_left_of_App(app)
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1);
            fig.Position(2) = app.UIFigure.Position(2);
        end
        %% General functions
        % Check the crystal structure to the user-input and see if the match
        function str_crystal_dropdown = update_crystaltype_value(app, str_crystal)
            if contains(str_crystal,"CUB-cP-Oh");        str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{1};
            elseif contains(str_crystal,"CUB-cI-Oh");    str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{2};
            elseif contains(str_crystal,"CUB-cF-Oh");    str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{3};
            elseif contains(str_crystal,"HEX-hP-D6h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{4};
            elseif contains(str_crystal,"RHL-hR-D3d");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{5};
            elseif contains(str_crystal,"TET-tP-D4h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{6};
            elseif contains(str_crystal,"TET-tI-D4h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{7};
            elseif contains(str_crystal,"ORC-oP-D2h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{8};
            elseif contains(str_crystal,"ORC-oS-D2h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{9};
            elseif contains(str_crystal,"ORC-oI-D2h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{10};
            elseif contains(str_crystal,"ORC-oF-D2h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{11};
            elseif contains(str_crystal,"MCL-mP-C2h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{12};
            elseif contains(str_crystal,"MCL-mS-C2h");   str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{13};
            elseif contains(str_crystal,"TRI-aP-Ci");    str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{14};
            else; str_crystal_dropdown = app.Compound_CrystalStructure_DropDown.Items{15};
            end
        end

        % Append / edit an entry from the materials database and save it
        function save_to_materials_database(app, material_name, material_data)
            % -- Updating materials properties database
            MatData                 = app.material_database;
            MatData.(material_name) = material_data;
            % -- Update database to save the changes
            path_matbase            = what('Material Properties Database (MPD)'); path_matbase = string(path_matbase.path);
            path_data               = path_matbase + "\data\";
            save_fullfile           = path_data + sprintf("MatData");
            save(char(save_fullfile), 'MatData', '-v7.3');
            app.material_database   = MatData;
        end

        % Update the materials properties database
        function delete_from_materials_database(app, material_name)
            % - Deleting an entry from the materials database
            MatData         = app.material_database;
            id_num          = MatData.(material_name).id;
            MatData         = rmfield(MatData, material_name);
            % - Changing all ID numbers to ensure they are consecutive, increasing integers
            field_names     = fieldnames(MatData);
            id_end          = numel(field_names);
            for i = id_num:id_end
                MatData.(field_names{i}).id = MatData.(field_names{i}).id - 1;
            end
            % - Update database to save the changes
            path_matbase            = what('Material Properties Database (MPD)'); path_matbase = string(path_matbase.path);
            path_data               = path_matbase + "\data\";
            save_fullfile           = path_data + sprintf("MatData");
            save(char(save_fullfile), 'MatData', '-v7.3');
            app.material_database   = MatData;
        end

        % Undo all changes to the materials database
        function undo_all_changes_to_materials_database(app)
            % -- Loading in the raw materials database that was first loaded
            MatData                 = app.material_database_raw;
            % -- Update database to revert it to its original state
            path_matbase            = what('Material Properties Database (MPD)'); path_matbase = string(path_matbase.path);
            path_data               = path_matbase + "\data\";
            save_fullfile           = path_data + sprintf("MatData");
            save(char(save_fullfile), 'MatData', '-v7.3');
            app.material_database   = MatData;
            initialize_element_properties(app);
            initialize_compound_properties(app);
        end

        % Revert to backup materials database
        function revert_to_default_materials_database(app)
            % -- Loading in the raw materials database that was first loaded
            MatData                 = load('v20250222_MatData'); MatData = MatData.MatData;
            % -- Update database to revert it to its original state
            path_matbase            = what('Material Properties Database (MPD)'); path_matbase = string(path_matbase.path);
            path_data               = path_matbase + "\data\";
            save_fullfile           = path_data + sprintf("MatData");
            save(char(save_fullfile), 'MatData', '-v7.3');
            app.material_database       = MatData;
            app.material_database_raw   = MatData;
            initialize_element_properties(app);
            initialize_compound_properties(app);
        end

        %% Element functions
        % Extract all element names
        function element_names = extract_element_names(~)
            element_names = read_mpd_elements();
            for i = 1:length(element_names)
                element_names{i} = char(sprintf("%i-%s", i, element_names{i}));
            end
        end

        % Define all drop-down box names for elements
        function initialize_element_properties(app)
            element_names                           = extract_element_names(app);
            app.SelectedElement_DropDown.Items      = element_names;
            app.SelectedElement_DropDown.Value      = app.SelectedElement_DropDown.Items{1};
            app.num_of_elements                     = length(app.SelectedElement_DropDown.Items);
            app.Element_IDNumber_Spinner.Limits     = [1 app.num_of_elements];
            read_element_properties(app, app.Element_IDNumber_Spinner.Limits(1));
            app.Element_Number_Label.Text           = sprintf("%i Element Entries", app.num_of_elements);
        end

        % Activate element fields for editing
        function activate_element_edit_fields(app)
            if app.element_edit_state == 1
                background_col = [0.85 1 1];
                % - Activating the field
                % -- Element Properties
                app.Element_ElectronValency_EditField.Editable              = "on";
                % -- Element Electronic Properties
                app.Element_WorkFunction_EditField.Editable                 = "on";
                app.Element_BandGap_EditField.Editable                      = "on";
                app.Element_Resistivity_EditField.Editable                  = "on";
                % -- Element Physical Properties
                app.Element_CrystalStructure_DropDown.Enable                = "on";
                app.Element_a_EditField.Editable                            = "on";
                app.Element_alpha_EditField.Editable                        = "on";
                app.Element_b_EditField.Editable                            = "on";
                app.Element_beta_EditField.Editable                         = "on";
                app.Element_c_EditField.Editable                            = "on";
                app.Element_gamma_EditField.Editable                        = "on";
                app.Element_MassDensity_EditField.Editable                  = "on";
                app.Element_SpecificHeat_EditField.Editable                 = "on";
                % -- Element Other Properties
                app.Element_MagneticSuceptibility_EditField.Editable        = "on";
                app.Element_MagneticType_EditField.Editable                 = "on";
                app.Element_NeutronCrossSection_EditField.Editable          = "on";
                app.Element_NeutronNumber_EditField.Editable                = "on";
                app.Element_Radioactive_EditField.Editable                  = "on";
                app.Element_FirstIonisation_EditField.Editable              = "on";
                app.Element_ElectronAffinity_EditField.Editable             = "on";
                app.Element_Electronegativity_EditField.Editable            = "on";
                app.Element_Phase_EditField.Editable                        = "on";
                app.Element_AtomicRadius_EditField.Editable                 = "on";
                % - Changing the background color
                % -- Element Electronic Properties
                app.Element_ElectronValency_EditField.BackgroundColor       = background_col;
                % -- Element Electronic Properties
                app.Element_WorkFunction_EditField.BackgroundColor          = background_col;
                app.Element_BandGap_EditField.BackgroundColor               = background_col;
                app.Element_Resistivity_EditField.BackgroundColor           = background_col;
                % -- Element Physical Properties
                app.Element_CrystalStructure_DropDown.BackgroundColor       = background_col;
                app.Element_a_EditField.BackgroundColor                     = background_col;
                app.Element_alpha_EditField.BackgroundColor                 = background_col;
                app.Element_b_EditField.BackgroundColor                     = background_col;
                app.Element_beta_EditField.BackgroundColor                  = background_col;
                app.Element_c_EditField.BackgroundColor                     = background_col;
                app.Element_gamma_EditField.BackgroundColor                 = background_col;
                app.Element_MassDensity_EditField.BackgroundColor           = background_col;
                app.Element_SpecificHeat_EditField.BackgroundColor          = background_col;
                % -- Element Other Properties
                app.Element_MagneticSuceptibility_EditField.BackgroundColor = background_col;
                app.Element_MagneticType_EditField.BackgroundColor          = background_col;
                app.Element_NeutronCrossSection_EditField.BackgroundColor   = background_col;
                app.Element_NeutronNumber_EditField.BackgroundColor         = background_col;
                app.Element_Radioactive_EditField.BackgroundColor           = background_col;
                app.Element_FirstIonisation_EditField.BackgroundColor       = background_col;
                app.Element_ElectronAffinity_EditField.BackgroundColor      = background_col;
                app.Element_Electronegativity_EditField.BackgroundColor     = background_col;
                app.Element_Phase_EditField.BackgroundColor                 = background_col;
                app.Element_AtomicRadius_EditField.BackgroundColor          = background_col;
                % -- Other Buttons
                app.Element_EditEntry_Button.Enable                         = "off";
                app.Element_EditSave_Button.Enable                          = "on";
                app.Element_EditCancel_Button.Enable                        = "on";
            elseif app.element_edit_state == 0
                background_col = [1 1 1];
                % - Activating the field
                % -- Element Properties
                app.Element_ElectronValency_EditField.Editable              = "off";
                % -- Element Electronic Properties
                app.Element_WorkFunction_EditField.Editable                 = "off";
                app.Element_BandGap_EditField.Editable                      = "off";
                app.Element_Resistivity_EditField.Editable                  = "off";
                % -- Element Physical Properties
                app.Element_CrystalStructure_DropDown.Enable                = "off";
                app.Element_a_EditField.Editable                            = "off";
                app.Element_alpha_EditField.Editable                        = "off";
                app.Element_b_EditField.Editable                            = "off";
                app.Element_beta_EditField.Editable                         = "off";
                app.Element_c_EditField.Editable                            = "off";
                app.Element_gamma_EditField.Editable                        = "off";
                app.Element_MassDensity_EditField.Editable                  = "off";
                app.Element_SpecificHeat_EditField.Editable                 = "off";
                % -- Element Other Properties
                app.Element_MagneticSuceptibility_EditField.Editable        = "off";
                app.Element_MagneticType_EditField.Editable                 = "off";
                app.Element_NeutronCrossSection_EditField.Editable          = "off";
                app.Element_NeutronNumber_EditField.Editable                = "off";
                app.Element_Radioactive_EditField.Editable                  = "off";
                app.Element_FirstIonisation_EditField.Editable              = "off";
                app.Element_ElectronAffinity_EditField.Editable             = "off";
                app.Element_Electronegativity_EditField.Editable            = "off";
                app.Element_Phase_EditField.Editable                        = "off";
                app.Element_AtomicRadius_EditField.Editable                 = "off";
                % - Changing the background color
                % -- Element Electronic Properties
                app.Element_ElectronValency_EditField.BackgroundColor       = background_col;
                % -- Element Electronic Properties
                app.Element_WorkFunction_EditField.BackgroundColor          = background_col;
                app.Element_BandGap_EditField.BackgroundColor               = background_col;
                app.Element_Resistivity_EditField.BackgroundColor           = background_col;
                % -- Element Physical Properties
                app.Element_CrystalStructure_DropDown.BackgroundColor       = background_col;
                app.Element_a_EditField.BackgroundColor                     = background_col;
                app.Element_alpha_EditField.BackgroundColor                 = background_col;
                app.Element_b_EditField.BackgroundColor                     = background_col;
                app.Element_beta_EditField.BackgroundColor                  = background_col;
                app.Element_c_EditField.BackgroundColor                     = background_col;
                app.Element_gamma_EditField.BackgroundColor                 = background_col;
                app.Element_MassDensity_EditField.BackgroundColor           = background_col;
                app.Element_SpecificHeat_EditField.BackgroundColor          = background_col;
                % -- Element Other Properties
                app.Element_MagneticSuceptibility_EditField.BackgroundColor = background_col;
                app.Element_MagneticType_EditField.BackgroundColor          = background_col;
                app.Element_NeutronCrossSection_EditField.BackgroundColor   = background_col;
                app.Element_NeutronNumber_EditField.BackgroundColor         = background_col;
                app.Element_Radioactive_EditField.BackgroundColor           = background_col;
                app.Element_FirstIonisation_EditField.BackgroundColor       = background_col;
                app.Element_ElectronAffinity_EditField.BackgroundColor      = background_col;
                app.Element_Electronegativity_EditField.BackgroundColor     = background_col;
                app.Element_Phase_EditField.BackgroundColor                 = background_col;
                app.Element_AtomicRadius_EditField.BackgroundColor          = background_col;
                % -- Other Buttons
                app.Element_EditEntry_Button.Enable                         = "on";
                app.Element_EditSave_Button.Enable                          = "off";
                app.Element_EditCancel_Button.Enable                        = "off";
            end
        end

        % Read element properties from materials database
        function read_element_properties(app, selected_element)
            app.selected_element_data                               = get_mpd_props(selected_element);
            app.selected_element_name                               = app.selected_element_data.formula;
            % -- Element DropDown
            element_names                                           = extract_element_names(app);
            app.SelectedElement_DropDown.Value                      = element_names{app.selected_element_data.id};
            % -- Element Information
            app.Element_IDNumber_Spinner.Value                      = app.selected_element_data.id;
            app.Element_Name_EditField.Value                        = app.selected_element_data.name;
            app.Element_Formula_EditField.Value                     = app.selected_element_data.formula;
            app.Element_Classification_EditField.Value              = app.selected_element_data.class;
            % -- Element Properties
            app.Element_Stoichiometry_EditField.Value               = app.selected_element_data.atom_stoic;
            app.Element_AtomicZNumber_EditField.Value               = app.selected_element_data.atom_z;
            app.Element_AtomicMass_EditField.Value                  = app.selected_element_data.atom_mass;
            if ~isnan(app.selected_element_data.atom_radius)
                    app.Element_AtomicRadius_EditField.Value        = app.selected_element_data.atom_radius;
            else;   app.Element_AtomicRadius_EditField.Value        = [];
            end
            app.Element_AtomicTerm_EditField.Value                  = app.selected_element_data.atom_term;
            app.Element_ElectronConfiguration_EditField.Value       = app.selected_element_data.elec_config;
            app.Element_ElectronValency_EditField.Value             = app.selected_element_data.elec_valency;
            if ~isnan(app.selected_element_data.elec_negativity)
                    app.Element_Electronegativity_EditField.Value   = app.selected_element_data.elec_negativity;
            else;   app.Element_Electronegativity_EditField.Value   = [];
            end
            if ~isnan(app.selected_element_data.elec_affinity)
                    app.Element_ElectronAffinity_EditField.Value    = app.selected_element_data.elec_affinity;
            else;   app.Element_ElectronAffinity_EditField.Value    = [];
            end
            if ~isnan(app.selected_element_data.elec_first_ionization)
                    app.Element_FirstIonisation_EditField.Value     = app.selected_element_data.elec_first_ionization;
            else;   app.Element_FirstIonisation_EditField.Value     = [];
            end
            app.Element_Radioactive_EditField.Value                 = app.selected_element_data.radioactivity;
            app.Element_NeutronNumber_EditField.Value               = app.selected_element_data.neut_number;
            if ~isnan(app.selected_element_data.neut_xsect)
                    app.Element_NeutronCrossSection_EditField.Value = app.selected_element_data.neut_xsect;
            else;   app.Element_NeutronCrossSection_EditField.Value = [];
            end
            % -- Element Physical Properties
            app.Element_Phase_EditField.Value                       = app.selected_element_data.phase_stp;
            if ~isnan(app.selected_element_data.specific_heat)
                    app.Element_SpecificHeat_EditField.Value        = app.selected_element_data.specific_heat;
            else;   app.Element_SpecificHeat_EditField.Value        = [];
            end
            if ~isnan(app.selected_element_data.density)
                    app.Element_MassDensity_EditField.Value         = app.selected_element_data.density;
            else;   app.Element_MassDensity_EditField.Value         = [];
            end
            app.Element_CrystalStructure_DropDown.Value             = update_crystaltype_value(app, app.selected_element_data.crystal_structure);
            if ~isnan(app.selected_element_data.crystal_a)
                    app.Element_a_EditField.Value                   = app.selected_element_data.crystal_a;
            else;   app.Element_a_EditField.Value                   = [];
            end
            if ~isnan(app.selected_element_data.crystal_b)
                    app.Element_b_EditField.Value                   = app.selected_element_data.crystal_b;
            else;   app.Element_b_EditField.Value                   = [];
            end
            if ~isnan(app.selected_element_data.crystal_c)
                    app.Element_c_EditField.Value                   = app.selected_element_data.crystal_c;
            else;   app.Element_c_EditField.Value                   = [];
            end
            if ~isnan(app.selected_element_data.crystal_alpha)
                    app.Element_alpha_EditField.Value               = app.selected_element_data.crystal_alpha;
            else;   app.Element_alpha_EditField.Value               = [];
            end
            if ~isnan(app.selected_element_data.crystal_beta)
                    app.Element_beta_EditField.Value                = app.selected_element_data.crystal_beta;
            else;   app.Element_beta_EditField.Value                = [];
            end
            if ~isnan(app.selected_element_data.crystal_gamma)
                    app.Element_gamma_EditField.Value               = app.selected_element_data.crystal_gamma;
            else;   app.Element_gamma_EditField.Value               = [];
            end

            % -- Element Electromagnetic Properties
            if ~isnan(app.selected_element_data.elec_resistivity)
                    app.Element_Resistivity_EditField.Value         = app.selected_element_data.elec_resistivity;
            else;   app.Element_Resistivity_EditField.Value         = [];
            end
            if ~isnan(app.selected_element_data.elec_band_gap)
                    app.Element_BandGap_EditField.Value             = app.selected_element_data.elec_band_gap;
            else;   app.Element_BandGap_EditField.Value             = [];
            end
            if ~isnan(app.selected_element_data.elec_work_function)
                    app.Element_WorkFunction_EditField.Value        = app.selected_element_data.elec_work_function;
            else;   app.Element_WorkFunction_EditField.Value        = [];
            end
            app.Element_MagneticType_EditField.Value                = app.selected_element_data.magn_type;
            if ~isnan(app.selected_element_data.magn_susceptibility)
                    app.Element_MagneticSuceptibility_EditField.Value   = app.selected_element_data.magn_susceptibility;
            else;   app.Element_MagneticSuceptibility_EditField.Value   = [];
            end
            app.Element_CrystalStructure_DropDown.Editable          = "off"; 
        end

        % Write element properties from materials database
        function write_element_properties(app)
            % -- Element Properties
            app.selected_element_data.elec_valency              = app.Element_ElectronValency_EditField.Value;
            % -- Element Electronic Properties
            app.selected_element_data.elec_work_function        = app.Element_WorkFunction_EditField.Value;
            app.selected_element_data.elec_band_gap             = app.Element_BandGap_EditField.Value;
            app.selected_element_data.elec_resistivity          = app.Element_Resistivity_EditField.Value;
            % -- Element Physical Properties
            app.selected_element_data.crystal_structure         = app.Element_CrystalStructure_DropDown.Value;
            app.selected_element_data.crystal_a                 = app.Element_a_EditField.Value;
            app.selected_element_data.crystal_alpha             = app.Element_alpha_EditField.Value;
            app.selected_element_data.crystal_b                 = app.Element_b_EditField.Value;
            app.selected_element_data.crystal_beta              = app.Element_beta_EditField.Value;
            app.selected_element_data.crystal_c                 = app.Element_c_EditField.Value;
            app.selected_element_data.crystal_gamma             = app.Element_gamma_EditField.Value;
            app.selected_element_data.density                   = app.Element_MassDensity_EditField.Value;
            app.selected_element_data.specific_heat             = app.Element_SpecificHeat_EditField.Value;
            % -- Element Other Properties
            app.selected_element_data.magn_susceptibility       = app.Element_MagneticSuceptibility_EditField.Value;
            app.selected_element_data.magn_type                 = app.Element_MagneticType_EditField.Value;
            app.selected_element_data.neut_xsect                = app.Element_NeutronCrossSection_EditField.Value;
            app.selected_element_data.neut_number               = app.Element_NeutronNumber_EditField.Value;
            app.selected_element_data.radioactivity             = app.Element_Radioactive_EditField.Value;
            app.selected_element_data.elec_first_ionization     = app.Element_FirstIonisation_EditField.Value;
            app.selected_element_data.elec_affinity             = app.Element_ElectronAffinity_EditField.Value;
            app.selected_element_data.elec_negativity           = app.Element_Electronegativity_EditField.Value;
            app.selected_element_data.phase_stp                 = app.Element_Phase_EditField.Value;
            app.selected_element_data.atom_radius               = app.Element_AtomicRadius_EditField.Value;
            % -- Updating materials properties database
            save_to_materials_database(app, app.selected_element_name, app.selected_element_data);
        end

        %% Compound functions
        % Extract all compound names
        function compound_names = extract_compound_names(app)
            compound_names = read_mpd_compounds();
            for i = 1:length(compound_names)
                compound_names{i} = char(sprintf("%i-%s", app.num_of_elements+i, compound_names{i}));
            end
        end

        % Define all drop-down box names for compounds
        function initialize_compound_properties(app)
            compound_names                          = extract_compound_names(app);
            app.SelectedCompound_DropDown.Items     = compound_names;
            app.SelectedCompound_DropDown.Value     = app.SelectedCompound_DropDown.Items{1};
            app.num_of_compounds                    = length(app.SelectedCompound_DropDown.Items);
            app.Compound_IDNumber_Spinner.Limits    = app.num_of_elements+[1 app.num_of_compounds];
            read_compound_properties(app, app.Compound_IDNumber_Spinner.Limits(1));
            app.Compound_Number_Label.Text          = sprintf("%i Compound Entries", app.num_of_compounds);
        end

        % Activate compound fields for editing
        function activate_compound_edit_fields(app)
            if app.compound_edit_state == 1
                background_col = [0.85 1 1];
                % - Activating the field
                % -- Compound Properties
                app.Compound_Stoichiometry_EditField.Editable               = "on";
                app.Compound_AverageZNumber_EditField.Editable              = "on";
                app.Compound_AtomicMass_EditField.Editable                  = "on";
                app.Compound_ElectronValency_EditField.Editable             = "on";
                % -- Compound Electronic Properties
                app.Compound_WorkFunction_EditField.Editable                = "on";
                app.Compound_BandGap_EditField.Editable                     = "on";
                app.Compound_Resistivity_EditField.Editable                 = "on";
                % -- Compound Physical Properties
                app.Compound_CrystalStructure_DropDown.Enable               = "on";
                app.Compound_a_EditField.Editable                           = "on";
                app.Compound_alpha_EditField.Editable                       = "on";
                app.Compound_b_EditField.Editable                           = "on";
                app.Compound_beta_EditField.Editable                        = "on";
                app.Compound_c_EditField.Editable                           = "on";
                app.Compound_gamma_EditField.Editable                       = "on";
                app.Compound_MassDensity_EditField.Editable                 = "on";
                app.Compound_SpecificHeat_EditField.Editable                = "on";
                % - Changing the background color
                % -- Compound Electronic Properties
                app.Compound_Stoichiometry_EditField.BackgroundColor        = background_col;
                app.Compound_AverageZNumber_EditField.BackgroundColor       = background_col;
                app.Compound_AtomicMass_EditField.BackgroundColor           = background_col;
                app.Compound_ElectronValency_EditField.BackgroundColor      = background_col;
                % -- Compound Electronic Properties
                app.Compound_WorkFunction_EditField.BackgroundColor         = background_col;
                app.Compound_BandGap_EditField.BackgroundColor              = background_col;
                app.Compound_Resistivity_EditField.BackgroundColor          = background_col;
                % -- Compound Physical Properties
                app.Compound_CrystalStructure_DropDown.BackgroundColor      = background_col;
                app.Compound_a_EditField.BackgroundColor                    = background_col;
                app.Compound_alpha_EditField.BackgroundColor                = background_col;
                app.Compound_b_EditField.BackgroundColor                    = background_col;
                app.Compound_beta_EditField.BackgroundColor                 = background_col;
                app.Compound_c_EditField.BackgroundColor                    = background_col;
                app.Compound_gamma_EditField.BackgroundColor                = background_col;
                app.Compound_MassDensity_EditField.BackgroundColor          = background_col;
                app.Compound_SpecificHeat_EditField.BackgroundColor         = background_col;
                % -- Other Buttons
                app.Compound_EditEntry_Button.Enable                        = "off";
                app.Compound_EditSave_Button.Enable                         = "on";
                app.Compound_EditCancel_Button.Enable                       = "on";
                app.Compound_Add_Button.Enable                              = "off";
                app.Compound_NewCancel_Button.Enable                        = "off";
                app.Compound_NewSave_Button.Enable                          = "off";
                app.Compound_Delete_Button.Enable                           = "off";
            elseif app.compound_edit_state == 0
                background_col = [1 1 1];
                % - Activating the field
                % -- Compound Properties
                app.Compound_Stoichiometry_EditField.Editable               = "off";
                app.Compound_AverageZNumber_EditField.Editable              = "off";
                app.Compound_AtomicMass_EditField.Editable                  = "off";
                app.Compound_ElectronValency_EditField.Editable             = "off";
                % -- Compound Electronic Properties
                app.Compound_WorkFunction_EditField.Editable                = "off";
                app.Compound_BandGap_EditField.Editable                     = "off";
                app.Compound_Resistivity_EditField.Editable                 = "off";
                % -- Compound Physical Properties
                app.Compound_CrystalStructure_DropDown.Enable               = "off";
                app.Compound_a_EditField.Editable                           = "off";
                app.Compound_alpha_EditField.Editable                       = "off";
                app.Compound_b_EditField.Editable                           = "off";
                app.Compound_beta_EditField.Editable                        = "off";
                app.Compound_c_EditField.Editable                           = "off";
                app.Compound_gamma_EditField.Editable                       = "off";
                app.Compound_MassDensity_EditField.Editable                 = "off";
                app.Compound_SpecificHeat_EditField.Editable                = "off";
                % - Changing the background color
                % -- Compound Electronic Properties
                app.Compound_Stoichiometry_EditField.BackgroundColor        = background_col;
                app.Compound_AverageZNumber_EditField.BackgroundColor       = background_col;
                app.Compound_AtomicMass_EditField.BackgroundColor           = background_col;
                app.Compound_ElectronValency_EditField.BackgroundColor      = background_col;
                % -- Compound Electronic Properties
                app.Compound_WorkFunction_EditField.BackgroundColor         = background_col;
                app.Compound_BandGap_EditField.BackgroundColor              = background_col;
                app.Compound_Resistivity_EditField.BackgroundColor          = background_col;
                % -- Compound Physical Properties
                app.Compound_CrystalStructure_DropDown.BackgroundColor      = background_col;
                app.Compound_a_EditField.BackgroundColor                    = background_col;
                app.Compound_alpha_EditField.BackgroundColor                = background_col;
                app.Compound_b_EditField.BackgroundColor                    = background_col;
                app.Compound_beta_EditField.BackgroundColor                 = background_col;
                app.Compound_c_EditField.BackgroundColor                    = background_col;
                app.Compound_gamma_EditField.BackgroundColor                = background_col;
                app.Compound_MassDensity_EditField.BackgroundColor          = background_col;
                app.Compound_SpecificHeat_EditField.BackgroundColor         = background_col;
                % -- Other Buttons
                app.Compound_EditEntry_Button.Enable                        = "on";
                app.Compound_EditSave_Button.Enable                         = "off";
                app.Compound_EditCancel_Button.Enable                       = "off";
                app.Compound_Add_Button.Enable                              = "on";
                app.Compound_NewCancel_Button.Enable                        = "off";
                app.Compound_NewSave_Button.Enable                          = "off";
                app.Compound_Delete_Button.Enable                           = "on";
            end
        end
        
        % Read compound properties from materials database
        function read_compound_properties(app, selected_compound)
            app.selected_compound_data                                      = get_mpd_props(selected_compound);
            app.selected_compound_name                                      = app.selected_compound_data.formula;
            % -- Element DropDown
            compound_names                                                  = extract_compound_names(app);
            app.SelectedCompound_DropDown.Value                             = compound_names{app.selected_compound_data.id-app.num_of_elements};
            % -- Compound Information
            app.Compound_IDNumber_Spinner.Value                             = app.selected_compound_data.id;
            app.Compound_Name_EditField.Value                               = app.selected_compound_data.name;
            app.Compound_Formula_EditField.Value                            = app.selected_compound_data.formula;
            app.Compound_Classification_EditField.Value                     = app.selected_compound_data.class;
            % -- Compound Properties
            app.Compound_Stoichiometry_EditField.Value                      = app.selected_compound_data.atom_stoic;
            app.Compound_AverageZNumber_EditField.Value                     = app.selected_compound_data.atom_z;
            app.Compound_AtomicMass_EditField.Value                         = app.selected_compound_data.atom_mass;
            app.Compound_ElectronValency_EditField.Value                    = app.selected_compound_data.elec_valency;
            if ~isnan(app.selected_compound_data.elec_resistivity)
                    app.Compound_Resistivity_EditField.Value                = app.selected_compound_data.elec_resistivity;
            else;   app.Compound_Resistivity_EditField.Value                = [];
            end
            if ~isnan(app.selected_compound_data.elec_band_gap)
                    app.Compound_BandGap_EditField.Value                    = app.selected_compound_data.elec_band_gap;
            else;   app.Compound_BandGap_EditField.Value                    = [];
            end
            if ~isnan(app.selected_compound_data.elec_work_function)
                    app.Compound_WorkFunction_EditField.Value               = app.selected_compound_data.elec_work_function;
            else;   app.Compound_WorkFunction_EditField.Value               = [];
            end
            % -- Compound Physical Properties
            if ~isnan(app.selected_compound_data.density)
                    app.Compound_MassDensity_EditField.Value                = app.selected_compound_data.density;
            else;   app.Compound_MassDensity_EditField.Value                = [];
            end
            app.Compound_CrystalStructure_DropDown.Value                    = update_crystaltype_value(app, app.selected_compound_data.crystal_structure);
            if ~isnan(app.selected_compound_data.crystal_a)
                    app.Compound_a_EditField.Value                          = app.selected_compound_data.crystal_a;
            else;   app.Compound_a_EditField.Value                          = [];
            end
            if ~isnan(app.selected_compound_data.crystal_b)
                    app.Compound_b_EditField.Value                          = app.selected_compound_data.crystal_b;
            else;   app.Compound_b_EditField.Value                          = [];
            end
            if ~isnan(app.selected_compound_data.crystal_c)
                    app.Compound_c_EditField.Value                          = app.selected_compound_data.crystal_c;
            else;   app.Compound_c_EditField.Value                          = [];
            end
            if ~isnan(app.selected_compound_data.crystal_alpha)
                    app.Compound_alpha_EditField.Value                      = app.selected_compound_data.crystal_alpha;
            else;   app.Compound_alpha_EditField.Value                      = [];
            end
            if ~isnan(app.selected_compound_data.crystal_beta)
                    app.Compound_beta_EditField.Value                       = app.selected_compound_data.crystal_beta;
            else;   app.Compound_beta_EditField.Value                       = [];
            end
            if ~isnan(app.selected_compound_data.crystal_gamma)
                    app.Compound_gamma_EditField.Value                      = app.selected_compound_data.crystal_gamma;
            else;   app.Compound_gamma_EditField.Value                      = [];
            end
        end

        % Write compound properties from materials database
        function write_compound_properties(app)
            % -- Compound Properties
            app.selected_compound_data.atom_stoic                = app.Compound_Stoichiometry_EditField.Value;
            app.selected_compound_data.atom_z                    = app.Compound_AverageZNumber_EditField.Value;
            app.selected_compound_data.atom_mass                 = app.Compound_AtomicMass_EditField.Value;
            app.selected_compound_data.elec_valency              = app.Compound_ElectronValency_EditField.Value;
            % -- Compound Electronic Properties
            app.selected_compound_data.elec_work_function        = app.Compound_WorkFunction_EditField.Value;
            app.selected_compound_data.elec_band_gap             = app.Compound_BandGap_EditField.Value;
            app.selected_compound_data.elec_resistivity          = app.Compound_Resistivity_EditField.Value;
            % -- Compound Physical Properties
            app.selected_compound_data.crystal_structure         = app.Compound_CrystalStructure_DropDown.Value;
            app.selected_compound_data.crystal_a                 = app.Compound_a_EditField.Value;
            app.selected_compound_data.crystal_alpha             = app.Compound_alpha_EditField.Value;
            app.selected_compound_data.crystal_b                 = app.Compound_b_EditField.Value;
            app.selected_compound_data.crystal_beta              = app.Compound_beta_EditField.Value;
            app.selected_compound_data.crystal_c                 = app.Compound_c_EditField.Value;
            app.selected_compound_data.crystal_gamma             = app.Compound_gamma_EditField.Value;
            app.selected_compound_data.density                   = app.Compound_MassDensity_EditField.Value;
            app.selected_compound_data.specific_heat             = app.Compound_SpecificHeat_EditField.Value;
            % -- Updating materials properties database
            save_to_materials_database(app, app.selected_compound_name, app.selected_compound_data);
        end

        % Activate compound fields to be defined for a new material
        function activate_new_compound_edit_fields(app)
            if app.new_compound_state == 1
                background_col = [0.85 1 1];
                % - Activating the field
                app.Compound_IDNumber_Spinner.Enable                        = "off";
                app.SelectedCompound_DropDown.Enable                        = "off";
                % -- Compound Identifiers
                app.Compound_Name_EditField.Editable                        = "on";
                app.Compound_Classification_EditField.Editable              = "on";
                % -- Compound Properties
                app.Compound_Stoichiometry_EditField.Editable               = "on";
                app.Compound_AverageZNumber_EditField.Editable              = "on";
                app.Compound_AtomicMass_EditField.Editable                  = "on";
                app.Compound_ElectronValency_EditField.Editable             = "on";
                % -- Compound Electronic Properties
                app.Compound_WorkFunction_EditField.Editable                = "on";
                app.Compound_BandGap_EditField.Editable                     = "on";
                app.Compound_Resistivity_EditField.Editable                 = "on";
                % -- Compound Physical Properties
                app.Compound_CrystalStructure_DropDown.Enable               = "on";
                app.Compound_a_EditField.Editable                           = "on";
                app.Compound_alpha_EditField.Editable                       = "on";
                app.Compound_b_EditField.Editable                           = "on";
                app.Compound_beta_EditField.Editable                        = "on";
                app.Compound_c_EditField.Editable                           = "on";
                app.Compound_gamma_EditField.Editable                       = "on";
                app.Compound_MassDensity_EditField.Editable                 = "on";
                app.Compound_SpecificHeat_EditField.Editable                = "on";
                % - Changing the background color
                % -- Compound Identifiers
                app.Compound_Name_EditField.BackgroundColor                 = background_col;
                app.Compound_Classification_EditField.BackgroundColor       = background_col;
                % -- Compound Electronic Properties
                app.Compound_Stoichiometry_EditField.BackgroundColor        = background_col;
                app.Compound_AverageZNumber_EditField.BackgroundColor       = background_col;
                app.Compound_AtomicMass_EditField.BackgroundColor           = background_col;
                app.Compound_ElectronValency_EditField.BackgroundColor      = background_col;
                % -- Compound Electronic Properties
                app.Compound_WorkFunction_EditField.BackgroundColor         = background_col;
                app.Compound_BandGap_EditField.BackgroundColor              = background_col;
                app.Compound_Resistivity_EditField.BackgroundColor          = background_col;
                % -- Compound Physical Properties
                app.Compound_CrystalStructure_DropDown.BackgroundColor      = background_col;
                app.Compound_a_EditField.BackgroundColor                    = background_col;
                app.Compound_alpha_EditField.BackgroundColor                = background_col;
                app.Compound_b_EditField.BackgroundColor                    = background_col;
                app.Compound_beta_EditField.BackgroundColor                 = background_col;
                app.Compound_c_EditField.BackgroundColor                    = background_col;
                app.Compound_gamma_EditField.BackgroundColor                = background_col;
                app.Compound_MassDensity_EditField.BackgroundColor          = background_col;
                app.Compound_SpecificHeat_EditField.BackgroundColor         = background_col;
                % -- Other Buttons
                app.Compound_EditEntry_Button.Enable                        = "off";
                app.Compound_EditSave_Button.Enable                         = "off";
                app.Compound_EditCancel_Button.Enable                       = "off";
                app.Compound_Add_Button.Enable                              = "off";
                app.Compound_NewCancel_Button.Enable                        = "on";
                app.Compound_NewSave_Button.Enable                          = "on";
                app.Compound_Delete_Button.Enable                           = "off";
            elseif app.new_compound_state == 0
                background_col = [1 1 1];
                % - Activating the field
                app.Compound_IDNumber_Spinner.Enable                        = "on";
                app.SelectedCompound_DropDown.Enable                        = "on";
                % -- Compound Identifiers
                app.Compound_Name_EditField.Editable                        = "off";
                app.Compound_Classification_EditField.Editable              = "off";
                % -- Compound Properties
                app.Compound_Stoichiometry_EditField.Editable               = "off";
                app.Compound_AverageZNumber_EditField.Editable              = "off";
                app.Compound_AtomicMass_EditField.Editable                  = "off";
                app.Compound_ElectronValency_EditField.Editable             = "off";
                % -- Compound Electronic Properties
                app.Compound_WorkFunction_EditField.Editable                = "off";
                app.Compound_BandGap_EditField.Editable                     = "off";
                app.Compound_Resistivity_EditField.Editable                 = "off";
                % -- Compound Physical Properties
                app.Compound_CrystalStructure_DropDown.Enable               = "off";
                app.Compound_a_EditField.Editable                           = "off";
                app.Compound_alpha_EditField.Editable                       = "off";
                app.Compound_b_EditField.Editable                           = "off";
                app.Compound_beta_EditField.Editable                        = "off";
                app.Compound_c_EditField.Editable                           = "off";
                app.Compound_gamma_EditField.Editable                       = "off";
                app.Compound_MassDensity_EditField.Editable                 = "off";
                app.Compound_SpecificHeat_EditField.Editable                = "off";
                % - Changing the background color
                % -- Compound Identifiers
                app.Compound_Name_EditField.BackgroundColor                 = background_col;
                app.Compound_Classification_EditField.BackgroundColor       = background_col;
                % -- Compound Electronic Properties
                app.Compound_Stoichiometry_EditField.BackgroundColor        = background_col;
                app.Compound_AverageZNumber_EditField.BackgroundColor       = background_col;
                app.Compound_AtomicMass_EditField.BackgroundColor           = background_col;
                app.Compound_ElectronValency_EditField.BackgroundColor      = background_col;
                % -- Compound Electronic Properties
                app.Compound_WorkFunction_EditField.BackgroundColor         = background_col;
                app.Compound_BandGap_EditField.BackgroundColor              = background_col;
                app.Compound_Resistivity_EditField.BackgroundColor          = background_col;
                % -- Compound Physical Properties
                app.Compound_CrystalStructure_DropDown.BackgroundColor      = background_col;
                app.Compound_a_EditField.BackgroundColor                    = background_col;
                app.Compound_alpha_EditField.BackgroundColor                = background_col;
                app.Compound_b_EditField.BackgroundColor                    = background_col;
                app.Compound_beta_EditField.BackgroundColor                 = background_col;
                app.Compound_c_EditField.BackgroundColor                    = background_col;
                app.Compound_gamma_EditField.BackgroundColor                = background_col;
                app.Compound_MassDensity_EditField.BackgroundColor          = background_col;
                app.Compound_SpecificHeat_EditField.BackgroundColor         = background_col;
                % -- Other Buttons
                app.Compound_EditEntry_Button.Enable                        = "on";
                app.Compound_EditSave_Button.Enable                         = "off";
                app.Compound_EditCancel_Button.Enable                       = "off";
                app.Compound_Add_Button.Enable                              = "on";
                app.Compound_NewCancel_Button.Enable                        = "off";
                app.Compound_NewSave_Button.Enable                          = "off";
                app.Compound_Delete_Button.Enable                           = "on";
            end
        end

        % Crate a blank entry for a new material
        function create_new_compound_entry(app)
            % - Finding the next id number
            field_names = fieldnames(app.material_database);
            id_end      = numel(field_names);
            id_number   = id_end + 1;
            % - Defining a new compound data structure
            app.new_compound_data                           = struct();
            % -- Compound Identifiers
            app.new_compound_data.id                        = id_number;
            app.new_compound_data.type                      = "Compound";
            app.new_compound_data.class                     = [];
            app.new_compound_data.name                      = [];
            app.new_compound_data.formula                   = "Placeholder";
            % -- Compound Properties
            app.new_compound_data.atom_stoic                = [];
            app.new_compound_data.atom_z                    = [];
            app.new_compound_data.atom_mass                 = [];
            app.new_compound_data.elec_valency              = [];
            % -- Compound Electronic Properties
            app.new_compound_data.elec_work_function        = [];
            app.new_compound_data.elec_band_gap             = [];
            app.new_compound_data.elec_resistivity          = [];
            % -- Compound Physical Properties
            app.new_compound_data.crystal_structure         = [];
            app.new_compound_data.crystal_a                 = [];
            app.new_compound_data.crystal_alpha             = [];
            app.new_compound_data.crystal_b                 = [];
            app.new_compound_data.crystal_beta              = [];
            app.new_compound_data.crystal_c                 = [];
            app.new_compound_data.crystal_gamma             = [];
            app.new_compound_data.density                   = [];
            app.new_compound_data.specific_heat             = [];
            % - Update the compound ID number spinner
            app.Compound_IDNumber_Spinner.Limits    = app.num_of_elements+[1 id_number];
            app.Compound_IDNumber_Spinner.Value     = id_number;
            % - Clear all necessary fields to create a blank entry
            % -- Compound Identifiers
            app.Compound_Formula_EditField.Value                        = 'Placeholder';
            app.Compound_Name_EditField.Value                           = '';
            app.Compound_Classification_EditField.Value                 = '';
            % -- Compound Properties
            app.Compound_Stoichiometry_EditField.Value                  = [];
            app.Compound_AverageZNumber_EditField.Value                 = [];
            app.Compound_AtomicMass_EditField.Value                     = [];
            app.Compound_ElectronValency_EditField.Value                = [];
            % -- Compound Electronic Properties
            app.Compound_WorkFunction_EditField.Value                   = [];
            app.Compound_BandGap_EditField.Value                        = [];
            app.Compound_Resistivity_EditField.Value                    = [];
            % -- Compound Physical Properties
            app.Compound_CrystalStructure_DropDown.Value                = app.Compound_CrystalStructure_DropDown.Items{15};
            app.Compound_a_EditField.Value                              = [];
            app.Compound_alpha_EditField.Value                          = [];
            app.Compound_b_EditField.Value                              = [];
            app.Compound_beta_EditField.Value                           = [];
            app.Compound_c_EditField.Value                              = [];
            app.Compound_gamma_EditField.Value                          = [];
            app.Compound_MassDensity_EditField.Value                    = [];
            app.Compound_SpecificHeat_EditField.Value                   = [];
        end

        % Write new compound properties from materials database
        function write_new_compound_properties(app)
            % -- Compound Identifiers
            app.new_compound_data.class                     = app.Compound_Classification_EditField.Value;
            app.new_compound_data.name                      = app.Compound_Name_EditField.Value;
            app.new_compound_data.formula                   = app.Compound_Formula_EditField.Value;
            % -- Compound Properties
            app.new_compound_data.atom_stoic                = app.Compound_Stoichiometry_EditField.Value;
            app.new_compound_data.atom_z                    = app.Compound_AverageZNumber_EditField.Value;
            app.new_compound_data.atom_mass                 = app.Compound_AtomicMass_EditField.Value;
            app.new_compound_data.elec_valency              = app.Compound_ElectronValency_EditField.Value;
            % -- Compound Electronic Properties
            app.new_compound_data.elec_work_function        = app.Compound_WorkFunction_EditField.Value;
            app.new_compound_data.elec_band_gap             = app.Compound_BandGap_EditField.Value;
            app.new_compound_data.elec_resistivity          = app.Compound_Resistivity_EditField.Value;
            % -- Compound Physical Properties
            app.new_compound_data.crystal_structure         = app.Compound_CrystalStructure_DropDown.Value;
            app.new_compound_data.crystal_a                 = app.Compound_a_EditField.Value;
            app.new_compound_data.crystal_alpha             = app.Compound_alpha_EditField.Value;
            app.new_compound_data.crystal_b                 = app.Compound_b_EditField.Value;
            app.new_compound_data.crystal_beta              = app.Compound_beta_EditField.Value;
            app.new_compound_data.crystal_c                 = app.Compound_c_EditField.Value;
            app.new_compound_data.crystal_gamma             = app.Compound_gamma_EditField.Value;
            app.new_compound_data.density                   = app.Compound_MassDensity_EditField.Value;
            app.new_compound_data.specific_heat             = app.Compound_SpecificHeat_EditField.Value;
            % -- Updating materials properties database
            app.new_compound_name                           = app.new_compound_data.formula;
            save_to_materials_database(app, app.new_compound_name, app.new_compound_data);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBaseX - Materials Database Editor (procopios.constantinou@psi.ch)';
            % 1 - Editing the color of the main buttons
            add_color       = [0.8, 0.9, 0.8];
            remove_color    = [0.9, 0.8, 0.8];
            misc_color      = [0.95, 0.95, 0.95];
            app.Element_EditEntry_Button.BackgroundColor        = misc_color;
            app.Element_EditSave_Button.BackgroundColor         = add_color;
            app.Element_EditCancel_Button.BackgroundColor       = remove_color;
            app.Compound_EditEntry_Button.BackgroundColor       = misc_color;
            app.Compound_EditSave_Button.BackgroundColor        = add_color;
            app.Compound_EditCancel_Button.BackgroundColor      = remove_color;
            app.Compound_NewSave_Button.BackgroundColor         = add_color;
            app.Compound_NewCancel_Button.BackgroundColor       = remove_color;
            app.Compound_Delete_Button.BackgroundColor          = remove_color;
            % -- Coloring the edit-fields that can be changed
            edit_color = [0.85 1 1];
            app.SelectedElement_DropDown.BackgroundColor        = edit_color;
            app.Element_Formula_EditField.BackgroundColor       = edit_color;
            app.Element_IDNumber_Spinner.BackgroundColor        = edit_color;
            app.SelectedCompound_DropDown.BackgroundColor       = edit_color;
            app.Compound_Formula_EditField.BackgroundColor      = edit_color;
            app.Compound_IDNumber_Spinner.BackgroundColor       = edit_color;
            % 2 - Initializing variables
            % 2.1 - Load the current version of the materials database
            MatData = load('MatData'); 
            app.material_database_raw       = MatData.MatData;
            app.material_database           = MatData.MatData;
            % 2.2 - Initializing variables
            initialize_element_properties(app);
            initialize_compound_properties(app);
            app.element_edit_state  = 0; activate_element_edit_fields(app);
            app.new_compound_state = 0;  activate_new_compound_edit_fields(app);
            app.compound_edit_state = 0; activate_compound_edit_fields(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run MatBaseX;
            open_MainMenu_to_left_of_App(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type', 'Figure');
            % Exclude those with 'MatBaseX' in the Name (title)
            matbaseFigs = findobj(hPlots, '-regexp', 'Name', 'MatBaseX');
            hPlotsToClose = setdiff(hPlots, matbaseFigs);
            % Close only the non-MatBaseX figures
            close(hPlotsToClose);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run MatBaseX_01_MaterialsDatabaseEditor; 
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos  = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 900;
            screen_size(4) = 500;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('Materials Database : ');  disp(app.material_database);
            disp('Element : ');             disp(app.selected_element_data);
            disp('Compound : ');            disp(app.selected_compound_data);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'MatBase', app.material_database);
            if "Elements" == string(app.Material_TabGroup.SelectedTab.Title)
                assignin('base', char(app.selected_element_name), app.selected_element_data);
            elseif "Compounds" == string(app.Material_TabGroup.SelectedTab.Title)
                assignin('base', char(app.selected_compound_name), app.selected_compound_data);
            end
        end

        % Button pushed function: SAVE_DATABASE_Button
        function SAVE_DATABASE_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            fname   = sprintf("v%s_MatData", string(yyyymmdd(datetime)));
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            structName          = char(save_filename(1:end-4));
            eval([structName ' = app.material_database;']);     % Create the data structure dynamically
            % Saving the data as .mat
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved materials database : '); display(app.material_database);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: UNDOALL_Button
        function UNDOALL_ButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, 'Are you sure you want to undo all changes made in the current instance?', 'Confirmation');
            if strcmp(choice, 'OK'); undo_all_changes_to_materials_database(app); end
        end

        % Button pushed function: RESET2ORIGINAL_Button
        function RESET2ORIGINAL_ButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, 'Are you sure you want to revert the materials database back to its default state (22-Feb-2025)? All changes made will be lost!', 'Confirmation');
            if strcmp(choice, 'OK'); revert_to_default_materials_database(app); end
        end

        % Value changed function: SelectedElement_DropDown
        function SelectedElement_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value               = app.SelectedElement_DropDown.Value;
            % 2 - Updating element properties to the selected element
            read_element_properties(app, extractAfter(value,'-'));
            % 3 - Switch editing mode off if element is changed
            if app.element_edit_state == 1; app.element_edit_state = 0; end
            activate_element_edit_fields(app);
        end

        % Value changed function: Element_IDNumber_Spinner
        function Element_IDNumber_SpinnerValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Element_IDNumber_Spinner.Value;
            % 2 - Updating element properties to the selected element
            read_element_properties(app, value);
            % 3 - Switch editing mode off if element is changed
            if app.element_edit_state == 1; app.element_edit_state = 0; end
            activate_element_edit_fields(app);
        end

        % Value changed function: Element_Formula_EditField
        function Element_Formula_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.Element_Formula_EditField.Value;
            % 2 - Validity check on the input value
            value           = string(value);
            % 3 - Checking if the input is an element
            element_names   = read_mpd_elements();
            index           = find(strcmp(element_names, value));
            if ~isempty(index)
                app.SelectedElement_DropDown.Value = app.SelectedElement_DropDown.Items{index};
                SelectedElement_DropDownValueChanged(app);
            else
                SelectedElement_DropDownValueChanged(app);
            end
        end

        % Button pushed function: Element_EditEntry_Button
        function Element_EditEntry_ButtonPushed(app, event)
            % 1 - Activating / deactivating the edit state
            if app.element_edit_state == 0;         app.element_edit_state = 1;
            elseif app.element_edit_state == 1;     app.element_edit_state = 0; read_element_properties(app, app.selected_element_name);
            end
            activate_element_edit_fields(app);
        end

        % Button pushed function: Element_EditCancel_Button
        function Element_EditCancel_ButtonPushed(app, event)
            % 1 - Return to default values
            app.element_edit_state = 0; 
            read_element_properties(app, app.selected_element_name);
            activate_element_edit_fields(app);
        end

        % Button pushed function: Element_EditSave_Button
        function Element_EditSave_ButtonPushed(app, event)
            app.element_edit_state = 0; 
            write_element_properties(app);
            activate_element_edit_fields(app);
        end

        % Button pushed function: Element_Save_Button
        function Element_Save_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter  = {'*.mat'};
            fname   = sprintf("%s_MPD", app.selected_element_name);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar            = waitbar(0.5,'Saving...'); 
            structName      = char(save_filename(1:end-4));
            eval([structName ' = app.selected_element_data;']);     % Create the data structure dynamically
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved data : '); display(app.selected_element_data);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: Element_Export_Button
        function Element_Export_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter  = {'*.txt'};
            fname   = sprintf("%s_MPD", app.selected_element_name);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            structName      = char(save_filename(1:end-4));
            data            = app.selected_element_data;
            eval([structName ' = data;']);
            % Open the text file for writing
            fileID          = fopen(save_fullfile, 'w');
            fields          = fieldnames(eval(structName));
            for i = 1:length(fields)
                value = eval([structName '.' fields{i}]);
                fprintf(fileID, '%s: %s\n', fields{i}, mat2str(value));
            end
            fclose(fileID);
            disp('-> exported data : '); display(data);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!');
        end

        % Value changed function: SelectedCompound_DropDown
        function SelectedCompound_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.SelectedCompound_DropDown.Value;
            % 2 - Updating compound properties to the selected compound
            read_compound_properties(app, extractAfter(value,'-'));
        end

        % Value changed function: Compound_IDNumber_Spinner
        function Compound_IDNumber_SpinnerValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Compound_IDNumber_Spinner.Value;
            % 2 - Updating element properties to the selected element
            read_compound_properties(app, value);
            % 3 - Switch editing mode off if element is changed
            if app.compound_edit_state == 1; app.compound_edit_state = 0; end
            activate_compound_edit_fields(app);
        end

        % Value changed function: Compound_Formula_EditField
        function Compound_Formula_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.Compound_Formula_EditField.Value;
            % 2 - Validity check on the input value
            value           = string(value);
            % 3 - Checking if the input is a compound
            % -- Defining a new compound
            if app.new_compound_state == 1
                % --- Validity check to ensure the compound does not exist
                element_names   = read_mpd_elements();
                compound_names  = read_mpd_compounds();
                materials       = horzcat(element_names, compound_names);
                index           = find(strcmp(materials, value));
                if ~isempty(index) || all(isstrprop(value, 'digit')) || isstrprop(extract(value, 1), 'digit') || value == ""
                    background_col = [1 1 0.85];
                    app.Compound_Formula_EditField.BackgroundColor = background_col;
                    app.Compound_NewSave_Button.Enable = "off";
                    if ~isempty(index); uialert(app.UIFigure, 'Material name is already taken. Please choose a new name.', 'Error', 'Icon', 'error');
                    elseif value == ""; uialert(app.UIFigure, 'Material name not defined. Please choose a new name.', 'Error', 'Icon', 'error');
                    elseif all(isstrprop(value, 'digit')); uialert(app.UIFigure, 'Material name is only numbers. Please choose a new name.', 'Error', 'Icon', 'error');
                    elseif isstrprop(extract(value, 1), 'digit'); uialert(app.UIFigure, 'Material name must have a letter at the start. Please choose a new name.', 'Error', 'Icon', 'error');
                    end
                else
                    app.Compound_NewSave_Button.Enable = "on";
                    background_col = [0.85 1 1];
                    app.Compound_Formula_EditField.BackgroundColor      = background_col;
                    app.new_compound_name                               = value;
                    try app.Compound_Stoichiometry_EditField.Value      = calc_stoichiometry(app.new_compound_name); catch; app.Compound_Stoichiometry_EditField.Value = []; end
                    try app.Compound_AverageZNumber_EditField.Value     = calc_average_z_number(app.new_compound_name); catch; app.Compound_AverageZNumber_EditField.Value = []; end
                    try app.Compound_AtomicMass_EditField.Value         = calc_molar_mass(app.new_compound_name); catch; app.Compound_AtomicMass_EditField.Value = []; end
                    try app.Compound_ElectronValency_EditField.Value    = calc_elec_valency(app.new_compound_name); catch; app.Compound_ElectronValency_EditField.Value = []; end
                end
            % -- Extracting the compound properties by its definition
            else
                compound_names  = read_mpd_compounds();
                index           = find(strcmp(compound_names, value));
                if ~isempty(index)
                    app.SelectedCompound_DropDown.Value = app.SelectedCompound_DropDown.Items{index};
                    SelectedCompound_DropDownValueChanged(app);
                else
                    SelectedCompound_DropDownValueChanged(app);
                end
            end
        end

        % Button pushed function: Compound_EditEntry_Button
        function Compound_EditEntry_ButtonPushed(app, event)
            % 1 - Activating / deactivating the edit state
            if app.compound_edit_state == 0;         app.compound_edit_state = 1;
            elseif app.compound_edit_state == 1;     app.compound_edit_state = 0;
            end
            activate_compound_edit_fields(app);
        end

        % Button pushed function: Compound_EditCancel_Button
        function Compound_EditCancel_ButtonPushed(app, event)
            % 1 - Return to default values
            app.compound_edit_state = 0; 
            read_compound_properties(app, app.selected_compound_name);
            activate_compound_edit_fields(app);
        end

        % Button pushed function: Compound_EditSave_Button
        function Compound_EditSave_ButtonPushed(app, event)
            app.compound_edit_state = 0; 
            write_compound_properties(app);
            activate_compound_edit_fields(app);
        end

        % Button pushed function: Compound_Save_Button
        function Compound_Save_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter  = {'*.mat'};
            fname   = sprintf("%s_MPD", app.selected_compound_name);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar            = waitbar(0.5,'Saving...'); 
            structName      = char(save_filename(1:end-4));
            eval([structName ' = app.selected_compound_data;']);     % Create the data structure dynamically
            % Saving the data as .mat
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved data : '); display(app.selected_compound_data);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: Compound_Export_Button
        function Compound_Export_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter  = {'*.txt'};
            fname   = sprintf("%s_MPD", app.selected_compound_name);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            structName      = char(save_filename(1:end-4));
            data            = app.selected_compound_data;
            eval([structName ' = data;']);
            % Open the text file for writing
            fileID          = fopen(save_fullfile, 'w');
            fields          = fieldnames(eval(structName));
            for i = 1:length(fields)
                value = eval([structName '.' fields{i}]);
                fprintf(fileID, '%s: %s\n', fields{i}, mat2str(value));
            end
            fclose(fileID);
            disp('-> exported data : '); display(data);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!');
        end

        % Button pushed function: Compound_Delete_Button
        function Compound_Delete_ButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, sprintf("Are you sure you want to delete entry: %s", app.SelectedCompound_DropDown.Value), 'Confirmation');
            if strcmp(choice, 'OK')
                % Extracting new id number
                field_names             = fieldnames(app.material_database);
                id_end                  = numel(field_names);
                if app.selected_compound_data.id == id_end;     new_id_num = app.selected_compound_data.id - 1;
                else;                                           new_id_num = app.selected_compound_data.id;
                end
                % Deleting the material database entry & ensuring ID numbers are in ascending order
                delete_from_materials_database(app, app.selected_compound_name);
                fprintf("\n--> Deleted Material: %s\n", app.selected_compound_name);
                % Updating to nearest compound
                initialize_compound_properties(app);
                compound_names = extract_compound_names(app);
                app.SelectedCompound_DropDown.Value = compound_names{new_id_num-app.num_of_elements};
                SelectedCompound_DropDownValueChanged(app);
            end
        end

        % Button pushed function: Compound_Add_Button
        function Compound_Add_ButtonPushed(app, event)
            % 1 - Activating / deactivating the edit state
            if app.new_compound_state == 0;         app.new_compound_state = 1;
            elseif app.new_compound_state == 1;     app.new_compound_state = 0;
            end
            activate_new_compound_edit_fields(app);
            create_new_compound_entry(app);
        end

        % Button pushed function: Compound_NewCancel_Button
        function Compound_NewCancel_ButtonPushed(app, event)
            % 1 - Return to default values
            app.new_compound_state = 0; activate_new_compound_edit_fields(app);
            previous_compound =  app.selected_compound_name;
            initialize_compound_properties(app);
            read_compound_properties(app, previous_compound);
        end

        % Button pushed function: Compound_NewSave_Button
        function Compound_NewSave_ButtonPushed(app, event)
            write_new_compound_properties(app);
            app.new_compound_state = 0; activate_new_compound_edit_fields(app);
            initialize_compound_properties(app);
            read_compound_properties(app, app.new_compound_name);
            fprintf("\n--> Saved Material: %s\n", app.new_compound_name);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Global_GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.Global_GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.Global_GridLayout);
            app.AppTitle.Text = 'Materials Database Editor';
            app.BUTTONS_GridLayout = uigridlayout(app.Global_GridLayout);
            app.AppButtons_GridLayout = uigridlayout(app.BUTTONS_GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart this App.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize this App to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all App information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer App Data directly to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.SAVE_DATABASE_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVE_DATABASE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_DATABASE_ButtonPushed, true);
            app.SAVE_DATABASE_Button.Tooltip = {'Save the material properties database as a MATLAB data structure file.'};
            app.SAVE_DATABASE_Button.Text = 'SAVE COPY DATABASE (*.mat)';
            app.UNDOALL_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.UNDOALL_Button.ButtonPushedFcn = createCallbackFcn(app, @UNDOALL_ButtonPushed, true);
            app.UNDOALL_Button.Tooltip = {'Undo all changes made to this instance of the materials database.'};
            app.UNDOALL_Button.Text = 'UNDO ALL';
            app.RESET2ORIGINAL_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESET2ORIGINAL_Button.ButtonPushedFcn = createCallbackFcn(app, @RESET2ORIGINAL_ButtonPushed, true);
            app.RESET2ORIGINAL_Button.Tooltip = {'Revert the material properties database to its original form, as defined on 22-Feb-2025.'};
            app.RESET2ORIGINAL_Button.Text = 'RESET TO ORIGINAL';
            app.AppIcon_Image = uiimage(app.AppButtons_GridLayout);
            app.AppIcon_Image.Tooltip = {'Manage the Materials Properties Database - add, delete, edit, and export material entries to streamline workflow. Enable other apps to access defined material data. Save the database to keep it persistent for all future needs.'};
            app.AppIcon_Image.ImageSource = 'Icon_MaterialsProperties.jpeg';
            app.Local_GridLayout = uigridlayout(app.Global_GridLayout);
            app.Material_TabGroup = uitabgroup(app.Local_GridLayout);
            app.Elements_Tab = uitab(app.Material_TabGroup);
            app.Elements_Tab.Title = 'Elements';
            app.Element_GridLayout = uigridlayout(app.Elements_Tab);
            app.SelectedElement_DropDown = uidropdown(app.Element_GridLayout);
            app.SelectedElement_DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedElement_DropDownValueChanged, true);
            app.SelectedElement_DropDown.Tooltip = {'Select an element from the drop down box.'};
            app.Element_Number_Label = uilabel(app.Element_GridLayout);
            app.Element_Information_Panel = uipanel(app.Element_GridLayout);
            app.Element_Information_Panel.Title = 'Element Information';
            app.Element_Information_GridLayout = uigridlayout(app.Element_Information_Panel);
            app.IDNumberSpinnerLabel = uilabel(app.Element_Information_GridLayout);
            app.IDNumberSpinnerLabel.Text = 'ID Number:';
            app.Element_IDNumber_Spinner = uispinner(app.Element_Information_GridLayout);
            app.Element_IDNumber_Spinner.Limits = [0 Inf];
            app.Element_IDNumber_Spinner.ValueDisplayFormat = '%.0f';
            app.Element_IDNumber_Spinner.ValueChangedFcn = createCallbackFcn(app, @Element_IDNumber_SpinnerValueChanged, true);
            app.Element_IDNumber_Spinner.Tooltip = {'Unique material ID number'};
            app.NameLabel = uilabel(app.Element_Information_GridLayout);
            app.NameLabel.Text = 'Name:';
            app.Element_Name_EditField = uieditfield(app.Element_Information_GridLayout, 'text');
            app.Element_Name_EditField.Editable = 'off';
            app.Element_Name_EditField.Tooltip = {'Name identifying the material'};
            app.FormulaLabel = uilabel(app.Element_Information_GridLayout);
            app.FormulaLabel.Text = 'Formula:';
            app.Element_Formula_EditField = uieditfield(app.Element_Information_GridLayout, 'text');
            app.Element_Formula_EditField.ValueChangedFcn = createCallbackFcn(app, @Element_Formula_EditFieldValueChanged, true);
            app.Element_Formula_EditField.Tooltip = {'Unique formula expression of the relative number of each type of atom'};
            app.ClassificationEditField_2Label = uilabel(app.Element_Information_GridLayout);
            app.ClassificationEditField_2Label.Text = 'Classification:';
            app.Element_Classification_EditField = uieditfield(app.Element_Information_GridLayout, 'text');
            app.Element_Classification_EditField.Editable = 'off';
            app.Element_Classification_EditField.Tooltip = {'Classification of the material'};
            app.Element_OtherProperties_Panel = uipanel(app.Element_GridLayout);
            app.Element_OtherProperties_Panel.Title = 'Other Properties';
            app.Element_OtherProperties_GridLayout = uigridlayout(app.Element_OtherProperties_Panel);
            app.AtomicTermEditField_2Label = uilabel(app.Element_OtherProperties_GridLayout);
            app.AtomicTermEditField_2Label.Text = 'Atomic Term:';
            app.Element_AtomicTerm_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'text');
            app.Element_AtomicTerm_EditField.Editable = 'off';
            app.ElectronConfigurationEditField_2Label = uilabel(app.Element_OtherProperties_GridLayout);
            app.ElectronConfigurationEditField_2Label.Text = 'Electron Configuration:';
            app.Element_ElectronConfiguration_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'text');
            app.Element_ElectronConfiguration_EditField.Editable = 'off';
            app.AtomicRadiusfmEditFieldLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.AtomicRadiusfmEditFieldLabel.Text = 'Atomic Radius (fm)';
            app.Element_AtomicRadius_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'numeric');
            app.Element_AtomicRadius_EditField.ValueDisplayFormat = '%.0f';
            app.Element_AtomicRadius_EditField.AllowEmpty = 'on';
            app.Element_AtomicRadius_EditField.Editable = 'off';
            app.PhaseatSTPEditFieldLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.PhaseatSTPEditFieldLabel.Text = 'Phase (at STP):';
            app.Element_Phase_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'text');
            app.Element_Phase_EditField.Editable = 'off';
            app.ElectronegativityPaulingscaleEditFieldLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.ElectronegativityPaulingscaleEditFieldLabel.Text = 'Electronegativity (Pauling scale):';
            app.Element_Electronegativity_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'numeric');
            app.Element_Electronegativity_EditField.ValueDisplayFormat = '%.3f';
            app.Element_Electronegativity_EditField.AllowEmpty = 'on';
            app.Element_Electronegativity_EditField.Editable = 'off';
            app.ElectronAffinityeVEditFieldLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.ElectronAffinityeVEditFieldLabel.Text = 'Electron Affinity (eV):';
            app.Element_ElectronAffinity_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'numeric');
            app.Element_ElectronAffinity_EditField.ValueDisplayFormat = '%.3f';
            app.Element_ElectronAffinity_EditField.AllowEmpty = 'on';
            app.Element_ElectronAffinity_EditField.Editable = 'off';
            app.FirstIonisationeVEditFieldLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.FirstIonisationeVEditFieldLabel.Text = 'First Ionisation (eV):';
            app.Element_FirstIonisation_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'numeric');
            app.Element_FirstIonisation_EditField.ValueDisplayFormat = '%.3f';
            app.Element_FirstIonisation_EditField.AllowEmpty = 'on';
            app.Element_FirstIonisation_EditField.Editable = 'off';
            app.RadioactiveEditFieldLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.RadioactiveEditFieldLabel.Text = 'Radioactive?';
            app.Element_Radioactive_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'text');
            app.Element_Radioactive_EditField.Editable = 'off';
            app.NeutronNumberLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.NeutronNumberLabel.Text = 'Neutron Number:';
            app.Element_NeutronNumber_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'numeric');
            app.Element_NeutronNumber_EditField.AllowEmpty = 'on';
            app.Element_NeutronNumber_EditField.Editable = 'off';
            app.NeutronCrossSectionbarnEditFieldLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.NeutronCrossSectionbarnEditFieldLabel.Text = 'Neutron Cross-Section (barn):';
            app.Element_NeutronCrossSection_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'numeric');
            app.Element_NeutronCrossSection_EditField.ValueDisplayFormat = '%.6f';
            app.Element_NeutronCrossSection_EditField.AllowEmpty = 'on';
            app.Element_NeutronCrossSection_EditField.Editable = 'off';
            app.MagneticTypeLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.MagneticTypeLabel.Text = 'Magnetic Type:';
            app.Element_MagneticType_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'text');
            app.Element_MagneticType_EditField.Editable = 'off';
            app.MagneticSuceptibilitycckgLabel = uilabel(app.Element_OtherProperties_GridLayout);
            app.MagneticSuceptibilitycckgLabel.Text = 'Magnetic Suceptibility (cc/kg):';
            app.Element_MagneticSuceptibility_EditField = uieditfield(app.Element_OtherProperties_GridLayout, 'numeric');
            app.Element_MagneticSuceptibility_EditField.ValueDisplayFormat = '%.5f';
            app.Element_MagneticSuceptibility_EditField.AllowEmpty = 'on';
            app.Element_MagneticSuceptibility_EditField.Editable = 'off';
            app.Element_Properties_Panel = uipanel(app.Element_GridLayout);
            app.Element_Properties_Panel.Title = 'Element Properties';
            app.Element_Properties_GridLayout = uigridlayout(app.Element_Properties_Panel);
            app.StoichiometryLabel = uilabel(app.Element_Properties_GridLayout);
            app.StoichiometryLabel.Text = 'Stoichiometry:';
            app.Element_Stoichiometry_EditField = uieditfield(app.Element_Properties_GridLayout, 'numeric');
            app.Element_Stoichiometry_EditField.Limits = [0 Inf];
            app.Element_Stoichiometry_EditField.ValueDisplayFormat = '%.0f';
            app.Element_Stoichiometry_EditField.Editable = 'off';
            app.Element_Stoichiometry_EditField.Tooltip = {'Used in: IMFP Calculator.'; 'Total number of atoms that constitute the a unit of the material.'};
            app.AtomicZNumberEditFieldLabel = uilabel(app.Element_Properties_GridLayout);
            app.AtomicZNumberEditFieldLabel.Text = 'Atomic Z-Number:';
            app.Element_AtomicZNumber_EditField = uieditfield(app.Element_Properties_GridLayout, 'numeric');
            app.Element_AtomicZNumber_EditField.Limits = [0 Inf];
            app.Element_AtomicZNumber_EditField.ValueDisplayFormat = '%.0f';
            app.Element_AtomicZNumber_EditField.Editable = 'off';
            app.Element_AtomicZNumber_EditField.Tooltip = {'Used in: IMFP Calculator.'; 'Atomic Z-number of the element.'};
            app.AtomicMassamuLabel = uilabel(app.Element_Properties_GridLayout);
            app.AtomicMassamuLabel.Text = 'Atomic Mass (g/mol):';
            app.Element_AtomicMass_EditField = uieditfield(app.Element_Properties_GridLayout, 'numeric');
            app.Element_AtomicMass_EditField.Limits = [0 Inf];
            app.Element_AtomicMass_EditField.ValueDisplayFormat = '%.6f';
            app.Element_AtomicMass_EditField.Editable = 'off';
            app.Element_AtomicMass_EditField.Tooltip = {'Used in: IMFP & X-Ray Photoelectric Mass Attenuation Coefficient.'; 'Atomic mass (or atomic weight) is the weighted average mass of an atom of an element.'};
            app.ElectronValencyLabel = uilabel(app.Element_Properties_GridLayout);
            app.ElectronValencyLabel.Text = 'Electron Valency:';
            app.Element_ElectronValency_EditField = uieditfield(app.Element_Properties_GridLayout, 'numeric');
            app.Element_ElectronValency_EditField.Limits = [0 Inf];
            app.Element_ElectronValency_EditField.ValueDisplayFormat = '%.0f';
            app.Element_ElectronValency_EditField.Editable = 'off';
            app.Element_ElectronValency_EditField.Tooltip = {'Used in: IMFP Calculator.'; 'Total number of electrons in the outermost shell.'};
            app.Element_ElectronicProperties_Panel = uipanel(app.Element_GridLayout);
            app.Element_ElectronicProperties_Panel.Title = 'Element Electronic Properties';
            app.Element_ElectronicProperties_GridLayout = uigridlayout(app.Element_ElectronicProperties_Panel);
            app.ResistivitymOhmEditFieldLabel = uilabel(app.Element_ElectronicProperties_GridLayout);
            app.ResistivitymOhmEditFieldLabel.Text = 'Resistivity (m Ohm):';
            app.Element_Resistivity_EditField = uieditfield(app.Element_ElectronicProperties_GridLayout, 'numeric');
            app.Element_Resistivity_EditField.Limits = [0 Inf];
            app.Element_Resistivity_EditField.ValueDisplayFormat = '%.3e';
            app.Element_Resistivity_EditField.AllowEmpty = 'on';
            app.Element_Resistivity_EditField.Editable = 'off';
            app.Element_Resistivity_EditField.Tooltip = {'Resistivity quantifies how strongly a material opposes the flow of electric current.'};
            app.BandGapeVEditFieldLabel = uilabel(app.Element_ElectronicProperties_GridLayout);
            app.BandGapeVEditFieldLabel.Text = 'Band Gap (eV):';
            app.Element_BandGap_EditField = uieditfield(app.Element_ElectronicProperties_GridLayout, 'numeric');
            app.Element_BandGap_EditField.Limits = [0 Inf];
            app.Element_BandGap_EditField.ValueDisplayFormat = '%.2f';
            app.Element_BandGap_EditField.AllowEmpty = 'on';
            app.Element_BandGap_EditField.Editable = 'off';
            app.Element_BandGap_EditField.Tooltip = {'Used in: IMFP Calculator.'; 'Energy range in a solid where no electronic states exist between the valence and conduction band.'};
            app.WorkFunctioneVEditFieldLabel = uilabel(app.Element_ElectronicProperties_GridLayout);
            app.WorkFunctioneVEditFieldLabel.Text = 'Work Function (eV):';
            app.Element_WorkFunction_EditField = uieditfield(app.Element_ElectronicProperties_GridLayout, 'numeric');
            app.Element_WorkFunction_EditField.Limits = [0 Inf];
            app.Element_WorkFunction_EditField.ValueDisplayFormat = '%.2f';
            app.Element_WorkFunction_EditField.AllowEmpty = 'on';
            app.Element_WorkFunction_EditField.Editable = 'off';
            app.Element_WorkFunction_EditField.Tooltip = {'Used in: Calculation of electron kinetic energies in PES analysis.'; 'Energy required to remove an electron to infinity from the surface of a given solid.'};
            app.Element_PhysicalProperties_Panel = uipanel(app.Element_GridLayout);
            app.Element_PhysicalProperties_Panel.Title = 'Element Physical Properties';
            app.Element_PhysicalProperties_GridLayout = uigridlayout(app.Element_PhysicalProperties_Panel);
            app.MassDensitygcm3Label = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.MassDensitygcm3Label.Text = 'Mass Density (g/cc):';
            app.Element_MassDensity_EditField = uieditfield(app.Element_PhysicalProperties_GridLayout, 'numeric');
            app.Element_MassDensity_EditField.Limits = [0 Inf];
            app.Element_MassDensity_EditField.ValueDisplayFormat = '%.6f';
            app.Element_MassDensity_EditField.AllowEmpty = 'on';
            app.Element_MassDensity_EditField.Editable = 'off';
            app.Element_MassDensity_EditField.Tooltip = {'Used in: IMFP & X-Ray Photoelectric Mass Attenuation Coefficient.'; 'Mass density is a measure of how much mass is contained within a given volume of a material.'};
            app.SpecificHeatJgKEditFieldLabel = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.SpecificHeatJgKEditFieldLabel.Text = 'Specific Heat (J/g/K):';
            app.Element_SpecificHeat_EditField = uieditfield(app.Element_PhysicalProperties_GridLayout, 'numeric');
            app.Element_SpecificHeat_EditField.Limits = [0 Inf];
            app.Element_SpecificHeat_EditField.ValueDisplayFormat = '%.3f';
            app.Element_SpecificHeat_EditField.AllowEmpty = 'on';
            app.Element_SpecificHeat_EditField.Editable = 'off';
            app.CrystalStructureLabel_4 = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.CrystalStructureLabel_4.Text = 'Crystal Structure:';
            app.Element_CrystalStructure_DropDown = uidropdown(app.Element_PhysicalProperties_GridLayout);
            app.Element_CrystalStructure_DropDown.Items = {'CUB-cP-Oh : Simple Cubic', 'CUB-cI-Oh : Body-Centered Cubic', 'CUB-cF-Oh : Face-Centered Cubic', 'HEX-hP-D6h : Simple Hexagonal', 'RHL-hR-D3d : Simple Rhombahedral', 'TET-tP-D4h : Simple Tetragonal', 'TET-tI-D4h : Body-Centered Tetragonal', 'ORC-oP-D2h : Simple Orthorhombic', 'ORC-oS-D2h : Base-Centered Orthorhombic', 'ORC-oI-D2h : Body-Centered Orthorhombic', 'ORC-oF-D2h : Face-Centered Orthorhombic', 'MCL-mP-C2h : Simple Monoclinic', 'MCL-mS-C2h : Base-Centered Monoclinic', 'TRI-aP-Ci : Simple Triclinic', 'N/A'};
            app.Element_CrystalStructure_DropDown.Enable = 'off';
            app.Element_CrystalStructure_DropDown.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the crystal structure'};
            app.Element_CrystalStructure_DropDown.Value = 'CUB-cP-Oh : Simple Cubic';
            app.apmEditFieldLabel = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.apmEditFieldLabel.Text = 'a (pm):';
            app.Element_a_EditField = uieditfield(app.Element_PhysicalProperties_GridLayout, 'numeric');
            app.Element_a_EditField.Limits = [0 Inf];
            app.Element_a_EditField.ValueDisplayFormat = '%.2f';
            app.Element_a_EditField.AllowEmpty = 'on';
            app.Element_a_EditField.Editable = 'off';
            app.Element_a_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-parameter a (picometer)'};
            app.alphadegreeEditFieldLabel = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.alphadegreeEditFieldLabel.Text = 'alpha (degree):';
            app.Element_alpha_EditField = uieditfield(app.Element_PhysicalProperties_GridLayout, 'numeric');
            app.Element_alpha_EditField.Limits = [0 180];
            app.Element_alpha_EditField.ValueDisplayFormat = '%.2f';
            app.Element_alpha_EditField.AllowEmpty = 'on';
            app.Element_alpha_EditField.Editable = 'off';
            app.Element_alpha_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-angle alpha (degree)'};
            app.bpmEditFieldLabel = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.bpmEditFieldLabel.Text = 'b (pm):';
            app.Element_b_EditField = uieditfield(app.Element_PhysicalProperties_GridLayout, 'numeric');
            app.Element_b_EditField.Limits = [0 Inf];
            app.Element_b_EditField.ValueDisplayFormat = '%.2f';
            app.Element_b_EditField.AllowEmpty = 'on';
            app.Element_b_EditField.Editable = 'off';
            app.Element_b_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-parameter b (picometer)'};
            app.betadegreeEditFieldLabel = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.betadegreeEditFieldLabel.Text = 'beta (degree):';
            app.Element_beta_EditField = uieditfield(app.Element_PhysicalProperties_GridLayout, 'numeric');
            app.Element_beta_EditField.Limits = [0 180];
            app.Element_beta_EditField.ValueDisplayFormat = '%.2f';
            app.Element_beta_EditField.AllowEmpty = 'on';
            app.Element_beta_EditField.Editable = 'off';
            app.Element_beta_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-angle beta (degree)'};
            app.cpmEditFieldLabel = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.cpmEditFieldLabel.Text = 'c (pm):';
            app.Element_c_EditField = uieditfield(app.Element_PhysicalProperties_GridLayout, 'numeric');
            app.Element_c_EditField.Limits = [0 Inf];
            app.Element_c_EditField.ValueDisplayFormat = '%.2f';
            app.Element_c_EditField.AllowEmpty = 'on';
            app.Element_c_EditField.Editable = 'off';
            app.Element_c_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-parameter c (picometer)'};
            app.gammadegreeEditFieldLabel = uilabel(app.Element_PhysicalProperties_GridLayout);
            app.gammadegreeEditFieldLabel.Text = 'gamma (degree):';
            app.Element_gamma_EditField = uieditfield(app.Element_PhysicalProperties_GridLayout, 'numeric');
            app.Element_gamma_EditField.Limits = [0 180];
            app.Element_gamma_EditField.ValueDisplayFormat = '%.2f';
            app.Element_gamma_EditField.AllowEmpty = 'on';
            app.Element_gamma_EditField.Editable = 'off';
            app.Element_gamma_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-angle gamma (degree)'};
            app.Element_EditEntry_Button = uibutton(app.Element_GridLayout, 'push');
            app.Element_EditEntry_Button.ButtonPushedFcn = createCallbackFcn(app, @Element_EditEntry_ButtonPushed, true);
            app.Element_EditEntry_Button.Tooltip = {'Edit the current entry'};
            app.Element_EditEntry_Button.Text = 'Edit Entry';
            app.GridLayout = uigridlayout(app.Element_GridLayout);
            app.Element_Save_Button = uibutton(app.GridLayout, 'push');
            app.Element_Save_Button.ButtonPushedFcn = createCallbackFcn(app, @Element_Save_ButtonPushed, true);
            app.Element_Save_Button.Tooltip = {'Save the selected element data as a .mat file'};
            app.Element_Save_Button.Text = 'Save Entry... (*.mat)';
            app.Element_Export_Button = uibutton(app.GridLayout, 'push');
            app.Element_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @Element_Export_ButtonPushed, true);
            app.Element_Export_Button.Tooltip = {'Export the selected element data as a .txt file'};
            app.Element_Export_Button.Text = 'Export Entry... (*.txt)';
            app.Element_Edit_GridLayout = uigridlayout(app.Element_GridLayout);
            app.Element_EditCancel_Button = uibutton(app.Element_Edit_GridLayout, 'push');
            app.Element_EditCancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Element_EditCancel_ButtonPushed, true);
            app.Element_EditCancel_Button.Tooltip = {'Cancel all edits made to the current entry'};
            app.Element_EditCancel_Button.Text = 'Cancel';
            app.Element_EditSave_Button = uibutton(app.Element_Edit_GridLayout, 'push');
            app.Element_EditSave_Button.ButtonPushedFcn = createCallbackFcn(app, @Element_EditSave_ButtonPushed, true);
            app.Element_EditSave_Button.Tooltip = {'Save and store all changes made to the current entry'};
            app.Element_EditSave_Button.Text = 'Save';
            app.Compounds_Tab = uitab(app.Material_TabGroup);
            app.Compounds_Tab.Title = 'Compounds';
            app.Compound_GridLayout = uigridlayout(app.Compounds_Tab);
            app.SelectedCompound_DropDown = uidropdown(app.Compound_GridLayout);
            app.SelectedCompound_DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedCompound_DropDownValueChanged, true);
            app.SelectedCompound_DropDown.Tooltip = {'Select a compound from the drop down box.'};
            app.Compound_Number_Label = uilabel(app.Compound_GridLayout);
            app.Compound_Information_Panel = uipanel(app.Compound_GridLayout);
            app.Compound_Information_Panel.Title = 'Compound Information';
            app.Compound_Information_GridLayout = uigridlayout(app.Compound_Information_Panel);
            app.IDNumberSpinnerLabel_2 = uilabel(app.Compound_Information_GridLayout);
            app.IDNumberSpinnerLabel_2.Text = 'ID Number:';
            app.Compound_IDNumber_Spinner = uispinner(app.Compound_Information_GridLayout);
            app.Compound_IDNumber_Spinner.Limits = [0 Inf];
            app.Compound_IDNumber_Spinner.ValueDisplayFormat = '%.0f';
            app.Compound_IDNumber_Spinner.ValueChangedFcn = createCallbackFcn(app, @Compound_IDNumber_SpinnerValueChanged, true);
            app.Compound_IDNumber_Spinner.Tooltip = {'Unique material ID number'};
            app.NameEditFieldLabel_2 = uilabel(app.Compound_Information_GridLayout);
            app.NameEditFieldLabel_2.Text = 'Name:';
            app.Compound_Name_EditField = uieditfield(app.Compound_Information_GridLayout, 'text');
            app.Compound_Name_EditField.Editable = 'off';
            app.Compound_Name_EditField.Tooltip = {'Name identifying the material'};
            app.FormulaLabel_2 = uilabel(app.Compound_Information_GridLayout);
            app.FormulaLabel_2.Text = 'Formula:';
            app.Compound_Formula_EditField = uieditfield(app.Compound_Information_GridLayout, 'text');
            app.Compound_Formula_EditField.ValueChangedFcn = createCallbackFcn(app, @Compound_Formula_EditFieldValueChanged, true);
            app.Compound_Formula_EditField.Tooltip = {'Unique formula expression of the relative number of each type of atom'};
            app.ClassificationEditField_2Label_2 = uilabel(app.Compound_Information_GridLayout);
            app.ClassificationEditField_2Label_2.Text = 'Classification:';
            app.Compound_Classification_EditField = uieditfield(app.Compound_Information_GridLayout, 'text');
            app.Compound_Classification_EditField.Editable = 'off';
            app.Compound_Classification_EditField.Tooltip = {'Classification of the material'};
            app.Compounds_OtherProperties_Panel = uipanel(app.Compound_GridLayout);
            app.Compounds_OtherProperties_Panel.Title = 'Other Properties';
            app.Compounds_OtherProperties_GridLayout = uigridlayout(app.Compounds_OtherProperties_Panel);
            app.Compound_Properties_Panel = uipanel(app.Compound_GridLayout);
            app.Compound_Properties_Panel.Title = 'Compound Properties';
            app.Compound_Properties_GridLayout = uigridlayout(app.Compound_Properties_Panel);
            app.StoichiometryLabel_2 = uilabel(app.Compound_Properties_GridLayout);
            app.StoichiometryLabel_2.Text = 'Stoichiometry:';
            app.Compound_Stoichiometry_EditField = uieditfield(app.Compound_Properties_GridLayout, 'numeric');
            app.Compound_Stoichiometry_EditField.Limits = [0 Inf];
            app.Compound_Stoichiometry_EditField.ValueDisplayFormat = '%.0f';
            app.Compound_Stoichiometry_EditField.AllowEmpty = 'on';
            app.Compound_Stoichiometry_EditField.Editable = 'off';
            app.Compound_Stoichiometry_EditField.Tooltip = {'Used in: IMFP Calculator.'; 'Total number of atoms that constitute the a unit of the material.'};
            app.AverageZNumberLabel = uilabel(app.Compound_Properties_GridLayout);
            app.AverageZNumberLabel.Text = 'Average Z-Number:';
            app.Compound_AverageZNumber_EditField = uieditfield(app.Compound_Properties_GridLayout, 'numeric');
            app.Compound_AverageZNumber_EditField.Limits = [0 Inf];
            app.Compound_AverageZNumber_EditField.ValueDisplayFormat = '%.6f';
            app.Compound_AverageZNumber_EditField.AllowEmpty = 'on';
            app.Compound_AverageZNumber_EditField.Editable = 'off';
            app.Compound_AverageZNumber_EditField.Tooltip = {'Used in: IMFP Calculator.'; 'Average Z-number of the compound.'};
            app.AtomicMassamuLabel_3 = uilabel(app.Compound_Properties_GridLayout);
            app.AtomicMassamuLabel_3.Text = 'Molar Mass (g/mol):';
            app.Compound_AtomicMass_EditField = uieditfield(app.Compound_Properties_GridLayout, 'numeric');
            app.Compound_AtomicMass_EditField.Limits = [0 Inf];
            app.Compound_AtomicMass_EditField.ValueDisplayFormat = '%.6f';
            app.Compound_AtomicMass_EditField.AllowEmpty = 'on';
            app.Compound_AtomicMass_EditField.Editable = 'off';
            app.Compound_AtomicMass_EditField.Tooltip = {'Used in: IMFP & X-Ray Photoelectric Mass Attenuation Coefficient.'; 'Compound mass (or molar mass) is the total mass of all the atoms contained within the compound material.'};
            app.ElectronValencyLabel_2 = uilabel(app.Compound_Properties_GridLayout);
            app.ElectronValencyLabel_2.Text = 'Electron Valency:';
            app.Compound_ElectronValency_EditField = uieditfield(app.Compound_Properties_GridLayout, 'numeric');
            app.Compound_ElectronValency_EditField.Limits = [0 Inf];
            app.Compound_ElectronValency_EditField.ValueDisplayFormat = '%.0f';
            app.Compound_ElectronValency_EditField.AllowEmpty = 'on';
            app.Compound_ElectronValency_EditField.Tooltip = {'Used in: IMFP Calculator.'; 'Total number of electrons in the outermost shell.'};
            app.Compound_ElectronicProperties_Panel = uipanel(app.Compound_GridLayout);
            app.Compound_ElectronicProperties_Panel.Title = 'Compound Electronic Properties';
            app.Compound_ElectronicProperties_GridLayout = uigridlayout(app.Compound_ElectronicProperties_Panel);
            app.ResistivitymOhmEditFieldLabel_3 = uilabel(app.Compound_ElectronicProperties_GridLayout);
            app.ResistivitymOhmEditFieldLabel_3.Text = 'Resistivity (m Ohm):';
            app.Compound_Resistivity_EditField = uieditfield(app.Compound_ElectronicProperties_GridLayout, 'numeric');
            app.Compound_Resistivity_EditField.Limits = [0 Inf];
            app.Compound_Resistivity_EditField.ValueDisplayFormat = '%.3e';
            app.Compound_Resistivity_EditField.AllowEmpty = 'on';
            app.Compound_Resistivity_EditField.Tooltip = {'Resistivity is a fundamental property that quantifies how strongly a material opposes the flow of electric current.'};
            app.BandGapeVEditFieldLabel_3 = uilabel(app.Compound_ElectronicProperties_GridLayout);
            app.BandGapeVEditFieldLabel_3.Text = 'Band Gap (eV):';
            app.Compound_BandGap_EditField = uieditfield(app.Compound_ElectronicProperties_GridLayout, 'numeric');
            app.Compound_BandGap_EditField.Limits = [0 Inf];
            app.Compound_BandGap_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_BandGap_EditField.AllowEmpty = 'on';
            app.Compound_BandGap_EditField.Tooltip = {'Used in: IMFP Calculator.'; 'Energy range in a solid where no electronic states exist between the valence and conduction band.'};
            app.WorkFunctioneVEditFieldLabel_3 = uilabel(app.Compound_ElectronicProperties_GridLayout);
            app.WorkFunctioneVEditFieldLabel_3.Text = 'Work Function (eV):';
            app.Compound_WorkFunction_EditField = uieditfield(app.Compound_ElectronicProperties_GridLayout, 'numeric');
            app.Compound_WorkFunction_EditField.Limits = [0 Inf];
            app.Compound_WorkFunction_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_WorkFunction_EditField.AllowEmpty = 'on';
            app.Compound_WorkFunction_EditField.Tooltip = {'Used in: Calculation of electron kinetic energies in PES analysis.'; 'Energy required to remove an electron to infinity from the surface of a given solid.'};
            app.Compound_PhysicalProperties_Panel = uipanel(app.Compound_GridLayout);
            app.Compound_PhysicalProperties_Panel.Title = 'Compound Physical Properties';
            app.Compound_PhysicalProperties_GridLayout = uigridlayout(app.Compound_PhysicalProperties_Panel);
            app.MassDensitygcm3Label_2 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.MassDensitygcm3Label_2.Text = 'Mass Density (g/cc):';
            app.Compound_MassDensity_EditField = uieditfield(app.Compound_PhysicalProperties_GridLayout, 'numeric');
            app.Compound_MassDensity_EditField.Limits = [0 Inf];
            app.Compound_MassDensity_EditField.ValueDisplayFormat = '%.6f';
            app.Compound_MassDensity_EditField.AllowEmpty = 'on';
            app.Compound_MassDensity_EditField.Tooltip = {'Used in: Used in: IMFP & X-Ray Photoelectric Mass Attenuation Coefficient.'; 'Mass density is a measure of how much mass is contained within a given volume of a material.'};
            app.SpecificHeatJgKEditFieldLabel_2 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.SpecificHeatJgKEditFieldLabel_2.Text = 'Specific Heat (J/g/K):';
            app.Compound_SpecificHeat_EditField = uieditfield(app.Compound_PhysicalProperties_GridLayout, 'numeric');
            app.Compound_SpecificHeat_EditField.Limits = [0 Inf];
            app.Compound_SpecificHeat_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_SpecificHeat_EditField.AllowEmpty = 'on';
            app.Compound_SpecificHeat_EditField.Editable = 'off';
            app.CrystalStructureLabel_3 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.CrystalStructureLabel_3.Text = 'Crystal Structure:';
            app.Compound_CrystalStructure_DropDown = uidropdown(app.Compound_PhysicalProperties_GridLayout);
            app.Compound_CrystalStructure_DropDown.Items = {'CUB-cP-Oh : Simple Cubic', 'CUB-cI-Oh : Body-Centered Cubic', 'CUB-cF-Oh : Face-Centered Cubic', 'HEX-hP-D6h : Simple Hexagonal', 'RHL-hR-D3d : Simple Rhombahedral', 'TET-tP-D4h : Simple Tetragonal', 'TET-tI-D4h : Body-Centered Tetragonal', 'ORC-oP-D2h : Simple Orthorhombic', 'ORC-oS-D2h : Base-Centered Orthorhombic', 'ORC-oI-D2h : Body-Centered Orthorhombic', 'ORC-oF-D2h : Face-Centered Orthorhombic', 'MCL-mP-C2h : Simple Monoclinic', 'MCL-mS-C2h : Base-Centered Monoclinic', 'TRI-aP-Ci : Simple Triclinic', 'N/A'};
            app.Compound_CrystalStructure_DropDown.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the crystal structure type'};
            app.Compound_CrystalStructure_DropDown.Value = 'CUB-cP-Oh : Simple Cubic';
            app.apmEditFieldLabel_2 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.apmEditFieldLabel_2.Text = 'a (pm):';
            app.Compound_a_EditField = uieditfield(app.Compound_PhysicalProperties_GridLayout, 'numeric');
            app.Compound_a_EditField.Limits = [0 Inf];
            app.Compound_a_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_a_EditField.AllowEmpty = 'on';
            app.Compound_a_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-parameter a (picometer)'};
            app.alphadegreeEditFieldLabel_2 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.alphadegreeEditFieldLabel_2.Text = 'alpha (degree):';
            app.Compound_alpha_EditField = uieditfield(app.Compound_PhysicalProperties_GridLayout, 'numeric');
            app.Compound_alpha_EditField.Limits = [0 180];
            app.Compound_alpha_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_alpha_EditField.AllowEmpty = 'on';
            app.Compound_alpha_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-angle alpha (degree)'};
            app.bpmEditFieldLabel_2 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.bpmEditFieldLabel_2.Text = 'b (pm):';
            app.Compound_b_EditField = uieditfield(app.Compound_PhysicalProperties_GridLayout, 'numeric');
            app.Compound_b_EditField.Limits = [0 Inf];
            app.Compound_b_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_b_EditField.AllowEmpty = 'on';
            app.Compound_b_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-parameter b (picometer)'};
            app.betadegreeEditFieldLabel_2 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.betadegreeEditFieldLabel_2.Text = 'beta (degree):';
            app.Compound_beta_EditField = uieditfield(app.Compound_PhysicalProperties_GridLayout, 'numeric');
            app.Compound_beta_EditField.Limits = [0 180];
            app.Compound_beta_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_beta_EditField.AllowEmpty = 'on';
            app.Compound_beta_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-angle beta (degree)'};
            app.cpmEditFieldLabel_2 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.cpmEditFieldLabel_2.Text = 'c (pm):';
            app.Compound_c_EditField = uieditfield(app.Compound_PhysicalProperties_GridLayout, 'numeric');
            app.Compound_c_EditField.Limits = [0 Inf];
            app.Compound_c_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_c_EditField.AllowEmpty = 'on';
            app.Compound_c_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-parameter c (picometer)'};
            app.gammadegreeEditFieldLabel_2 = uilabel(app.Compound_PhysicalProperties_GridLayout);
            app.gammadegreeEditFieldLabel_2.Text = 'gamma (degree):';
            app.Compound_gamma_EditField = uieditfield(app.Compound_PhysicalProperties_GridLayout, 'numeric');
            app.Compound_gamma_EditField.Limits = [0 180];
            app.Compound_gamma_EditField.ValueDisplayFormat = '%.2f';
            app.Compound_gamma_EditField.AllowEmpty = 'on';
            app.Compound_gamma_EditField.Tooltip = {'Used in: Crystallography Viewer.'; 'Defines the lattice-angle gamma (degree)'};
            app.Compound_EditEntry_Button = uibutton(app.Compound_GridLayout, 'push');
            app.Compound_EditEntry_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_EditEntry_ButtonPushed, true);
            app.Compound_EditEntry_Button.Tooltip = {'Edit the current entry'};
            app.Compound_EditEntry_Button.Text = 'Edit Entry';
            app.Compound_Add_Button = uibutton(app.Compound_GridLayout, 'push');
            app.Compound_Add_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_Add_ButtonPushed, true);
            app.Compound_Add_Button.Tooltip = {'Add a new compound to the material property database'};
            app.Compound_Add_Button.Text = '(+) Add New Entry';
            app.Compound_Delete_Button = uibutton(app.Compound_GridLayout, 'push');
            app.Compound_Delete_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_Delete_ButtonPushed, true);
            app.Compound_Delete_Button.Tooltip = {'Delete the selected material from the database'};
            app.Compound_Delete_Button.Text = '(-) Delete Entry';
            app.Compound_Export_GridLayout = uigridlayout(app.Compound_GridLayout);
            app.Compound_Save_Button = uibutton(app.Compound_Export_GridLayout, 'push');
            app.Compound_Save_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_Save_ButtonPushed, true);
            app.Compound_Save_Button.Tooltip = {'Save the selected compound data as a .mat file'};
            app.Compound_Save_Button.Text = 'Save Entry... (*.mat)';
            app.Compound_Export_Button = uibutton(app.Compound_Export_GridLayout, 'push');
            app.Compound_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_Export_ButtonPushed, true);
            app.Compound_Export_Button.Tooltip = {'Export the selected compound data as a .txt file'};
            app.Compound_Export_Button.Text = 'Export Entry... (*.txt)';
            app.Compound_Edit_GridLayout = uigridlayout(app.Compound_GridLayout);
            app.Compound_EditCancel_Button = uibutton(app.Compound_Edit_GridLayout, 'push');
            app.Compound_EditCancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_EditCancel_ButtonPushed, true);
            app.Compound_EditCancel_Button.Tooltip = {'Cancel all edits made to the current entry'};
            app.Compound_EditCancel_Button.Text = 'Cancel';
            app.Compound_EditSave_Button = uibutton(app.Compound_Edit_GridLayout, 'push');
            app.Compound_EditSave_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_EditSave_ButtonPushed, true);
            app.Compound_EditSave_Button.Tooltip = {'Save and store all changes made to the current entry'};
            app.Compound_EditSave_Button.Text = 'Save';
            app.Compound_New_GridLayout = uigridlayout(app.Compound_GridLayout);
            app.Compound_NewCancel_Button = uibutton(app.Compound_New_GridLayout, 'push');
            app.Compound_NewCancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_NewCancel_ButtonPushed, true);
            app.Compound_NewCancel_Button.Tooltip = {'Cancel and do not save the new entry'};
            app.Compound_NewCancel_Button.Text = 'Cancel';
            app.Compound_NewSave_Button = uibutton(app.Compound_New_GridLayout, 'push');
            app.Compound_NewSave_Button.ButtonPushedFcn = createCallbackFcn(app, @Compound_NewSave_ButtonPushed, true);
            app.Compound_NewSave_Button.Tooltip = {'Save and store all information defined in the new entry'};
            app.Compound_NewSave_Button.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatBaseX_01_MaterialsDatabaseEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end