classdef MatBaseX_03_XrayPhotoionization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Main_GridLayout               matlab.ui.container.GridLayout
        XSect_Panel                   matlab.ui.container.Panel
        XSect_GridLayout              matlab.ui.container.GridLayout
        XSect_Buttons_GridLayout      matlab.ui.container.GridLayout
        XSect_Save_Button             matlab.ui.control.Button
        XSect_Export_Button           matlab.ui.control.Button
        XSect_Delta_PlotAll_Button    matlab.ui.control.Button
        XSect_Delta_PlotCL_Button     matlab.ui.control.Button
        XSect_Delta_EditField         matlab.ui.control.NumericEditField
        SigmabarnatomLabel_4          matlab.ui.control.Label
        XSect_Gamma_PlotAll_Button    matlab.ui.control.Button
        XSect_Gamma_PlotCL_Button     matlab.ui.control.Button
        XSect_Gamma_EditField         matlab.ui.control.NumericEditField
        SigmabarnatomLabel_3          matlab.ui.control.Label
        XSect_Beta_PlotAll_Button     matlab.ui.control.Button
        XSect_Beta_PlotCL_Button      matlab.ui.control.Button
        XSect_Beta_EditField          matlab.ui.control.NumericEditField
        SigmabarnatomLabel_2          matlab.ui.control.Label
        XSect_Sigma_PlotAll_Button    matlab.ui.control.Button
        XSect_Sigma_PlotCL_Button     matlab.ui.control.Button
        XSect_Sigma_EditField         matlab.ui.control.NumericEditField
        SigmabarnatomLabel            matlab.ui.control.Label
        XSect_PhotonEnergy_EditField  matlab.ui.control.NumericEditField
        PhotonEnergyeVEditFieldLabel  matlab.ui.control.Label
        XSect_CoreLevel_DropDown      matlab.ui.control.DropDown
        CoreLevelDropDownLabel        matlab.ui.control.Label
        XSect_Formalism_DropDown      matlab.ui.control.DropDown
        XSectFormalismLabel           matlab.ui.control.Label
        BindingEnergy_Panel           matlab.ui.container.Panel
        BE_GridLayout                 matlab.ui.container.GridLayout
        ExportBE_Button               matlab.ui.control.Button
        PlotBE_Button                 matlab.ui.control.Button
        TableBE_UITable               matlab.ui.control.Table
        SelectedMaterial_EditField    matlab.ui.control.EditField
        ElementLabel                  matlab.ui.control.Label
        PeriodicTable_ButtonGroup     matlab.ui.container.ButtonGroup
        Lr_Button                     matlab.ui.control.ToggleButton
        No_Button                     matlab.ui.control.ToggleButton
        Md_Button                     matlab.ui.control.ToggleButton
        Fm_Button                     matlab.ui.control.ToggleButton
        Es_Button                     matlab.ui.control.ToggleButton
        Cf_Button                     matlab.ui.control.ToggleButton
        Bk_Button                     matlab.ui.control.ToggleButton
        Cm_Button                     matlab.ui.control.ToggleButton
        Am_Button                     matlab.ui.control.ToggleButton
        Pu_Button                     matlab.ui.control.ToggleButton
        Np_Button                     matlab.ui.control.ToggleButton
        U_Button                      matlab.ui.control.ToggleButton
        Pa_Button                     matlab.ui.control.ToggleButton
        Th_Button                     matlab.ui.control.ToggleButton
        Lu_Button                     matlab.ui.control.ToggleButton
        Yb_Button                     matlab.ui.control.ToggleButton
        Tm_Button                     matlab.ui.control.ToggleButton
        Er_Button                     matlab.ui.control.ToggleButton
        Ho_Button                     matlab.ui.control.ToggleButton
        Dy_Button                     matlab.ui.control.ToggleButton
        Tb_Button                     matlab.ui.control.ToggleButton
        Gd_Button                     matlab.ui.control.ToggleButton
        Eu_Button                     matlab.ui.control.ToggleButton
        Sm_Button                     matlab.ui.control.ToggleButton
        Pm_Button                     matlab.ui.control.ToggleButton
        Nd_Button                     matlab.ui.control.ToggleButton
        Pr_Button                     matlab.ui.control.ToggleButton
        Ce_Button                     matlab.ui.control.ToggleButton
        Og_Button                     matlab.ui.control.ToggleButton
        Ts_Button                     matlab.ui.control.ToggleButton
        Lv_Button                     matlab.ui.control.ToggleButton
        Mc_Button                     matlab.ui.control.ToggleButton
        Fl_Button                     matlab.ui.control.ToggleButton
        Nh_Button                     matlab.ui.control.ToggleButton
        Cn_Button                     matlab.ui.control.ToggleButton
        Rg_Button                     matlab.ui.control.ToggleButton
        Ds_Button                     matlab.ui.control.ToggleButton
        Mt_Button                     matlab.ui.control.ToggleButton
        Hs_Button                     matlab.ui.control.ToggleButton
        Bh_Button                     matlab.ui.control.ToggleButton
        Sg_Button                     matlab.ui.control.ToggleButton
        Db_Button                     matlab.ui.control.ToggleButton
        Rf_Button                     matlab.ui.control.ToggleButton
        Ac_Button                     matlab.ui.control.ToggleButton
        Ra_Button                     matlab.ui.control.ToggleButton
        Fr_Button                     matlab.ui.control.ToggleButton
        Rn_Button                     matlab.ui.control.ToggleButton
        At_Button                     matlab.ui.control.ToggleButton
        Po_Button                     matlab.ui.control.ToggleButton
        Bi_Button                     matlab.ui.control.ToggleButton
        Pb_Button                     matlab.ui.control.ToggleButton
        Tl_Button                     matlab.ui.control.ToggleButton
        Hg_Button                     matlab.ui.control.ToggleButton
        Au_Button                     matlab.ui.control.ToggleButton
        Pt_Button                     matlab.ui.control.ToggleButton
        Ir_Button                     matlab.ui.control.ToggleButton
        Os_Button                     matlab.ui.control.ToggleButton
        Re_Button                     matlab.ui.control.ToggleButton
        W_Button                      matlab.ui.control.ToggleButton
        Ta_Button                     matlab.ui.control.ToggleButton
        Hf_Button                     matlab.ui.control.ToggleButton
        La_Button                     matlab.ui.control.ToggleButton
        Ba_Button                     matlab.ui.control.ToggleButton
        Cs_Button                     matlab.ui.control.ToggleButton
        Xe_Button                     matlab.ui.control.ToggleButton
        I_Button                      matlab.ui.control.ToggleButton
        Te_Button                     matlab.ui.control.ToggleButton
        Sb_Button                     matlab.ui.control.ToggleButton
        Sn_Button                     matlab.ui.control.ToggleButton
        In_Button                     matlab.ui.control.ToggleButton
        Cd_Button                     matlab.ui.control.ToggleButton
        Ag_Button                     matlab.ui.control.ToggleButton
        Pd_Button                     matlab.ui.control.ToggleButton
        Rh_Button                     matlab.ui.control.ToggleButton
        Ru_Button                     matlab.ui.control.ToggleButton
        Tc_Button                     matlab.ui.control.ToggleButton
        Mo_Button                     matlab.ui.control.ToggleButton
        Nb_Button                     matlab.ui.control.ToggleButton
        Zr_Button                     matlab.ui.control.ToggleButton
        Y_Button                      matlab.ui.control.ToggleButton
        Sr_Button                     matlab.ui.control.ToggleButton
        Rb_Button                     matlab.ui.control.ToggleButton
        Kr_Button                     matlab.ui.control.ToggleButton
        Br_Button                     matlab.ui.control.ToggleButton
        Se_Button                     matlab.ui.control.ToggleButton
        As_Button                     matlab.ui.control.ToggleButton
        Ge_Button                     matlab.ui.control.ToggleButton
        Ga_Button                     matlab.ui.control.ToggleButton
        Zn_Button                     matlab.ui.control.ToggleButton
        Cu_Button                     matlab.ui.control.ToggleButton
        Ni_Button                     matlab.ui.control.ToggleButton
        Co_Button                     matlab.ui.control.ToggleButton
        Fe_Button                     matlab.ui.control.ToggleButton
        Mn_Button                     matlab.ui.control.ToggleButton
        Cr_Button                     matlab.ui.control.ToggleButton
        V_Button                      matlab.ui.control.ToggleButton
        Ti_Button                     matlab.ui.control.ToggleButton
        Sc_Button                     matlab.ui.control.ToggleButton
        Ca_Button                     matlab.ui.control.ToggleButton
        K_Button                      matlab.ui.control.ToggleButton
        Ar_Button                     matlab.ui.control.ToggleButton
        Cl_Button                     matlab.ui.control.ToggleButton
        S_Button                      matlab.ui.control.ToggleButton
        P_Button                      matlab.ui.control.ToggleButton
        Si_Button                     matlab.ui.control.ToggleButton
        Al_Button                     matlab.ui.control.ToggleButton
        Label_12                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Mg_Button                     matlab.ui.control.ToggleButton
        Na_Button                     matlab.ui.control.ToggleButton
        Ne_Button                     matlab.ui.control.ToggleButton
        F_Button                      matlab.ui.control.ToggleButton
        O_Button                      matlab.ui.control.ToggleButton
        N_Button                      matlab.ui.control.ToggleButton
        C_Button                      matlab.ui.control.ToggleButton
        B_Button                      matlab.ui.control.ToggleButton
        Be_Button                     matlab.ui.control.ToggleButton
        Li_Button                     matlab.ui.control.ToggleButton
        He_Button                     matlab.ui.control.ToggleButton
        Label_17                      matlab.ui.control.Label
        Label_16                      matlab.ui.control.Label
        Label_15                      matlab.ui.control.Label
        Label_14                      matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        H_Button                      matlab.ui.control.ToggleButton
        Label_18                      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        AppButtons_GridLayout         matlab.ui.container.GridLayout
        ViewSpectroscopy_Button       matlab.ui.control.Button
        AppIcon_Image                 matlab.ui.control.Image
        TRANSFER_Button               matlab.ui.control.Button
        APPINFO_Button                matlab.ui.control.Button
        RESIZEUI_Button               matlab.ui.control.Button
        RESTART_Button                matlab.ui.control.Button
        CLOSEFIGS_Button              matlab.ui.control.Button
        AppTitle                      matlab.ui.control.Label
        MAINMENU_GridLayout           matlab.ui.container.GridLayout
        MAINMENU_Button               matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarize all data for analysis
        myAppData
        % - Material & Formalism Selection
        selected_element            % selected element
        selected_formalism          % selected xsect formalism
        selected_corelevel          % selected core-level
        % - X-Ray Binding Energies Table
        list_of_all_corelevels      % list of all available core-levels for selected element
        list_of_all_be              % list of all available binding energies for selected element
        % - Independent Variables
        eph                         % photon energy defined by user
        % - Dependent Variables
        sigma                       % Subshell photoionization cross section in units of barn/atom
        beta                        % Beta (dipole) parameter of the photoelectron angular distribution
        gamma                       % Gamma (non-dipole) parameter of the photoelectron angular distribution
        delta                       % Delta (non-dipole) parameter of the photoelectron angular distribution
        sigma_ratio                 % Ratio of Sigma values for all binding energies
        % - Other Variables
        eph_R                       % Range: photon energy range 
        sigma_R                     % Range: Subshell photoionization cross section in units of barn/atom
        beta_R                      % Range: Beta (dipole) parameter of the photoelectron angular distribution
        gamma_R                     % Range: Gamma (non-dipole) parameter of the photoelectron angular distribution
        delta_R                     % Range: Delta (non-dipole) parameter of the photoelectron angular distribution
        x_lims                      % x-limits of plot
        y_sigma_lims                % y-limits of sigma plot
        y_beta_gamma_lims           % y-limits of beta & gamma plot
        y_delta_lims                % y-limits of delta plot
    end
    
    % Private methods available to the app only
    methods (Access = private)
        %% Open Figure Locations 
        % Open Location of MainMenu App
        function open_MainMenu_to_left_of_App(app)
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1);
            fig.Position(2) = app.UIFigure.Position(2);
        end
        %% General Functions
        % Define list of all formalisms
        function list_of_formalisms = define_dropdown_formalisms(~)
            list_of_formalisms = {...
                'Cant(2022) - 1500 to 10000 eV',...
                'Trzhaskovskaya(2018) - 1500 to 10000 eV',...
                'Yeh Lindau(1985) - 10 to 1500 eV',...
                'Scofield(1973) - 1 to 1500 keV',...
                };
        end

        %% Update Functions
        % Find all the core-levels for the selected element
        function update_core_level_properties(app)
            % - 1 : Update the list of core-level names for the selected element
            [app.list_of_all_be, app.list_of_all_corelevels]    = calc_be(app.selected_element);
            [app.list_of_all_be, indx]                          = sort(app.list_of_all_be); 
            app.list_of_all_corelevels                          = app.list_of_all_corelevels(indx);
            cl_labels                                           = string(size(app.list_of_all_be));
            cl_table_name                                       = string(size(app.list_of_all_be));
            for i = 1:length(app.list_of_all_be)
                cl_table_name(i) = sprintf("%s(%s)",app.selected_element, app.list_of_all_corelevels(i)); 
                cl_labels(i) = sprintf("%s-%.2feV",app.list_of_all_corelevels(i),app.list_of_all_be(i)); 
            end
            % - 2 : Calculate the relative sigma values
            temp_data = calc_xsect_sigma(app.eph, app.selected_element, app.list_of_all_corelevels, app.selected_formalism);
            temp_data(isnan(temp_data)) = 0;
            app.sigma_ratio = temp_data./ max(temp_data(:)); 
            if isrow(app.sigma_ratio); app.sigma_ratio = app.sigma_ratio'; end
            % - 3 : Update the core-level selection
            app.XSect_CoreLevel_DropDown.Items = cl_labels;
            XSect_CoreLevel_DropDownValueChanged(app);
            % - 4 : Update UITable for BE spectrum
            app.TableBE_UITable.Data = [];
            for i = 1:length(app.list_of_all_be)
                app.TableBE_UITable.Data{i,1} = char(cl_table_name(i));
                app.TableBE_UITable.Data{i,2} = round(app.list_of_all_be(i),4);
                app.TableBE_UITable.Data{i,3} = round(app.sigma_ratio(i),4);
            end
            % - 5 : Sort in terms of the relative sigma value
            app.TableBE_UITable.Data = sortrows(app.TableBE_UITable.Data, 3, 'descend');
            % - 6 : Appending results to data-structure
            write_app_data(app);
        end

        % Function that calculates all single dependent variables
        function update_single_dependents(app)
            % - 1 : Calculating all relevant values
            [app.sigma, app.beta, app.gamma, app.delta] = calc_xsect(app.eph, app.selected_element, app.selected_corelevel, app.selected_formalism,1);
            if app.sigma < 0 || isnan(app.sigma) || isempty(app.sigma); app.sigma = 0; end
            if isempty(app.beta) || isnan(app.beta); app.beta = 0; end
            if isempty(app.gamma) || isnan(app.gamma); app.gamma = 0; end
            if isempty(app.delta) || isnan(app.delta); app.delta = 0; end
            % - 2 : Update all edit-fields to read the new values
            app.XSect_Sigma_EditField.Value = app.sigma;
            app.XSect_Gamma_EditField.Value = app.gamma;
            app.XSect_Beta_EditField.Value = app.beta;
            app.XSect_Delta_EditField.Value = app.delta;
            % - 3 : Appending results to data-structure
            write_app_data(app);
            fprintf("---> sigma: " + string(app.sigma) + " barn/atom \n");
            fprintf("---> beta: " + string(app.beta) + " \n");
            fprintf("---> gamma: " + string(app.gamma) + " \n");
            fprintf("---> delta: " + string(app.delta) + " \n");
        end

        % Function that calculates range of photon energy cross-sections
        function update_range_dependents(app)
            % - 1 : Define the initial parameters based on the formalism
            if strcmpi(app.selected_formalism,"S1973")   
                app.eph_R           = logspace(3, 6.5, 200);
                app.x_lims          = [0.9*1000, 1.1*1500000];
                app.y_sigma_lims    = [1e-10, 1e7];
            elseif strcmpi(app.selected_formalism,"YL1985")
                app.eph_R           = 10:10:1500;
                app.x_lims          = [0, 1550];
                app.y_sigma_lims    = [1e0, 1e8];
            elseif strcmpi(app.selected_formalism,"T2018")
                app.eph_R           = 1500:50:10000;
                app.x_lims          = [0.9*1000, 1.1*10000];
                app.y_sigma_lims    = [1e-3, 1e6];
            elseif strcmpi(app.selected_formalism,"C2022")  
                app.eph_R           = 1500:50:10000;
                app.x_lims          = [0.9*1000, 1.1*10000];
                app.y_sigma_lims    = [1e-3, 1e6];
            end
            if isrow(app.eph_R); app.eph_R = app.eph_R'; end
            app.y_beta_gamma_lims       = [-1, 2.75];
            app.y_delta_lims            = [0.0, 0.555];
            % - 2 : Calculate ranged photon energy photoionization parameters
            [app.sigma_R, app.beta_R, app.gamma_R, app.delta_R] = calc_xsect(app.eph_R, app.selected_element, app.selected_corelevel, app.selected_formalism);
            % - 3 : Appending results to data-structure
            write_app_data(app);
        end
    
        % Function that calculates single photon energy relative cross-sections
        function update_sigma_ratio_only(app)
            % - 1 : Calculate single photon energy photoionization parameters
            temp_data = calc_xsect_sigma(app.eph, app.selected_element, app.list_of_all_corelevels, app.selected_formalism);
            temp_data(isnan(temp_data)) = 0;
            app.sigma_ratio = temp_data./ max(temp_data(:)); 
            if isrow(app.sigma_ratio); app.sigma_ratio = app.sigma_ratio'; end
            % - 2 : Update UITable for BE spectrum
            % app.TableBE_UITable.Data = [];
            for i = 1:length(app.list_of_all_be)
                % app.TableBE_UITable.Data{i,1} = char(app.list_of_all_corelevels(i));
                % app.TableBE_UITable.Data{i,2} = round(app.list_of_all_be(i),4);
                app.TableBE_UITable.Data{i,3} = round(app.sigma_ratio(i),4);
            end
            % - 3 : Sort in terms of the relative sigma value
            app.TableBE_UITable.Data = sortrows(app.TableBE_UITable.Data, 3, 'descend');
            % - 4 : Appending results to data-structure
            write_app_data(app);
        end
    
        % Function that updates when the element is changes
        function state_change_of_element(app)
            % 1 - Update all dependencies
            update_core_level_properties(app);
            % 2 - Appending results to data-structure
            write_app_data(app);
        end

        % Function that updates when the element is changes
        function state_change_of_formalism(app)
            % 1 - Update all dependencies
            update_single_dependents(app);              % Calculate cross-section with single photon energy
            update_range_dependents(app);               % Calculate cross-section with range of photon energy
            update_sigma_ratio_only(app);                    % Calculate cross-section ratios
            % 2 - Appending results to data-structure
            write_app_data(app);
        end

       % Write the app data into a data-structure
        function write_app_data(app)
            app.myAppData               = struct();
            % -- Defining the input arguments
            app.myAppData.formalism     = string(app.selected_formalism);
            app.myAppData.element       = string(app.selected_element);
            app.myAppData.cls           = app.list_of_all_corelevels;
            app.myAppData.be            = app.list_of_all_be;
            app.myAppData.sigma_ratio   = round(app.sigma_ratio, 4);
            % -- Extracting the cross-sections at the chosen photon energy
            app.myAppData.clevel        = app.selected_corelevel;
            [app.myAppData.benergy,~]   = calc_be(app.selected_element, app.selected_corelevel);
            app.myAppData.eph           = app.eph;
            app.myAppData.sigma         = app.sigma;
            app.myAppData.beta          = app.beta;
            app.myAppData.gamma         = app.gamma;
            app.myAppData.delta         = app.delta;
            % -- Extracting the cross-section across the full photon energy range
            app.myAppData.eph_R         = app.eph_R;
            app.myAppData.sigma_R       = app.sigma_R;
            app.myAppData.beta_R        = app.beta_R;
            app.myAppData.gamma_R       = app.gamma_R;
            app.myAppData.delta_R       = app.delta_R;
        end

        %% Plot Functions
        % Plot sigma photoionization figure
        function plot_sigma_cl(app)
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.selected_corelevel); cl_color = cl_color{1};
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            plot(app.myAppData.eph_R, app.myAppData.sigma_R,...
                '-', 'linewidth', 1.25,  'color', cl_color); 
            scatter(app.myAppData.eph, app.myAppData.sigma,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(app.selected_corelevel, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.98, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.98, 0.91, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \sigma [barn/atom] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; 
            if strcmpi(app.selected_formalism,"S1973"); ax.XScale = 'log';
            else; ax.XScale = 'linear'; end
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([app.x_lims, app.y_sigma_lims]);
        end

        % Plot beta photoionization figure
        function plot_beta_cl(app)
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.selected_corelevel); cl_color = cl_color{1};
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            plot(app.myAppData.eph_R, app.myAppData.beta_R,...
                '-', 'linewidth', 1.25,  'color', cl_color); 
            scatter(app.myAppData.eph, app.myAppData.beta,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(app.selected_corelevel, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.98, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.98, 0.91, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \beta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; 
            if strcmpi(app.selected_formalism,"S1973"); ax.XScale = 'log';
            else; ax.XScale = 'linear'; end
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([app.x_lims, app.y_beta_gamma_lims]);
        end

        % Plot gamma photoionization figure
        function plot_gamma_cl(app)
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.selected_corelevel); cl_color = cl_color{1};
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            plot(app.myAppData.eph_R, app.myAppData.gamma_R,...
                '-', 'linewidth', 1.25,  'color', cl_color); 
            scatter(app.myAppData.eph, app.myAppData.gamma,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(app.selected_corelevel, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.98, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.98, 0.91, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \gamma ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; 
            if strcmpi(app.selected_formalism,"S1973"); ax.XScale = 'log';
            else; ax.XScale = 'linear'; end
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([app.x_lims, app.y_beta_gamma_lims]);
        end

        % Plot delta photoionization figure
        function plot_delta_cl(app)
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.selected_corelevel); cl_color = cl_color{1};
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            plot(app.myAppData.eph_R, app.myAppData.delta_R,...
                '-', 'linewidth', 1.25,  'color', cl_color); 
            scatter(app.myAppData.eph, app.myAppData.delta,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(app.selected_corelevel, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.98, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.98, 0.91, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \delta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; 
            if strcmpi(app.selected_formalism,"S1973"); ax.XScale = 'log';
            else; ax.XScale = 'linear'; end
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([app.x_lims, app.y_delta_lims]);
        end

        % Plot full sigma photoionization figure
        function plot_sigma_all(app)
            % - Extracting all photionization data
            sigma_All   = calc_xsect_sigma(app.eph_R, app.selected_element, app.list_of_all_corelevels, app.selected_formalism);
            nan_indx    = find(all(isnan(sigma_All),1));
            sigma_final = sigma_All; sigma_final(:,nan_indx) = [];
            cls_final   = app.list_of_all_corelevels; cls_final(nan_indx) = [];
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.list_of_all_corelevels);
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            for i = 1:length(cls_final)
                plot(app.eph_R, sigma_final(:,i), '-', 'linewidth', 1.25,  'color', cl_color{i}, 'DisplayName',cls_final(i)); 
            end
            % - Plotting the selected core-level
            cl_color = read_spectroscopy_colors(app.selected_corelevel); cl_color = cl_color{1};
            scatter(app.myAppData.eph, app.myAppData.sigma,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(cls_final, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.98, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.98, 0.91, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \sigma [barn/atom] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; 
            if strcmpi(app.selected_formalism,"S1973"); ax.XScale = 'log';
            else; ax.XScale = 'linear'; end
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([app.x_lims, app.y_sigma_lims]);
        end

        % Plot full beta photoionization figure
        function plot_beta_all(app)
            % - Extracting all photionization data
            beta_All   = calc_xsect_beta(app.eph_R, app.selected_element, app.list_of_all_corelevels, app.selected_formalism);
            nan_indx    = find(all(isnan(beta_All),1));
            beta_final = beta_All; beta_final(:,nan_indx) = [];
            cls_final   = app.list_of_all_corelevels; cls_final(nan_indx) = [];
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.list_of_all_corelevels);
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            for i = 1:length(cls_final)
                plot(app.eph_R, beta_final(:,i), '-', 'linewidth', 1.25,  'color', cl_color{i}, 'DisplayName',cls_final(i)); 
            end
            % - Plotting the selected core-level
            cl_color = read_spectroscopy_colors(app.selected_corelevel); cl_color = cl_color{1};
            scatter(app.myAppData.eph, app.myAppData.beta,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(cls_final, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.98, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.98, 0.91, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \beta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; 
            if strcmpi(app.selected_formalism,"S1973"); ax.XScale = 'log';
            else; ax.XScale = 'linear'; end
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([app.x_lims, app.y_beta_gamma_lims]);
        end

        % Plot full gamma photoionization figure
        function plot_gamma_all(app)
            % - Extracting all photionization data
            gamma_All   = calc_xsect_gamma(app.eph_R, app.selected_element, app.list_of_all_corelevels, app.selected_formalism);
            nan_indx    = find(all(isnan(gamma_All),1));
            gamma_final = gamma_All; gamma_final(:,nan_indx) = [];
            cls_final   = app.list_of_all_corelevels; cls_final(nan_indx) = [];
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.list_of_all_corelevels);
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            for i = 1:length(cls_final)
                plot(app.eph_R, gamma_final(:,i), '-', 'linewidth', 1.25,  'color', cl_color{i}, 'DisplayName',cls_final(i)); 
            end
            % - Plotting the selected core-level
            cl_color = read_spectroscopy_colors(app.selected_corelevel); cl_color = cl_color{1};
            scatter(app.myAppData.eph, app.myAppData.gamma,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(cls_final, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.98, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.98, 0.91, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \gamma ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; 
            if strcmpi(app.selected_formalism,"S1973"); ax.XScale = 'log';
            else; ax.XScale = 'linear'; end
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([app.x_lims, app.y_beta_gamma_lims]);
        end

        % Plot full delta photoionization figure
        function plot_delta_all(app)
            % - Extracting all photionization data
            delta_All   = calc_xsect_delta(app.eph_R, app.selected_element, app.list_of_all_corelevels, app.selected_formalism);
            nan_indx    = find(all(isnan(delta_All),1));
            delta_final = delta_All; delta_final(:,nan_indx) = [];
            cls_final   = app.list_of_all_corelevels; cls_final(nan_indx) = [];
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.list_of_all_corelevels);
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            for i = 1:length(cls_final)
                plot(app.eph_R, delta_final(:,i), '-', 'linewidth', 1.25,  'color', cl_color{i}, 'DisplayName',cls_final(i)); 
            end
            % - Plotting the selected core-level
            cl_color = read_spectroscopy_colors(app.selected_corelevel); cl_color = cl_color{1};
            scatter(app.myAppData.eph, app.myAppData.delta,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(cls_final, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.98, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.98, 0.91, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \delta [barn/atom] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; 
            if strcmpi(app.selected_formalism,"S1973"); ax.XScale = 'log';
            else; ax.XScale = 'linear'; end
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([app.x_lims, app.y_delta_lims]);
        end

        % Plot binding energy spectrum
        function plot_binding_energy_spectrum(app)
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.list_of_all_corelevels);
            ele_indx = get_mpd_props(app.selected_element); ele_indx = ele_indx.atom_z;
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 1000; 
            fig.Position(4) = 425;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plot the figure
            nexttile(); hold on; grid on; grid minor;
            for i = 1:length(app.list_of_all_corelevels)
                stem(app.list_of_all_be(i), app.sigma_ratio(i), '-', 'linewidth', 2.0, 'marker', 'none', 'color', cl_color{i});
                text(app.list_of_all_be(i), app.sigma_ratio(i), sprintf('%s-%s(%.2f)', app.selected_element, app.list_of_all_corelevels(i), app.list_of_all_be(i)),...
                    'Rotation',90, 'FontWeight','normal', 'FontSize',10, 'color', cl_color{i});
            end
            % - Labeling the x- and y-axes
            text(0.99, 0.96, sprintf("%s(Z=%i)", app.selected_element, ele_indx),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','right');
            text(0.99, 0.91, sprintf("hv = %i eV", app.eph),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','right');
            legend(app.list_of_all_corelevels, 'location', 'southeastoutside', 'FontSize', 8);
            xlabel('Binding Energy [eV]', 'FontWeight','bold');
            ylabel('Relative Sigma [arb.]', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'log';
            if isnan(max(app.sigma_ratio(:)));  axis([1, 130000, 0, 1.0]);
            else;                               axis([1, 130000, 0, 1.4*max(app.sigma_ratio(:))]);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBaseX - Photoionization Binding Energies & Cross-Section Database (procopios.constantinou@psi.ch)';
            % 1 - Initializing fields
            % -- Defining the list of formalisms
            list_of_formalisms = define_dropdown_formalisms(app);
            app.XSect_Formalism_DropDown.Items = list_of_formalisms;
            app.XSect_Formalism_DropDown.Value = app.XSect_Formalism_DropDown.Items{1};
            % -- Coloring the edit-fields that can be changed
            edit_color = [0.85 1 1];
            app.XSect_Formalism_DropDown.BackgroundColor = edit_color;
            app.XSect_CoreLevel_DropDown.BackgroundColor = edit_color;
            app.XSect_PhotonEnergy_EditField.BackgroundColor = edit_color;
            % 2 - Default parameters upon startup
            app.myAppData               = struct();
            app.selected_element        = "Si";
            app.selected_formalism      = "C2022";
            app.selected_corelevel      = "1s1";
            app.eph                     = 2000;
            app.eph_R                   = [];
            % 3 - First Tab Initialisation
            % -- Initializing the UI table
            app.TableBE_UITable.Data = []; 
            s = uistyle('HorizontalAlignment','left'); addStyle(app.TableBE_UITable,s);
            % 4 - First determination
            PeriodicTable_ButtonGroupSelectionChanged(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run MatBaseX;
            open_MainMenu_to_left_of_App(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type', 'Figure');
            % Exclude those with 'MatBaseX' in the Name (title)
            matbaseFigs = findobj(hPlots, '-regexp', 'Name', 'MatBaseX');
            hPlotsToClose = setdiff(hPlots, matbaseFigs);
            % Close only the non-MatBaseX figures
            close(hPlotsToClose);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run MatBaseX_03_XrayPhotoionization;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 700;
            screen_size(4) = 575;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('myAppData : '); disp(app.myAppData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            fname = sprintf("%s%s_XSECT_%s", app.selected_element, app.selected_corelevel, app.selected_formalism);
            assignin('base', char(fname), app.myAppData);
        end

        % Button pushed function: ViewSpectroscopy_Button
        function ViewSpectroscopy_ButtonPushed(app, event)
            view_spectroscopy(app.selected_element, app.eph, -1);
        end

        % Selection changed function: PeriodicTable_ButtonGroup
        function PeriodicTable_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton  = app.PeriodicTable_ButtonGroup.SelectedObject;
            app.selected_element     = selectedButton.Text;
            fprintf("--> Element: " + string(app.selected_element) + "\n");
            % 2 - Updating the relevant UI components
            app.SelectedMaterial_EditField.Value = app.selected_element;
            % 3 - Update Functions
            state_change_of_element(app);
        end

        % Value changed function: XSect_Formalism_DropDown
        function XSect_Formalism_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XSect_Formalism_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the formalism selected
            if strcmpi(value,app.XSect_Formalism_DropDown.Items{4});          value = "S1973";
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{3});      value = "YL1985";
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{2});      value = "T2018";
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{1});      value = "C2022";
            end
            % 3 - Validity check on the buttons to plot photoionization parameters
            if value == "S1973"
                app.XSect_Sigma_PlotCL_Button.Enable  = 'on';
                app.XSect_Sigma_PlotAll_Button.Enable = 'on';
                app.XSect_Beta_PlotCL_Button.Enable   = 'off';
                app.XSect_Beta_PlotAll_Button.Enable  = 'off';
                app.XSect_Gamma_PlotCL_Button.Enable  = 'off';
                app.XSect_Gamma_PlotAll_Button.Enable = 'off';
                app.XSect_Delta_PlotCL_Button.Enable  = 'off';
                app.XSect_Delta_PlotAll_Button.Enable = 'off';
            elseif value == "YL1985"
                app.XSect_Sigma_PlotCL_Button.Enable  = 'on';
                app.XSect_Sigma_PlotAll_Button.Enable = 'on';
                app.XSect_Beta_PlotCL_Button.Enable   = 'on';
                app.XSect_Beta_PlotAll_Button.Enable  = 'on';
                app.XSect_Gamma_PlotCL_Button.Enable  = 'off';
                app.XSect_Gamma_PlotAll_Button.Enable = 'off';
                app.XSect_Delta_PlotCL_Button.Enable  = 'off';
                app.XSect_Delta_PlotAll_Button.Enable = 'off';
            elseif value == "T2018" || value == "C2022"
                app.XSect_Sigma_PlotCL_Button.Enable  = 'on';
                app.XSect_Sigma_PlotAll_Button.Enable = 'on';
                app.XSect_Beta_PlotCL_Button.Enable   = 'on';
                app.XSect_Beta_PlotAll_Button.Enable  = 'on';
                app.XSect_Gamma_PlotCL_Button.Enable  = 'on';
                app.XSect_Gamma_PlotAll_Button.Enable = 'on';
                app.XSect_Delta_PlotCL_Button.Enable  = 'on';
                app.XSect_Delta_PlotAll_Button.Enable = 'on';
            end
            % 4 - Assigning the output
            app.selected_formalism = value;
            fprintf("--> formalism: " + string(app.selected_formalism) + "\n");
            % 5 - Update Functions
            state_change_of_formalism(app);
        end

        % Value changed function: XSect_CoreLevel_DropDown
        function XSect_CoreLevel_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XSect_CoreLevel_DropDown.Value;
            value = string(value(1:3));
            % 2 - Validity check on the input
            % 3 - Assigning the output
            app.selected_corelevel = value;
            fprintf("--> Core-Level: " + string(app.selected_corelevel) + "\n");
            % 4 - Validity check on the photon energy
            [binding_energy, ~] = calc_be(app.selected_element, app.selected_corelevel);
            if app.eph < binding_energy
                app.eph = binding_energy;
                app.XSect_PhotonEnergy_EditField.Value = app.eph;
            end
            % 5 - Update Functions
            update_single_dependents(app);
            update_range_dependents(app);
        end

        % Value changed function: XSect_PhotonEnergy_EditField
        function XSect_PhotonEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XSect_PhotonEnergy_EditField.Value;
            % 2 - Validity check on the input value
            [binding_energy, ~] = calc_be(app.selected_element, app.selected_corelevel);
            if isempty(value) || isnan(value) || value < binding_energy
                value = binding_energy;
            end
            app.eph = value;
            app.XSect_PhotonEnergy_EditField.Value = app.eph;
            % 2 - Assigning the output
            fprintf("--> Photon Energy: " + string(app.eph) + " eV\n");
            % 3 - Update Functions
            update_single_dependents(app);
            update_sigma_ratio_only(app);
        end

        % Button pushed function: XSect_Sigma_PlotCL_Button
        function XSect_Sigma_PlotCL_ButtonPushed(app, event)
            plot_sigma_cl(app);
        end

        % Button pushed function: XSect_Sigma_PlotAll_Button
        function XSect_Sigma_PlotAll_ButtonPushed(app, event)
            plot_sigma_all(app);
        end

        % Button pushed function: XSect_Beta_PlotCL_Button
        function XSect_Beta_PlotCL_ButtonPushed(app, event)
            plot_beta_cl(app);
        end

        % Button pushed function: XSect_Beta_PlotAll_Button
        function XSect_Beta_PlotAll_ButtonPushed(app, event)
            plot_beta_all(app);
        end

        % Button pushed function: XSect_Gamma_PlotCL_Button
        function XSect_Gamma_PlotCL_ButtonPushed(app, event)
            plot_gamma_cl(app);
        end

        % Button pushed function: XSect_Gamma_PlotAll_Button
        function XSect_Gamma_PlotAll_ButtonPushed(app, event)
            plot_gamma_all(app);
        end

        % Button pushed function: XSect_Delta_PlotCL_Button
        function XSect_Delta_PlotCL_ButtonPushed(app, event)
            plot_delta_cl(app);
        end

        % Button pushed function: XSect_Delta_PlotAll_Button
        function XSect_Delta_PlotAll_ButtonPushed(app, event)
            plot_delta_all(app);
        end

        % Button pushed function: PlotBE_Button
        function PlotBE_ButtonPushed(app, event)
            plot_binding_energy_spectrum(app);
        end

        % Button pushed function: ExportBE_Button
        function ExportBE_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            fname = sprintf("%s_BE_hv%ieV", app.selected_element, app.eph);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            % info = "";
            info = sprintf("Element: %s\n", app.selected_element);
            % Sample data
            T_Data = table(app.myAppData.cls, app.myAppData.be, app.myAppData.sigma_ratio);
            T_Data.Properties.VariableNames = {'clevel','BE_eV','rSigma'};
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: XSect_Save_Button
        function XSect_Save_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            fname = sprintf("%s%s_XSECT_%s", app.selected_element, app.selected_corelevel, app.selected_formalism);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            structName          = char(save_filename(1:end-4));
            eval([structName ' = app.myAppData;']);     % Create the data structure dynamically
            % Saving the data as .mat
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved data : '); display(app.myAppData);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!');
        end

        % Button pushed function: XSect_Export_Button
        function XSect_Export_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            fname = sprintf("%s%s_XSECT_%s", app.selected_element, app.selected_corelevel, app.selected_formalism);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("Element:\t%s\n", app.selected_element);
            info = info + sprintf("Core-Level:\t%s\n", app.selected_corelevel);
            info = info + sprintf("Formalism:\t%s\n", app.selected_formalism);
            info = info + sprintf("hv:\t\t%.4e eV\n", app.eph);
            info = info + sprintf("Sigma:\t\t%.4e barn/atom\n", app.sigma);
            info = info + sprintf("Beta:\t\t%.4e\n", app.beta);
            info = info + sprintf("Gamma:\t\t%.4e\n", app.gamma);
            info = info + sprintf("Delta:\t\t%.4e\n", app.delta);
            % Sample data
            T_Data = table(app.myAppData.eph_R, app.myAppData.sigma_R, app.myAppData.beta_R, app.myAppData.gamma_R, app.myAppData.delta_R);
            T_Data.Properties.VariableNames = {'eph_eV','sigma_barn_per_atom','beta','gamma','delta'};
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'Photoionization Binding Energies & Cross-Section Database';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart this App.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize this App to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all App information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer App Data directly to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.AppIcon_Image = uiimage(app.AppButtons_GridLayout);
            app.AppIcon_Image.Tooltip = {'Extract the binding energy spectrum, photoionization cross-sections, and photoionization asymmetry parameters of all elements between 1-98 with our powerful app. Utilize a range of formalisms, including the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018) and Cant (2022) methods. Easily identify the best core-levels to probe by finding the maximum cross-sections and gain a comprehensive overview of the core-level spectra of all relevant elements!'};
            app.AppIcon_Image.ImageSource = 'Icon_Photoionization.jpeg';
            app.ViewSpectroscopy_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.ViewSpectroscopy_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewSpectroscopy_ButtonPushed, true);
            app.ViewSpectroscopy_Button.Tooltip = {'Plot the binding energies of all core-levels for the selected element.'};
            app.ViewSpectroscopy_Button.Text = 'View Spectroscopy (BE + Auger) ';
            app.PeriodicTable_ButtonGroup = uibuttongroup(app.GridLayout);
            app.PeriodicTable_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriodicTable_ButtonGroupSelectionChanged, true);
            app.PeriodicTable_ButtonGroup.Tooltip = {'Select an element.'};
            app.Label = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label.Text = '1';
            app.Label_18 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_18.Text = '18';
            app.H_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.H_Button.Text = 'H';
            app.Label_2 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_2.Text = '2';
            app.Label_13 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_13.Text = '13';
            app.Label_14 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_14.Text = '14';
            app.Label_15 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_15.Text = '15';
            app.Label_16 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_16.Text = '16';
            app.Label_17 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_17.Text = '17';
            app.He_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.He_Button.Text = 'He';
            app.Li_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Li_Button.Text = 'Li';
            app.Be_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Be_Button.Text = 'Be';
            app.B_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.B_Button.Text = 'B';
            app.C_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.C_Button.Text = 'C';
            app.N_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.N_Button.Text = 'N';
            app.O_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.O_Button.Text = 'O';
            app.F_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.F_Button.Text = 'F';
            app.Ne_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ne_Button.Text = 'Ne';
            app.Na_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Na_Button.Text = 'Na';
            app.Mg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mg_Button.Text = 'Mg';
            app.Label_3 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_12.Text = '12';
            app.Al_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Al_Button.Text = 'Al';
            app.Si_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Si_Button.Text = 'Si';
            app.Si_Button.Value = true;
            app.P_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.P_Button.Text = 'P';
            app.S_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.S_Button.Text = 'S';
            app.Cl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cl_Button.Text = 'Cl';
            app.Ar_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ar_Button.Text = 'Ar';
            app.K_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.K_Button.Text = 'K';
            app.Ca_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ca_Button.Text = 'Ca';
            app.Sc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sc_Button.Text = 'Sc';
            app.Ti_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ti_Button.Text = 'Ti';
            app.V_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.V_Button.Text = 'V';
            app.Cr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cr_Button.Text = 'Cr';
            app.Mn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mn_Button.Text = 'Mn';
            app.Fe_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fe_Button.Text = 'Fe';
            app.Co_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Co_Button.Text = 'Co';
            app.Ni_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ni_Button.Text = 'Ni';
            app.Cu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cu_Button.Text = 'Cu';
            app.Zn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Zn_Button.Text = 'Zn';
            app.Ga_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ga_Button.Text = 'Ga';
            app.Ge_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ge_Button.Text = 'Ge';
            app.As_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.As_Button.Text = 'As';
            app.Se_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Se_Button.Text = 'Se';
            app.Br_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Br_Button.Text = 'Br';
            app.Kr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Kr_Button.Text = 'Kr';
            app.Rb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rb_Button.Text = 'Rb';
            app.Sr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sr_Button.Text = 'Sr';
            app.Y_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Y_Button.Text = 'Y';
            app.Zr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Zr_Button.Text = 'Zr';
            app.Nb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nb_Button.Text = 'Nb';
            app.Mo_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mo_Button.Text = 'Mo';
            app.Tc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tc_Button.Text = 'Tc';
            app.Ru_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ru_Button.Text = 'Ru';
            app.Rh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rh_Button.Text = 'Rh';
            app.Pd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pd_Button.Text = 'Pd';
            app.Ag_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ag_Button.Text = 'Ag';
            app.Cd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cd_Button.Text = 'Cd';
            app.In_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.In_Button.Text = 'In';
            app.Sn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sn_Button.Text = 'Sn';
            app.Sb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sb_Button.Text = 'Sb';
            app.Te_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Te_Button.Text = 'Te';
            app.I_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.I_Button.Text = 'I';
            app.Xe_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Xe_Button.Text = 'Xe';
            app.Cs_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cs_Button.Text = 'Cs';
            app.Ba_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ba_Button.Text = 'Ba';
            app.La_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.La_Button.Text = 'La';
            app.Hf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hf_Button.Text = 'Hf';
            app.Ta_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ta_Button.Text = 'Ta';
            app.W_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.W_Button.Text = 'W';
            app.Re_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Re_Button.Text = 'Re';
            app.Os_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Os_Button.Text = 'Os';
            app.Ir_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ir_Button.Text = 'Ir';
            app.Pt_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pt_Button.Text = 'Pt';
            app.Au_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Au_Button.Text = 'Au';
            app.Hg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hg_Button.Text = 'Hg';
            app.Tl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tl_Button.Text = 'Tl';
            app.Pb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pb_Button.Text = 'Pb';
            app.Bi_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bi_Button.Text = 'Bi';
            app.Po_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Po_Button.Text = 'Po';
            app.At_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.At_Button.Text = 'At';
            app.Rn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rn_Button.Text = 'Rn';
            app.Fr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fr_Button.Text = 'Fr';
            app.Ra_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ra_Button.Text = 'Ra';
            app.Ac_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ac_Button.Text = 'Ac';
            app.Rf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rf_Button.Enable = 'off';
            app.Rf_Button.Text = 'Rf';
            app.Db_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Db_Button.Enable = 'off';
            app.Db_Button.Text = 'Db';
            app.Sg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sg_Button.Enable = 'off';
            app.Sg_Button.Text = 'Sg';
            app.Bh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bh_Button.Enable = 'off';
            app.Bh_Button.Text = 'Bh';
            app.Hs_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hs_Button.Enable = 'off';
            app.Hs_Button.Text = 'Hs';
            app.Mt_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mt_Button.Enable = 'off';
            app.Mt_Button.Text = 'Mt';
            app.Ds_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ds_Button.Enable = 'off';
            app.Ds_Button.Text = 'Ds';
            app.Rg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rg_Button.Enable = 'off';
            app.Rg_Button.Text = 'Rg';
            app.Cn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cn_Button.Enable = 'off';
            app.Cn_Button.Text = 'Cn';
            app.Nh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nh_Button.Enable = 'off';
            app.Nh_Button.Text = 'Nh';
            app.Fl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fl_Button.Enable = 'off';
            app.Fl_Button.Text = 'Fl';
            app.Mc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mc_Button.Enable = 'off';
            app.Mc_Button.Text = 'Mc';
            app.Lv_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lv_Button.Enable = 'off';
            app.Lv_Button.Text = 'Lv';
            app.Ts_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ts_Button.Enable = 'off';
            app.Ts_Button.Text = 'Ts';
            app.Og_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Og_Button.Enable = 'off';
            app.Og_Button.Text = 'Og';
            app.Ce_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ce_Button.Text = 'Ce';
            app.Pr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pr_Button.Text = 'Pr';
            app.Nd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nd_Button.Text = 'Nd';
            app.Pm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pm_Button.Text = 'Pm';
            app.Sm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sm_Button.Text = 'Sm';
            app.Eu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Eu_Button.Text = 'Eu';
            app.Gd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Gd_Button.Text = 'Gd';
            app.Tb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tb_Button.Text = 'Tb';
            app.Dy_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Dy_Button.Text = 'Dy';
            app.Ho_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ho_Button.Text = 'Ho';
            app.Er_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Er_Button.Text = 'Er';
            app.Tm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tm_Button.Text = 'Tm';
            app.Yb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Yb_Button.Text = 'Yb';
            app.Lu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lu_Button.Text = 'Lu';
            app.Th_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Th_Button.Text = 'Th';
            app.Pa_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pa_Button.Text = 'Pa';
            app.U_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.U_Button.Text = 'U';
            app.Np_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Np_Button.Text = 'Np';
            app.Pu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pu_Button.Text = 'Pu';
            app.Am_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Am_Button.Text = 'Am';
            app.Cm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cm_Button.Text = 'Cm';
            app.Bk_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bk_Button.Text = 'Bk';
            app.Cf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cf_Button.Text = 'Cf';
            app.Es_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Es_Button.Enable = 'off';
            app.Es_Button.Text = 'Es';
            app.Fm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fm_Button.Enable = 'off';
            app.Fm_Button.Text = 'Fm';
            app.Md_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Md_Button.Enable = 'off';
            app.Md_Button.Text = 'Md';
            app.No_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.No_Button.Enable = 'off';
            app.No_Button.Text = 'No';
            app.Lr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lr_Button.Enable = 'off';
            app.Lr_Button.Text = 'Lr';
            app.Main_GridLayout = uigridlayout(app.GridLayout);
            app.BindingEnergy_Panel = uipanel(app.Main_GridLayout);
            app.BindingEnergy_Panel.Title = 'Photoionization Binding Energies';
            app.BE_GridLayout = uigridlayout(app.BindingEnergy_Panel);
            app.ElementLabel = uilabel(app.BE_GridLayout);
            app.ElementLabel.Text = 'Selected Element:';
            app.SelectedMaterial_EditField = uieditfield(app.BE_GridLayout, 'text');
            app.SelectedMaterial_EditField.Editable = 'off';
            app.SelectedMaterial_EditField.Tooltip = {'Selected element from the Periodic Table.'};
            app.SelectedMaterial_EditField.Value = 'Si';
            app.TableBE_UITable = uitable(app.BE_GridLayout);
            app.TableBE_UITable.ColumnName = {'Level'; 'BE(eV)'; 'Relative Sigma'};
            app.TableBE_UITable.RowName = {};
            app.TableBE_UITable.ColumnSortable = [false true true];
            app.TableBE_UITable.Multiselect = 'off';
            app.TableBE_UITable.Tooltip = {'Table that shows the accesible core-levels, along with their corresponding binding energies (eV) and relative intensities at the defined photon energy.'};
            app.PlotBE_Button = uibutton(app.BE_GridLayout, 'push');
            app.PlotBE_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotBE_ButtonPushed, true);
            app.PlotBE_Button.Tooltip = {'Plot the binding energies of all core-levels for the selected element.'};
            app.PlotBE_Button.Text = 'Plot BE Spectrum';
            app.ExportBE_Button = uibutton(app.BE_GridLayout, 'push');
            app.ExportBE_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportBE_ButtonPushed, true);
            app.ExportBE_Button.Tooltip = {'Export the selected element table data as a text-file (.txt), including the core-level, binding energy and relative sigma values.'};
            app.ExportBE_Button.Text = 'Export BE Table (*.txt)';
            app.XSect_Panel = uipanel(app.Main_GridLayout);
            app.XSect_Panel.Title = 'Cross-Sections & Asymmetry Parameters';
            app.XSect_GridLayout = uigridlayout(app.XSect_Panel);
            app.XSectFormalismLabel = uilabel(app.XSect_GridLayout);
            app.XSectFormalismLabel.Text = 'XSect Formalism:';
            app.XSect_Formalism_DropDown = uidropdown(app.XSect_GridLayout);
            app.XSect_Formalism_DropDown.Items = {'Cant(2022) - 1500 to 10000 eV', 'Trzhaskovskaya(2018) - 1500 to 10000 eV', 'Yeh Lindau(1985) - 10 to 1500 eV', 'Scofield(1973) - 1 to 1500 keV'};
            app.XSect_Formalism_DropDown.ValueChangedFcn = createCallbackFcn(app, @XSect_Formalism_DropDownValueChanged, true);
            app.XSect_Formalism_DropDown.Tooltip = {'Select the formalism to calculate the photoionisation cross-section and asymmetry parameters.'};
            app.XSect_Formalism_DropDown.Value = 'Cant(2022) - 1500 to 10000 eV';
            app.CoreLevelDropDownLabel = uilabel(app.XSect_GridLayout);
            app.CoreLevelDropDownLabel.Text = 'Core-Level:';
            app.XSect_CoreLevel_DropDown = uidropdown(app.XSect_GridLayout);
            app.XSect_CoreLevel_DropDown.ValueChangedFcn = createCallbackFcn(app, @XSect_CoreLevel_DropDownValueChanged, true);
            app.XSect_CoreLevel_DropDown.Tooltip = {'Select an available core-level.'};
            app.PhotonEnergyeVEditFieldLabel = uilabel(app.XSect_GridLayout);
            app.PhotonEnergyeVEditFieldLabel.Text = 'Photon Energy (eV):';
            app.XSect_PhotonEnergy_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_PhotonEnergy_EditField.Limits = [0 Inf];
            app.XSect_PhotonEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.XSect_PhotonEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @XSect_PhotonEnergy_EditFieldValueChanged, true);
            app.XSect_PhotonEnergy_EditField.Tooltip = {'Incident photon energy in eV.'};
            app.XSect_PhotonEnergy_EditField.Value = 2000;
            app.SigmabarnatomLabel = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel.Text = 'Sigma (barn/atom):';
            app.XSect_Sigma_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_Sigma_EditField.ValueDisplayFormat = '%.4e';
            app.XSect_Sigma_EditField.Editable = 'off';
            app.XSect_Sigma_EditField.Tooltip = {'Sigma subshell photoionization cross section in units of barn/atom.'};
            app.XSect_Sigma_PlotCL_Button = uibutton(app.XSect_GridLayout, 'push');
            app.XSect_Sigma_PlotCL_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Sigma_PlotCL_ButtonPushed, true);
            app.XSect_Sigma_PlotCL_Button.Tooltip = {'Plot the sigma subshell photoionization cross section of the selected core-level and element.'};
            app.XSect_Sigma_PlotCL_Button.Text = 'Plot CL';
            app.XSect_Sigma_PlotAll_Button = uibutton(app.XSect_GridLayout, 'push');
            app.XSect_Sigma_PlotAll_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Sigma_PlotAll_ButtonPushed, true);
            app.XSect_Sigma_PlotAll_Button.Tooltip = {'Plot the sigma subshell photoionization cross section of all core-levels for the selected element.'};
            app.XSect_Sigma_PlotAll_Button.Text = 'Plot All';
            app.SigmabarnatomLabel_2 = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel_2.Text = 'Beta:';
            app.XSect_Beta_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_Beta_EditField.ValueDisplayFormat = '%.4f';
            app.XSect_Beta_EditField.Editable = 'off';
            app.XSect_Beta_EditField.Tooltip = {'Beta (dipole) parameter of the photoelectron angular distribution.'};
            app.XSect_Beta_PlotCL_Button = uibutton(app.XSect_GridLayout, 'push');
            app.XSect_Beta_PlotCL_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Beta_PlotCL_ButtonPushed, true);
            app.XSect_Beta_PlotCL_Button.Tooltip = {'Plot the beta (dipole) parameter of the photoelectron angular distribution of the selected core-level and element.'};
            app.XSect_Beta_PlotCL_Button.Text = 'Plot CL';
            app.XSect_Beta_PlotAll_Button = uibutton(app.XSect_GridLayout, 'push');
            app.XSect_Beta_PlotAll_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Beta_PlotAll_ButtonPushed, true);
            app.XSect_Beta_PlotAll_Button.Tooltip = {'Plot the beta (dipole) parameter of the photoelectron angular distribution of all core-levels for the selected element.'};
            app.XSect_Beta_PlotAll_Button.Text = 'Plot All';
            app.SigmabarnatomLabel_3 = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel_3.Text = 'Gamma:';
            app.XSect_Gamma_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_Gamma_EditField.ValueDisplayFormat = '%.4f';
            app.XSect_Gamma_EditField.Editable = 'off';
            app.XSect_Gamma_EditField.Tooltip = {'Gamma (non-dipole) parameter of the photoelectron angular distribution.'};
            app.XSect_Gamma_PlotCL_Button = uibutton(app.XSect_GridLayout, 'push');
            app.XSect_Gamma_PlotCL_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Gamma_PlotCL_ButtonPushed, true);
            app.XSect_Gamma_PlotCL_Button.Tooltip = {'Plot the gamma (non-dipole) parameter of the photoelectron angular distribution of the selected core-level and element.'};
            app.XSect_Gamma_PlotCL_Button.Text = 'Plot CL';
            app.XSect_Gamma_PlotAll_Button = uibutton(app.XSect_GridLayout, 'push');
            app.XSect_Gamma_PlotAll_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Gamma_PlotAll_ButtonPushed, true);
            app.XSect_Gamma_PlotAll_Button.Tooltip = {'Plot the gamma (non-dipole) parameter of the photoelectron angular distribution of all core-levels for the selected element.'};
            app.XSect_Gamma_PlotAll_Button.Text = 'Plot All';
            app.SigmabarnatomLabel_4 = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel_4.Text = 'Delta:';
            app.XSect_Delta_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_Delta_EditField.ValueDisplayFormat = '%.4f';
            app.XSect_Delta_EditField.Editable = 'off';
            app.XSect_Delta_EditField.Tooltip = {'Delta (non-dipole) parameter of the photoelectron angular distribution.'};
            app.XSect_Delta_PlotCL_Button = uibutton(app.XSect_GridLayout, 'push');
            app.XSect_Delta_PlotCL_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Delta_PlotCL_ButtonPushed, true);
            app.XSect_Delta_PlotCL_Button.Tooltip = {'Plot the delta (non-dipole) parameter of the photoelectron angular distribution of the selected core-level and element.'};
            app.XSect_Delta_PlotCL_Button.Text = 'Plot CL';
            app.XSect_Delta_PlotAll_Button = uibutton(app.XSect_GridLayout, 'push');
            app.XSect_Delta_PlotAll_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Delta_PlotAll_ButtonPushed, true);
            app.XSect_Delta_PlotAll_Button.Tooltip = {'Plot the delta (non-dipole) parameter of the photoelectron angular distribution of all core-levels for the selected element.'};
            app.XSect_Delta_PlotAll_Button.Text = 'Plot All';
            app.XSect_Buttons_GridLayout = uigridlayout(app.XSect_GridLayout);
            app.XSect_Export_Button = uibutton(app.XSect_Buttons_GridLayout, 'push');
            app.XSect_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Export_ButtonPushed, true);
            app.XSect_Export_Button.Tooltip = {'Export data as a text-file (.txt); cross-section & asymmetry parameters of the selected element & core-level.'};
            app.XSect_Export_Button.Text = 'Export CL Data (*.txt)';
            app.XSect_Save_Button = uibutton(app.XSect_Buttons_GridLayout, 'push');
            app.XSect_Save_Button.ButtonPushedFcn = createCallbackFcn(app, @XSect_Save_ButtonPushed, true);
            app.XSect_Save_Button.Tooltip = {'Save data as a MATLAB Structure (.mat); cross-section & asymmetry parameters of the selected element & core-level.'};
            app.XSect_Save_Button.Text = 'Save CL Data (*.mat)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatBaseX_03_XrayPhotoionization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end