classdef MatBaseX_04_XrayAbsorption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Main_GridLayout               matlab.ui.container.GridLayout
        XASF_Panel                    matlab.ui.container.Panel
        XASF_GridLayout               matlab.ui.container.GridLayout
        XASF_Buttons_GridLayout       matlab.ui.container.GridLayout
        XASF_Save_Button              matlab.ui.control.Button
        XASF_Export_Button            matlab.ui.control.Button
        XASF_TR_Plot_Button           matlab.ui.control.Button
        XASF_R_EditField              matlab.ui.control.NumericEditField
        SigmabarnatomLabel_10         matlab.ui.control.Label
        XASF_T_EditField              matlab.ui.control.NumericEditField
        SigmabarnatomLabel_9          matlab.ui.control.Label
        XASF_nalphaC_Plot_Button      matlab.ui.control.Button
        XASF_alphaC_EditField         matlab.ui.control.NumericEditField
        SigmabarnatomLabel_8          matlab.ui.control.Label
        XASF_n_EditField              matlab.ui.control.NumericEditField
        SigmabarnatomLabel_7          matlab.ui.control.Label
        XASF_betagamma_Plot_Button    matlab.ui.control.Button
        XASF_delta_EditField          matlab.ui.control.NumericEditField
        SigmabarnatomLabel_6          matlab.ui.control.Label
        XASF_beta_EditField           matlab.ui.control.NumericEditField
        SigmabarnatomLabel_5          matlab.ui.control.Label
        XASF_mu_Plot_Button           matlab.ui.control.Button
        XASF_mu_EditField             matlab.ui.control.NumericEditField
        SigmabarnatomLabel_3          matlab.ui.control.Label
        XASF_muM_EditField            matlab.ui.control.NumericEditField
        SigmabarnatomLabel_4          matlab.ui.control.Label
        XASF_ff_Plot_Button           matlab.ui.control.Button
        XASF_f2_EditField             matlab.ui.control.NumericEditField
        SigmabarnatomLabel_2          matlab.ui.control.Label
        XASF_f1_EditField             matlab.ui.control.NumericEditField
        SigmabarnatomLabel            matlab.ui.control.Label
        XASF_FilmThickness_EditField  matlab.ui.control.NumericEditField
        FilmThicknessnmLabel          matlab.ui.control.Label
        XASF_PhotonEnergy_EditField   matlab.ui.control.NumericEditField
        PhotonEnergyeVEditFieldLabel  matlab.ui.control.Label
        XASF_Formalism_DropDown       matlab.ui.control.DropDown
        XSectFormalismLabel           matlab.ui.control.Label
        XAE_Panel                     matlab.ui.container.Panel
        XAE_GridLayout                matlab.ui.container.GridLayout
        XAE_Export_Button             matlab.ui.control.Button
        XAE_Plot_Button               matlab.ui.control.Button
        XAE_UITable                   matlab.ui.control.Table
        SelectedMaterial_EditField    matlab.ui.control.EditField
        ElementLabel                  matlab.ui.control.Label
        PeriodicTable_ButtonGroup     matlab.ui.container.ButtonGroup
        Lr_Button                     matlab.ui.control.ToggleButton
        No_Button                     matlab.ui.control.ToggleButton
        Md_Button                     matlab.ui.control.ToggleButton
        Fm_Button                     matlab.ui.control.ToggleButton
        Es_Button                     matlab.ui.control.ToggleButton
        Cf_Button                     matlab.ui.control.ToggleButton
        Bk_Button                     matlab.ui.control.ToggleButton
        Cm_Button                     matlab.ui.control.ToggleButton
        Am_Button                     matlab.ui.control.ToggleButton
        Pu_Button                     matlab.ui.control.ToggleButton
        Np_Button                     matlab.ui.control.ToggleButton
        U_Button                      matlab.ui.control.ToggleButton
        Pa_Button                     matlab.ui.control.ToggleButton
        Th_Button                     matlab.ui.control.ToggleButton
        Lu_Button                     matlab.ui.control.ToggleButton
        Yb_Button                     matlab.ui.control.ToggleButton
        Tm_Button                     matlab.ui.control.ToggleButton
        Er_Button                     matlab.ui.control.ToggleButton
        Ho_Button                     matlab.ui.control.ToggleButton
        Dy_Button                     matlab.ui.control.ToggleButton
        Tb_Button                     matlab.ui.control.ToggleButton
        Gd_Button                     matlab.ui.control.ToggleButton
        Eu_Button                     matlab.ui.control.ToggleButton
        Sm_Button                     matlab.ui.control.ToggleButton
        Pm_Button                     matlab.ui.control.ToggleButton
        Nd_Button                     matlab.ui.control.ToggleButton
        Pr_Button                     matlab.ui.control.ToggleButton
        Ce_Button                     matlab.ui.control.ToggleButton
        Og_Button                     matlab.ui.control.ToggleButton
        Ts_Button                     matlab.ui.control.ToggleButton
        Lv_Button                     matlab.ui.control.ToggleButton
        Mc_Button                     matlab.ui.control.ToggleButton
        Fl_Button                     matlab.ui.control.ToggleButton
        Nh_Button                     matlab.ui.control.ToggleButton
        Cn_Button                     matlab.ui.control.ToggleButton
        Rg_Button                     matlab.ui.control.ToggleButton
        Ds_Button                     matlab.ui.control.ToggleButton
        Mt_Button                     matlab.ui.control.ToggleButton
        Hs_Button                     matlab.ui.control.ToggleButton
        Bh_Button                     matlab.ui.control.ToggleButton
        Sg_Button                     matlab.ui.control.ToggleButton
        Db_Button                     matlab.ui.control.ToggleButton
        Rf_Button                     matlab.ui.control.ToggleButton
        Ac_Button                     matlab.ui.control.ToggleButton
        Ra_Button                     matlab.ui.control.ToggleButton
        Fr_Button                     matlab.ui.control.ToggleButton
        Rn_Button                     matlab.ui.control.ToggleButton
        At_Button                     matlab.ui.control.ToggleButton
        Po_Button                     matlab.ui.control.ToggleButton
        Bi_Button                     matlab.ui.control.ToggleButton
        Pb_Button                     matlab.ui.control.ToggleButton
        Tl_Button                     matlab.ui.control.ToggleButton
        Hg_Button                     matlab.ui.control.ToggleButton
        Au_Button                     matlab.ui.control.ToggleButton
        Pt_Button                     matlab.ui.control.ToggleButton
        Ir_Button                     matlab.ui.control.ToggleButton
        Os_Button                     matlab.ui.control.ToggleButton
        Re_Button                     matlab.ui.control.ToggleButton
        W_Button                      matlab.ui.control.ToggleButton
        Ta_Button                     matlab.ui.control.ToggleButton
        Hf_Button                     matlab.ui.control.ToggleButton
        La_Button                     matlab.ui.control.ToggleButton
        Ba_Button                     matlab.ui.control.ToggleButton
        Cs_Button                     matlab.ui.control.ToggleButton
        Xe_Button                     matlab.ui.control.ToggleButton
        I_Button                      matlab.ui.control.ToggleButton
        Te_Button                     matlab.ui.control.ToggleButton
        Sb_Button                     matlab.ui.control.ToggleButton
        Sn_Button                     matlab.ui.control.ToggleButton
        In_Button                     matlab.ui.control.ToggleButton
        Cd_Button                     matlab.ui.control.ToggleButton
        Ag_Button                     matlab.ui.control.ToggleButton
        Pd_Button                     matlab.ui.control.ToggleButton
        Rh_Button                     matlab.ui.control.ToggleButton
        Ru_Button                     matlab.ui.control.ToggleButton
        Tc_Button                     matlab.ui.control.ToggleButton
        Mo_Button                     matlab.ui.control.ToggleButton
        Nb_Button                     matlab.ui.control.ToggleButton
        Zr_Button                     matlab.ui.control.ToggleButton
        Y_Button                      matlab.ui.control.ToggleButton
        Sr_Button                     matlab.ui.control.ToggleButton
        Rb_Button                     matlab.ui.control.ToggleButton
        Kr_Button                     matlab.ui.control.ToggleButton
        Br_Button                     matlab.ui.control.ToggleButton
        Se_Button                     matlab.ui.control.ToggleButton
        As_Button                     matlab.ui.control.ToggleButton
        Ge_Button                     matlab.ui.control.ToggleButton
        Ga_Button                     matlab.ui.control.ToggleButton
        Zn_Button                     matlab.ui.control.ToggleButton
        Cu_Button                     matlab.ui.control.ToggleButton
        Ni_Button                     matlab.ui.control.ToggleButton
        Co_Button                     matlab.ui.control.ToggleButton
        Fe_Button                     matlab.ui.control.ToggleButton
        Mn_Button                     matlab.ui.control.ToggleButton
        Cr_Button                     matlab.ui.control.ToggleButton
        V_Button                      matlab.ui.control.ToggleButton
        Ti_Button                     matlab.ui.control.ToggleButton
        Sc_Button                     matlab.ui.control.ToggleButton
        Ca_Button                     matlab.ui.control.ToggleButton
        K_Button                      matlab.ui.control.ToggleButton
        Ar_Button                     matlab.ui.control.ToggleButton
        Cl_Button                     matlab.ui.control.ToggleButton
        S_Button                      matlab.ui.control.ToggleButton
        P_Button                      matlab.ui.control.ToggleButton
        Si_Button                     matlab.ui.control.ToggleButton
        Al_Button                     matlab.ui.control.ToggleButton
        Label_12                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Mg_Button                     matlab.ui.control.ToggleButton
        Na_Button                     matlab.ui.control.ToggleButton
        Ne_Button                     matlab.ui.control.ToggleButton
        He_Button                     matlab.ui.control.ToggleButton
        F_Button                      matlab.ui.control.ToggleButton
        Label_17                      matlab.ui.control.Label
        O_Button                      matlab.ui.control.ToggleButton
        Label_16                      matlab.ui.control.Label
        N_Button                      matlab.ui.control.ToggleButton
        Label_15                      matlab.ui.control.Label
        C_Button                      matlab.ui.control.ToggleButton
        Label_14                      matlab.ui.control.Label
        B_Button                      matlab.ui.control.ToggleButton
        Label_13                      matlab.ui.control.Label
        Compounds_DropDown            matlab.ui.control.DropDown
        Be_Button                     matlab.ui.control.ToggleButton
        Label_2                       matlab.ui.control.Label
        Li_Button                     matlab.ui.control.ToggleButton
        H_Button                      matlab.ui.control.ToggleButton
        Label_18                      matlab.ui.control.Label
        AvailableCompoundsLabel       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        AppButtons_GridLayout         matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        TRANSFER_Button               matlab.ui.control.Button
        APPINFO_Button                matlab.ui.control.Button
        RESIZEUI_Button               matlab.ui.control.Button
        RESTART_Button                matlab.ui.control.Button
        CLOSEFIGS_Button              matlab.ui.control.Button
        AppTitle                      matlab.ui.control.Label
        MAINMENU_GridLayout           matlab.ui.container.GridLayout
        MAINMENU_Button               matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarise all data for analysis
        myAppData
        % - Material & Formalism Selection
        selected_element            % selected element
        selected_compound           % selected compound
        selected_material           % selected material
        selected_formalism          % selected formalism
        % - X-Ray Absorption Edge Table
        edge_labels                 % x-ray absorption edge labels
        edge_name                   % x-ray absorption edge name
        edge_energy                 % x-ray absorption edge energy (eV)
        edge_width                  % x-ray absorption edge width (eV)
        edge_jump                   % x-ray absorption edge jump
        % - Independent Variables
        eph                         % photon energy defined by user (eV)
        film_thickness              % material film thickness (nm)
        % - Dependent Variables
        f1                          % real part of atomic scattering factor (coherent scattering) [e/atom]
        f2                          % imaginary part of atomic scattering factor (absorption) [e/atom]
        muM                         % mass absorption coefficient (μ/ρ) [cm^2]
        mu                          % mass linear attenuation coefficient [cm]
        beta                        % beta parameter
        delta                       % delta parameter
        n                           % refractive index
        ne                          % electron density (e/cc)
        alphaC                      % critical angle (deg.)
        T                           % transmittance
        R                           % reflectance
        % - Other Variables
        eph_R                       % photon energy range of formalism
        f1_R                        % real part of atomic scattering factor (coherent scattering) [e/atom]
        f2_R                        % imaginary part of atomic scattering factor (absorption) [e/atom]
        muM_R                       % mass absorption coefficient (μ/ρ) [cm^2]
        mu_R                        % mass linear attenuation coefficient [cm]
        beta_R                      % beta parameter
        delta_R                     % delta parameter
        n_R                         % refractive index
        ne_R                        % electron density (e/cc)
        alphaC_R                    % critical angle (deg.)
        T_R                         % transmittance
        R_R                         % reflectance
    end
    
    % Private methods available to the app only
    methods (Access = private)
        %% Open Figure Locations 
        % Open Location of MainMenu App
        function open_MainMenu_to_left_of_App(app)
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1);
            fig.Position(2) = app.UIFigure.Position(2);
        end
        %% General Functions
        % Define the list of formalisms
        function list_of_formalisms = define_formalisms(~)
            list_of_formalisms = {...
                'NIST(2005) - 1 to 433,000 eV ',...
                'Henke(1993) - 1 to 30,000 eV',...
                };
        end

        %% Material Property Functions
        % Extract all compound names
        function compound_names = extract_compound_names(~)
            compound_names = read_mpd_compounds();
            for i = 1:length(compound_names)
                compound_names{i} = char(sprintf("%i-%s", 118+i, compound_names{i}));
            end
        end

        % Define all drop-down box names for compounds
        function initialize_compound_properties(app)
            compound_names                  = extract_compound_names(app);
            app.Compounds_DropDown.Items    = compound_names;
            app.Compounds_DropDown.Value    = app.Compounds_DropDown.Items{1};
            app.selected_compound           = extractAfter(app.Compounds_DropDown.Value,"-");
        end

        %% Update Functions
        % Function that calculates all single dependent variables
        function update_single_dependents(app)
            % - 1 : Extracting relevant material parameters
            mat_props   = get_mpd_props(app.selected_material);
            na          = mpd_calc_number_density(app.selected_material);
            Mm          = mat_props.atom_mass;
            rho         = mat_props.density;
            % - 2 : Calculating all relevant values
            [app.f1, app.f2]        = calc_xasf(app.eph, app.selected_material, app.selected_formalism, 1);
            [app.beta, app.delta, app.alphaC, app.n, app.ne] = calc_xasf_params(app.eph, app.f1, app.f2, na);
            app.n                   = real(app.n);
            app.muM                 = calc_xasf_mass_absorb_coeff(app.eph, app.selected_material, Mm);
            app.mu                  = calc_xasf_linear_absorb_coeff(app.eph, app.selected_material, Mm, rho);
            [app.T, app.R] = calc_xasf_TandR(app.eph, app.mu, app.film_thickness);
            % - 3 : Update all edit-fields to read the new values
            app.XASF_f1_EditField.Value      = app.f1;
            app.XASF_f2_EditField.Value      = app.f2;
            app.XASF_muM_EditField.Value     = app.muM;
            app.XASF_mu_EditField.Value      = app.mu;
            app.XASF_beta_EditField.Value    = app.beta;
            app.XASF_delta_EditField.Value   = app.delta;
            app.XASF_n_EditField.Value       = app.n;
            app.XASF_alphaC_EditField.Value  = app.alphaC;
            app.XASF_T_EditField.Value       = app.T;
            app.XASF_R_EditField.Value       = app.R;
            % - 4 : Appending results to data-structure
            write_app_data(app);
            fprintf("---> f1: " + string(app.f1) + " e/atom \n");
            fprintf("---> f2: " + string(app.f2) + " e/atom \n");
            fprintf("---> mu/rho: " + string(app.muM) + " cm^2 \n");
            fprintf("---> mu: " + string(app.mu) + " cm^-1 \n");
            fprintf("---> beta: " + string(app.beta) + " \n");
            fprintf("---> delta: " + string(app.delta) + " \n");
            fprintf("---> n: " + string(app.n) + " \n");
            fprintf("---> alpha_C: " + string(app.alphaC) + " degree \n");
            fprintf("---> T: " + string(app.T) + " \n");
            fprintf("---> R: " + string(app.R) + " \n");
        end

        % Function that calculates all range dependent variables
        function update_range_dependents(app)
            app.eph_R = logspace(1,6,5e2); 
            if isrow(app.eph_R); app.eph_R = app.eph_R'; end
            % - 1 : Extracting relevant material parameters
            mat_props   = get_mpd_props(app.selected_material);
            na          = mpd_calc_number_density(app.selected_material);
            Mm          = mat_props.atom_mass;
            rho         = mat_props.density;
            % - 2 : Calculating all relevant values
            [app.f1_R, app.f2_R]        = calc_xasf(app.eph_R, app.selected_material, app.selected_formalism, 0);
            [app.beta_R, app.delta_R, app.alphaC_R, app.n_R, app.ne_R] = calc_xasf_params(app.eph_R, app.f1_R, app.f2_R, na);
            app.n_R                   = real(app.n_R);
            app.muM_R                 = calc_xasf_mass_absorb_coeff(app.eph_R, app.selected_material, Mm);
            app.mu_R                  = calc_xasf_linear_absorb_coeff(app.eph_R, app.selected_material, Mm, rho);
            [app.T_R, app.R_R] = calc_xasf_TandR(app.eph_R, app.mu_R, app.film_thickness);
            % - 3 : Appending results to data-structure
            write_app_data(app);
        end

        % Function that updates the XAE UI Table
        function update_table_properties(app)
            % 1 - Extracting all available edge properties
            formula     = parse_chemical_formula(app.selected_material);
            labels      = {};
            energy      = cell(1,length(formula));
            names       = cell(1,length(formula));
            widths      = cell(1,length(formula));
            jumps       = cell(1,length(formula));
            for i = 1:length(formula)
                [energy{i}, names{i}, widths{i}, jumps{i}] = calc_xae(formula(i).element);
                for j = 1:length(energy{i})
                    labels{end+1} = sprintf("%s(%s)", formula(i).element, names{i}(j));
                end
            end
            % 2 - Assigning data
            app.edge_energy         = cat(1, energy{:});
            app.edge_labels         = cat(1, labels{:});
            app.edge_name           = cat(1, names{:});
            app.edge_width          = cat(1, widths{:});
            app.edge_jump           = cat(1, jumps{:});
            % 3 - Sorting in ascending order of energy
            [app.edge_energy, indx] = sort(app.edge_energy); 
            app.edge_labels         = app.edge_labels(indx);
            app.edge_name           = app.edge_name(indx);
            app.edge_width          = app.edge_width(indx);
            app.edge_jump           = app.edge_jump(indx);
            % 4 - Update XAE UI Table
            app.XAE_UITable.Data = [];
            for i = 1:length(app.edge_name)
                app.XAE_UITable.Data{i,1} = char(app.edge_labels{i});
                app.XAE_UITable.Data{i,2} = round(app.edge_energy(i),4);
                app.XAE_UITable.Data{i,3} = round(app.edge_jump(i),4);
            end
            % 5 - Sort in terms of the relative sigma value
            app.XAE_UITable.Data = sortrows(app.XAE_UITable.Data, 3, 'descend');
            % 6 - Appending results to data-structure
            write_app_data(app);
        end

        % Function that updates when the element is changes
        function update_change_of_material(app)
            % 1 - Update all dependencies
            update_table_properties(app);
            update_single_dependents(app);
            update_range_dependents(app);
            % 2 - Appending results to data-structure
            write_app_data(app);
        end

        % Function that updates when the element is changes
        function update_change_of_formalism(app)
            % 1 - Update all dependencies
            update_single_dependents(app);
            update_range_dependents(app);
            % 2 - Appending results to data-structure
            write_app_data(app);
        end

       % Write the app data into a data-structure
        function write_app_data(app)
            app.myAppData               = struct();
            % -- Defining the input arguments
            app.myAppData.formalism         = string(app.selected_formalism);
            app.myAppData.material          = string(app.selected_material);
            app.myAppData.edge_labels       = app.edge_labels;
            app.myAppData.edge_name         = app.edge_name;
            app.myAppData.edge_energy       = app.edge_energy;
            app.myAppData.edge_width        = app.edge_width;
            app.myAppData.edge_jump         = app.edge_jump;
            % -- Defining all variables
            app.myAppData.eph               = app.eph;
            app.myAppData.film_thickness    = app.film_thickness;
            app.myAppData.f1                = app.f1;
            app.myAppData.f2                = app.f2;
            app.myAppData.muM               = app.muM;
            app.myAppData.mu                = app.mu;
            app.myAppData.beta              = app.beta;
            app.myAppData.delta             = app.delta;
            app.myAppData.n                 = app.n;
            app.myAppData.alphaC            = app.alphaC;
            app.myAppData.T                 = app.T;
            app.myAppData.R                 = app.R;
            % -- Defining all variables
            app.myAppData.eph_R                = app.eph_R;
            app.myAppData.f1_R                 = app.f1_R;
            app.myAppData.f2_R                 = app.f2_R;
            app.myAppData.muM_R                = app.muM_R;
            app.myAppData.mu_R                 = app.mu_R;
            app.myAppData.beta_R               = app.beta_R;
            app.myAppData.delta_R              = app.delta_R;
            app.myAppData.n_R                  = app.n_R;
            app.myAppData.alphaC_R             = app.alphaC_R;
            app.myAppData.T_R                  = app.T_R;
            app.myAppData.R_R                  = app.R_R;
        end

        %% Plot Functions
        % Defining the two colors to use
        function plot_colors = get_plot_colors(~)
            plot_colors = {};
            plot_colors{1} = [0.7 0.1 0.1];
            plot_colors{2} = [0.1 0.1 0.7];
        end

        % Plot ff
        function plot_ff(app)
            % - Initializing parameters
            colors          = get_plot_colors(app);
            mat_props       = get_mpd_props(app.selected_material);
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 1000; 
            fig.Position(4) = 350;
            % - Creating a tiled axis
            t = tiledlayout(1,2);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plotting first variable
            nexttile(); hold on; grid on; grid minor;
            plot(app.eph_R, app.f1_R, '-', 'color', colors{1}, 'linewidth', 2);
            scatter(app.eph, app.f1, 'SizeData', 60, 'markeredgecolor', colors{1}, 'markerfacecolor', colors{1}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('(\mu/\rho)', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.96, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.90, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' f_1 [e/atom] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'log';
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            axis([5, 1e6, 0, 1.5*max(app.f1_R(:))]);
            % - Plotting second variable
            nexttile(); hold on; grid on; grid minor;
            plot(app.eph_R, app.f2_R, '-', 'color', colors{2}, 'linewidth', 2);
            scatter(app.eph, app.f2, 'SizeData', 60, 'markeredgecolor', colors{2}, 'markerfacecolor', colors{2}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('\mu', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.10, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.04, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' f_2 [e/atom] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            if mat_props.atom_z < 14;   axis([5, 1e6, 1e-11, 1e3]);
            else;                       axis([5, 1e6, 1e-5, 1e3]);
            end
        end

        % Plot mu
        function plot_mu(app)
            % - Initializing parameters
            colors          = get_plot_colors(app);
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 1000; 
            fig.Position(4) = 350;
            % - Creating a tiled axis
            t = tiledlayout(1,2);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plotting first variable
            nexttile(); hold on; grid on; grid minor;
            plot(app.eph_R, app.muM_R, '-', 'color', colors{1}, 'linewidth', 2);
            scatter(app.eph, app.muM, 'SizeData', 60, 'markeredgecolor', colors{1}, 'markerfacecolor', colors{1}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('(\mu/\rho)', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.10, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.04, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' (\mu/\rho) [cm^{2}] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([5, 1e6, 1e-11, 1e8]);
            % - Plotting second variable
            nexttile(); hold on; grid on; grid minor;
            plot(app.eph_R, app.mu_R, '-', 'color', colors{2}, 'linewidth', 2);
            scatter(app.eph, app.mu, 'SizeData', 60, 'markeredgecolor', colors{2}, 'markerfacecolor', colors{2}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('\mu', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.10, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.04, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \mu [cm^{-1}] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([5, 1e6, 1e-15, 1e9]);
        end

        % Plot beta and delta
        function plot_beta(app)
            % - Initializing parameters
            colors          = get_plot_colors(app);
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 1000; 
            fig.Position(4) = 350;
            % - Creating a tiled axis
            t = tiledlayout(1,2);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plotting first variable
            nexttile(); hold on; grid on; grid minor;
            plot(app.eph_R, app.beta_R, '-', 'color', colors{1}, 'linewidth', 2);
            scatter(app.eph, app.beta, 'SizeData', 60, 'markeredgecolor', colors{1}, 'markerfacecolor', colors{1}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('\beta', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.10, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.04, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \beta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([5, 1e6, 1e-23, 1e3]);
            % - Plotting second variable
            nexttile(); hold on; grid on; grid minor;
            plot(app.eph_R, app.delta_R, '-', 'color', colors{2}, 'linewidth', 2);
            scatter(app.eph, app.delta, 'SizeData', 60, 'markeredgecolor', colors{2}, 'markerfacecolor', colors{2}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('\delta', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.10, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.04, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \delta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([5, 1e6, 1e-15, 1e3]);
        end

        % Plot n
        function plot_n(app)
            % - Initializing parameters
            colors          = get_plot_colors(app);
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 1000; 
            fig.Position(4) = 350;
            % - Creating a tiled axis
            t = tiledlayout(1,2);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plotting first variable
            nexttile(); hold on; grid on; grid minor;
            plot(app.eph_R, app.n_R, '-', 'color', colors{1}, 'linewidth', 2);
            scatter(app.eph, app.n, 'SizeData', 60, 'markeredgecolor', colors{1}, 'markerfacecolor', colors{1}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('n', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.10, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.04, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' n ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'log';
            axis([5, 1e6, -2, 2]);
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            % - Plotting second variable
            nexttile(); hold on; grid on; grid minor;
            plot(app.eph_R, app.alphaC_R, '-', 'color', colors{2}, 'linewidth', 2);
            scatter(app.eph, app.alphaC, 'SizeData', 60, 'markeredgecolor', colors{2}, 'markerfacecolor', colors{2}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('\alpha_C', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.10, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.04, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \alpha_C [degree]', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([5, 1e6, 1e-5, 1e3]);
        end

        % Plot tr
        function plot_tr(app)
            % - Initializing parameters
            colors          = get_plot_colors(app);
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plotting first variable
            nexttile(); hold on; grid on; grid minor;plot(app.eph_R, app.T_R, '-', 'color', colors{2}, 'linewidth', 2);
            scatter(app.eph, app.T, 'SizeData', 60, 'markeredgecolor', colors{2}, 'markerfacecolor', colors{2}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            plot(app.eph_R, app.R_R, '-', 'color', colors{1}, 'linewidth', 2);
            scatter(app.eph, app.R, 'SizeData', 60, 'markeredgecolor', colors{1}, 'markerfacecolor', colors{1}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend('T', 'R', 'location', 'northeast', 'FontSize', 8); 
            text(0.02, 0.10, sprintf("%s", app.selected_material),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.04, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' n ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'log';
            axis([1, 1e6, 0, 1]);
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
        end

        % Plot binding energy spectrum
        function plot_absorption_spectrum(app)
            % - Initializing variables
            formula             = parse_chemical_formula(app.selected_material);
            elements            = {formula(:).element};
            num_of_elements     = length(elements);
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 800; 
            fig.Position(4) = 350;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plot the figure
            nexttile(); hold on; grid on; grid minor;
            for i = 1:num_of_elements
                [energy, names, widths, jumps] = calc_xae(elements{i});
                nED         = length(names);
                colorList   = read_spectroscopy_colors(names);
                for k = 1:nED; stem(energy(k), jumps(k), '-', 'linewidth', 1 + widths(k)/7, 'marker', 'none', 'color', colorList{k});end 
                for k = 1:nED
                    text(energy(k), jumps(k), sprintf('%s-%s(%.2f)', elements{i}, names(k), energy(k)),...
                        'Rotation',90, 'FontWeight','normal', 'FontSize',10, 'color', colorList{k}); 
                end
            end
            % - Formatting the axis
            text(0.02, 0.96, sprintf("%s", app.selected_material),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.91, sprintf("IXAS(2025)"),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            xlabel('Energy [eV]', 'FontWeight','bold');
            ylabel(' Edge Jump ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'log';
            axis([0.05, 5e5, 0, ceil(1.5*max(app.edge_jump(:)))]);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBaseX - X-Ray Absorption Edge & Scattering Factor Database (procopios.constantinou@psi.ch)';
            % 1 - Initialising fields
            % -- Defining the list of formalisms
            list_of_formalisms = define_formalisms(app);
            app.XASF_Formalism_DropDown.Items = list_of_formalisms;
            app.XASF_Formalism_DropDown.Value = app.XASF_Formalism_DropDown.Items{1};
            % -- Defining available compounds
            initialize_compound_properties(app);
            % -- Coloring the edit-fields that can be changed
            edit_color = [0.85 1 1];
            app.XASF_Formalism_DropDown.BackgroundColor      = edit_color;
            app.Compounds_DropDown.BackgroundColor      = edit_color;
            app.XASF_PhotonEnergy_EditField.BackgroundColor  = edit_color;
            app.XASF_FilmThickness_EditField.BackgroundColor = edit_color;
            % 2 - Default parameters upon startup
            app.myAppData                   = struct();
            app.selected_formalism          = "2005NIST";
            app.selected_material           = app.SelectedMaterial_EditField.Value;
            app.eph                         = app.XASF_PhotonEnergy_EditField.Value;
            app.film_thickness              = app.XASF_FilmThickness_EditField.Value;
            % 3 - First Tab Initialisation
            % -- Initializing the UI table
            app.XAE_UITable.Data = []; 
            s = uistyle('HorizontalAlignment','left'); addStyle(app.XAE_UITable,s);
            % 4 - First determination
            PeriodicTable_ButtonGroupSelectionChanged(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run MatBaseX;
            open_MainMenu_to_left_of_App(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type', 'Figure');
            % Exclude those with 'MatBaseX' in the Name (title)
            matbaseFigs = findobj(hPlots, '-regexp', 'Name', 'MatBaseX');
            hPlotsToClose = setdiff(hPlots, matbaseFigs);
            % Close only the non-MatBaseX figures
            close(hPlotsToClose);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run MatBaseX_04_XrayAbsorption;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 700;
            screen_size(4) = 600;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('myAppData : '); disp(app.myAppData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            fname = sprintf("%s_XASF_%s", app.selected_material, app.selected_formalism);
            assignin('base', char(fname), app.myAppData);
        end

        % Selection changed function: PeriodicTable_ButtonGroup
        function PeriodicTable_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton  = app.PeriodicTable_ButtonGroup.SelectedObject;
            app.selected_element        = selectedButton.Text;
            app.selected_material       = app.selected_element;
            fprintf("--> Element: " + string(app.selected_material) + "\n");
            % 2 - Updating the relevant UI components
            app.SelectedMaterial_EditField.Value = app.selected_material;
            % 3 - Update Functions
            update_change_of_material(app);
        end

        % Value changed function: Compounds_DropDown
        function Compounds_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value                   = app.Compounds_DropDown.Value;
            app.selected_compound   = extractAfter(value, "-");
            app.selected_material   = app.selected_compound;
            fprintf("--> Compound: " + string(app.selected_compound) + "\n");
            % 2 - Updating the relevant UI components
            app.SelectedMaterial_EditField.Value = app.selected_material;
            % 3 - Update Functions
            update_change_of_material(app);
        end

        % Value changed function: XASF_Formalism_DropDown
        function XASF_Formalism_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XASF_Formalism_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the formalism selected
            if strcmpi(value,app.XASF_Formalism_DropDown.Items{1});          value = "2005NIST";
            elseif strcmpi(value,app.XASF_Formalism_DropDown.Items{2});      value = "1993Henke";
            end
            % 3 - Assigning the output
            app.selected_formalism = value;
            fprintf("--> formalism: " + string(app.selected_formalism) + "\n");
            % 4 - Update Functions
            update_change_of_formalism(app);
        end

        % Value changed function: XASF_PhotonEnergy_EditField
        function XASF_PhotonEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XASF_PhotonEnergy_EditField.Value;
            app.eph = value;
            % 2 - Update Functions
            update_change_of_material(app);
        end

        % Value changed function: XASF_FilmThickness_EditField
        function XASF_FilmThickness_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XASF_FilmThickness_EditField.Value;
            app.film_thickness = value;
            % 2 - Update Functions
            update_change_of_material(app);
        end

        % Button pushed function: XASF_ff_Plot_Button
        function XASF_ff_Plot_ButtonPushed(app, event)
            plot_ff(app);
        end

        % Button pushed function: XASF_mu_Plot_Button
        function XASF_mu_Plot_ButtonPushed(app, event)
            plot_mu(app);
        end

        % Button pushed function: XASF_betagamma_Plot_Button
        function XASF_betagamma_Plot_ButtonPushed(app, event)
            plot_beta(app);
        end

        % Button pushed function: XASF_nalphaC_Plot_Button
        function XASF_nalphaC_Plot_ButtonPushed(app, event)
            plot_n(app);
        end

        % Button pushed function: XASF_TR_Plot_Button
        function XASF_TR_Plot_ButtonPushed(app, event)
            plot_tr(app);
        end

        % Button pushed function: XAE_Plot_Button
        function XAE_Plot_ButtonPushed(app, event)
            plot_absorption_spectrum(app);
        end

        % Button pushed function: XAE_Export_Button
        function XAE_Export_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            fname = sprintf("%s_XAE", app.selected_material);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            % info = "";
            info = sprintf("Material:\t%s\n", app.selected_material);
            % Sample data
            T_Data = table(app.myAppData.edge_labels, app.myAppData.edge_energy, app.myAppData.edge_jump, app.myAppData.edge_width);
            T_Data.Properties.VariableNames = {'edge_label','edge_energy_eV','edge_jump','edge_width_eV'};
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: XASF_Save_Button
        function XASF_Save_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            fname = sprintf("%s_XASF_%s", app.selected_material,  app.selected_formalism);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            structName          = char(save_filename(1:end-4));
            eval([structName ' = app.myAppData;']);     % Create the data structure dynamically
            % Saving the data as .mat
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved data : '); display(app.myAppData);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!');
        end

        % Button pushed function: XASF_Export_Button
        function XASF_Export_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            fname = sprintf("%s_XASF_%s", app.selected_material,  app.selected_formalism);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            % info = "";
            info = sprintf("Material:\t%s\nFormalism:\t%s\n", app.selected_material, app.selected_formalism);
            info = info + sprintf("hv:\t\t%i eV\nf1:\t\t%.4e e/atom\nf2:\t\t%.4e e/atom\nmuM:\t\t%.4e cm^2\nmu:\t\t%.4e cm^-1\n",...
                app.eph, app.f1, app.f2, app.muM, app.mu);
            info = info + sprintf("beta:\t\t%.4e\ndelta:\t\t%.4e\nn:\t\t%.4e\nalpha_C:\t%.4e degree\n",...
                app.beta, app.delta, app.n, app.alphaC);
            info = info + sprintf("R:\t\t%.4e\nT:\t\t%.4e\n",...
                app.R, app.T);
            % Sample data
            T_Data = table(app.myAppData.eph_R,...
                app.myAppData.f1_R, app.myAppData.f2_R,...
                app.myAppData.muM_R, app.myAppData.mu_R,...
                app.myAppData.beta_R, app.myAppData.delta_R,...
                app.myAppData.n_R, app.myAppData.alphaC_R,...
                app.myAppData.T_R, app.myAppData.R_R);
            T_Data.Properties.VariableNames = {'eph_eV','f1_e_per_atom','f2_e_per_atom','muM_cm2','mu_cm-1','beta','delta','n','alphaC_degree','T','R'};
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'X-Ray Absorption Edge & Scattering Factor Database';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart this App.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize this App to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all App information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer App Data directly to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Extract x-ray absorption edges and scattering factors for elements 1-92, and derived compounds, using the Henke (1995) & NIST(2005) methods. Calculate photoelectric mass attenuation coefficients, and determine material properties like index of refraction, critical angle, transmission, and reflectance. Identify optimal edges for resonance spectroscopy!'};
            app.Image.ImageSource = 'Icon_XRayAbsorption.png';
            app.PeriodicTable_ButtonGroup = uibuttongroup(app.GridLayout);
            app.PeriodicTable_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriodicTable_ButtonGroupSelectionChanged, true);
            app.PeriodicTable_ButtonGroup.Tooltip = {'Select an element.'};
            app.Label = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label.Text = '1';
            app.AvailableCompoundsLabel = uilabel(app.PeriodicTable_ButtonGroup);
            app.AvailableCompoundsLabel.Text = 'Available Compounds:';
            app.Label_18 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_18.Text = '18';
            app.H_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.H_Button.Text = 'H';
            app.Li_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Li_Button.Text = 'Li';
            app.Label_2 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_2.Text = '2';
            app.Be_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Be_Button.Text = 'Be';
            app.Compounds_DropDown = uidropdown(app.PeriodicTable_ButtonGroup);
            app.Compounds_DropDown.ValueChangedFcn = createCallbackFcn(app, @Compounds_DropDownValueChanged, true);
            app.Compounds_DropDown.Tooltip = {'Select an available compound from the list taken from the Materials Properties Database.'};
            app.Label_13 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_13.Text = '13';
            app.B_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.B_Button.Text = 'B';
            app.Label_14 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_14.Text = '14';
            app.C_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.C_Button.Text = 'C';
            app.Label_15 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_15.Text = '15';
            app.N_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.N_Button.Text = 'N';
            app.Label_16 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_16.Text = '16';
            app.O_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.O_Button.Text = 'O';
            app.Label_17 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_17.Text = '17';
            app.F_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.F_Button.Text = 'F';
            app.He_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.He_Button.Text = 'He';
            app.Ne_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ne_Button.Text = 'Ne';
            app.Na_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Na_Button.Text = 'Na';
            app.Mg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mg_Button.Text = 'Mg';
            app.Label_3 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_12.Text = '12';
            app.Al_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Al_Button.Text = 'Al';
            app.Si_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Si_Button.Text = 'Si';
            app.Si_Button.Value = true;
            app.P_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.P_Button.Text = 'P';
            app.S_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.S_Button.Text = 'S';
            app.Cl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cl_Button.Text = 'Cl';
            app.Ar_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ar_Button.Text = 'Ar';
            app.K_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.K_Button.Text = 'K';
            app.Ca_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ca_Button.Text = 'Ca';
            app.Sc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sc_Button.Text = 'Sc';
            app.Ti_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ti_Button.Text = 'Ti';
            app.V_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.V_Button.Text = 'V';
            app.Cr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cr_Button.Text = 'Cr';
            app.Mn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mn_Button.Text = 'Mn';
            app.Fe_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fe_Button.Text = 'Fe';
            app.Co_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Co_Button.Text = 'Co';
            app.Ni_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ni_Button.Text = 'Ni';
            app.Cu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cu_Button.Text = 'Cu';
            app.Zn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Zn_Button.Text = 'Zn';
            app.Ga_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ga_Button.Text = 'Ga';
            app.Ge_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ge_Button.Text = 'Ge';
            app.As_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.As_Button.Text = 'As';
            app.Se_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Se_Button.Text = 'Se';
            app.Br_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Br_Button.Text = 'Br';
            app.Kr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Kr_Button.Text = 'Kr';
            app.Rb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rb_Button.Text = 'Rb';
            app.Sr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sr_Button.Text = 'Sr';
            app.Y_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Y_Button.Text = 'Y';
            app.Zr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Zr_Button.Text = 'Zr';
            app.Nb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nb_Button.Text = 'Nb';
            app.Mo_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mo_Button.Text = 'Mo';
            app.Tc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tc_Button.Text = 'Tc';
            app.Ru_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ru_Button.Text = 'Ru';
            app.Rh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rh_Button.Text = 'Rh';
            app.Pd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pd_Button.Text = 'Pd';
            app.Ag_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ag_Button.Text = 'Ag';
            app.Cd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cd_Button.Text = 'Cd';
            app.In_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.In_Button.Text = 'In';
            app.Sn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sn_Button.Text = 'Sn';
            app.Sb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sb_Button.Text = 'Sb';
            app.Te_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Te_Button.Text = 'Te';
            app.I_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.I_Button.Text = 'I';
            app.Xe_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Xe_Button.Text = 'Xe';
            app.Cs_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cs_Button.Text = 'Cs';
            app.Ba_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ba_Button.Text = 'Ba';
            app.La_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.La_Button.Text = 'La';
            app.Hf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hf_Button.Text = 'Hf';
            app.Ta_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ta_Button.Text = 'Ta';
            app.W_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.W_Button.Text = 'W';
            app.Re_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Re_Button.Text = 'Re';
            app.Os_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Os_Button.Text = 'Os';
            app.Ir_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ir_Button.Text = 'Ir';
            app.Pt_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pt_Button.Text = 'Pt';
            app.Au_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Au_Button.Text = 'Au';
            app.Hg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hg_Button.Text = 'Hg';
            app.Tl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tl_Button.Text = 'Tl';
            app.Pb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pb_Button.Text = 'Pb';
            app.Bi_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bi_Button.Text = 'Bi';
            app.Po_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Po_Button.Text = 'Po';
            app.At_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.At_Button.Text = 'At';
            app.Rn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rn_Button.Text = 'Rn';
            app.Fr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fr_Button.Text = 'Fr';
            app.Ra_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ra_Button.Text = 'Ra';
            app.Ac_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ac_Button.Text = 'Ac';
            app.Rf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rf_Button.Enable = 'off';
            app.Rf_Button.Text = 'Rf';
            app.Db_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Db_Button.Enable = 'off';
            app.Db_Button.Text = 'Db';
            app.Sg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sg_Button.Enable = 'off';
            app.Sg_Button.Text = 'Sg';
            app.Bh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bh_Button.Enable = 'off';
            app.Bh_Button.Text = 'Bh';
            app.Hs_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hs_Button.Enable = 'off';
            app.Hs_Button.Text = 'Hs';
            app.Mt_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mt_Button.Enable = 'off';
            app.Mt_Button.Text = 'Mt';
            app.Ds_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ds_Button.Enable = 'off';
            app.Ds_Button.Text = 'Ds';
            app.Rg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rg_Button.Enable = 'off';
            app.Rg_Button.Text = 'Rg';
            app.Cn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cn_Button.Enable = 'off';
            app.Cn_Button.Text = 'Cn';
            app.Nh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nh_Button.Enable = 'off';
            app.Nh_Button.Text = 'Nh';
            app.Fl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fl_Button.Enable = 'off';
            app.Fl_Button.Text = 'Fl';
            app.Mc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mc_Button.Enable = 'off';
            app.Mc_Button.Text = 'Mc';
            app.Lv_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lv_Button.Enable = 'off';
            app.Lv_Button.Text = 'Lv';
            app.Ts_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ts_Button.Enable = 'off';
            app.Ts_Button.Text = 'Ts';
            app.Og_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Og_Button.Enable = 'off';
            app.Og_Button.Text = 'Og';
            app.Ce_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ce_Button.Text = 'Ce';
            app.Pr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pr_Button.Text = 'Pr';
            app.Nd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nd_Button.Text = 'Nd';
            app.Pm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pm_Button.Text = 'Pm';
            app.Sm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sm_Button.Text = 'Sm';
            app.Eu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Eu_Button.Text = 'Eu';
            app.Gd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Gd_Button.Text = 'Gd';
            app.Tb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tb_Button.Text = 'Tb';
            app.Dy_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Dy_Button.Text = 'Dy';
            app.Ho_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ho_Button.Text = 'Ho';
            app.Er_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Er_Button.Text = 'Er';
            app.Tm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tm_Button.Text = 'Tm';
            app.Yb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Yb_Button.Text = 'Yb';
            app.Lu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lu_Button.Text = 'Lu';
            app.Th_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Th_Button.Text = 'Th';
            app.Pa_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pa_Button.Text = 'Pa';
            app.U_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.U_Button.Text = 'U';
            app.Np_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Np_Button.Enable = 'off';
            app.Np_Button.Text = 'Np';
            app.Pu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pu_Button.Enable = 'off';
            app.Pu_Button.Text = 'Pu';
            app.Am_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Am_Button.Enable = 'off';
            app.Am_Button.Text = 'Am';
            app.Cm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cm_Button.Enable = 'off';
            app.Cm_Button.Text = 'Cm';
            app.Bk_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bk_Button.Enable = 'off';
            app.Bk_Button.Text = 'Bk';
            app.Cf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cf_Button.Enable = 'off';
            app.Cf_Button.Text = 'Cf';
            app.Es_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Es_Button.Enable = 'off';
            app.Es_Button.Text = 'Es';
            app.Fm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fm_Button.Enable = 'off';
            app.Fm_Button.Text = 'Fm';
            app.Md_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Md_Button.Enable = 'off';
            app.Md_Button.Text = 'Md';
            app.No_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.No_Button.Enable = 'off';
            app.No_Button.Text = 'No';
            app.Lr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lr_Button.Enable = 'off';
            app.Lr_Button.Text = 'Lr';
            app.Main_GridLayout = uigridlayout(app.GridLayout);
            app.XAE_Panel = uipanel(app.Main_GridLayout);
            app.XAE_Panel.Title = 'X-Ray Absorption Edges';
            app.XAE_GridLayout = uigridlayout(app.XAE_Panel);
            app.ElementLabel = uilabel(app.XAE_GridLayout);
            app.ElementLabel.Text = 'Selected Material:';
            app.SelectedMaterial_EditField = uieditfield(app.XAE_GridLayout, 'text');
            app.SelectedMaterial_EditField.Editable = 'off';
            app.SelectedMaterial_EditField.Tooltip = {'Selected material.'};
            app.SelectedMaterial_EditField.Value = 'Si';
            app.XAE_UITable = uitable(app.XAE_GridLayout);
            app.XAE_UITable.ColumnName = {'Edge'; 'Energy (eV)'; 'Jump'};
            app.XAE_UITable.RowName = {};
            app.XAE_UITable.ColumnSortable = [false true true];
            app.XAE_UITable.Multiselect = 'off';
            app.XAE_UITable.Tooltip = {'Table that shows the available x-ray absorption edges, along with their corresponding energies and and edge jumps.'};
            app.XAE_Plot_Button = uibutton(app.XAE_GridLayout, 'push');
            app.XAE_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @XAE_Plot_ButtonPushed, true);
            app.XAE_Plot_Button.Tooltip = {'Plot the x-ray absorption edges versus energy for the selected material.'};
            app.XAE_Plot_Button.Text = 'Plot Edge Spectrum';
            app.XAE_Export_Button = uibutton(app.XAE_GridLayout, 'push');
            app.XAE_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @XAE_Export_ButtonPushed, true);
            app.XAE_Export_Button.Tooltip = {'Export the selected material table data as a text-file (.txt), including the x-ray absorption edges, energies and edge jumps.'};
            app.XAE_Export_Button.Text = 'Export Edge Table (*.txt)';
            app.XASF_Panel = uipanel(app.Main_GridLayout);
            app.XASF_Panel.Title = 'X-Ray Atomic Scattering Factor Parameters';
            app.XASF_GridLayout = uigridlayout(app.XASF_Panel);
            app.XSectFormalismLabel = uilabel(app.XASF_GridLayout);
            app.XSectFormalismLabel.Text = 'XASF Formalism:';
            app.XASF_Formalism_DropDown = uidropdown(app.XASF_GridLayout);
            app.XASF_Formalism_DropDown.Items = {'NIST(2005) - 1 to 433,000 eV', 'Henke(1993) - 1 to 30,000 eV'};
            app.XASF_Formalism_DropDown.ValueChangedFcn = createCallbackFcn(app, @XASF_Formalism_DropDownValueChanged, true);
            app.XASF_Formalism_DropDown.Tooltip = {'Select the formalism to calculate the x-ray atomic scattering factors; either Henke(1993) or NIST(2005).'};
            app.XASF_Formalism_DropDown.Value = 'NIST(2005) - 1 to 433,000 eV';
            app.PhotonEnergyeVEditFieldLabel = uilabel(app.XASF_GridLayout);
            app.PhotonEnergyeVEditFieldLabel.Text = 'Photon Energy (eV):';
            app.XASF_PhotonEnergy_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_PhotonEnergy_EditField.Limits = [1 500000];
            app.XASF_PhotonEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.XASF_PhotonEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @XASF_PhotonEnergy_EditFieldValueChanged, true);
            app.XASF_PhotonEnergy_EditField.Tooltip = {'Incident photon energy in eV.'};
            app.XASF_PhotonEnergy_EditField.Value = 2000;
            app.FilmThicknessnmLabel = uilabel(app.XASF_GridLayout);
            app.FilmThicknessnmLabel.Text = 'Film Thickness (nm):';
            app.XASF_FilmThickness_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_FilmThickness_EditField.Limits = [0 Inf];
            app.XASF_FilmThickness_EditField.ValueDisplayFormat = '%.0f';
            app.XASF_FilmThickness_EditField.ValueChangedFcn = createCallbackFcn(app, @XASF_FilmThickness_EditFieldValueChanged, true);
            app.XASF_FilmThickness_EditField.Tooltip = {'Film thickness of the selected material in nm. Used to calculate the Transmission / Reflectance values.'};
            app.XASF_FilmThickness_EditField.Value = 100;
            app.SigmabarnatomLabel = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel.Text = 'f1 (e/atom):';
            app.XASF_f1_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_f1_EditField.ValueDisplayFormat = '%.4f';
            app.XASF_f1_EditField.AllowEmpty = 'on';
            app.XASF_f1_EditField.Editable = 'off';
            app.XASF_f1_EditField.Tooltip = {'f1. the real part of atomic scattering factor (coherent scattering) [e/atom]'};
            app.SigmabarnatomLabel_2 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_2.Text = 'f2 (e/atom):';
            app.XASF_f2_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_f2_EditField.ValueDisplayFormat = '%.4e';
            app.XASF_f2_EditField.AllowEmpty = 'on';
            app.XASF_f2_EditField.Editable = 'off';
            app.XASF_f2_EditField.Tooltip = {'f2, the imaginary part of atomic scattering factor (absorption) [e/atom].'};
            app.XASF_ff_Plot_Button = uibutton(app.XASF_GridLayout, 'push');
            app.XASF_ff_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @XASF_ff_Plot_ButtonPushed, true);
            app.XASF_ff_Plot_Button.Tooltip = {'Plot of f1 (and f2) versus photon energy.'};
            app.XASF_ff_Plot_Button.Text = 'Plot';
            app.SigmabarnatomLabel_4 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_4.Text = 'μ/ρ (cm^2):';
            app.XASF_muM_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_muM_EditField.ValueDisplayFormat = '%.4e';
            app.XASF_muM_EditField.AllowEmpty = 'on';
            app.XASF_muM_EditField.Editable = 'off';
            app.XASF_muM_EditField.Tooltip = {'The mass absorption coefficient (μ/ρ) [cm^2].'};
            app.SigmabarnatomLabel_3 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_3.Text = 'μ (cm^-1):';
            app.XASF_mu_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_mu_EditField.ValueDisplayFormat = '%.4e';
            app.XASF_mu_EditField.AllowEmpty = 'on';
            app.XASF_mu_EditField.Editable = 'off';
            app.XASF_mu_EditField.Tooltip = {'The linear attenuation coefficient [cm]'};
            app.XASF_mu_Plot_Button = uibutton(app.XASF_GridLayout, 'push');
            app.XASF_mu_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @XASF_mu_Plot_ButtonPushed, true);
            app.XASF_mu_Plot_Button.Tooltip = {'Plot of μ/ρ (and μ) versus photon energy.'};
            app.XASF_mu_Plot_Button.Text = 'Plot ';
            app.SigmabarnatomLabel_5 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_5.Text = 'β:';
            app.XASF_beta_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_beta_EditField.ValueDisplayFormat = '%.4e';
            app.XASF_beta_EditField.AllowEmpty = 'on';
            app.XASF_beta_EditField.Editable = 'off';
            app.XASF_beta_EditField.Tooltip = {'The β-parameter is a measure of the absorptive aspect of the wave-matter interaction and used in many calculations of x-ray interactions.'};
            app.SigmabarnatomLabel_6 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_6.Text = 'δ:';
            app.XASF_delta_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_delta_EditField.ValueDisplayFormat = '%.4e';
            app.XASF_delta_EditField.AllowEmpty = 'on';
            app.XASF_delta_EditField.Editable = 'off';
            app.XASF_delta_EditField.Tooltip = {'The δ-parameter is a measure of the dispersive aspect of the wave-matter interaction and is used in many calculations of x-ray interactions.'};
            app.XASF_betagamma_Plot_Button = uibutton(app.XASF_GridLayout, 'push');
            app.XASF_betagamma_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @XASF_betagamma_Plot_ButtonPushed, true);
            app.XASF_betagamma_Plot_Button.Tooltip = {'Plot of β (and δ) versus photon energy.'};
            app.XASF_betagamma_Plot_Button.Text = 'Plot ';
            app.SigmabarnatomLabel_7 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_7.Text = 'n:';
            app.XASF_n_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_n_EditField.ValueDisplayFormat = '%.7f';
            app.XASF_n_EditField.AllowEmpty = 'on';
            app.XASF_n_EditField.Editable = 'off';
            app.XASF_n_EditField.Tooltip = {'The real-part of the index of refraction, n, for the selected material.'};
            app.SigmabarnatomLabel_8 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_8.Text = 'alpha_C:';
            app.XASF_alphaC_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_alphaC_EditField.ValueDisplayFormat = '%.7f';
            app.XASF_alphaC_EditField.AllowEmpty = 'on';
            app.XASF_alphaC_EditField.Editable = 'off';
            app.XASF_alphaC_EditField.Tooltip = {'The critical angle for the selected material, alphaC, defined as the incident angle below which one gets total external reflection of the x-rays. Below the critical angle, the beam is fully reflected from the material.'};
            app.XASF_nalphaC_Plot_Button = uibutton(app.XASF_GridLayout, 'push');
            app.XASF_nalphaC_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @XASF_nalphaC_Plot_ButtonPushed, true);
            app.XASF_nalphaC_Plot_Button.Tooltip = {'Plot of n (and alphaC) versus photon energy.'};
            app.XASF_nalphaC_Plot_Button.Text = 'Plot ';
            app.SigmabarnatomLabel_9 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_9.Text = 'T:';
            app.XASF_T_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_T_EditField.ValueDisplayFormat = '%.7f';
            app.XASF_T_EditField.AllowEmpty = 'on';
            app.XASF_T_EditField.Editable = 'off';
            app.XASF_T_EditField.Tooltip = {'X-ray transmittance measures the fraction of X-ray intensity that passes through a material without being absorbed or scattered. Calculated from the linear attenuation coefficient and film thickness.'};
            app.SigmabarnatomLabel_10 = uilabel(app.XASF_GridLayout);
            app.SigmabarnatomLabel_10.Text = 'R (1-T):';
            app.XASF_R_EditField = uieditfield(app.XASF_GridLayout, 'numeric');
            app.XASF_R_EditField.ValueDisplayFormat = '%.7f';
            app.XASF_R_EditField.AllowEmpty = 'on';
            app.XASF_R_EditField.Editable = 'off';
            app.XASF_R_EditField.Tooltip = {'X-ray reflectance measures the fraction of X-ray intensity that is reflected off the surface of a material. Calculated from the linear attenuation coefficient and film thickness, or simply 1 - T.'};
            app.XASF_TR_Plot_Button = uibutton(app.XASF_GridLayout, 'push');
            app.XASF_TR_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @XASF_TR_Plot_ButtonPushed, true);
            app.XASF_TR_Plot_Button.Tooltip = {'Plot of T (and R) versus photon energy.'};
            app.XASF_TR_Plot_Button.Text = 'Plot ';
            app.XASF_Buttons_GridLayout = uigridlayout(app.XASF_GridLayout);
            app.XASF_Export_Button = uibutton(app.XASF_Buttons_GridLayout, 'push');
            app.XASF_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @XASF_Export_ButtonPushed, true);
            app.XASF_Export_Button.Tooltip = {'Export data as a text-file (.txt); exports all calculated parameters from the selected material, whilst also including a full table of their photon energy dependence.'};
            app.XASF_Export_Button.Text = 'Export XASF Data (*.txt)';
            app.XASF_Save_Button = uibutton(app.XASF_Buttons_GridLayout, 'push');
            app.XASF_Save_Button.ButtonPushedFcn = createCallbackFcn(app, @XASF_Save_ButtonPushed, true);
            app.XASF_Save_Button.Tooltip = {'Save data as a MATLAB Structure (.mat); saves all calculated parameters from the selected material, whilst also including a full table of their photon energy dependence.'};
            app.XASF_Save_Button.Text = 'Save XASF Data (*.mat)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatBaseX_04_XrayAbsorption

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end