classdef MatBaseX_05_IMFP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Global_GridLayout               matlab.ui.container.GridLayout
        ElecParams_Panel                matlab.ui.container.Panel
        ElecParams_GridLayout           matlab.ui.container.GridLayout
        Notes_Label                     matlab.ui.control.Label
        GridLayout                      matlab.ui.container.GridLayout
        IMFP_SaveAll_Button             matlab.ui.control.Button
        IMFP_ExportAll_Button           matlab.ui.control.Button
        IMFP_Plot_All_Button            matlab.ui.control.Button
        IMFP_Save_Button                matlab.ui.control.Button
        IMFP_Export_Button              matlab.ui.control.Button
        IMFP_Plot_Button                matlab.ui.control.Button
        IMFP_dIMFP_EditField            matlab.ui.control.NumericEditField
        CalculatedIMFPnmLabel_2         matlab.ui.control.Label
        IMFP_IMFP_EditField             matlab.ui.control.NumericEditField
        CalculatedIMFPnmLabel           matlab.ui.control.Label
        IMFP_ElecEnergy_EditField       matlab.ui.control.NumericEditField
        ElectronEnergyeVLabel           matlab.ui.control.Label
        IMFP_Formalism_DropDown         matlab.ui.control.DropDown
        XSectFormalismLabel             matlab.ui.control.Label
        MatParams_Panel                 matlab.ui.container.Panel
        MatParams_GridLayout            matlab.ui.container.GridLayout
        MatParams_Stoiciometry_EditField  matlab.ui.control.NumericEditField
        BandgapenergyeVLabel_2          matlab.ui.control.Label
        MatParams_Bandgap_EditField     matlab.ui.control.NumericEditField
        BandgapenergyeVLabel            matlab.ui.control.Label
        MatParams_AtomZ_EditField       matlab.ui.control.NumericEditField
        AtomicmassZLabel                matlab.ui.control.Label
        MatParams_AtomWeight_EditField  matlab.ui.control.NumericEditField
        AtomicweightamuLabel            matlab.ui.control.Label
        MatParams_Valency_EditField     matlab.ui.control.NumericEditField
        ValencyEditFieldLabel           matlab.ui.control.Label
        MatParams_Density_EditField     matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label            matlab.ui.control.Label
        SelectedMaterial_Lamp           matlab.ui.control.Lamp
        SelectedMaterial_EditField      matlab.ui.control.EditField
        SelectedMaterialLabel           matlab.ui.control.Label
        PeriodicTable_ButtonGroup       matlab.ui.container.ButtonGroup
        Lr_Button                       matlab.ui.control.ToggleButton
        No_Button                       matlab.ui.control.ToggleButton
        Md_Button                       matlab.ui.control.ToggleButton
        Fm_Button                       matlab.ui.control.ToggleButton
        Es_Button                       matlab.ui.control.ToggleButton
        Cf_Button                       matlab.ui.control.ToggleButton
        Bk_Button                       matlab.ui.control.ToggleButton
        Cm_Button                       matlab.ui.control.ToggleButton
        Am_Button                       matlab.ui.control.ToggleButton
        Pu_Button                       matlab.ui.control.ToggleButton
        Np_Button                       matlab.ui.control.ToggleButton
        U_Button                        matlab.ui.control.ToggleButton
        Pa_Button                       matlab.ui.control.ToggleButton
        Th_Button                       matlab.ui.control.ToggleButton
        Lu_Button                       matlab.ui.control.ToggleButton
        Yb_Button                       matlab.ui.control.ToggleButton
        Tm_Button                       matlab.ui.control.ToggleButton
        Er_Button                       matlab.ui.control.ToggleButton
        Ho_Button                       matlab.ui.control.ToggleButton
        Dy_Button                       matlab.ui.control.ToggleButton
        Tb_Button                       matlab.ui.control.ToggleButton
        Gd_Button                       matlab.ui.control.ToggleButton
        Eu_Button                       matlab.ui.control.ToggleButton
        Sm_Button                       matlab.ui.control.ToggleButton
        Pm_Button                       matlab.ui.control.ToggleButton
        Nd_Button                       matlab.ui.control.ToggleButton
        Pr_Button                       matlab.ui.control.ToggleButton
        Ce_Button                       matlab.ui.control.ToggleButton
        Og_Button                       matlab.ui.control.ToggleButton
        Ts_Button                       matlab.ui.control.ToggleButton
        Lv_Button                       matlab.ui.control.ToggleButton
        Mc_Button                       matlab.ui.control.ToggleButton
        Fl_Button                       matlab.ui.control.ToggleButton
        Nh_Button                       matlab.ui.control.ToggleButton
        Cn_Button                       matlab.ui.control.ToggleButton
        Rg_Button                       matlab.ui.control.ToggleButton
        Ds_Button                       matlab.ui.control.ToggleButton
        Mt_Button                       matlab.ui.control.ToggleButton
        Hs_Button                       matlab.ui.control.ToggleButton
        Bh_Button                       matlab.ui.control.ToggleButton
        Sg_Button                       matlab.ui.control.ToggleButton
        Db_Button                       matlab.ui.control.ToggleButton
        Rf_Button                       matlab.ui.control.ToggleButton
        Ac_Button                       matlab.ui.control.ToggleButton
        Ra_Button                       matlab.ui.control.ToggleButton
        Fr_Button                       matlab.ui.control.ToggleButton
        Rn_Button                       matlab.ui.control.ToggleButton
        At_Button                       matlab.ui.control.ToggleButton
        Po_Button                       matlab.ui.control.ToggleButton
        Bi_Button                       matlab.ui.control.ToggleButton
        Pb_Button                       matlab.ui.control.ToggleButton
        Tl_Button                       matlab.ui.control.ToggleButton
        Hg_Button                       matlab.ui.control.ToggleButton
        Au_Button                       matlab.ui.control.ToggleButton
        Pt_Button                       matlab.ui.control.ToggleButton
        Ir_Button                       matlab.ui.control.ToggleButton
        Os_Button                       matlab.ui.control.ToggleButton
        Re_Button                       matlab.ui.control.ToggleButton
        W_Button                        matlab.ui.control.ToggleButton
        Ta_Button                       matlab.ui.control.ToggleButton
        Hf_Button                       matlab.ui.control.ToggleButton
        La_Button                       matlab.ui.control.ToggleButton
        Ba_Button                       matlab.ui.control.ToggleButton
        Cs_Button                       matlab.ui.control.ToggleButton
        Xe_Button                       matlab.ui.control.ToggleButton
        I_Button                        matlab.ui.control.ToggleButton
        Te_Button                       matlab.ui.control.ToggleButton
        Sb_Button                       matlab.ui.control.ToggleButton
        Sn_Button                       matlab.ui.control.ToggleButton
        In_Button                       matlab.ui.control.ToggleButton
        Cd_Button                       matlab.ui.control.ToggleButton
        Ag_Button                       matlab.ui.control.ToggleButton
        Pd_Button                       matlab.ui.control.ToggleButton
        Rh_Button                       matlab.ui.control.ToggleButton
        Ru_Button                       matlab.ui.control.ToggleButton
        Tc_Button                       matlab.ui.control.ToggleButton
        Mo_Button                       matlab.ui.control.ToggleButton
        Nb_Button                       matlab.ui.control.ToggleButton
        Zr_Button                       matlab.ui.control.ToggleButton
        Y_Button                        matlab.ui.control.ToggleButton
        Sr_Button                       matlab.ui.control.ToggleButton
        Rb_Button                       matlab.ui.control.ToggleButton
        Kr_Button                       matlab.ui.control.ToggleButton
        Br_Button                       matlab.ui.control.ToggleButton
        Se_Button                       matlab.ui.control.ToggleButton
        As_Button                       matlab.ui.control.ToggleButton
        Ge_Button                       matlab.ui.control.ToggleButton
        Ga_Button                       matlab.ui.control.ToggleButton
        Zn_Button                       matlab.ui.control.ToggleButton
        Cu_Button                       matlab.ui.control.ToggleButton
        Ni_Button                       matlab.ui.control.ToggleButton
        Co_Button                       matlab.ui.control.ToggleButton
        Fe_Button                       matlab.ui.control.ToggleButton
        Mn_Button                       matlab.ui.control.ToggleButton
        Cr_Button                       matlab.ui.control.ToggleButton
        V_Button                        matlab.ui.control.ToggleButton
        Ti_Button                       matlab.ui.control.ToggleButton
        Sc_Button                       matlab.ui.control.ToggleButton
        Ca_Button                       matlab.ui.control.ToggleButton
        K_Button                        matlab.ui.control.ToggleButton
        Ar_Button                       matlab.ui.control.ToggleButton
        Cl_Button                       matlab.ui.control.ToggleButton
        S_Button                        matlab.ui.control.ToggleButton
        P_Button                        matlab.ui.control.ToggleButton
        Si_Button                       matlab.ui.control.ToggleButton
        Al_Button                       matlab.ui.control.ToggleButton
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Mg_Button                       matlab.ui.control.ToggleButton
        Na_Button                       matlab.ui.control.ToggleButton
        Ne_Button                       matlab.ui.control.ToggleButton
        He_Button                       matlab.ui.control.ToggleButton
        Label_17                        matlab.ui.control.Label
        F_Button                        matlab.ui.control.ToggleButton
        Label_16                        matlab.ui.control.Label
        O_Button                        matlab.ui.control.ToggleButton
        Label_15                        matlab.ui.control.Label
        N_Button                        matlab.ui.control.ToggleButton
        Label_14                        matlab.ui.control.Label
        C_Button                        matlab.ui.control.ToggleButton
        Label_13                        matlab.ui.control.Label
        B_Button                        matlab.ui.control.ToggleButton
        Compounds_DropDown              matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        Be_Button                       matlab.ui.control.ToggleButton
        Li_Button                       matlab.ui.control.ToggleButton
        H_Button                        matlab.ui.control.ToggleButton
        Label_18                        matlab.ui.control.Label
        AvailableCompoundsLabel         matlab.ui.control.Label
        Label_1                         matlab.ui.control.Label
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        TRANSFER_Button                 matlab.ui.control.Button
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
        AppTitle                        matlab.ui.control.Label
        MAINMENU_GridLayout             matlab.ui.container.GridLayout
        MAINMENU_Button                 matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)        
        % - MATLAB data structure to summarise all data for analysis
        myAppData
        % - Variables for determining the IMFP
        selected_element            % selected element
        selected_compound           % selected compound
        selected_material           % selected material
        selected_formalism          % selected IMFP calculator formalism
        density                     % density of the material (g/cc)
        ele_valency                 % number of valence electrons per atom (for an element)
        atom_weight                 % atomic or molecular weight (in amu == g/mol)
        atom_znum                   % atomic mass number of the element (or average for compound)
        ele_bgap                    % band gap energy (eV)
        stoic                       % stoichiometry of the material (e.g. for elements, stoic = 1, for molecules of the form G_gH_h, the stoiciometry is g + h)
        % - Variables for determining the IMFP
        ele_kin                     % Scalar: electron kinetic energy (for PES; KE = BE - PHI) (eV)
        ele_imfp                    % Scalar: electron IMFP values (Angstrom)
        ele_imfp_sig                % Scalar: std. dev. of the electron IMFP values (Angstrom)
        ele_imfp_R                  % Range: electron IMFP values (Angstrom)
        ele_kin_R                   % Range: electron kinetic energy (for PES; KE = BE - PHI) (eV)
    end
    % Private methods available to the app only
    methods (Access = private)
        %% Open Figure Locations 
        % Open Location of MainMenu App
        function open_MainMenu_to_left_of_App(app)
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1);
            fig.Position(2) = app.UIFigure.Position(2);
        end
        %% General Functions
        % Define the list of formalisms
        function list_of_formalisms = define_formalisms(~)
            list_of_formalisms = {...
                'Jablonski(2023) - JTP',...
                'Seah(2012) - S4',...
                'Seah(2012) - S3 (Organic)',...
                'Seah(2012) - S3',...
                'Seah(2011) - S2',...
                'Seah(2011) - S1',...
                'NIST(1999) - Optical Experiments',...
                'Tanuma(1994) - TPP-2M (Averaged)',...
                'Tanuma(1994) - TPP-2M',...
                'Seah(1979) - Universal',...
                };
        end

        % Find the formalism used
        function list_of_formalisms_id = define_formalism_id(~)
            list_of_formalisms_id = read_formalisms("imfp");
            list_of_formalisms_id = string(list_of_formalisms_id);
        end

        %% Material Property Functions
        % - Check the material properties to the user-input and see if the match
        function update_lamp4material(app)
            idx         = 0;
            mat_props   = get_mpd_props(app.selected_material);
            % - If there is an entry for the material
            if ~isempty(mat_props)        
                % --- Extract the material parameters
                mat_density         = round(mat_props.density,4);
                mat_ele_valency     = round(mat_props.elec_valency,4);
                mat_atom_weight     = round(mat_props.atom_mass,4);
                mat_atom_znum       = round(mat_props.atom_z,4);
                mat_ele_bgap        = round(mat_props.elec_band_gap,4);
                mat_stoic           = round(mat_props.atom_stoic,4);
                % --- Extract the user input values
                user_density        = round(app.density,4);
                user_ele_valency    = round(app.ele_valency,4);
                user_atom_weight    = round(app.atom_weight,4);
                user_atom_znum      = round(app.atom_znum,4);
                user_ele_bgap       = round(app.ele_bgap,4);
                user_stoic          = round(app.stoic,4);
                % --- Compare the material parameters with user inputs
                if user_density ~= mat_density; idx = 1; end
                if user_ele_valency ~= mat_ele_valency; idx = 1; end
                if user_atom_weight ~= mat_atom_weight; idx = 1; end
                if user_atom_znum ~= mat_atom_znum; idx = 1; end
                if user_ele_bgap ~= mat_ele_bgap; idx = 1; end
                if user_stoic ~= mat_stoic; idx = 1; end
            % - If there is no entry for the material exists (red light)
            else; idx = 2;
            end
            % - Assigning lamp color
            if idx == 0; app.SelectedMaterial_Lamp.Color = [0 1 0];         % green light
            elseif idx == 1; app.SelectedMaterial_Lamp.Color = [1 0.75 0];  % amber light
            else; app.SelectedMaterial_Lamp.Color = [1 0 0];                % red light
            end
        end

        % Extract all compound names
        function compound_names = extract_compound_names(~)
            compound_names = read_mpd_compounds();
            for i = 1:length(compound_names)
                compound_names{i} = char(sprintf("%i-%s", 118+i, compound_names{i}));
            end
        end

        % Define all drop-down box names for compounds
        function initialize_compound_properties(app)
            compound_names                  = extract_compound_names(app);
            app.Compounds_DropDown.Items    = compound_names;
            app.Compounds_DropDown.Value    = app.Compounds_DropDown.Items{1};
            app.selected_compound           = extractAfter(app.Compounds_DropDown.Value,"-");
        end

        % Read material properties from materials database
        function read_material_properties(app)
            % -- Compound Data
            compound_data               = get_mpd_props(app.selected_material);
            if ~isempty(compound_data)
                app.selected_material       = compound_data.formula;
                % -- Compound Properties
                app.density                 = compound_data.density;
                app.ele_valency             = compound_data.elec_valency;
                app.atom_weight             = compound_data.atom_mass;
                app.atom_znum               = compound_data.atom_z;
                app.ele_bgap                = compound_data.elec_band_gap;
                app.stoic                   = compound_data.atom_stoic;
            else
                app.density                 = app.MatParams_Density_EditField.Value;
                app.ele_valency             = app.MatParams_Valency_EditField.Value;
                app.atom_weight             = app.MatParams_AtomWeight_EditField.Value;
                app.atom_znum               = app.MatParams_AtomZ_EditField.Value;
                app.ele_bgap                = app.MatParams_Bandgap_EditField.Value;
                app.stoic                   = app.MatParams_Stoiciometry_EditField.Value;
            end
        end

        % Update material properties to selected material
        function update_material_properties(app)
            app.SelectedMaterial_EditField.Value        = app.selected_material;
            app.MatParams_Density_EditField.Value         = app.density;
            app.MatParams_Valency_EditField.Value         = app.ele_valency;
            app.MatParams_AtomWeight_EditField.Value      = app.atom_weight;
            app.MatParams_AtomZ_EditField.Value           = app.atom_znum;
            app.MatParams_Bandgap_EditField.Value         = app.ele_bgap;
            app.MatParams_Stoiciometry_EditField.Value    = app.stoic;
        end

        %% Update Functions
        % Function that updates when the element is changes
        function update_change_of_material(app)
            % 1 - Update all dependencies
            read_material_properties(app);
            update_material_properties(app);
            update_lamp4material(app);
            % 2 - Calculate the IMFP
            calculate_imfp(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Extract the data structure
        function write_app_data(app)
            app.myAppData = struct();
            % -- Defining the input arguments
            app.myAppData.formalism              = app.selected_formalism;
            app.myAppData.material               = app.selected_material;
            app.myAppData.density                = app.density;
            app.myAppData.ele_valency            = app.ele_valency;
            app.myAppData.atom_weight            = app.atom_weight;
            app.myAppData.atom_znum              = app.atom_znum;
            app.myAppData.ele_bgap               = app.ele_bgap;
            app.myAppData.stoic                  = app.stoic;
            % -- Defining the output arguments (kinetic energy value)
            app.myAppData.ele_kin                = app.ele_kin;
            app.myAppData.ele_imfp               = app.ele_imfp;
            app.myAppData.ele_imfp_sig           = app.ele_imfp_sig;
            app.myAppData.ele_kin_R              = app.ele_kin_R; 
            app.myAppData.ele_imfp_R             = app.ele_imfp_R;
        end

        % Code that executes to calculate the IMFP
        function calculate_imfp(app)
            % 1 - Calculating scalar IMFP
            list_of_formalisms_id  = define_formalism_id(app);
            if strcmpi(app.selected_formalism,list_of_formalisms_id(10));          app.ele_imfp = calc_imfp_universal(app.ele_kin);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(9));       app.ele_imfp = calc_imfp_tpp2m(app.ele_kin, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(8));       app.ele_imfp = calc_imfp_tpp2m_avg(app.ele_kin);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(7));       [app.ele_imfp, ~] = calc_imfp_optical(app.ele_kin, app.selected_material);  
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(6));       app.ele_imfp = calc_imfp_S1(app.ele_kin, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(5));       app.ele_imfp = calc_imfp_S2(app.ele_kin, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(4));       app.ele_imfp = calc_eal_S3(app.ele_kin, app.density, app.atom_weight, app.atom_znum, app.stoic);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(3));       app.ele_imfp = calc_eal_S3_organic(app.ele_kin);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(2));       app.ele_imfp = calc_eal_S4(app.ele_kin, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(1));       app.ele_imfp = calc_imfp_jtp(app.ele_kin, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            end
            if app.ele_imfp < 0 || isnan(app.ele_imfp); app.ele_imfp = 0; end
            app.IMFP_IMFP_EditField.Value = app.ele_imfp;
            fprintf("---> IMFP: " + string(app.ele_imfp) + " Ang.\n");
            % 2 - Calculating std. dev. of IMFP
            ele_imfp_vals(1)    = calc_imfp_tpp2m(app.ele_kin, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            ele_imfp_vals(2)    = calc_imfp_tpp2m_avg(app.ele_kin);
            ele_imfp_vals(3)    = calc_imfp_universal(app.ele_kin);
            ele_imfp_vals(4)    = calc_imfp_S1(app.ele_kin, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            ele_imfp_vals(5)    = calc_imfp_S2(app.ele_kin, app.atom_znum);
            ele_imfp_vals(6)    = calc_eal_S3(app.ele_kin, app.density, app.atom_weight, app.atom_znum, app.stoic);
            ele_imfp_vals(7)    = calc_eal_S4(app.ele_kin, app.atom_znum);
            ele_imfp_vals(8)    = calc_imfp_jtp(app.ele_kin, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            app.ele_imfp_sig    = std(ele_imfp_vals);
            if app.ele_imfp_sig < 0 || isnan(app.ele_imfp_sig); app.ele_imfp_sig = 0; end
            app.IMFP_dIMFP_EditField.Value = app.ele_imfp_sig;
            % 3 - Calculating ranged IMFP
            app.ele_kin_R              = logspace(log10(50),log10(200000),200)';
            if strcmpi(app.selected_formalism,list_of_formalisms_id(10));          app.ele_imfp_R = calc_imfp_universal(app.ele_kin_R);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(9));       app.ele_imfp_R = calc_imfp_tpp2m(app.ele_kin_R, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(8));       app.ele_imfp_R = calc_imfp_tpp2m_avg(app.ele_kin_R);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(7));       [app.ele_imfp_R, ~] = calc_imfp_optical(app.ele_kin_R, app.selected_material);  
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(6));       app.ele_imfp_R = calc_imfp_S1(app.ele_kin_R, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(5));       app.ele_imfp_R = calc_imfp_S2(app.ele_kin_R, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(4));       app.ele_imfp_R = calc_eal_S3(app.ele_kin_R, app.density, app.atom_weight, app.atom_znum, app.stoic);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(3));       app.ele_imfp_R = calc_eal_S3_organic(app.ele_kin_R);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(2));       app.ele_imfp_R = calc_eal_S4(app.ele_kin_R, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(1));       app.ele_imfp_R = calc_imfp_jtp(app.ele_kin_R, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            end
        end

        %% Plot Functions
        % Plot singlet imfp figure
        function plot_imfp_single(app)
            % 1 - Calculating broad-range IMFP
            ele_kin_broad = logspace(log10(1),log10(1e6),200)';
            list_of_formalisms_id  = define_formalism_id(app);
            if strcmpi(app.selected_formalism,list_of_formalisms_id(10));          ele_imfp_broad = calc_imfp_universal(ele_kin_broad);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(9));       ele_imfp_broad = calc_imfp_tpp2m(ele_kin_broad, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(8));       ele_imfp_broad = calc_imfp_tpp2m_avg(ele_kin_broad);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(7));       ele_imfp_broad = calc_imfp_optical(ele_kin_broad, app.selected_material, 1);  
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(6));       ele_imfp_broad = calc_imfp_S1(ele_kin_broad, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(5));       ele_imfp_broad = calc_imfp_S2(ele_kin_broad, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(4));       ele_imfp_broad = calc_eal_S3(ele_kin_broad, app.density, app.atom_weight, app.atom_znum, app.stoic);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(3));       ele_imfp_broad = calc_eal_S3_organic(ele_kin_broad);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(2));       ele_imfp_broad = calc_eal_S4(ele_kin_broad, app.atom_znum);
            elseif strcmpi(app.selected_formalism,list_of_formalisms_id(1));       ele_imfp_broad = calc_imfp_jtp(ele_kin_broad, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            end
            % 2 - Plotting figure
            % -- Defining the plot properties
            color = [0.8, 0.1, 0.1];
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 750; 
            fig.Position(4) = 400;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plot IMFP data
            nexttile(); hold on; grid on; grid minor;
            plot(ele_kin_broad, ele_imfp_broad, '-', 'color', color, 'LineWidth', 0.5, 'HandleVisibility','off');
            plot(app.ele_kin_R, app.ele_imfp_R, '-', 'color', color, 'LineWidth', 3);
            errorbar(app.ele_kin, app.ele_imfp, app.ele_imfp_sig, app.ele_imfp_sig,...
                'ro', 'LineWidth', 1, 'markerfacecolor', 'k', 'color', 'k', 'markersize', 8, 'CapSize', 8);
            % - Labelling the x- and y-axes
            xlabel('Electron Kinetic Energy [eV] ', 'FontWeight','bold');
            ylabel(' IMFP [Angstrom] ', 'FontWeight','bold');
            text(0.02, 0.97, sprintf("%s(Z=%.1f)", app.selected_material, app.atom_znum),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            text(0.02, 0.92, sprintf("%s", app.selected_formalism),...
                'FontSize', 8, 'color', 'k', 'Units','normalized', 'FontWeight', 'normal', 'HorizontalAlignment','left');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([1, 210000, 1, 5e3]);
            % -- Add a legend
            lgnd_txt = {app.selected_formalism, 'Calculated Value'};
            legend(lgnd_txt,'location', 'eastoutside', 'FontSize', 8);
        end

        % Plot all imfp figure
        function plot_imfp_all(app)
            % -- Calculating all IMFP formalisms
            ele_kin_broad   = logspace(log10(1),log10(200000),100)';
            universal       = calc_imfp_universal(ele_kin_broad);
            tpp2m           = calc_imfp_tpp2m(app.ele_kin_R, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            tpp2m_avg       = calc_imfp_tpp2m_avg(app.ele_kin_R);
            [opt, dopt]     = calc_imfp_optical(app.ele_kin_R, app.selected_material);  
            S1              = calc_imfp_S1(app.ele_kin_R, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            S2              = calc_imfp_S2(app.ele_kin_R, app.atom_znum);
            S3              = calc_eal_S3(app.ele_kin_R, app.density, app.atom_weight, app.atom_znum, app.stoic);
            S3O             = calc_eal_S3_organic(app.ele_kin_R);
            S4              = calc_eal_S4(app.ele_kin_R, app.atom_znum);
            jtp             = calc_imfp_jtp(app.ele_kin_R, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            % -- Defining the plot properties
            linewidth = 2;
            colors = [
                0, 0, 0;                    % Black
                0.8, 0.1, 0.1;              % Dark Red
                0.75, 0.1, 0.1;             % Similar to Dark Red
                0, 0.5, 0;                  % Green
                0.2, 0.2, 0.8;              % Dark Blue
                0.22, 0.2, 0.75;            % Similar to Dark Blue
                0.10, 0.20, 0.50;           % Purple
                0.10, 0.20, 0.50;           % Deep Purple
                0.3010, 0.7450, 0.9330;     % Light Blue
                0.9, 0.6, 0.1               % Orange
            ];
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 750; 
            fig.Position(4) = 400;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plot all IMFP data
            nexttile(); hold on; grid on; grid minor;
            % - PLOTTING OPTICAL/EXPERIMENTAL DATA
            errorbar(app.ele_kin_R, opt, dopt, dopt,...
                'r.-', 'LineWidth', 0.75, 'color', colors(4,:), 'CapSize', 8);
            % - PLOTTING UNIVERSAL CURVE
            loglog(ele_kin_broad, universal,...
                '-', 'color', colors(1,:), 'LineWidth', linewidth);
            % - PLOTTING TPP2M-CURVE
            loglog(app.ele_kin_R, tpp2m,...
                '-', 'color', 'b', 'LineWidth', linewidth, 'color', colors(2,:));
            % - PLOTTING TPP2M-AVERAGE-CURVE
            loglog(app.ele_kin_R, tpp2m_avg,...
                ':', 'color', 'b', 'LineWidth', linewidth, 'color', colors(3,:));
            % - PLOTTING S1- & S2-CURVE
            loglog(app.ele_kin_R, S1,...
                '-', 'LineWidth', linewidth, 'color', colors(5,:));
            loglog(app.ele_kin_R, S2,...
                ':', 'LineWidth', linewidth, 'color', colors(6,:));
            % - PLOTTING S3- & S4-CURVE
            loglog(app.ele_kin_R, S3,...
                '-', 'LineWidth', linewidth, 'color', colors(7,:));
            loglog(app.ele_kin_R, S3O,...
                '--', 'LineWidth', linewidth, 'color', colors(8,:));
            loglog(app.ele_kin_R, S4,...
                ':', 'LineWidth', linewidth, 'color', colors(9,:));
            % - PLOTTING JTP CURVE
            loglog(app.ele_kin_R, jtp,...
                '-', 'LineWidth', linewidth, 'color', colors(10,:));
            % - FORMATTING THE FIGURE
            % -- Labelling the x- and y-axes
            xlabel('Electron Kinetic Energy [eV] ', 'FontWeight','bold');
            ylabel(' IMFP [Angstrom] ', 'FontWeight','bold');
            text(0.02, 0.97, sprintf("%s(Z=%.1f)", app.selected_material, app.atom_znum),...
                'FontSize', 14, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([1, 210000, 1, 5e3]);
            % -- Add a legend
            list_of_formalisms = {...
                'Seah(1979)-Universal',...
                'Tanuma(1994)-TPP-2M',...
                'Tanuma(1994)-TPP-2M(Average)',...
                'NIST(1999)-OpticalExperiments',...
                'Seah(2011)-S1',...
                'Seah(2011)-S2',...
                'Seah(2012)-S3',...
                'Seah(2012)-S3(Organic)',...
                'Seah(2012)-S4',...
                'Jablonski(2023)-JTP',...
                };
            h = zeros(length(list_of_formalisms), 1);
            h(1)    = plot(NaN,NaN,'k-',    'LineWidth', linewidth, 'color',colors(1,:));
            h(2)    = plot(NaN,NaN,'r-',    'LineWidth', linewidth, 'color',colors(2,:));
            h(3)    = plot(NaN,NaN,'r:',    'LineWidth', linewidth, 'color',colors(3,:));
            h(4)    = plot(NaN,NaN,'r.-',   'LineWidth', 0.75*linewidth, 'color',colors(4,:));
            h(5)    = plot(NaN,NaN,'g-',    'LineWidth', linewidth, 'color',colors(5,:));
            h(6)    = plot(NaN,NaN,'g:',   'LineWidth', linewidth, 'color',colors(6,:));
            h(7)    = plot(NaN,NaN,'r-',    'LineWidth', linewidth, 'color',colors(7,:));
            h(8)    = plot(NaN,NaN,'r--',    'LineWidth', linewidth, 'color',colors(8,:));
            h(9)    = plot(NaN,NaN,'r:',   'LineWidth', linewidth, 'color',colors(9,:));
            h(10)   = plot(NaN,NaN,'r-',    'LineWidth', linewidth, 'color',colors(10,:));
            legend(h,list_of_formalisms,'location', 'eastoutside', 'FontSize', 8);
        end


    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBaseX - Electron Inelastic Mean Free Path Calculator (procopios.constantinou@psi.ch)';
            % 1 - Initialising fields
            % -- Defining the list of formalisms
            list_of_formalisms = define_formalisms(app);
            app.IMFP_Formalism_DropDown.Items = list_of_formalisms;
            app.IMFP_Formalism_DropDown.Value = app.IMFP_Formalism_DropDown.Items{1};
            % -- Defining available compounds
            initialize_compound_properties(app);
            % -- Coloring the edit-fields that can be changed
            edit_color = [0.85 1 1];
            app.IMFP_Formalism_DropDown.BackgroundColor      = edit_color;
            app.Compounds_DropDown.BackgroundColor      = edit_color;
            app.IMFP_ElecEnergy_EditField.BackgroundColor    = edit_color;
            app.MatParams_Stoiciometry_EditField.BackgroundColor  = edit_color;
            app.MatParams_Bandgap_EditField.BackgroundColor       = edit_color;
            app.MatParams_AtomZ_EditField.BackgroundColor         = edit_color;
            app.MatParams_AtomWeight_EditField.BackgroundColor    = edit_color;
            app.MatParams_Valency_EditField.BackgroundColor       = edit_color;
            app.MatParams_Density_EditField.BackgroundColor       = edit_color;
            app.SelectedMaterial_EditField.BackgroundColor      = edit_color;
            % 2 - Default parameters upon startup
            app.myAppData                   = struct();
            list_of_formalisms_id           = define_formalism_id(app);
            app.selected_formalism          = list_of_formalisms_id(1);
            app.selected_material           = app.SelectedMaterial_EditField.Value;
            read_material_properties(app);
            app.ele_kin                     = app.IMFP_ElecEnergy_EditField.Value;
            app.ele_kin_R                   = logspace(log10(50),log10(200000),100)';
            app.ele_imfp_R                  = [];
            % 3 - First determination
            PeriodicTable_ButtonGroupSelectionChanged(app);
            IMFP_Formalism_DropDownValueChanged(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run MatBaseX;
            open_MainMenu_to_left_of_App(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type', 'Figure');
            % Exclude those with 'MatBaseX' in the Name (title)
            matbaseFigs = findobj(hPlots, '-regexp', 'Name', 'MatBaseX');
            hPlotsToClose = setdiff(hPlots, matbaseFigs);
            % Close only the non-MatBaseX figures
            close(hPlotsToClose);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run MatBaseX_05_IMFP;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 700;
            screen_size(4) = 550;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('myAppData : '); disp(app.myAppData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            temp_form = char(app.selected_formalism); temp_form(temp_form=='(') = []; temp_form(temp_form==')') = [];
            fname = sprintf("%s_IMFP_%s", app.selected_material, temp_form);
            assignin('base', char(fname), app.myAppData);
        end

        % Selection changed function: PeriodicTable_ButtonGroup
        function PeriodicTable_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton              = app.PeriodicTable_ButtonGroup.SelectedObject;
            app.selected_element        = selectedButton.Text;
            app.selected_material       = app.selected_element;
            fprintf("--> Element: " + string(app.selected_element) + "\n");
            % 2 - Updating the relevant UI components
            app.SelectedMaterial_EditField.Value = app.selected_material;
            % 3 - Update Functions
            update_change_of_material(app);
        end

        % Value changed function: Compounds_DropDown
        function Compounds_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value                   = app.Compounds_DropDown.Value;
            app.selected_compound   = extractAfter(value, "-");
            app.selected_material   = app.selected_compound;
            fprintf("--> Compound: " + string(app.selected_compound) + "\n");
            % 2 - Updating the relevant UI components
            app.SelectedMaterial_EditField.Value = app.selected_material;
            % 3 - Update Functions
            update_change_of_material(app);
        end

        % Value changed function: SelectedMaterial_EditField
        function SelectedMaterial_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value                       = app.SelectedMaterial_EditField.Value;
            app.selected_material       = string(value);
            fprintf("--> Material: " + string(app.selected_material) + "\n");
            % 2 - Validity check on the material
            % -- Checking if an identical element exists
            element_names   = read_mpd_elements();
            element_idx     = find(contains(string(element_names),app.selected_material), 1);
            if ~isempty(element_idx)
                name = char(sprintf("%s_Button", element_names{element_idx}));
                app.(name).Value = 1;
                PeriodicTable_ButtonGroupSelectionChanged(app, event);
            else
                % -- Checking if an identical compound exists
                compound_names  = extract_compound_names(app);
                compound_idx    = find(contains(string(compound_names),app.selected_material), 1);
                if ~isempty(compound_idx)
                    app.Compounds_DropDown.Value = app.Compounds_DropDown.Items{compound_idx};
                    Compounds_DropDownValueChanged(app, event);
                else
                    update_change_of_material(app);
                end
            end
        end

        % Value changed function: MatParams_Density_EditField
        function MatParams_Density_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.MatParams_Density_EditField.Value;
            app.density     = value;
            % 2 - Update Functions
            update_lamp4material(app);
            calculate_imfp(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: MatParams_Valency_EditField
        function MatParams_Valency_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value               = app.MatParams_Valency_EditField.Value;
            app.ele_valency     = value;
            % 2 - Update Functions
            update_lamp4material(app);
            calculate_imfp(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: MatParams_AtomWeight_EditField
        function MatParams_AtomWeight_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value               = app.MatParams_AtomWeight_EditField.Value;
            app.atom_weight     = value;
            % 2 - Update Functions
            update_lamp4material(app);
            calculate_imfp(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: MatParams_AtomZ_EditField
        function MatParams_AtomZ_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.MatParams_AtomZ_EditField.Value;
            app.atom_znum   = value;
            % 2 - Update Functions
            update_lamp4material(app);
            calculate_imfp(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: MatParams_Bandgap_EditField
        function MatParams_Bandgap_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.MatParams_Bandgap_EditField.Value;
            app.ele_bgap    = value;
            % 2 - Update Functions
            update_lamp4material(app);
            calculate_imfp(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: MatParams_Stoiciometry_EditField
        function MatParams_Stoiciometry_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.MatParams_Stoiciometry_EditField.Value;
            app.stoic       = value;
            % 2 - Update Functions
            update_lamp4material(app);
            calculate_imfp(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: IMFP_Formalism_DropDown
        function IMFP_Formalism_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.IMFP_Formalism_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the formalism selected
            list_of_formalisms_id = define_formalism_id(app);
            if strcmpi(value,app.IMFP_Formalism_DropDown.Items{1});          value = list_of_formalisms_id(1);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{2});      value = list_of_formalisms_id(2);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{3});      value = list_of_formalisms_id(3);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{4});      value = list_of_formalisms_id(4);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{5});      value = list_of_formalisms_id(5);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{6});      value = list_of_formalisms_id(6);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{7});      value = list_of_formalisms_id(7);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{8});      value = list_of_formalisms_id(8);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{9});      value = list_of_formalisms_id(9);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{10});     value = list_of_formalisms_id(10);
            end
            % 3 - Validity check on the material parameters required
            if strcmpi(value,"Universal(1979)") || strcmpi(value,"TPP2M-avg(1994)") || strcmpi(value,"S3O(2012)") || strcmpi(value,"NIST(1999)")
                app.MatParams_Stoiciometry_EditField.Enable   = 'off';
                app.MatParams_Bandgap_EditField.Enable        = 'off';
                app.MatParams_AtomZ_EditField.Enable          = 'off';
                app.MatParams_AtomWeight_EditField.Enable     = 'off';
                app.MatParams_Valency_EditField.Enable        = 'off';
                app.MatParams_Density_EditField.Enable        = 'off';
            elseif value == "TPP2M(1994)"
                app.MatParams_Stoiciometry_EditField.Enable   = 'off';
                app.MatParams_Bandgap_EditField.Enable        = 'on';
                app.MatParams_AtomZ_EditField.Enable          = 'off';
                app.MatParams_AtomWeight_EditField.Enable     = 'on';
                app.MatParams_Valency_EditField.Enable        = 'on';
                app.MatParams_Density_EditField.Enable        = 'on';
            elseif value == "S2(2011)" || strcmpi(value,"S4(2012)")
                app.MatParams_Stoiciometry_EditField.Enable   = 'off';
                app.MatParams_Bandgap_EditField.Enable        = 'off';
                app.MatParams_AtomZ_EditField.Enable          = 'on';
                app.MatParams_AtomWeight_EditField.Enable     = 'off';
                app.MatParams_Valency_EditField.Enable        = 'off';
                app.MatParams_Density_EditField.Enable        = 'off';
            elseif value == "S1(2011)"
                app.MatParams_Stoiciometry_EditField.Enable   = 'off';
                app.MatParams_Bandgap_EditField.Enable        = 'on';
                app.MatParams_AtomZ_EditField.Enable          = 'on';
                app.MatParams_AtomWeight_EditField.Enable     = 'on';
                app.MatParams_Valency_EditField.Enable        = 'off';
                app.MatParams_Density_EditField.Enable        = 'on';
            elseif value == "S3(2012)"
                app.MatParams_Stoiciometry_EditField.Enable   = 'on';
                app.MatParams_Bandgap_EditField.Enable        = 'off';
                app.MatParams_AtomZ_EditField.Enable          = 'on';
                app.MatParams_AtomWeight_EditField.Enable     = 'on';
                app.MatParams_Valency_EditField.Enable        = 'off';
                app.MatParams_Density_EditField.Enable        = 'on';
            elseif value == "JTP(2023)"
                app.MatParams_Stoiciometry_EditField.Enable   = 'off';
                app.MatParams_Bandgap_EditField.Enable        = 'on';
                app.MatParams_AtomZ_EditField.Enable          = 'off';
                app.MatParams_AtomWeight_EditField.Enable     = 'on';
                app.MatParams_Valency_EditField.Enable        = 'on';
                app.MatParams_Density_EditField.Enable        = 'on';
            end
            % 4 - Assigning the output
            app.selected_formalism = value;
            fprintf("--> formalism: " + string(app.selected_formalism) + "\n");
            % 5 - Update Functions
            calculate_imfp(app);
            % 6 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: IMFP_ElecEnergy_EditField
        function IMFP_ElecEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.IMFP_ElecEnergy_EditField.Value;
            app.ele_kin     = value;  
            fprintf("--> Electron Energy: " + string(app.ele_kin) + " eV\n");
            % 2 - Update Functions
            calculate_imfp(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Button pushed function: IMFP_Plot_Button
        function IMFP_Plot_ButtonPushed(app, event)
            plot_imfp_single(app);
        end

        % Button pushed function: IMFP_Plot_All_Button
        function IMFP_Plot_All_ButtonPushed(app, event)
            plot_imfp_all(app)
        end

        % Button pushed function: IMFP_Export_Button
        function IMFP_Export_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            temp_form = char(app.selected_formalism); temp_form(temp_form=='(') = []; temp_form(temp_form==')') = [];
            fname = sprintf("%s_IMFP_%s", app.selected_material, temp_form);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("Material:\t\t%s\n", app.selected_material);
            info = info + sprintf("Density:\t\t%.4e g/cc\n", app.density);
            info = info + sprintf("Valency:\t\t%.4e\n", app.ele_valency);
            info = info + sprintf("Atomic Weight:\t\t%.4e g/mol\n", app.atom_weight);
            info = info + sprintf("Atomic Z:\t\t%.4e\n", app.atom_znum);
            info = info + sprintf("Band Gap Energy:\t%.4e eV\n", app.ele_bgap);
            info = info + sprintf("Stoichiometry:\t\t%.4e\n", app.stoic);
            % Sample data
            Ek_eV           = app.ele_kin_R; Ek_eV = round(Ek_eV,4);
            IMFP_Angstrom   = app.ele_imfp_R; IMFP_Angstrom = round(IMFP_Angstrom,4);
            T_Data = table(Ek_eV,IMFP_Angstrom);
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: IMFP_Save_Button
        function IMFP_Save_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.mat'};
            temp_form = char(app.selected_formalism); temp_form(temp_form=='(') = []; temp_form(temp_form==')') = [];
            fname = sprintf("%s_IMFP_%s", app.selected_material, temp_form);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            structName          = char(save_filename(1:end-4));
            eval([structName ' = app.myAppData;']);     % Create the data structure dynamically
            % Saving the data as .mat
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved data : '); display(app.myAppData);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!');
        end

        % Button pushed function: IMFP_ExportAll_Button
        function IMFP_ExportAll_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = sprintf("%s_IMFP_all", app.selected_material);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("Material:\t\t%s\n", app.selected_material);
            info = info + sprintf("Density:\t\t%.4e g/cc\n", app.density);
            info = info + sprintf("Valency:\t\t%.4e\n", app.ele_valency);
            info = info + sprintf("Atomic Weight:\t\t%.4e g/mol\n", app.atom_weight);
            info = info + sprintf("Atomic Z:\t\t%.4e\n", app.atom_znum);
            info = info + sprintf("Band Gap Energy:\t%.4e eV\n", app.ele_bgap);
            info = info + sprintf("Stoichiometry:\t\t%.4e\n", app.stoic);
            % Sample data
            Ek_eV           = app.ele_kin_R; Ek_eV = round(Ek_eV,4);
            universal       = calc_imfp_universal(Ek_eV); universal = round(universal,4);
            tpp2m           = calc_imfp_tpp2m(Ek_eV, app.density, app.ele_valency, app.atom_weight, app.ele_bgap); tpp2m = round(tpp2m,4);
            tpp2m_avg       = calc_imfp_tpp2m_avg(Ek_eV); tpp2m_avg = round(tpp2m_avg,4);
            [opt, ~]        = calc_imfp_optical(Ek_eV, app.selected_material); opt = round(opt,4);
            S1              = calc_imfp_S1(Ek_eV, app.density, app.atom_weight, app.ele_bgap, app.atom_znum); S1 = round(S1,4);
            S2              = calc_imfp_S2(Ek_eV, app.atom_znum); S2 = round(S2,4);
            S3              = calc_eal_S3(Ek_eV, app.density, app.atom_weight, app.atom_znum, app.stoic); S3 = round(S3,4);
            S3O             = calc_eal_S3_organic(Ek_eV); S3O = round(S3O,4);
            S4              = calc_eal_S4(Ek_eV, app.atom_znum); S4 = round(S4,4);
            jtp             = calc_imfp_jtp(Ek_eV, app.density, app.ele_valency, app.atom_weight, app.ele_bgap); jtp = round(jtp,4);
            T_Data = table(Ek_eV,universal,tpp2m,tpp2m_avg,opt,S1,S2,S3,S3O,S4,jtp);
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: IMFP_SaveAll_Button
        function IMFP_SaveAll_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.mat'};
            fname = sprintf("%s_IMFP_all", app.selected_material);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Construct table to save data
            wbar = waitbar(0.5,'Saving...'); 
            % -- Extracting all IMFP curves
            Ek_eV           = app.ele_kin_R; Ek_eV = round(Ek_eV,4);
            universal       = calc_imfp_universal(Ek_eV); universal = round(universal,4);
            tpp2m           = calc_imfp_tpp2m(Ek_eV, app.density, app.ele_valency, app.atom_weight, app.ele_bgap); tpp2m = round(tpp2m,4);
            tpp2m_avg       = calc_imfp_tpp2m_avg(Ek_eV); tpp2m_avg = round(tpp2m_avg,4);
            [opt, ~]        = calc_imfp_optical(Ek_eV, app.selected_material); opt = round(opt,4);
            S1              = calc_imfp_S1(Ek_eV, app.density, app.atom_weight, app.ele_bgap, app.atom_znum); S1 = round(S1,4);
            S2              = calc_imfp_S2(Ek_eV, app.atom_znum); S2 = round(S2,4);
            S3              = calc_eal_S3(Ek_eV, app.density, app.atom_weight, app.atom_znum, app.stoic); S3 = round(S3,4);
            S3O             = calc_eal_S3_organic(Ek_eV); S3O = round(S3O,4);
            S4              = calc_eal_S4(Ek_eV, app.atom_znum); S4 = round(S4,4);
            jtp             = calc_imfp_jtp(Ek_eV, app.density, app.ele_valency, app.atom_weight, app.ele_bgap); jtp = round(jtp,4);
            % -- Constructing as a data structure
            dataStr = struct();
            dataStr.material                = app.selected_material;
            dataStr.density                 = app.density;
            dataStr.ele_valency             = app.ele_valency;
            dataStr.atom_weight             = app.atom_weight;
            dataStr.atom_znum               = app.atom_znum;
            dataStr.ele_bgap                = app.ele_bgap;
            dataStr.stoic                   = app.stoic;
            dataStr.Ek_eV                   = Ek_eV;
            dataStr.universal               = universal;
            dataStr.tpp2m                   = tpp2m;
            dataStr.tpp2m_avg              = tpp2m_avg;
            dataStr.opt                     = opt;
            dataStr.S1                      = S1;
            dataStr.S2                      = S2;
            dataStr.S3                      = S3;
            dataStr.S3O                     = S3O;
            dataStr.S4                      = S4;
            dataStr.jtp                     = jtp;
            % -- Saving the data
            structName          = char(save_filename(1:end-4));
            eval([structName ' = dataStr;']);     % Create the data structure dynamically
            % Saving the data as .mat
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved data : '); disp(dataStr);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Global_GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.Global_GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.Global_GridLayout);
            app.AppTitle.Text = 'Electron Inelastic Mean Free Path Calculator';
            app.AppButtons_GridLayout = uigridlayout(app.Global_GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart this App.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize this App to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all App information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer App Data directly to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Calculate the electron inelastic mean free path (IMFP) using all available formalsms; Optical (NIST), Universal, TPP-2M, S1, S2, S3, S4, and JTP methods. Conveniently access material parameters from the Materials Properties Database or manually input data for materials not included in the database.'};
            app.Image.ImageSource = 'Icon_IMFP.jpeg';
            app.PeriodicTable_ButtonGroup = uibuttongroup(app.Global_GridLayout);
            app.PeriodicTable_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriodicTable_ButtonGroupSelectionChanged, true);
            app.PeriodicTable_ButtonGroup.Tooltip = {'Select an element.'};
            app.Label_1 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_1.Text = '1';
            app.AvailableCompoundsLabel = uilabel(app.PeriodicTable_ButtonGroup);
            app.AvailableCompoundsLabel.Text = 'Available Compounds:';
            app.Label_18 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_18.Text = '18';
            app.H_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.H_Button.Text = 'H';
            app.Li_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Li_Button.Text = 'Li';
            app.Be_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Be_Button.Text = 'Be';
            app.Label_2 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_2.Text = '2';
            app.Compounds_DropDown = uidropdown(app.PeriodicTable_ButtonGroup);
            app.Compounds_DropDown.ValueChangedFcn = createCallbackFcn(app, @Compounds_DropDownValueChanged, true);
            app.Compounds_DropDown.Tooltip = {'Select an available compound from the list taken from the Materials Properties Database.'};
            app.B_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.B_Button.Text = 'B';
            app.Label_13 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_13.Text = '13';
            app.C_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.C_Button.Text = 'C';
            app.Label_14 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_14.Text = '14';
            app.N_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.N_Button.Text = 'N';
            app.Label_15 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_15.Text = '15';
            app.O_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.O_Button.Text = 'O';
            app.Label_16 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_16.Text = '16';
            app.F_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.F_Button.Text = 'F';
            app.Label_17 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_17.Text = '17';
            app.He_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.He_Button.Text = 'He';
            app.Ne_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ne_Button.Text = 'Ne';
            app.Na_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Na_Button.Text = 'Na';
            app.Mg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mg_Button.Text = 'Mg';
            app.Label_3 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_12.Text = '12';
            app.Al_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Al_Button.Text = 'Al';
            app.Si_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Si_Button.Text = 'Si';
            app.Si_Button.Value = true;
            app.P_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.P_Button.Text = 'P';
            app.S_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.S_Button.Text = 'S';
            app.Cl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cl_Button.Text = 'Cl';
            app.Ar_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ar_Button.Text = 'Ar';
            app.K_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.K_Button.Text = 'K';
            app.Ca_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ca_Button.Text = 'Ca';
            app.Sc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sc_Button.Text = 'Sc';
            app.Ti_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ti_Button.Text = 'Ti';
            app.V_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.V_Button.Text = 'V';
            app.Cr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cr_Button.Text = 'Cr';
            app.Mn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mn_Button.Text = 'Mn';
            app.Fe_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fe_Button.Text = 'Fe';
            app.Co_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Co_Button.Text = 'Co';
            app.Ni_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ni_Button.Text = 'Ni';
            app.Cu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cu_Button.Text = 'Cu';
            app.Zn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Zn_Button.Text = 'Zn';
            app.Ga_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ga_Button.Text = 'Ga';
            app.Ge_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ge_Button.Text = 'Ge';
            app.As_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.As_Button.Text = 'As';
            app.Se_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Se_Button.Text = 'Se';
            app.Br_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Br_Button.Text = 'Br';
            app.Kr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Kr_Button.Text = 'Kr';
            app.Rb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rb_Button.Text = 'Rb';
            app.Sr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sr_Button.Text = 'Sr';
            app.Y_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Y_Button.Text = 'Y';
            app.Zr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Zr_Button.Text = 'Zr';
            app.Nb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nb_Button.Text = 'Nb';
            app.Mo_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mo_Button.Text = 'Mo';
            app.Tc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tc_Button.Text = 'Tc';
            app.Ru_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ru_Button.Text = 'Ru';
            app.Rh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rh_Button.Text = 'Rh';
            app.Pd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pd_Button.Text = 'Pd';
            app.Ag_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ag_Button.Text = 'Ag';
            app.Cd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cd_Button.Text = 'Cd';
            app.In_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.In_Button.Text = 'In';
            app.Sn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sn_Button.Text = 'Sn';
            app.Sb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sb_Button.Text = 'Sb';
            app.Te_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Te_Button.Text = 'Te';
            app.I_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.I_Button.Text = 'I';
            app.Xe_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Xe_Button.Text = 'Xe';
            app.Cs_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cs_Button.Text = 'Cs';
            app.Ba_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ba_Button.Text = 'Ba';
            app.La_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.La_Button.Text = 'La';
            app.Hf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hf_Button.Text = 'Hf';
            app.Ta_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ta_Button.Text = 'Ta';
            app.W_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.W_Button.Text = 'W';
            app.Re_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Re_Button.Text = 'Re';
            app.Os_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Os_Button.Text = 'Os';
            app.Ir_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ir_Button.Text = 'Ir';
            app.Pt_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pt_Button.Text = 'Pt';
            app.Au_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Au_Button.Text = 'Au';
            app.Hg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hg_Button.Text = 'Hg';
            app.Tl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tl_Button.Text = 'Tl';
            app.Pb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pb_Button.Text = 'Pb';
            app.Bi_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bi_Button.Text = 'Bi';
            app.Po_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Po_Button.Text = 'Po';
            app.At_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.At_Button.Text = 'At';
            app.Rn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rn_Button.Text = 'Rn';
            app.Fr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fr_Button.Text = 'Fr';
            app.Ra_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ra_Button.Text = 'Ra';
            app.Ac_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ac_Button.Text = 'Ac';
            app.Rf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rf_Button.Text = 'Rf';
            app.Db_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Db_Button.Text = 'Db';
            app.Sg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sg_Button.Text = 'Sg';
            app.Bh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bh_Button.Text = 'Bh';
            app.Hs_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hs_Button.Text = 'Hs';
            app.Mt_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mt_Button.Text = 'Mt';
            app.Ds_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ds_Button.Text = 'Ds';
            app.Rg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rg_Button.Text = 'Rg';
            app.Cn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cn_Button.Text = 'Cn';
            app.Nh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nh_Button.Text = 'Nh';
            app.Fl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fl_Button.Text = 'Fl';
            app.Mc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mc_Button.Text = 'Mc';
            app.Lv_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lv_Button.Text = 'Lv';
            app.Ts_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ts_Button.Text = 'Ts';
            app.Og_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Og_Button.Text = 'Og';
            app.Ce_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ce_Button.Text = 'Ce';
            app.Pr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pr_Button.Text = 'Pr';
            app.Nd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nd_Button.Text = 'Nd';
            app.Pm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pm_Button.Text = 'Pm';
            app.Sm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sm_Button.Text = 'Sm';
            app.Eu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Eu_Button.Text = 'Eu';
            app.Gd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Gd_Button.Text = 'Gd';
            app.Tb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tb_Button.Text = 'Tb';
            app.Dy_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Dy_Button.Text = 'Dy';
            app.Ho_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ho_Button.Text = 'Ho';
            app.Er_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Er_Button.Text = 'Er';
            app.Tm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tm_Button.Text = 'Tm';
            app.Yb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Yb_Button.Text = 'Yb';
            app.Lu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lu_Button.Text = 'Lu';
            app.Th_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Th_Button.Text = 'Th';
            app.Pa_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pa_Button.Text = 'Pa';
            app.U_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.U_Button.Text = 'U';
            app.Np_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Np_Button.Text = 'Np';
            app.Pu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pu_Button.Text = 'Pu';
            app.Am_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Am_Button.Text = 'Am';
            app.Cm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cm_Button.Text = 'Cm';
            app.Bk_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bk_Button.Text = 'Bk';
            app.Cf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cf_Button.Text = 'Cf';
            app.Es_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Es_Button.Text = 'Es';
            app.Fm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fm_Button.Text = 'Fm';
            app.Md_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Md_Button.Text = 'Md';
            app.No_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.No_Button.Text = 'No';
            app.Lr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lr_Button.Text = 'Lr';
            app.MatParams_Panel = uipanel(app.Global_GridLayout);
            app.MatParams_Panel.Title = 'Material Parameters';
            app.MatParams_GridLayout = uigridlayout(app.MatParams_Panel);
            app.SelectedMaterialLabel = uilabel(app.MatParams_GridLayout);
            app.SelectedMaterialLabel.Text = 'Selected Material:';
            app.SelectedMaterial_EditField = uieditfield(app.MatParams_GridLayout, 'text');
            app.SelectedMaterial_EditField.ValueChangedFcn = createCallbackFcn(app, @SelectedMaterial_EditFieldValueChanged, true);
            app.SelectedMaterial_EditField.Tooltip = {'Enter an element / compound to search for the crystal structure parameters in the Materials Properties Database.'};
            app.SelectedMaterial_EditField.Value = 'Si';
            app.SelectedMaterial_Lamp = uilamp(app.MatParams_GridLayout);
            app.SelectedMaterial_Lamp.Tooltip = {'Green: Material & parameters agree to the database'; 'Amber: Material is in the database, but parameters do not exist or conform to the database'; 'Red: Material does not exist in the database'};
            app.Bulkdensitygcm3Label = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label.Text = 'Bulk density (g/cm^3):';
            app.MatParams_Density_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.MatParams_Density_EditField.Limits = [0 Inf];
            app.MatParams_Density_EditField.ValueDisplayFormat = '%.3f';
            app.MatParams_Density_EditField.ValueChangedFcn = createCallbackFcn(app, @MatParams_Density_EditFieldValueChanged, true);
            app.MatParams_Density_EditField.Tooltip = {'Enter the bulk density of the material in grams per cubic cm (g/cm^3).'};
            app.MatParams_Density_EditField.Value = 2.33;
            app.ValencyEditFieldLabel = uilabel(app.MatParams_GridLayout);
            app.ValencyEditFieldLabel.Text = 'Valency:';
            app.MatParams_Valency_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.MatParams_Valency_EditField.Limits = [0 Inf];
            app.MatParams_Valency_EditField.ValueDisplayFormat = '%.0f';
            app.MatParams_Valency_EditField.ValueChangedFcn = createCallbackFcn(app, @MatParams_Valency_EditFieldValueChanged, true);
            app.MatParams_Valency_EditField.Tooltip = {'Enter the valency of the material, which is the total number of electrons located in the outermost unoccupied electron shell.'};
            app.MatParams_Valency_EditField.Value = 4;
            app.AtomicweightamuLabel = uilabel(app.MatParams_GridLayout);
            app.AtomicweightamuLabel.Text = 'Atomic weight (amu):';
            app.MatParams_AtomWeight_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.MatParams_AtomWeight_EditField.Limits = [0 Inf];
            app.MatParams_AtomWeight_EditField.ValueDisplayFormat = '%.3f';
            app.MatParams_AtomWeight_EditField.ValueChangedFcn = createCallbackFcn(app, @MatParams_AtomWeight_EditFieldValueChanged, true);
            app.MatParams_AtomWeight_EditField.Tooltip = {'Enter the atomic weight of the material, or average if a compound is used.'};
            app.MatParams_AtomWeight_EditField.Value = 28.085;
            app.AtomicmassZLabel = uilabel(app.MatParams_GridLayout);
            app.AtomicmassZLabel.Text = 'Atomic mass (Z):';
            app.MatParams_AtomZ_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.MatParams_AtomZ_EditField.Limits = [0 Inf];
            app.MatParams_AtomZ_EditField.ValueDisplayFormat = '%.3f';
            app.MatParams_AtomZ_EditField.ValueChangedFcn = createCallbackFcn(app, @MatParams_AtomZ_EditFieldValueChanged, true);
            app.MatParams_AtomZ_EditField.Tooltip = {'Enter the atomic Z number of the material, or average if a compound is used.'};
            app.MatParams_AtomZ_EditField.Value = 14;
            app.BandgapenergyeVLabel = uilabel(app.MatParams_GridLayout);
            app.BandgapenergyeVLabel.Text = 'Band gap energy (eV):';
            app.MatParams_Bandgap_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.MatParams_Bandgap_EditField.Limits = [0 Inf];
            app.MatParams_Bandgap_EditField.ValueDisplayFormat = '%.3f';
            app.MatParams_Bandgap_EditField.ValueChangedFcn = createCallbackFcn(app, @MatParams_Bandgap_EditFieldValueChanged, true);
            app.MatParams_Bandgap_EditField.Tooltip = {'Enter the electron band-gap energy of the material in electron-Volts (eV).'};
            app.MatParams_Bandgap_EditField.Value = 1.107;
            app.BandgapenergyeVLabel_2 = uilabel(app.MatParams_GridLayout);
            app.BandgapenergyeVLabel_2.Text = 'Stoichiometry:';
            app.MatParams_Stoiciometry_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.MatParams_Stoiciometry_EditField.Limits = [0 Inf];
            app.MatParams_Stoiciometry_EditField.ValueDisplayFormat = '%.0f';
            app.MatParams_Stoiciometry_EditField.ValueChangedFcn = createCallbackFcn(app, @MatParams_Stoiciometry_EditFieldValueChanged, true);
            app.MatParams_Stoiciometry_EditField.Tooltip = {'Enter the stoiciometry of the material (e.g. for elements, stoic = 1, for molecules of the form G_gH_h, the stoiciometry is g + h).'};
            app.MatParams_Stoiciometry_EditField.Value = 1;
            app.ElecParams_Panel = uipanel(app.Global_GridLayout);
            app.ElecParams_Panel.Title = 'Inelastic Mean Free Path Calculator';
            app.ElecParams_GridLayout = uigridlayout(app.ElecParams_Panel);
            app.XSectFormalismLabel = uilabel(app.ElecParams_GridLayout);
            app.XSectFormalismLabel.Text = 'IMFP Formalism:';
            app.IMFP_Formalism_DropDown = uidropdown(app.ElecParams_GridLayout);
            app.IMFP_Formalism_DropDown.Items = {'Seah(1979) - Universal', 'Tanuma(1994) - TPP-2M', 'NIST(1999) - Optial Experiments', 'Seah(2011) - S1', 'Seah(2011) - S2', 'Seah(2012) - S3', 'Seah(2012) - S3 (Organic)', 'Seah(2012) - S4', 'Jablonski(2023) - JTP'};
            app.IMFP_Formalism_DropDown.ValueChangedFcn = createCallbackFcn(app, @IMFP_Formalism_DropDownValueChanged, true);
            app.IMFP_Formalism_DropDown.Tooltip = {'Select the formalism to be used to calculate the inelastic mean free path (IMFP) parameter. '};
            app.IMFP_Formalism_DropDown.Value = 'Jablonski(2023) - JTP';
            app.ElectronEnergyeVLabel = uilabel(app.ElecParams_GridLayout);
            app.ElectronEnergyeVLabel.Text = 'Electron Kinetic Energy (eV):';
            app.IMFP_ElecEnergy_EditField = uieditfield(app.ElecParams_GridLayout, 'numeric');
            app.IMFP_ElecEnergy_EditField.Limits = [0 Inf];
            app.IMFP_ElecEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.IMFP_ElecEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @IMFP_ElecEnergy_EditFieldValueChanged, true);
            app.IMFP_ElecEnergy_EditField.Tooltip = {'Enter the electron kinetic energy in electron-Volts (eV).'};
            app.IMFP_ElecEnergy_EditField.Value = 1000;
            app.CalculatedIMFPnmLabel = uilabel(app.ElecParams_GridLayout);
            app.CalculatedIMFPnmLabel.Text = 'IMFP (Angstrom):';
            app.IMFP_IMFP_EditField = uieditfield(app.ElecParams_GridLayout, 'numeric');
            app.IMFP_IMFP_EditField.Limits = [0 Inf];
            app.IMFP_IMFP_EditField.ValueDisplayFormat = '%.3f';
            app.IMFP_IMFP_EditField.Editable = 'off';
            app.IMFP_IMFP_EditField.Tooltip = {'The calculated IMFP value using the chosen calculator formalism.'};
            app.IMFP_IMFP_EditField.Value = 21.085;
            app.CalculatedIMFPnmLabel_2 = uilabel(app.ElecParams_GridLayout);
            app.CalculatedIMFPnmLabel_2.Text = 'Std. Dev. IMFP (Angstrom):';
            app.IMFP_dIMFP_EditField = uieditfield(app.ElecParams_GridLayout, 'numeric');
            app.IMFP_dIMFP_EditField.Limits = [0 Inf];
            app.IMFP_dIMFP_EditField.ValueDisplayFormat = '%.3f';
            app.IMFP_dIMFP_EditField.Editable = 'off';
            app.IMFP_dIMFP_EditField.Tooltip = {'The standard deviation of the IMFP value obtained from all the calculator formalisms. Useful for estimating the precision of the IMFP value.'};
            app.IMFP_dIMFP_EditField.Value = 2.725;
            app.GridLayout = uigridlayout(app.ElecParams_GridLayout);
            app.IMFP_Plot_Button = uibutton(app.GridLayout, 'push');
            app.IMFP_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @IMFP_Plot_ButtonPushed, true);
            app.IMFP_Plot_Button.Tooltip = {'Plot the IMFP vs electron kinetic energy of the formalism selected.'};
            app.IMFP_Plot_Button.Text = 'Plot IMFP';
            app.IMFP_Export_Button = uibutton(app.GridLayout, 'push');
            app.IMFP_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @IMFP_Export_ButtonPushed, true);
            app.IMFP_Export_Button.Tooltip = {'Export data as a text-file (.txt); only the selected formalism IMFP values will be exported.'};
            app.IMFP_Export_Button.Text = 'Export Data (*.txt)';
            app.IMFP_Save_Button = uibutton(app.GridLayout, 'push');
            app.IMFP_Save_Button.ButtonPushedFcn = createCallbackFcn(app, @IMFP_Save_ButtonPushed, true);
            app.IMFP_Save_Button.Tooltip = {'Save data as a MATLAB Structure (.mat); only the selected formalism IMFP values are saved.'};
            app.IMFP_Save_Button.Text = 'Save Data (*.mat)';
            app.IMFP_Plot_All_Button = uibutton(app.GridLayout, 'push');
            app.IMFP_Plot_All_Button.ButtonPushedFcn = createCallbackFcn(app, @IMFP_Plot_All_ButtonPushed, true);
            app.IMFP_Plot_All_Button.Tooltip = {'Plot the IMFP vs electron kinetic energy for all formalisms.'};
            app.IMFP_Plot_All_Button.Text = 'Plot All IMFP';
            app.IMFP_ExportAll_Button = uibutton(app.GridLayout, 'push');
            app.IMFP_ExportAll_Button.ButtonPushedFcn = createCallbackFcn(app, @IMFP_ExportAll_ButtonPushed, true);
            app.IMFP_ExportAll_Button.Tooltip = {'Export data as a text-file (.txt); all the IMFP formalisms are exported as a table.'};
            app.IMFP_ExportAll_Button.Text = 'Export All Data (*.txt)';
            app.IMFP_SaveAll_Button = uibutton(app.GridLayout, 'push');
            app.IMFP_SaveAll_Button.ButtonPushedFcn = createCallbackFcn(app, @IMFP_SaveAll_ButtonPushed, true);
            app.IMFP_SaveAll_Button.Tooltip = {'Save data as a MATLAB Structure (.mat); all the IMFP formalisms are saved.'};
            app.IMFP_SaveAll_Button.Text = 'Save All Data (*.mat)';
            app.Notes_Label = uilabel(app.ElecParams_GridLayout);
            app.Notes_Label.Text = {'Notes: '; ' - NIST(1999) contains data only for elements'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatBaseX_05_IMFP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end