classdef MatBaseX_06_XPSSensitivityFactor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        IMFPParams_Panel                matlab.ui.container.Panel
        IMFPParams_GridLayout           matlab.ui.container.GridLayout
        SelectedMaterial_EditField      matlab.ui.control.EditField
        ElementLabel                    matlab.ui.control.Label
        IMFP_IMFP_EditField             matlab.ui.control.NumericEditField
        CalculatedIMFPnmLabel           matlab.ui.control.Label
        IMFP_Ek_EditField               matlab.ui.control.NumericEditField
        SigmabarnatomLabel_5            matlab.ui.control.Label
        IMFP_Formalism_DropDown         matlab.ui.control.DropDown
        IMFPFormalismDropDownLabel      matlab.ui.control.Label
        SF_Panel                        matlab.ui.container.Panel
        SF_GridLayout                   matlab.ui.container.GridLayout
        RSF_EditField                   matlab.ui.control.NumericEditField
        SigmabarnatomLabel_8            matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        SF_vs_Theta_Export_Button       matlab.ui.control.Button
        SF_SaveAllData_Button           matlab.ui.control.Button
        SF_vs_Phi_Export_Button         matlab.ui.control.Button
        SF_vs_PhotonEnergy_Export_Button  matlab.ui.control.Button
        AngularAnisotropy_Plot_Button   matlab.ui.control.Button
        SF_vs_Theta_Plot_Button         matlab.ui.control.Button
        SF_vs_Phi_Plot_Button           matlab.ui.control.Button
        SF_vs_PhotonEnergy_Plot_Button  matlab.ui.control.Button
        TotalRSF_EditField              matlab.ui.control.NumericEditField
        SigmabarnatomLabel_10           matlab.ui.control.Label
        TotalSF_EditField               matlab.ui.control.NumericEditField
        SigmabarnatomLabel_9            matlab.ui.control.Label
        SF_EditField                    matlab.ui.control.NumericEditField
        SigmabarnatomLabel_6            matlab.ui.control.Label
        XSect_Panel                     matlab.ui.container.Panel
        XSect_GridLayout                matlab.ui.container.GridLayout
        XSect_FP_EditField              matlab.ui.control.NumericEditField
        SigmabarnatomLabel_7            matlab.ui.control.Label
        SelectedElement_DropDown        matlab.ui.control.DropDown
        ElementLabel_2                  matlab.ui.control.Label
        XSect_Delta_EditField           matlab.ui.control.NumericEditField
        SigmabarnatomLabel_4            matlab.ui.control.Label
        XSect_Gamma_EditField           matlab.ui.control.NumericEditField
        SigmabarnatomLabel_3            matlab.ui.control.Label
        XSect_Beta_EditField            matlab.ui.control.NumericEditField
        SigmabarnatomLabel_2            matlab.ui.control.Label
        XSect_Sigma_EditField           matlab.ui.control.NumericEditField
        SigmabarnatomLabel              matlab.ui.control.Label
        XSect_PhotonEnergy_EditField    matlab.ui.control.NumericEditField
        PhotonEnergyeVEditFieldLabel    matlab.ui.control.Label
        SelectedCoreLevel_DropDown      matlab.ui.control.DropDown
        CoreLevelDropDownLabel          matlab.ui.control.Label
        XSect_Formalism_DropDown        matlab.ui.control.DropDown
        XSectFormalismLabel             matlab.ui.control.Label
        Geometry_Panel                  matlab.ui.container.Panel
        Geometry_GridLayout             matlab.ui.container.GridLayout
        SF_Equation_Label               matlab.ui.control.Label
        Geometry_P_EditField            matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        Geometry_phi_EditField          matlab.ui.control.NumericEditField
        phidegLabel                     matlab.ui.control.Label
        Geometry_theta_EditField        matlab.ui.control.NumericEditField
        phidegLabel_3                   matlab.ui.control.Label
        Geometry_Label                  matlab.ui.control.Label
        Geometry_Figure                 matlab.ui.control.Image
        PeriodicTableSingle_ButtonGroup  matlab.ui.container.ButtonGroup
        Compounds_DropDown              matlab.ui.control.DropDown
        AvailableCompoundsLabel         matlab.ui.control.Label
        Lr_Button                       matlab.ui.control.ToggleButton
        No_Button                       matlab.ui.control.ToggleButton
        Md_Button                       matlab.ui.control.ToggleButton
        Fm_Button                       matlab.ui.control.ToggleButton
        Es_Button                       matlab.ui.control.ToggleButton
        Cf_Button                       matlab.ui.control.ToggleButton
        Bk_Button                       matlab.ui.control.ToggleButton
        Cm_Button                       matlab.ui.control.ToggleButton
        Am_Button                       matlab.ui.control.ToggleButton
        Pu_Button                       matlab.ui.control.ToggleButton
        Np_Button                       matlab.ui.control.ToggleButton
        U_Button                        matlab.ui.control.ToggleButton
        Pa_Button                       matlab.ui.control.ToggleButton
        Th_Button                       matlab.ui.control.ToggleButton
        Lu_Button                       matlab.ui.control.ToggleButton
        Yb_Button                       matlab.ui.control.ToggleButton
        Tm_Button                       matlab.ui.control.ToggleButton
        Er_Button                       matlab.ui.control.ToggleButton
        Ho_Button                       matlab.ui.control.ToggleButton
        Dy_Button                       matlab.ui.control.ToggleButton
        Tb_Button                       matlab.ui.control.ToggleButton
        Gd_Button                       matlab.ui.control.ToggleButton
        Eu_Button                       matlab.ui.control.ToggleButton
        Sm_Button                       matlab.ui.control.ToggleButton
        Pm_Button                       matlab.ui.control.ToggleButton
        Nd_Button                       matlab.ui.control.ToggleButton
        Pr_Button                       matlab.ui.control.ToggleButton
        Ce_Button                       matlab.ui.control.ToggleButton
        Og_Button                       matlab.ui.control.ToggleButton
        Ts_Button                       matlab.ui.control.ToggleButton
        Lv_Button                       matlab.ui.control.ToggleButton
        Mc_Button                       matlab.ui.control.ToggleButton
        Fl_Button                       matlab.ui.control.ToggleButton
        Nh_Button                       matlab.ui.control.ToggleButton
        Cn_Button                       matlab.ui.control.ToggleButton
        Rg_Button                       matlab.ui.control.ToggleButton
        Ds_Button                       matlab.ui.control.ToggleButton
        Mt_Button                       matlab.ui.control.ToggleButton
        Hs_Button                       matlab.ui.control.ToggleButton
        Bh_Button                       matlab.ui.control.ToggleButton
        Sg_Button                       matlab.ui.control.ToggleButton
        Db_Button                       matlab.ui.control.ToggleButton
        Rf_Button                       matlab.ui.control.ToggleButton
        Ac_Button                       matlab.ui.control.ToggleButton
        Ra_Button                       matlab.ui.control.ToggleButton
        Fr_Button                       matlab.ui.control.ToggleButton
        Rn_Button                       matlab.ui.control.ToggleButton
        At_Button                       matlab.ui.control.ToggleButton
        Po_Button                       matlab.ui.control.ToggleButton
        Bi_Button                       matlab.ui.control.ToggleButton
        Pb_Button                       matlab.ui.control.ToggleButton
        Tl_Button                       matlab.ui.control.ToggleButton
        Hg_Button                       matlab.ui.control.ToggleButton
        Au_Button                       matlab.ui.control.ToggleButton
        Pt_Button                       matlab.ui.control.ToggleButton
        Ir_Button                       matlab.ui.control.ToggleButton
        Os_Button                       matlab.ui.control.ToggleButton
        Re_Button                       matlab.ui.control.ToggleButton
        W_Button                        matlab.ui.control.ToggleButton
        Ta_Button                       matlab.ui.control.ToggleButton
        Hf_Button                       matlab.ui.control.ToggleButton
        La_Button                       matlab.ui.control.ToggleButton
        Ba_Button                       matlab.ui.control.ToggleButton
        Cs_Button                       matlab.ui.control.ToggleButton
        Xe_Button                       matlab.ui.control.ToggleButton
        I_Button                        matlab.ui.control.ToggleButton
        Te_Button                       matlab.ui.control.ToggleButton
        Sb_Button                       matlab.ui.control.ToggleButton
        Sn_Button                       matlab.ui.control.ToggleButton
        In_Button                       matlab.ui.control.ToggleButton
        Cd_Button                       matlab.ui.control.ToggleButton
        Ag_Button                       matlab.ui.control.ToggleButton
        Pd_Button                       matlab.ui.control.ToggleButton
        Rh_Button                       matlab.ui.control.ToggleButton
        Ru_Button                       matlab.ui.control.ToggleButton
        Tc_Button                       matlab.ui.control.ToggleButton
        Mo_Button                       matlab.ui.control.ToggleButton
        Nb_Button                       matlab.ui.control.ToggleButton
        Zr_Button                       matlab.ui.control.ToggleButton
        Y_Button                        matlab.ui.control.ToggleButton
        Sr_Button                       matlab.ui.control.ToggleButton
        Rb_Button                       matlab.ui.control.ToggleButton
        Kr_Button                       matlab.ui.control.ToggleButton
        Br_Button                       matlab.ui.control.ToggleButton
        Se_Button                       matlab.ui.control.ToggleButton
        As_Button                       matlab.ui.control.ToggleButton
        Ge_Button                       matlab.ui.control.ToggleButton
        Ga_Button                       matlab.ui.control.ToggleButton
        Zn_Button                       matlab.ui.control.ToggleButton
        Cu_Button                       matlab.ui.control.ToggleButton
        Ni_Button                       matlab.ui.control.ToggleButton
        Co_Button                       matlab.ui.control.ToggleButton
        Fe_Button                       matlab.ui.control.ToggleButton
        Mn_Button                       matlab.ui.control.ToggleButton
        Cr_Button                       matlab.ui.control.ToggleButton
        V_Button                        matlab.ui.control.ToggleButton
        Ti_Button                       matlab.ui.control.ToggleButton
        Sc_Button                       matlab.ui.control.ToggleButton
        Ca_Button                       matlab.ui.control.ToggleButton
        K_Button                        matlab.ui.control.ToggleButton
        Ar_Button                       matlab.ui.control.ToggleButton
        Cl_Button                       matlab.ui.control.ToggleButton
        S_Button                        matlab.ui.control.ToggleButton
        P_Button                        matlab.ui.control.ToggleButton
        Si_Button                       matlab.ui.control.ToggleButton
        Al_Button                       matlab.ui.control.ToggleButton
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Mg_Button                       matlab.ui.control.ToggleButton
        Na_Button                       matlab.ui.control.ToggleButton
        Ne_Button                       matlab.ui.control.ToggleButton
        F_Button                        matlab.ui.control.ToggleButton
        O_Button                        matlab.ui.control.ToggleButton
        N_Button                        matlab.ui.control.ToggleButton
        C_Button                        matlab.ui.control.ToggleButton
        B_Button                        matlab.ui.control.ToggleButton
        Be_Button                       matlab.ui.control.ToggleButton
        Li_Button                       matlab.ui.control.ToggleButton
        He_Button                       matlab.ui.control.ToggleButton
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        H_Button                        matlab.ui.control.ToggleButton
        Label_18                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        TRANSFER_Button                 matlab.ui.control.Button
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
        AppTitle                        matlab.ui.control.Label
        MAINMENU_GridLayout             matlab.ui.container.GridLayout
        MAINMENU_Button                 matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarize all data for analysis
        myAppData
        % - Variables for determining the Sensitivity Factor
        % --- Material selected
        selected_material           % selected material
        % - X-Ray Binding Energies Table
        list_of_elements        % list of all available elements in the material
        list_of_corelevels      % list of all available core-levels for selected element
        list_of_be              % list of all binding energies for selected element
        % --- Experimental Geometry
        theta           % scalar of the polar angle between the photoelectron vector relative to electric field vector (i.e. at normal emission: LV (p-pol, E//MP) = 0, LH (s-pol, E⊥MP) = 90) [degree]
        phi             % scalar of the azimuthal angle between the photon momentum vector relative to the projection of the photoelectron vector on to the plane perpendicular to the electric field vector (i.e. normal emission = 0) [degree]
        P               % scalar of degree of polarization, where 1 or 0 is equivalent to full linear polarization, and 0.5 is equivalent to unpolarized light.
        % --- IMFP parameters 
        formalism_imfp  % string for imfp calculator formalism
        ele_kin         % electron kinetic energy (for PES; KE = BE - PHI) (eV)
        ele_imfp        % electron IMFP values (Angstrom)
        % --- Cross-section parameters
        formalism_xsect             % string of the photoionization cross-section formalism to use
        selected_element            % selected element
        selected_corelevel          % selected corelevel
        eph                         % photon energy defined by user
        sigma                       % Subshell photoionization cross section in units of barn/atom
        beta                        % Beta (dipole) parameter of the photoelectron angular distribution
        gamma                       % Gamma (non-dipole) parameter of the photoelectron angular distribution
        delta                       % Delta (non-dipole) parameter of the photoelectron angular distribution
        % --- Sensitivity Factor
        sf              % sensitivity factor
        sfParams        % parameters used to derive the sensitivity factor
        rsf             % relative sensitivity factor
        tot_sf          % total Orbital Contribution: sensitivity factor
        tot_rsf         % total Orbital Contribution: relative sensitivity factor
        % --- Sensitivity Factor (over a range)
        eph_R
        theta_R
        phi_R
        sf_vs_hv
        sf_vs_theta
        sf_vs_phi
        tot_sf_vs_hv
        tot_sf_vs_theta
        tot_sf_vs_phi
        x_lims
        y_lims
    end
    
    % Private methods available to the app only
    methods (Access = private)
        %% Open Figure Locations 
        % Open Location of MainMenu App
        function open_MainMenu_to_left_of_App(app)
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1);
            fig.Position(2) = app.UIFigure.Position(2);
        end
        %% General Functions
        % Define the list of IMFP formalisms
        function list_of_imfp_formalisms = define_imfp_formalisms(~)
            list_of_imfp_formalisms = {...
                'Jablonski(2023) - JTP',...
                'Seah(2012) - S4',...
                'Seah(2012) - S3 (Organic)',...
                'Seah(2012) - S3',...
                'Seah(2011) - S2',...
                'Seah(2011) - S1',...
                'NIST(1999) - Optical Experiments',...
                'Tanuma(1994) - TPP-2M (Averaged)',...
                'Tanuma(1994) - TPP-2M',...
                'Seah(1979) - Universal',...
                };
        end

        % Find the IMFP formalism used
        function list_of_imfp_formalisms_id = define_imfp_formalism_id(~)
            list_of_imfp_formalisms_id = read_formalisms("imfp");
            list_of_imfp_formalisms_id = string(list_of_imfp_formalisms_id);
        end

        % Define the list of XSECT formalisms
        function list_of_xsect_formalisms = define_xsect_formalisms(~)
            list_of_xsect_formalisms = {...
                'Cant(2022) - 1500 to 10000 eV',...
                'Trzhaskovskaya(2018) - 1500 to 10000 eV',...
                'Yeh Lindau(1985) - 10 to 1500 eV',...
                'Scofield(1973) - 1 to 1500 keV',...
                };
        end

        % Find the XSECT formalism used
        function list_of_xsect_formalisms_id = define_xsect_formalism_id(~)
            list_of_xsect_formalisms_id   = ["Cant(2022)","Trz(2018)","Yeh(1985)","Scofield(1973)"];
        end

        %% Material Property Functions
        % Extract all compound names
        function compound_names = extract_compound_names(~)
            compound_names = read_mpd_compounds();
            for i = 1:length(compound_names)
                compound_names{i} = char(sprintf("%i-%s", 118+i, compound_names{i}));
            end
        end

        % Define all drop-down box names for compounds
        function initialize_compound_properties(app)
            compound_names                  = extract_compound_names(app);
            app.Compounds_DropDown.Items    = compound_names;
            app.Compounds_DropDown.Value    = app.Compounds_DropDown.Items{1};
        end

        %% Update Functions
        % Find all the elements for the selected material
        function write_element_level_list(app)
            formula                     = parse_chemical_formula(app.selected_material);
            elements                    = sort({formula(:).element});
            app.list_of_elements        = elements;
            app.SelectedElement_DropDown.Items  = app.list_of_elements;
            app.SelectedElement_DropDown.Value  = app.SelectedElement_DropDown.Items{1};
            app.selected_element        = app.SelectedElement_DropDown.Items{1};
        end

        % Find all the core-levels for the selected element
        function write_core_level_list(app)
            % - Finding core-levels that have finite binding energy & cross-section
            % xsect_cls   = read_xsect_core_levels(app.selected_element);
            init_dat    = read_spectroscopy_element_data("xsect", app.selected_element);
            xsect_cls   = table2array(init_dat(:,2));
            [app.list_of_be, app.list_of_corelevels]    = calc_be(app.selected_element, xsect_cls);
            [app.list_of_be, indx]                      = sort(app.list_of_be); 
            app.list_of_corelevels                      = app.list_of_corelevels(indx);
            cl_labels                                   = string(size(app.list_of_be));
            for i = 1:length(app.list_of_be); cl_labels(i) = sprintf("%s-%.2feV",app.list_of_corelevels(i),app.list_of_be(i)); end
            app.SelectedCoreLevel_DropDown.Items    = cl_labels;
            app.SelectedCoreLevel_DropDown.Value    = app.SelectedCoreLevel_DropDown.Items{1};
            app.selected_corelevel          = extractBefore(app.SelectedCoreLevel_DropDown.Items{1}, "-");
        end

        % Function that updates when the element is changes
        function state_change_of_material(app)
            % 1 - Update all dependencies
            write_element_level_list(app);
            write_core_level_list(app);
            % 2 - Calculate the SF
            calculate_sf(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Function that calculates all single dependent variables
        function update_single_dependents(app)
            scale_factor = calc_total_orbital_contribution(app.selected_corelevel);
            scale_factor = scale_factor{1};
            % 1 - Calculate Sensitivity Factor
            [app.sf, app.sfParams] = ...
                calc_sf(...
                app.selected_material, app.selected_element, app.selected_corelevel,...
                app.eph, app.theta, app.phi, app.P,...
                app.formalism_xsect, app.formalism_imfp, 1);
            app.tot_sf = scale_factor .* app.sf;
            % 2 - Calculate Relative Sensitivity Factor
            app.rsf = ...
                calc_rsf(...
                app.selected_material, app.selected_element, app.selected_corelevel,...
                app.eph, app.theta, app.phi, app.P,...
                app.formalism_xsect, app.formalism_imfp, 1);
            app.tot_rsf = scale_factor .* app.rsf;
            % 3 - Update all edit-fields to read the new values
            if isnan(app.sfParams.sigma); app.sfParams.sigma = 0; end
            if isnan(app.sf); app.sf = 0; end
            if isnan(app.rsf); app.rsf = 0; end
            if isnan(app.tot_sf); app.tot_sf = 0; end
            if isnan(app.tot_rsf); app.tot_rsf = 0; end
            app.XSect_Sigma_EditField.Value         = app.sfParams.sigma;
            app.XSect_Beta_EditField.Value          = app.sfParams.beta;
            app.XSect_Gamma_EditField.Value         = app.sfParams.gamma;
            app.XSect_Delta_EditField.Value         = app.sfParams.delta;
            app.XSect_FP_EditField.Value            = app.sfParams.FP;
            app.IMFP_Ek_EditField.Value             = app.sfParams.ke;
            app.IMFP_IMFP_EditField.Value           = app.sfParams.imfp;
            app.SF_EditField.Value                  = app.sf;
            app.RSF_EditField.Value                 = app.rsf;
            app.TotalSF_EditField.Value             = app.tot_sf;
            app.TotalRSF_EditField.Value            = app.tot_rsf;
        end

        % Function that calculates range of dependent variables
        function update_range_dependents(app)
            % - 1 : Define the initial parameters based on the formalism
            list_of_xsect_formalisms_id = define_xsect_formalism_id(app);
            if strcmpi(app.formalism_xsect,list_of_xsect_formalisms_id(4))   
                app.eph_R           = logspace(3, 6.5, 200);
                app.x_lims          = [0.9*1000, 1.1*1500000];
                app.y_lims          = [1e-10, 1e7];
            elseif strcmpi(app.formalism_xsect,list_of_xsect_formalisms_id(3))
                app.eph_R           = 10:20:1500;
                app.x_lims          = [5, 1550];
                app.y_lims          = [1e0, 1e8];
            elseif strcmpi(app.formalism_xsect,list_of_xsect_formalisms_id(2))
                app.eph_R           = 1500:100:10000;
                app.x_lims          = [0.9*1000, 1.1*10000];
                app.y_lims          = [1e-3, 1e8];
            elseif strcmpi(app.formalism_xsect,list_of_xsect_formalisms_id(1))  
                app.eph_R           = 1500:50:10000;
                app.x_lims          = [0.9*1000, 1.1*10000];
                app.y_lims          = [1e-3, 1e8];
            end
            if isrow(app.eph_R); app.eph_R = app.eph_R'; end
            scale_factor = calc_total_orbital_contribution(app.selected_corelevel);
            scale_factor = scale_factor{1};
            % 2 - Photon Energy Dependence
            [app.sf_vs_hv, ~] = ...
                calc_sf(...
                app.selected_material, app.selected_element, app.selected_corelevel,...
                app.eph_R, app.theta, app.phi, app.P,...
                app.formalism_xsect, app.formalism_imfp);
            app.tot_sf_vs_hv = scale_factor .* app.sf_vs_hv;
            % 3 - Emission Angle Dependence
            app.theta_R = 0:2:90; 
            if isrow(app.theta_R); app.theta_R = app.theta_R'; end
            [app.sf_vs_theta, ~] = ...
                calc_sf(...
                app.selected_material, app.selected_element, app.selected_corelevel,...
                app.eph, app.theta_R, app.phi, app.P,...
                app.formalism_xsect, app.formalism_imfp);
            app.tot_sf_vs_theta = scale_factor .* app.sf_vs_theta;
            % 4 - Azimuthal Angle Dependence
            app.phi_R = 0:2:180; 
            if isrow(app.phi_R); app.phi_R = app.phi_R'; end
            [app.sf_vs_phi, ~] = ...
                calc_sf(...
                app.selected_material, app.selected_element, app.selected_corelevel,...
                app.eph, app.theta, app.phi_R, app.P,...
                app.formalism_xsect, app.formalism_imfp);
            app.tot_sf_vs_phi = scale_factor .* app.sf_vs_phi;
        end

        % Code that executes to calculate the sensitivity factor
        function calculate_sf(app)
            update_single_dependents(app);
            update_range_dependents(app);
        end

        % Extract the data structure
        function write_app_data(app)
            app.myAppData = struct();
            % -- Defining the input arguments
            app.myAppData.material                  = app.selected_material;
            app.myAppData.theta                     = app.theta;
            app.myAppData.phi                       = app.phi;
            app.myAppData.P                         = app.P;
            app.myAppData.formalism_imfp            = app.formalism_imfp;
            app.myAppData.formalism_xsect           = app.formalism_xsect;
            app.myAppData.selected_element          = app.selected_element;
            app.myAppData.selected_corelevel        = app.selected_corelevel;
            app.myAppData.eph                       = app.eph;
            app.myAppData.sf                        = app.sf;
            app.myAppData.sfParams                  = app.sfParams;
            app.myAppData.rsf                       = app.rsf;
            app.myAppData.tot_sf                    = app.tot_sf;
            app.myAppData.tot_rsf                   = app.tot_rsf;
            app.myAppData.eph_R                     = app.eph_R;
            app.myAppData.sf_vs_hv                  = app.sf_vs_hv;
            app.myAppData.tot_sf_vs_hv              = app.tot_sf_vs_hv;
            app.myAppData.theta_R                   = app.theta_R;
            app.myAppData.sf_vs_theta               = app.sf_vs_theta;
            app.myAppData.tot_sf_vs_theta           = app.tot_sf_vs_theta;
            app.myAppData.phi_R                     = app.phi_R;
            app.myAppData.sf_vs_phi                 = app.sf_vs_phi;
            app.myAppData.tot_sf_vs_phi             = app.tot_sf_vs_phi;
        end

        %% Plot Functions
        % Plot sensitivity factor versus photon energy
        function plot_sf_vs_hv(app)
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.selected_corelevel);
            cl_color = cl_color{1};
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            plot(app.myAppData.eph_R, app.myAppData.sf_vs_hv,...
                '-', 'linewidth', 1.25,  'color', cl_color); 
            scatter(app.myAppData.eph, app.myAppData.sf,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(app.selected_corelevel, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.02, 0.05, sprintf("%s-%s(%s)", app.selected_material, app.selected_element, app.selected_corelevel),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' SF ', 'FontWeight','bold');
            ax = gca; ax.XScale = 'log'; ax.YScale = 'log'; 
            axis([app.x_lims, app.y_lims]);
        end

        % Plot sensitivity factor versus theta
        function plot_sf_vs_theta(app)
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.selected_corelevel);
            cl_color = cl_color{1};
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            plot(app.myAppData.theta_R, app.myAppData.sf_vs_theta,...
                '-', 'linewidth', 1.25,  'color', cl_color); 
            scatter(app.myAppData.theta, app.myAppData.sf,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(app.selected_corelevel, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.02, 0.05, sprintf("%s-%s(%s)", app.selected_material, app.selected_element, app.selected_corelevel),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            xlabel(' Theta [degree] ', 'FontWeight','bold');
            ylabel(' SF ', 'FontWeight','bold');
            ax = gca; ax.XScale = 'linear'; ax.YScale = 'log'; 
            axis([0, 90, app.y_lims]);
        end

        % Plot sensitivity factor versus photon energy
        function plot_sf_vs_phi(app)
            % - Initializing variables
            cl_color = read_spectroscopy_colors(app.selected_corelevel);
            cl_color = cl_color{1};
            % - Creating figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; 
            fig.Position(4) = 400;
            % - Creating tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Creating tile
            nexttile(); hold on; grid on; grid minor;
            plot(app.myAppData.phi_R, app.myAppData.sf_vs_phi,...
                '-', 'linewidth', 1.25,  'color', cl_color); 
            scatter(app.myAppData.phi, app.myAppData.sf,...
                'SizeData', 50, 'markeredgecolor', cl_color, 'markerfacecolor', cl_color, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            % - Formatting the axis
            legend(app.selected_corelevel, 'location', 'eastoutside', 'FontSize', 9);
            % - Labeling the x- and y-axes
            text(0.02, 0.05, sprintf("%s-%s(%s)", app.selected_material, app.selected_element, app.selected_corelevel),...
                'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold', 'HorizontalAlignment','left');
            xlabel(' Phi [degree] ', 'FontWeight','bold');
            ylabel(' SF ', 'FontWeight','bold');
            ax = gca; ax.XScale = 'linear'; ax.YScale = 'log'; 
            axis([0, 180, app.y_lims]);
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBaseX - XPS Sensitivity Factor Calculator (procopios.constantinou@psi.ch)';
            % 1 - Initializing fields
            % -- Coloring the edit-fields that can be changed
            edit_color = [0.85 1 1];
            app.Compounds_DropDown.BackgroundColor                  = edit_color;
            app.Geometry_theta_EditField.BackgroundColor            = edit_color;
            app.Geometry_phi_EditField.BackgroundColor              = edit_color;
            app.Geometry_P_EditField.BackgroundColor                = edit_color;
            app.IMFP_Formalism_DropDown.BackgroundColor             = edit_color;
            app.XSect_Formalism_DropDown.BackgroundColor            = edit_color;
            app.SelectedElement_DropDown.BackgroundColor            = edit_color;
            app.SelectedCoreLevel_DropDown.BackgroundColor          = edit_color;
            app.XSect_PhotonEnergy_EditField.BackgroundColor        = edit_color;
            app.SelectedMaterial_EditField.BackgroundColor          = edit_color;
            % 2 - Default parameters upon startup
            % --- Compounds
            initialize_compound_properties(app);
            % --- Experimental Geometry
            app.theta                               = app.Geometry_theta_EditField.Value;
            app.phi                                 = app.Geometry_phi_EditField.Value;
            app.P                                   = app.Geometry_P_EditField.Value;
            % -- IMFP parameters
            app.selected_material                   = "Si";
            list_of_imfp_formalisms                 = define_imfp_formalisms(app);
            app.IMFP_Formalism_DropDown.Items        = list_of_imfp_formalisms;
            app.IMFP_Formalism_DropDown.Value        = app.IMFP_Formalism_DropDown.Items{1};
            list_of_imfp_formalisms                 = define_imfp_formalism_id(app);
            app.formalism_imfp                      = list_of_imfp_formalisms(1);
            % -- XSECT parameters
            list_of_xsect_formalisms                = define_xsect_formalisms(app);
            app.XSect_Formalism_DropDown.Items      = list_of_xsect_formalisms;
            app.XSect_Formalism_DropDown.Value      = app.XSect_Formalism_DropDown.Items{1};
            list_of_xsect_formalisms                = define_xsect_formalism_id(app);
            app.formalism_xsect                     = list_of_xsect_formalisms(1);
            write_element_level_list(app);
            write_core_level_list(app);
            app.eph                                 = app.XSect_PhotonEnergy_EditField.Value;
            % -- Data structure
            app.myAppData       = struct();
            % 3 - Verify check
            PeriodicTableSingle_ButtonGroupSelectionChanged(app);
            app.SelectedCoreLevel_DropDown.Value    = app.SelectedCoreLevel_DropDown.Items{3};
            app.SelectedCoreLevel_DropDownValueChanged(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run MatBaseX;
            open_MainMenu_to_left_of_App(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run MatBaseX_06_XPSSensitivityFactor;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 970;
            screen_size(4) = 600;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('myAppData : '); disp(app.myAppData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            fname = sprintf("%s_%s%s_SF_hv%.0feV", app.selected_material, app.selected_element, app.selected_corelevel, app.eph);
            assignin('base', char(fname), app.myAppData);
        end

        % Selection changed function: PeriodicTableSingle_ButtonGroup
        function PeriodicTableSingle_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton          = app.PeriodicTableSingle_ButtonGroup.SelectedObject;
            app.selected_material   = selectedButton.Text;
            % 2 - Updating the relevant UI components
            app.SelectedMaterial_EditField.Value = app.selected_material;
            % 3 - Update the values
            state_change_of_material(app);
        end

        % Value changed function: Compounds_DropDown
        function Compounds_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value                   = app.Compounds_DropDown.Value;
            app.selected_material   = extractAfter(value, "-");
            % 2 - Updating the relevant UI components
            app.SelectedMaterial_EditField.Value = app.selected_material;
            % 3 - Update Functions
            state_change_of_material(app);
        end

        % Value changed function: SelectedMaterial_EditField
        function SelectedMaterial_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value                       = app.SelectedMaterial_EditField.Value;
            app.selected_material       = string(value);
            fprintf("--> Material: " + string(app.selected_material) + "\n");
            % 2 - Validity check on the material
            background_col = [0.85 1 1];
            app.SelectedMaterial_EditField.BackgroundColor = background_col;
            % -- Checking if an identical element exists
            element_names   = read_mpd_elements();
            element_idx     = find(contains(string(element_names),app.selected_material), 1);
            if ~isempty(element_idx)
                name = char(sprintf("%s_Button", element_names{element_idx}));
                app.(name).Value = 1;
                PeriodicTable_ButtonGroupSelectionChanged(app, event);
            else
                % -- Checking if an identical compound exists
                compound_names  = extract_compound_names(app);
                compound_idx    = find(contains(string(compound_names),app.selected_material), 1);
                if ~isempty(compound_idx)
                    app.Compounds_DropDown.Value = app.Compounds_DropDown.Items{compound_idx};
                    Compounds_DropDownValueChanged(app, event);
                else
                    background_col = [1 1 0.85];
                    app.SelectedMaterial_EditField.BackgroundColor = background_col;
                end
            end
        end

        % Value changed function: Geometry_theta_EditField
        function Geometry_theta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.Geometry_theta_EditField.Value;
            app.theta   = value;
            % 2 - Update Functions
            calculate_sf(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: Geometry_phi_EditField
        function Geometry_phi_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.Geometry_phi_EditField.Value;
            app.phi     = value;
            % 2 - Update Functions
            calculate_sf(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: Geometry_P_EditField
        function Geometry_P_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.Geometry_P_EditField.Value;
            app.P       = value;
            % 2 - Update Functions
            calculate_sf(app);
            % 3 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: IMFP_Formalism_DropDown
        function IMFP_Formalism_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.IMFP_Formalism_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the formalism selected
            list_of_formalisms_id = define_imfp_formalism_id(app);
            if strcmpi(value,app.IMFP_Formalism_DropDown.Items{1});          value = list_of_formalisms_id(1);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{2});      value = list_of_formalisms_id(2);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{3});      value = list_of_formalisms_id(3);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{4});      value = list_of_formalisms_id(4);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{5});      value = list_of_formalisms_id(5);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{6});      value = list_of_formalisms_id(6);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{7});      value = list_of_formalisms_id(7);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{8});      value = list_of_formalisms_id(8);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{9});      value = list_of_formalisms_id(9);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{10});     value = list_of_formalisms_id(10);
            end
            % 4 - Assigning the output
            app.formalism_imfp = value;
            % 5 - Update Functions
            calculate_sf(app);
            % 6 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: XSect_Formalism_DropDown
        function XSect_Formalism_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XSect_Formalism_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the formalism selected
            list_of_formalisms_id = define_xsect_formalism_id(app);
            if strcmpi(value,app.XSect_Formalism_DropDown.Items{1});          value = list_of_formalisms_id(1);
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{2});      value = list_of_formalisms_id(2);
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{3});      value = list_of_formalisms_id(3);
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{4});      value = list_of_formalisms_id(4);
            end
            % 4 - Assigning the output
            app.formalism_xsect = value;
            % 5 - Update Functions
            calculate_sf(app);
            % 6 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: SelectedElement_DropDown
        function SelectedElement_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.SelectedElement_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the input
            app.selected_element = value;
            % 3 - Update Functions
            write_core_level_list(app);
            SelectedCoreLevel_DropDownValueChanged(app);
        end

        % Value changed function: SelectedCoreLevel_DropDown
        function SelectedCoreLevel_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.SelectedCoreLevel_DropDown.Value;
            value = string(extractBefore(value, "-"));
            % 2 - Validity check on the input
            app.selected_corelevel = value;
            % 3 - Validity check on the photon energy
            [binding_energy, ~] = calc_be(app.selected_element, app.selected_corelevel);
            if app.eph < binding_energy
                app.eph = binding_energy + 50;
                app.XSect_PhotonEnergy_EditField.Value = app.eph;
            end
            % 4 - Update Functions
            calculate_sf(app);
            % 5 - Appending results to data-structure
            write_app_data(app);
        end

        % Value changed function: XSect_PhotonEnergy_EditField
        function XSect_PhotonEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XSect_PhotonEnergy_EditField.Value;
            % 2 - Validity check on the input value
            [binding_energy, ~] = calc_be(app.selected_element, app.selected_corelevel);
            if isempty(value) || isnan(value) || value < binding_energy
                value = binding_energy + 50;
            end
            app.eph = value;
            app.XSect_PhotonEnergy_EditField.Value = app.eph;
            % 3 - Update Functions
            calculate_sf(app);
            % 4 - Appending results to data-structure
            write_app_data(app);
        end

        % Button pushed function: SF_vs_PhotonEnergy_Plot_Button
        function SF_vs_PhotonEnergy_Plot_ButtonPushed(app, event)
            plot_sf_vs_hv(app);
        end

        % Button pushed function: SF_vs_Theta_Plot_Button
        function SF_vs_Theta_Plot_ButtonPushed(app, event)
            plot_sf_vs_theta(app);
        end

        % Button pushed function: SF_vs_Phi_Plot_Button
        function SF_vs_Phi_Plot_ButtonPushed(app, event)
            plot_sf_vs_phi(app);
        end

        % Button pushed function: AngularAnisotropy_Plot_Button
        function AngularAnisotropy_Plot_ButtonPushed(app, event)
            view_angle_aniso(app.selected_element, app.selected_corelevel, app.eph, app.P, app.formalism_xsect);
            % - Shifting figure to the right of the app
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
        end

        % Button pushed function: SF_vs_PhotonEnergy_Export_Button
        function SF_vs_PhotonEnergy_Export_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            temp_form = char(app.formalism_xsect); temp_form(temp_form=='(') = []; temp_form(temp_form==')') = [];
            fname = sprintf("%s_%s%s_%s_SF_hv", app.selected_material, app.selected_element, app.selected_corelevel, temp_form);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("-- INPUTS -- \n");
            info = info + sprintf("Material:\t\t%s\n", app.selected_material);
            info = info + sprintf("Element:\t\t%s\n", app.selected_element);
            info = info + sprintf("Core-Level:\t\t%s\n", app.selected_corelevel);
            info = info + sprintf("Formalism (XSECT):\t%s\n", app.formalism_xsect);
            info = info + sprintf("Formalism (IMFP):\t%s\n", app.formalism_imfp);
            info = info + sprintf("eph:\t\t\t%.4e eV\n", app.eph);
            info = info + sprintf("theta:\t\t\t%.4e degree\n", app.theta);
            info = info + sprintf("phi:\t\t\t%.4e degree\n", app.phi);
            info = info + sprintf("P:\t\t\t%.4e\n", app.P);
            info = info + sprintf("-- IMFP OUTPUTS -- \n");
            info = info + sprintf("ke:\t\t\t%.4e eV\n", app.sfParams.ke);
            info = info + sprintf("imfp:\t\t\t%.4e Angstrom\n", app.sfParams.imfp);
            info = info + sprintf("-- XSECT OUTPUTS -- \n");
            info = info + sprintf("sigma:\t\t\t%.4e barn/atom\n", app.sfParams.sigma);
            info = info + sprintf("beta:\t\t\t%.4e\n", app.sfParams.beta);
            info = info + sprintf("gamma:\t\t\t%s\n", app.sfParams.gamma);
            info = info + sprintf("delta:\t\t\t%.4e\n", app.sfParams.delta);
            info = info + sprintf("FP:\t\t\t%.4e\n", app.sfParams.FP);
            info = info + sprintf("sf:\t\t\t%.4e eV\n", app.sf);
            info = info + sprintf("tot_sf:\t\t\t%.4e eV\n", app.tot_sf);
            info = info + sprintf("rsf:\t\t\t%.4e (relative to C1s)\n", app.rsf);
            info = info + sprintf("tot_rsf:\t\t%.4e (relative to C1s)\n", app.tot_rsf);
            info = info + sprintf("\n-- TABLE OF DATA -- \n");
            % Sample data
            Eph_eV      = app.myAppData.eph_R;
            SF          = app.myAppData.sf_vs_hv;
            SF_tot_orb  = app.myAppData.tot_sf_vs_hv;
            T_Data = table(Eph_eV,SF,SF_tot_orb);
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: SF_vs_Theta_Export_Button
        function SF_vs_Theta_Export_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            temp_form = char(app.formalism_xsect); temp_form(temp_form=='(') = []; temp_form(temp_form==')') = [];
            fname = sprintf("%s_%s%s_%s_SF_theta", app.selected_material, app.selected_element, app.selected_corelevel, temp_form);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("-- INPUTS -- \n");
            info = info + sprintf("Material:\t\t%s\n", app.selected_material);
            info = info + sprintf("Element:\t\t%s\n", app.selected_element);
            info = info + sprintf("Core-Level:\t\t%s\n", app.selected_corelevel);
            info = info + sprintf("Formalism (XSECT):\t%s\n", app.formalism_xsect);
            info = info + sprintf("Formalism (IMFP):\t%s\n", app.formalism_imfp);
            info = info + sprintf("eph:\t\t\t%.4e eV\n", app.eph);
            info = info + sprintf("theta:\t\t\t%.4e degree\n", app.theta);
            info = info + sprintf("phi:\t\t\t%.4e degree\n", app.phi);
            info = info + sprintf("P:\t\t\t%.4e\n", app.P);
            info = info + sprintf("-- IMFP OUTPUTS -- \n");
            info = info + sprintf("ke:\t\t\t%.4e eV\n", app.sfParams.ke);
            info = info + sprintf("imfp:\t\t\t%.4e Angstrom\n", app.sfParams.imfp);
            info = info + sprintf("-- XSECT OUTPUTS -- \n");
            info = info + sprintf("sigma:\t\t\t%.4e barn/atom\n", app.sfParams.sigma);
            info = info + sprintf("beta:\t\t\t%.4e\n", app.sfParams.beta);
            info = info + sprintf("gamma:\t\t\t%s\n", app.sfParams.gamma);
            info = info + sprintf("delta:\t\t\t%.4e\n", app.sfParams.delta);
            info = info + sprintf("FP:\t\t\t%.4e\n", app.sfParams.FP);
            info = info + sprintf("sf:\t\t\t%.4e eV\n", app.sf);
            info = info + sprintf("tot_sf:\t\t\t%.4e eV\n", app.tot_sf);
            info = info + sprintf("rsf:\t\t\t%.4e (relative to C1s)\n", app.rsf);
            info = info + sprintf("tot_rsf:\t\t%.4e (relative to C1s)\n", app.tot_rsf);
            info = info + sprintf("\n-- TABLE OF DATA -- \n");
            % Sample data
            Theta_deg       = app.myAppData.theta_R;
            SF              = app.myAppData.sf_vs_theta;
            SF_tot_orb      = app.myAppData.tot_sf_vs_theta;
            T_Data = table(Theta_deg,SF,SF_tot_orb);
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: SF_vs_Phi_Export_Button
        function SF_vs_Phi_Export_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            temp_form = char(app.formalism_xsect); temp_form(temp_form=='(') = []; temp_form(temp_form==')') = [];
            fname = sprintf("%s_%s%s_%s_SF_phi", app.selected_material, app.selected_element, app.selected_corelevel, temp_form);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("-- INPUTS -- \n");
            info = info + sprintf("Material:\t\t%s\n", app.selected_material);
            info = info + sprintf("Element:\t\t%s\n", app.selected_element);
            info = info + sprintf("Core-Level:\t\t%s\n", app.selected_corelevel);
            info = info + sprintf("Formalism (XSECT):\t%s\n", app.formalism_xsect);
            info = info + sprintf("Formalism (IMFP):\t%s\n", app.formalism_imfp);
            info = info + sprintf("eph:\t\t\t%.4e eV\n", app.eph);
            info = info + sprintf("theta:\t\t\t%.4e degree\n", app.theta);
            info = info + sprintf("phi:\t\t\t%.4e degree\n", app.phi);
            info = info + sprintf("P:\t\t\t%.4e\n", app.P);
            info = info + sprintf("-- IMFP OUTPUTS -- \n");
            info = info + sprintf("ke:\t\t\t%.4e eV\n", app.sfParams.ke);
            info = info + sprintf("imfp:\t\t\t%.4e Angstrom\n", app.sfParams.imfp);
            info = info + sprintf("-- XSECT OUTPUTS -- \n");
            info = info + sprintf("sigma:\t\t\t%.4e barn/atom\n", app.sfParams.sigma);
            info = info + sprintf("beta:\t\t\t%.4e\n", app.sfParams.beta);
            info = info + sprintf("gamma:\t\t\t%s\n", app.sfParams.gamma);
            info = info + sprintf("delta:\t\t\t%.4e\n", app.sfParams.delta);
            info = info + sprintf("FP:\t\t\t%.4e\n", app.sfParams.FP);
            info = info + sprintf("sf:\t\t\t%.4e eV\n", app.sf);
            info = info + sprintf("tot_sf:\t\t\t%.4e eV\n", app.tot_sf);
            info = info + sprintf("rsf:\t\t\t%.4e (relative to C1s)\n", app.rsf);
            info = info + sprintf("tot_rsf:\t\t%.4e (relative to C1s)\n", app.tot_rsf);
            info = info + sprintf("\n-- TABLE OF DATA -- \n");
            % Sample data
            Phi_deg         = app.myAppData.phi_R;
            SF              = app.myAppData.sf_vs_phi;
            SF_tot_orb      = app.myAppData.tot_sf_vs_phi;
            T_Data = table(Phi_deg,SF,SF_tot_orb);
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: SF_SaveAllData_Button
        function SF_SaveAllData_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.mat'};
            temp_form = char(app.formalism_xsect); temp_form(temp_form=='(') = []; temp_form(temp_form==')') = [];
            fname = sprintf("%s_%s%s_%s_SF", app.selected_material, app.selected_element, app.selected_corelevel, temp_form);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            structName          = char(save_filename(1:end-4));
            eval([structName ' = app.myAppData;']);     % Create the data structure dynamically
            % Saving the data as .mat
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved data : '); display(app.myAppData);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'XPS Sensitivity Factor Calculator';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart this App.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize this App to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all App information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer App Data directly to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Calculate the XPS Sensitivity Factor (SF) using all the IMFP, photoionization cross-sections, and asymmetry databases. Customize the experimental geometry, photon polarization, and formalisms for IMFP and cross-section determination. Select core-levels and generate sensitivity factor plots against photon energy and emission angle with a single click. Indispensable for precise, quantitative XPS analysis!'};
            app.Image.ImageSource = 'Icon_SensitivityFactorXPS.jpeg';
            app.PeriodicTableSingle_ButtonGroup = uibuttongroup(app.GridLayout);
            app.PeriodicTableSingle_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriodicTableSingle_ButtonGroupSelectionChanged, true);
            app.PeriodicTableSingle_ButtonGroup.Tooltip = {'Select an element.'};
            app.Label = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label.Text = '1';
            app.Label_18 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_18.Text = '18';
            app.H_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.H_Button.Text = 'H';
            app.Label_2 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_2.Text = '2';
            app.Label_13 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_13.Text = '13';
            app.Label_14 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_14.Text = '14';
            app.Label_15 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_15.Text = '15';
            app.Label_16 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_16.Text = '16';
            app.Label_17 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_17.Text = '17';
            app.He_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.He_Button.Text = 'He';
            app.Li_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Li_Button.Text = 'Li';
            app.Be_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Be_Button.Text = 'Be';
            app.B_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.B_Button.Text = 'B';
            app.C_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.C_Button.Text = 'C';
            app.N_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.N_Button.Text = 'N';
            app.O_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.O_Button.Text = 'O';
            app.F_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.F_Button.Text = 'F';
            app.Ne_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ne_Button.Text = 'Ne';
            app.Na_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Na_Button.Text = 'Na';
            app.Mg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mg_Button.Text = 'Mg';
            app.Label_3 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_12.Text = '12';
            app.Al_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Al_Button.Text = 'Al';
            app.Si_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Si_Button.Text = 'Si';
            app.Si_Button.Value = true;
            app.P_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.P_Button.Text = 'P';
            app.S_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.S_Button.Text = 'S';
            app.Cl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cl_Button.Text = 'Cl';
            app.Ar_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ar_Button.Text = 'Ar';
            app.K_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.K_Button.Text = 'K';
            app.Ca_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ca_Button.Text = 'Ca';
            app.Sc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sc_Button.Text = 'Sc';
            app.Ti_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ti_Button.Text = 'Ti';
            app.V_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.V_Button.Text = 'V';
            app.Cr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cr_Button.Text = 'Cr';
            app.Mn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mn_Button.Text = 'Mn';
            app.Fe_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fe_Button.Text = 'Fe';
            app.Co_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Co_Button.Text = 'Co';
            app.Ni_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ni_Button.Text = 'Ni';
            app.Cu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cu_Button.Text = 'Cu';
            app.Zn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Zn_Button.Text = 'Zn';
            app.Ga_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ga_Button.Text = 'Ga';
            app.Ge_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ge_Button.Text = 'Ge';
            app.As_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.As_Button.Text = 'As';
            app.Se_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Se_Button.Text = 'Se';
            app.Br_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Br_Button.Text = 'Br';
            app.Kr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Kr_Button.Text = 'Kr';
            app.Rb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rb_Button.Text = 'Rb';
            app.Sr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sr_Button.Text = 'Sr';
            app.Y_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Y_Button.Text = 'Y';
            app.Zr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Zr_Button.Text = 'Zr';
            app.Nb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nb_Button.Text = 'Nb';
            app.Mo_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mo_Button.Text = 'Mo';
            app.Tc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tc_Button.Text = 'Tc';
            app.Ru_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ru_Button.Text = 'Ru';
            app.Rh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rh_Button.Text = 'Rh';
            app.Pd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pd_Button.Text = 'Pd';
            app.Ag_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ag_Button.Text = 'Ag';
            app.Cd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cd_Button.Text = 'Cd';
            app.In_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.In_Button.Text = 'In';
            app.Sn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sn_Button.Text = 'Sn';
            app.Sb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sb_Button.Text = 'Sb';
            app.Te_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Te_Button.Text = 'Te';
            app.I_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.I_Button.Text = 'I';
            app.Xe_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Xe_Button.Text = 'Xe';
            app.Cs_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cs_Button.Text = 'Cs';
            app.Ba_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ba_Button.Text = 'Ba';
            app.La_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.La_Button.Text = 'La';
            app.Hf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hf_Button.Text = 'Hf';
            app.Ta_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ta_Button.Text = 'Ta';
            app.W_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.W_Button.Text = 'W';
            app.Re_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Re_Button.Text = 'Re';
            app.Os_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Os_Button.Text = 'Os';
            app.Ir_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ir_Button.Text = 'Ir';
            app.Pt_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pt_Button.Text = 'Pt';
            app.Au_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Au_Button.Text = 'Au';
            app.Hg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hg_Button.Text = 'Hg';
            app.Tl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tl_Button.Text = 'Tl';
            app.Pb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pb_Button.Text = 'Pb';
            app.Bi_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bi_Button.Text = 'Bi';
            app.Po_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Po_Button.Text = 'Po';
            app.At_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.At_Button.Text = 'At';
            app.Rn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rn_Button.Text = 'Rn';
            app.Fr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fr_Button.Text = 'Fr';
            app.Ra_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ra_Button.Text = 'Ra';
            app.Ac_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ac_Button.Text = 'Ac';
            app.Rf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rf_Button.Enable = 'off';
            app.Rf_Button.Text = 'Rf';
            app.Db_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Db_Button.Enable = 'off';
            app.Db_Button.Text = 'Db';
            app.Sg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sg_Button.Enable = 'off';
            app.Sg_Button.Text = 'Sg';
            app.Bh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bh_Button.Enable = 'off';
            app.Bh_Button.Text = 'Bh';
            app.Hs_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hs_Button.Enable = 'off';
            app.Hs_Button.Text = 'Hs';
            app.Mt_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mt_Button.Enable = 'off';
            app.Mt_Button.Text = 'Mt';
            app.Ds_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ds_Button.Enable = 'off';
            app.Ds_Button.Text = 'Ds';
            app.Rg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rg_Button.Enable = 'off';
            app.Rg_Button.Text = 'Rg';
            app.Cn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cn_Button.Enable = 'off';
            app.Cn_Button.Text = 'Cn';
            app.Nh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nh_Button.Enable = 'off';
            app.Nh_Button.Text = 'Nh';
            app.Fl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fl_Button.Enable = 'off';
            app.Fl_Button.Text = 'Fl';
            app.Mc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mc_Button.Enable = 'off';
            app.Mc_Button.Text = 'Mc';
            app.Lv_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lv_Button.Enable = 'off';
            app.Lv_Button.Text = 'Lv';
            app.Ts_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ts_Button.Enable = 'off';
            app.Ts_Button.Text = 'Ts';
            app.Og_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Og_Button.Enable = 'off';
            app.Og_Button.Text = 'Og';
            app.Ce_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ce_Button.Text = 'Ce';
            app.Pr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pr_Button.Text = 'Pr';
            app.Nd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nd_Button.Text = 'Nd';
            app.Pm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pm_Button.Text = 'Pm';
            app.Sm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sm_Button.Text = 'Sm';
            app.Eu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Eu_Button.Text = 'Eu';
            app.Gd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Gd_Button.Text = 'Gd';
            app.Tb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tb_Button.Text = 'Tb';
            app.Dy_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Dy_Button.Text = 'Dy';
            app.Ho_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ho_Button.Text = 'Ho';
            app.Er_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Er_Button.Text = 'Er';
            app.Tm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tm_Button.Text = 'Tm';
            app.Yb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Yb_Button.Text = 'Yb';
            app.Lu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lu_Button.Text = 'Lu';
            app.Th_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Th_Button.Text = 'Th';
            app.Pa_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pa_Button.Text = 'Pa';
            app.U_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.U_Button.Text = 'U';
            app.Np_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Np_Button.Text = 'Np';
            app.Pu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pu_Button.Text = 'Pu';
            app.Am_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Am_Button.Text = 'Am';
            app.Cm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cm_Button.Text = 'Cm';
            app.Bk_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bk_Button.Text = 'Bk';
            app.Cf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cf_Button.Text = 'Cf';
            app.Es_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Es_Button.Enable = 'off';
            app.Es_Button.Text = 'Es';
            app.Fm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fm_Button.Enable = 'off';
            app.Fm_Button.Text = 'Fm';
            app.Md_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Md_Button.Enable = 'off';
            app.Md_Button.Text = 'Md';
            app.No_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.No_Button.Enable = 'off';
            app.No_Button.Text = 'No';
            app.Lr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lr_Button.Enable = 'off';
            app.Lr_Button.Text = 'Lr';
            app.AvailableCompoundsLabel = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.AvailableCompoundsLabel.Text = 'Available Compounds:';
            app.Compounds_DropDown = uidropdown(app.PeriodicTableSingle_ButtonGroup);
            app.Compounds_DropDown.ValueChangedFcn = createCallbackFcn(app, @Compounds_DropDownValueChanged, true);
            app.Compounds_DropDown.Tooltip = {'Select an available compound from the list taken from the Materials Properties Database.'};
            app.Geometry_Panel = uipanel(app.GridLayout);
            app.Geometry_Panel.Title = 'Experimental Geometry';
            app.Geometry_GridLayout = uigridlayout(app.Geometry_Panel);
            app.Geometry_Figure = uiimage(app.Geometry_GridLayout);
            app.Geometry_Figure.Tooltip = {'Image of the XPS experimental geometry.'};
            app.Geometry_Figure.ImageSource = 'XPS_Geometry-01.png';
            app.Geometry_Label = uilabel(app.Geometry_GridLayout);
            app.Geometry_Label.Text = 'Diagram illustrating the angles involved in the calculation of asymmetry effects, where hv denotes the direction of photon flux, E denotes the direction of polarisation, e denotes the direction of photoemission, and θ and ϕ refer to the angles for anisotropic emission of electrons.';
            app.phidegLabel_3 = uilabel(app.Geometry_GridLayout);
            app.phidegLabel_3.Text = 'theta (degree):';
            app.Geometry_theta_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.Geometry_theta_EditField.Limits = [0 90];
            app.Geometry_theta_EditField.ValueDisplayFormat = '%.2f';
            app.Geometry_theta_EditField.ValueChangedFcn = createCallbackFcn(app, @Geometry_theta_EditFieldValueChanged, true);
            app.Geometry_theta_EditField.Tooltip = {'The polar angle (in degrees) between the photoelectron vector relative to electric field vector (i.e. at normal emission: LV (p-pol, E//MP) = 0, LH (s-pol, E⊥MP) = 90)'};
            app.phidegLabel = uilabel(app.Geometry_GridLayout);
            app.phidegLabel.Text = 'phi (degree):';
            app.Geometry_phi_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.Geometry_phi_EditField.Limits = [0 180];
            app.Geometry_phi_EditField.ValueDisplayFormat = '%.2f';
            app.Geometry_phi_EditField.ValueChangedFcn = createCallbackFcn(app, @Geometry_phi_EditFieldValueChanged, true);
            app.Geometry_phi_EditField.Tooltip = {'The azimuthal angle (in degrees) between the photon momentum vector relative to the projection of the photoelectron vector on to the plane perpendicular to the electric field vector (i.e. normal emission = 0)'};
            app.PEditFieldLabel = uilabel(app.Geometry_GridLayout);
            app.PEditFieldLabel.Text = 'P:';
            app.Geometry_P_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.Geometry_P_EditField.Limits = [0 1];
            app.Geometry_P_EditField.ValueDisplayFormat = '%.2f';
            app.Geometry_P_EditField.ValueChangedFcn = createCallbackFcn(app, @Geometry_P_EditFieldValueChanged, true);
            app.Geometry_P_EditField.Tooltip = {'Scalar of degree of polarization, where 1 or 0 is equivalent to full linear polarization, and 0.5 is equivalent to unpolarized light.'};
            app.Geometry_P_EditField.Value = 1;
            app.SF_Equation_Label = uilabel(app.Geometry_GridLayout);
            app.SF_Equation_Label.Interpreter = 'latex';
            app.SF_Equation_Label.Text = 'SF = σ\cdot λ\cdot F(β,γ,δ)';
            app.XSect_Panel = uipanel(app.GridLayout);
            app.XSect_Panel.Title = 'Photoionization Cross-Sections & Asymmetry Parameters';
            app.XSect_GridLayout = uigridlayout(app.XSect_Panel);
            app.XSectFormalismLabel = uilabel(app.XSect_GridLayout);
            app.XSectFormalismLabel.Text = 'XSect Formalism:';
            app.XSect_Formalism_DropDown = uidropdown(app.XSect_GridLayout);
            app.XSect_Formalism_DropDown.Items = {'Cant(2022) - 1500 to 10000 eV', 'Trzhaskovskaya(2018) - 1500 to 10000 eV', 'Yeh Lindau(1985) - 10 to 1500 eV', 'Scofield(1973) - 1 to 1500 keV'};
            app.XSect_Formalism_DropDown.ValueChangedFcn = createCallbackFcn(app, @XSect_Formalism_DropDownValueChanged, true);
            app.XSect_Formalism_DropDown.Tooltip = {'Select the formalism to calculate the photoionisation cross-section and asymmetry parameters.'};
            app.XSect_Formalism_DropDown.Value = 'Cant(2022) - 1500 to 10000 eV';
            app.CoreLevelDropDownLabel = uilabel(app.XSect_GridLayout);
            app.CoreLevelDropDownLabel.Text = 'Core-Level:';
            app.SelectedCoreLevel_DropDown = uidropdown(app.XSect_GridLayout);
            app.SelectedCoreLevel_DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedCoreLevel_DropDownValueChanged, true);
            app.SelectedCoreLevel_DropDown.Tooltip = {'Select an available core-level for the selected element.'};
            app.PhotonEnergyeVEditFieldLabel = uilabel(app.XSect_GridLayout);
            app.PhotonEnergyeVEditFieldLabel.Text = 'Photon Energy (eV):';
            app.XSect_PhotonEnergy_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_PhotonEnergy_EditField.Limits = [0 Inf];
            app.XSect_PhotonEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.XSect_PhotonEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @XSect_PhotonEnergy_EditFieldValueChanged, true);
            app.XSect_PhotonEnergy_EditField.Tooltip = {'Incident photon energy in eV.'};
            app.XSect_PhotonEnergy_EditField.Value = 2000;
            app.SigmabarnatomLabel = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel.Text = 'Sigma (barn/atom):';
            app.XSect_Sigma_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_Sigma_EditField.ValueDisplayFormat = '%.4e';
            app.XSect_Sigma_EditField.AllowEmpty = 'on';
            app.XSect_Sigma_EditField.Editable = 'off';
            app.XSect_Sigma_EditField.Tooltip = {'Sigma subshell photoionization cross section in units of barn/atom.'};
            app.SigmabarnatomLabel_2 = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel_2.Text = 'Beta:';
            app.XSect_Beta_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_Beta_EditField.ValueDisplayFormat = '%.4f';
            app.XSect_Beta_EditField.AllowEmpty = 'on';
            app.XSect_Beta_EditField.Editable = 'off';
            app.XSect_Beta_EditField.Tooltip = {'Beta (dipole) parameter of the photoelectron angular distribution.'};
            app.SigmabarnatomLabel_3 = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel_3.Text = 'Gamma:';
            app.XSect_Gamma_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_Gamma_EditField.ValueDisplayFormat = '%.4f';
            app.XSect_Gamma_EditField.AllowEmpty = 'on';
            app.XSect_Gamma_EditField.Editable = 'off';
            app.XSect_Gamma_EditField.Tooltip = {'Gamma (non-dipole) parameter of the photoelectron angular distribution.'};
            app.SigmabarnatomLabel_4 = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel_4.Text = 'Delta:';
            app.XSect_Delta_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_Delta_EditField.ValueDisplayFormat = '%.4f';
            app.XSect_Delta_EditField.AllowEmpty = 'on';
            app.XSect_Delta_EditField.Editable = 'off';
            app.XSect_Delta_EditField.Tooltip = {'Delta (non-dipole) parameter of the photoelectron angular distribution.'};
            app.ElementLabel_2 = uilabel(app.XSect_GridLayout);
            app.ElementLabel_2.Text = 'Element:';
            app.SelectedElement_DropDown = uidropdown(app.XSect_GridLayout);
            app.SelectedElement_DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedElement_DropDownValueChanged, true);
            app.SelectedElement_DropDown.Tooltip = {'Choose an element that is contained in the selected material.'};
            app.SigmabarnatomLabel_7 = uilabel(app.XSect_GridLayout);
            app.SigmabarnatomLabel_7.Text = 'F(β,γ,δ): ';
            app.XSect_FP_EditField = uieditfield(app.XSect_GridLayout, 'numeric');
            app.XSect_FP_EditField.ValueDisplayFormat = '%.4f';
            app.XSect_FP_EditField.AllowEmpty = 'on';
            app.XSect_FP_EditField.Editable = 'off';
            app.XSect_FP_EditField.Tooltip = {'Angular Anisotropy parameter.'};
            app.SF_Panel = uipanel(app.GridLayout);
            app.SF_Panel.Title = 'Sensitivity Factor';
            app.SF_GridLayout = uigridlayout(app.SF_Panel);
            app.SigmabarnatomLabel_6 = uilabel(app.SF_GridLayout);
            app.SigmabarnatomLabel_6.Text = 'SF:';
            app.SF_EditField = uieditfield(app.SF_GridLayout, 'numeric');
            app.SF_EditField.ValueDisplayFormat = '%.4e';
            app.SF_EditField.AllowEmpty = 'on';
            app.SF_EditField.Editable = 'off';
            app.SF_EditField.Tooltip = {'Output of the Sensitivity Factor (SF)'};
            app.SigmabarnatomLabel_9 = uilabel(app.SF_GridLayout);
            app.SigmabarnatomLabel_9.Text = 'tot. orb. SF:';
            app.TotalSF_EditField = uieditfield(app.SF_GridLayout, 'numeric');
            app.TotalSF_EditField.ValueDisplayFormat = '%.4e';
            app.TotalSF_EditField.AllowEmpty = 'on';
            app.TotalSF_EditField.Editable = 'off';
            app.TotalSF_EditField.Tooltip = {'Total Orbital Contribution (s,p,d,f) of the Sensitivity Factor (SF).'};
            app.SigmabarnatomLabel_10 = uilabel(app.SF_GridLayout);
            app.SigmabarnatomLabel_10.Text = 'tot. orb. RSF(C1s):';
            app.TotalRSF_EditField = uieditfield(app.SF_GridLayout, 'numeric');
            app.TotalRSF_EditField.ValueDisplayFormat = '%.4e';
            app.TotalRSF_EditField.AllowEmpty = 'on';
            app.TotalRSF_EditField.Editable = 'off';
            app.TotalRSF_EditField.Tooltip = {'Total Orbital Contribution (s,p,d,f) of the Relative Sensitivity Factor (RSF).'};
            app.GridLayout2 = uigridlayout(app.SF_GridLayout);
            app.SF_vs_PhotonEnergy_Plot_Button = uibutton(app.GridLayout2, 'push');
            app.SF_vs_PhotonEnergy_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @SF_vs_PhotonEnergy_Plot_ButtonPushed, true);
            app.SF_vs_PhotonEnergy_Plot_Button.Tooltip = {'Plot the sensitivity factor (SF) versus photon energy.'};
            app.SF_vs_PhotonEnergy_Plot_Button.Text = 'Plot SF Vs Photon Energy';
            app.SF_vs_Phi_Plot_Button = uibutton(app.GridLayout2, 'push');
            app.SF_vs_Phi_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @SF_vs_Phi_Plot_ButtonPushed, true);
            app.SF_vs_Phi_Plot_Button.Tooltip = {'Plot the sensitivity factor (SF) versus phi.'};
            app.SF_vs_Phi_Plot_Button.Text = 'Plot SF Vs Phi';
            app.SF_vs_Theta_Plot_Button = uibutton(app.GridLayout2, 'push');
            app.SF_vs_Theta_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @SF_vs_Theta_Plot_ButtonPushed, true);
            app.SF_vs_Theta_Plot_Button.Tooltip = {'Plot the sensitivity factor (SF) versus theta.'};
            app.SF_vs_Theta_Plot_Button.Text = 'Plot SF Vs Theta';
            app.AngularAnisotropy_Plot_Button = uibutton(app.GridLayout2, 'push');
            app.AngularAnisotropy_Plot_Button.ButtonPushedFcn = createCallbackFcn(app, @AngularAnisotropy_Plot_ButtonPushed, true);
            app.AngularAnisotropy_Plot_Button.Tooltip = {'Plot the Angular Anisotropy parameter, F, as a polar plot of theta and phi.'};
            app.AngularAnisotropy_Plot_Button.Text = {'Plot Angular Anisotropy '; '(F(β,γ,δ))'};
            app.SF_vs_PhotonEnergy_Export_Button = uibutton(app.GridLayout2, 'push');
            app.SF_vs_PhotonEnergy_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @SF_vs_PhotonEnergy_Export_ButtonPushed, true);
            app.SF_vs_PhotonEnergy_Export_Button.Tooltip = {'Export data as a text-file (.txt); the Sensitivity Factor (SF) is ssaved versus photon energy.'};
            app.SF_vs_PhotonEnergy_Export_Button.Text = {'Export Photon Energy '; 'Data (*.txt)'};
            app.SF_vs_Phi_Export_Button = uibutton(app.GridLayout2, 'push');
            app.SF_vs_Phi_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @SF_vs_Phi_Export_ButtonPushed, true);
            app.SF_vs_Phi_Export_Button.Tooltip = {'Export data as a text-file (.txt); the Sensitivity Factor (SF) is ssaved versus phi.'};
            app.SF_vs_Phi_Export_Button.Text = 'Export Phi Data (*.txt)';
            app.SF_SaveAllData_Button = uibutton(app.GridLayout2, 'push');
            app.SF_SaveAllData_Button.ButtonPushedFcn = createCallbackFcn(app, @SF_SaveAllData_ButtonPushed, true);
            app.SF_SaveAllData_Button.Tooltip = {'Save data as a MATLAB Structure (.mat).'};
            app.SF_SaveAllData_Button.Text = 'Save All Data (*.mat)';
            app.SF_vs_Theta_Export_Button = uibutton(app.GridLayout2, 'push');
            app.SF_vs_Theta_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @SF_vs_Theta_Export_ButtonPushed, true);
            app.SF_vs_Theta_Export_Button.Tooltip = {'Export data as a text-file (.txt); the Sensitivity Factor (SF) is ssaved versus theta.'};
            app.SF_vs_Theta_Export_Button.Text = 'Export Theta Data (*.txt)';
            app.SigmabarnatomLabel_8 = uilabel(app.SF_GridLayout);
            app.SigmabarnatomLabel_8.Text = 'RSF(C1s):';
            app.RSF_EditField = uieditfield(app.SF_GridLayout, 'numeric');
            app.RSF_EditField.ValueDisplayFormat = '%.4e';
            app.RSF_EditField.AllowEmpty = 'on';
            app.RSF_EditField.Editable = 'off';
            app.RSF_EditField.Tooltip = {'Output of the Relative Sensitivity Factor (RSF), measured relative to the C(1s) core-level.'};
            app.IMFPParams_Panel = uipanel(app.GridLayout);
            app.IMFPParams_Panel.Title = 'Inelastic Mean Free Path Parameters';
            app.IMFPParams_GridLayout = uigridlayout(app.IMFPParams_Panel);
            app.IMFPFormalismDropDownLabel = uilabel(app.IMFPParams_GridLayout);
            app.IMFPFormalismDropDownLabel.Text = 'IMFP Formalism:';
            app.IMFP_Formalism_DropDown = uidropdown(app.IMFPParams_GridLayout);
            app.IMFP_Formalism_DropDown.Items = {'Universal', 'Optical(NIST)', 'TPP-2M', 'TPP-2M(avg.)', 'S1', 'S2', 'S3', 'S4', 'JTP'};
            app.IMFP_Formalism_DropDown.ValueChangedFcn = createCallbackFcn(app, @IMFP_Formalism_DropDownValueChanged, true);
            app.IMFP_Formalism_DropDown.Tooltip = {'Select the formalism to be used to calculate the inelastic mean free path (IMFP) parameter. '};
            app.IMFP_Formalism_DropDown.Value = 'JTP';
            app.SigmabarnatomLabel_5 = uilabel(app.IMFPParams_GridLayout);
            app.SigmabarnatomLabel_5.Text = 'eKE (eV):';
            app.IMFP_Ek_EditField = uieditfield(app.IMFPParams_GridLayout, 'numeric');
            app.IMFP_Ek_EditField.ValueDisplayFormat = '%.0f';
            app.IMFP_Ek_EditField.AllowEmpty = 'on';
            app.IMFP_Ek_EditField.Editable = 'off';
            app.IMFP_Ek_EditField.Tooltip = {'Calculated electron kinetic energy in eV for the particular core-level and photon energy being used.'};
            app.CalculatedIMFPnmLabel = uilabel(app.IMFPParams_GridLayout);
            app.CalculatedIMFPnmLabel.Text = 'IMFP (Angstrom):';
            app.IMFP_IMFP_EditField = uieditfield(app.IMFPParams_GridLayout, 'numeric');
            app.IMFP_IMFP_EditField.Limits = [0 Inf];
            app.IMFP_IMFP_EditField.ValueDisplayFormat = '%.3f';
            app.IMFP_IMFP_EditField.AllowEmpty = 'on';
            app.IMFP_IMFP_EditField.Editable = 'off';
            app.IMFP_IMFP_EditField.Tooltip = {'Calculated IMFP value using the chosen calculator formalism in units of Angstrom.'};
            app.ElementLabel = uilabel(app.IMFPParams_GridLayout);
            app.ElementLabel.Text = 'Selected Material:';
            app.SelectedMaterial_EditField = uieditfield(app.IMFPParams_GridLayout, 'text');
            app.SelectedMaterial_EditField.ValueChangedFcn = createCallbackFcn(app, @SelectedMaterial_EditFieldValueChanged, true);
            app.SelectedMaterial_EditField.Tooltip = {'Selected material.'};
            app.SelectedMaterial_EditField.Value = 'Si';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatBaseX_06_XPSSensitivityFactor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end