classdef MatBaseX_07_PES_NLayerSimulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Model_Panel                     matlab.ui.container.Panel
        NLayerStack_GridLayout          matlab.ui.container.GridLayout
        Bulk_UITable                    matlab.ui.control.Table
        Layer_UITable                   matlab.ui.control.Table
        LayerDeleteRowN_Button          matlab.ui.control.Button
        LayerDelete_Button              matlab.ui.control.Button
        LayerAdd_Button                 matlab.ui.control.Button
        ViewSampleStack_Button          matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        ExportSF_Panel                  matlab.ui.container.Panel
        ExportModel_GridLayout          matlab.ui.container.GridLayout
        ExportData_Theta_Button         matlab.ui.control.Button
        ExportData_PhotonEnergy_Button  matlab.ui.control.Button
        ViewPESModel_Theta_Button       matlab.ui.control.Button
        ViewPESModel_PhotonEnergy_Button  matlab.ui.control.Button
        RunPESModel_Button              matlab.ui.control.Button
        Geometry_Panel                  matlab.ui.container.Panel
        Geometry_GridLayout             matlab.ui.container.GridLayout
        ProbingDepth_GridLayout         matlab.ui.container.GridLayout
        Export_ProbingDepth_Button      matlab.ui.control.Button
        View_ProbingDepth_Button        matlab.ui.control.Button
        ProbingDepth_EditField          matlab.ui.control.NumericEditField
        ProbingDepthnmLabel             matlab.ui.control.Label
        ElectronKineticEnergy_EditField  matlab.ui.control.NumericEditField
        ElectronKineticEnergyeVLabel    matlab.ui.control.Label
        Geometry_P_EditField            matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        Geometry_phi_EditField          matlab.ui.control.NumericEditField
        phidegLabel_5                   matlab.ui.control.Label
        Geometry_theta_EditField        matlab.ui.control.NumericEditField
        phidegLabel_4                   matlab.ui.control.Label
        PhotonEnergy_EditField          matlab.ui.control.NumericEditField
        PhotonEnergyeVEditFieldLabel    matlab.ui.control.Label
        Params_Panel                    matlab.ui.container.Panel
        Params_GridLayout               matlab.ui.container.GridLayout
        TotalOrbCont_CheckBox           matlab.ui.control.CheckBox
        IMFP_Formalism_DropDown         matlab.ui.control.DropDown
        IMFPFormalismDropDownLabel      matlab.ui.control.Label
        XSect_Formalism_DropDown        matlab.ui.control.DropDown
        XSectFormalismLabel             matlab.ui.control.Label
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        LoadModel_Button                matlab.ui.control.Button
        SaveModel_Button                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        TRANSFER_Button                 matlab.ui.control.Button
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
        AppTitle                        matlab.ui.control.Label
        MAINMENU_GridLayout             matlab.ui.container.GridLayout
        MAINMENU_Button                 matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarise all data for analysis
        myAppData           % my app data structure
        % - Variables for the app
        % --- Experimental Geometry
        eph                 % Incident photon energy (eV)
        theta               % Emission Angle (degree)
        phi                 % Azimuthal Phi Angle (degree)
        P                   % Polarisation Factor
        eph_R               % Range: Incident photon energy (eV)
        theta_R             % Range: Emission Angle (degree)
        % --- Calculators & Formalisms 
        formalism_imfp      % IMFP formalism    
        formalism_xsect     % XECT formalism
        tot_orb_cont        % Calculate total orbital contributions
        % --- Sample Stack
        num_of_layers       % number of overlayers
        lyr_mat  	        % Mx1 cell-vector of the material for each layer in the stack; e.g. "Si", "SiO2", "Al2O3"...
        lyr_ele             % Mx1 cell-vector of strings of the element to be probed; e.g. "H", "He", "Si", "In"...
        lyr_cls  	        % Mx1 cell-vector of strings of the core-level to be probed; e.g. "5s1", "5p1", "5p3", "5d3", "5d5", "5f5', "5f7"...
        lyr_thick	        % Mx1 cell-vector of the thickness of each layer in the stack (nm)
        lyr_density	        % Mx1 cell-vector of the number density of each layer in the stack (atoms/cc)
        lyr_exclude	        % Mx1 cell-vector of whether the layer should be normalised in the normalisation process or not (1 or 0)
        tab_layers          % Table the contains all the surface layer material properties
        tab_bulk            % Table the contains all the bulk material properties 
        % --- Intensity Simulation Outputs
        lyr_I               % scalar of the total photoelectron intensity originating from the M'th layer
        lyr_Inorm           % scalar of the normalized photoelectron intensity originating from the M'th layer
        lyr_I_vs_hv         % Range: the total photoelectron intensity originating from the M'th layer
        lyr_Inorm_vs_hv     % Range: the normalized photoelectron intensity originating from the M'th layer
        lyr_I_vs_theta      % Range: the total photoelectron intensity originating from the M'th layer
        lyr_Inorm_vs_theta  % Range: the normalized photoelectron intensity originating from the M'th layer
        % --- Probing Depth Outputs
        info_depth          % scalar of the information depth (nm)
        ke                  % kinetic energy of electrons
        % --- Plot properties
        x_lims              % x-limits of photon energy dependence
    end
    
    % Private methods available to the app only
    methods (Access = private)
        %% Open Figure Locations 
        % Open Location of MainMenu App
        function open_MainMenu_to_left_of_App(app)
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1);
            fig.Position(2) = app.UIFigure.Position(2);
        end
        %% General Functions
        % Define the list of IMFP formalisms
        function list_of_imfp_formalisms = define_imfp_formalisms(~)
            list_of_imfp_formalisms = {...
                'Jablonski(2023) - JTP',...
                'Seah(2012) - S4',...
                'Seah(2012) - S3 (Organic)',...
                'Seah(2012) - S3',...
                'Seah(2011) - S2',...
                'Seah(2011) - S1',...
                'NIST(1999) - Optical Experiments',...
                'Tanuma(1994) - TPP-2M (Averaged)',...
                'Tanuma(1994) - TPP-2M',...
                'Seah(1979) - Universal',...
                };
        end

        % Find the IMFP formalism used
        function list_of_imfp_formalisms_id = define_imfp_formalism_id(~)
            list_of_imfp_formalisms_id = read_formalisms("imfp");
            list_of_imfp_formalisms_id = string(list_of_imfp_formalisms_id);
        end

        % Define the list of XSECT formalisms
        function list_of_xsect_formalisms = define_xsect_formalisms(~)
            list_of_xsect_formalisms = {...
                'Cant(2022) - 1500 to 10000 eV',...
                'Trzhaskovskaya(2018) - 1500 to 10000 eV',...
                'Yeh Lindau(1985) - 10 to 1500 eV',...
                'Scofield(1973) - 1 to 1500 keV',...
                };
        end

        % Find the XSECT formalism used
        function list_of_xsect_formalisms_id = define_xsect_formalism_id(~)
            list_of_xsect_formalisms_id   = ["Cant(2022)","Trz(2018)","Yeh(1985)","Scofield(1973)"];
        end
        
        % -- Extract all material names
        function list_of_all_mats = define_materials(~)
            % -- Extracting all elements
            element_names = read_mpd_elements();
            for i = 1:length(element_names)
                element_names{i} = char(sprintf("%s", element_names{i}));
            end
            element_names = element_names(1:92);
            % -- Extracting all compounds
            compound_names = read_mpd_compounds();
            for i = 1:length(compound_names)
                compound_names{i} = char(sprintf("%s", compound_names{i}));
            end
            list_of_all_mats = horzcat(element_names,compound_names);
        end

        % -- Find the minimum photon energy that can be used for a given stack
        function min_eph_val = find_min_eph(app)
            % 1 - Extracting the list of all core-levels being probed
            full_selected_cls = vertcat(app.tab_layers{:,3}, app.tab_bulk{:,3});
            full_selected_cls = string(full_selected_cls);
            % 2 - Extracting a list of the elements and specific core-levels
            be = [];
            for i = 1:length(full_selected_cls)
                be_val = extractAfter(full_selected_cls(i),'-');
                be_val = extractBefore(be_val,'e');
                be(i) = double(be_val);
            end
            % 3 - Finding the minimum photon energy
            min_eph_val = abs(max(be(:)));
        end

        %% Update Functions
        % Find all the core-levels for the selected material
        function core_levels = update_core_level_list(~, material)
            % -- Extracting all elements in the material
            formula                     = parse_chemical_formula(material);
            elements                    = sort({formula(:).element});
            % - Finding core-levels that have finite binding energy & cross-section
            cl_labels = {};
            for i = 1:length(elements)
                % xsect_cls                           = read_xsect_core_levels(elements{i});
                init_dat    = read_spectroscopy_element_data("xsect", elements{i});
                xsect_cls   = table2array(init_dat(:,2));
                [list_of_be, list_of_corelevels]    = calc_be(elements{i}, xsect_cls);
                [list_of_be, indx]                  = sort(list_of_be); 
                list_of_corelevels                  = list_of_corelevels(indx);
                for j = 1:length(list_of_be); cl_labels{end+1} = char(sprintf("%s(%s)-%.2feV",elements{i}, list_of_corelevels(j),list_of_be(j))); end
            end
            core_levels = cl_labels;
        end
        
        % Function that calculates all single dependent variables
        function update_single_dependents(app)
            % 1 : Appending the table data
            app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
            app.tab_bulk    = array2table(app.Bulk_UITable.Data, 'VariableNames', app.Bulk_UITable.ColumnName);
            % 2 : Extracting formalisms
            % 3 : Defining the sample stack to be modelled  
            app.lyr_mat         = horzcat(app.tab_layers.(2)', app.tab_bulk.(2)'); 
            app.lyr_thick       = horzcat(app.tab_layers.(4)', app.tab_bulk.(4)');    
            app.lyr_density     = horzcat(app.tab_layers.(5)', app.tab_bulk.(5)');  
            app.lyr_exclude        = horzcat(app.tab_layers.(6)', app.tab_bulk.(6)');
            full_selected_cls = string(vertcat(app.tab_layers{:,3}, app.tab_bulk{:,3}));
            app.lyr_cls = {}; app.lyr_ele = {};
            for i = 1:length(full_selected_cls) 
                temp_str            = extractBefore(full_selected_cls(i),'-');
                app.lyr_ele{i}      = extractBefore(temp_str,'(');
                app.lyr_cls{i}      = extractAfter(temp_str,'(');
                app.lyr_cls{i}      = extractBefore(app.lyr_cls{i},')');
            end
            % 4 : Calculating Signal(%)
            [app.lyr_I, app.lyr_Inorm]  = ...
                simul_pes_nlayer_intensity(...
                app.lyr_mat,  app.lyr_ele, app.lyr_cls, app.lyr_thick, app.lyr_density, app.lyr_exclude,...
                app.eph, app.theta, app.phi, app.P, app.formalism_xsect, app.formalism_imfp);
            if app.TotalOrbCont_CheckBox.Value == 1
                norm_val = zeros(size(app.lyr_Inorm{1}));
                % -- Scaling intensities by total orbital contribution
                for i = 1:length(app.lyr_mat)
                    scale_factor = calc_total_orbital_contribution(app.lyr_cls{i}); 
                    scale_factor = scale_factor{1};
                    app.lyr_I{i} = scale_factor.*app.lyr_I{i};
                    app.lyr_Inorm{i} = scale_factor.*app.lyr_Inorm{i};
                    norm_val = norm_val + app.lyr_Inorm{i};
                end
                % -- Renormalizing the data
                for i = 1:length(app.lyr_mat); app.lyr_Inorm{i} = app.lyr_Inorm{i} ./ norm_val; end
            end
            % 5 : Update all edit-fields to read the new values
            app.Bulk_UITable.Data{1,7}          = round(100*app.lyr_Inorm{end},1);
            for i = 1:app.num_of_layers
                app.Layer_UITable.Data{i,7}     = round(100*app.lyr_Inorm{i},1);
            end
            % 6 : Calculating the probing depth
            app.ke = app.ElectronKineticEnergy_EditField.Value;
            [app.info_depth, ~, ~] = simul_pes_nlayer_info_depth(app.lyr_mat, app.lyr_thick, app.ke, app.theta, app.formalism_imfp);
            app.ProbingDepth_EditField.Value = app.info_depth;
        end

        % Function that calculates range of dependent variables
        function update_range_dependents(app)
            % 1 : Define the initial parameters based on the formalism
            list_of_xsect_formalisms_id = define_xsect_formalism_id(app);
            if strcmpi(app.formalism_xsect,list_of_xsect_formalisms_id(4))   
                app.eph_R           = logspace(3, 6.5, 200);
                app.x_lims          = [0.9*1000, 1.1*1500000];
            elseif strcmpi(app.formalism_xsect,list_of_xsect_formalisms_id(3))
                app.eph_R           = 10:20:1500;
                app.x_lims          = [5, 1550];
            elseif strcmpi(app.formalism_xsect,list_of_xsect_formalisms_id(2))
                app.eph_R           = 1500:100:10000;
                app.x_lims          = [0.9*1000, 1.1*10000];
            elseif strcmpi(app.formalism_xsect,list_of_xsect_formalisms_id(1))  
                app.eph_R           = 1500:50:10000;
                app.x_lims          = [0.9*1000, 1.1*10000];
            end
            app.theta_R             = 0:2:90;
            if isrow(app.eph_R); app.eph_R = app.eph_R'; end
            if isrow(app.theta_R); app.theta_R = app.theta_R'; end
            % 2 : Photon Energy Dependence
            [app.lyr_I_vs_hv, app.lyr_Inorm_vs_hv]  = ...
                simul_pes_nlayer_intensity(...
                app.lyr_mat,  app.lyr_ele, app.lyr_cls, app.lyr_thick, app.lyr_density, app.lyr_exclude,...
                app.eph_R, app.theta, app.phi, app.P, app.formalism_xsect, app.formalism_imfp);
            if app.TotalOrbCont_CheckBox.Value == 1
                norm_val = zeros(size(app.lyr_Inorm_vs_hv{1}));
                % -- Scaling intensities by total orbital contribution
                for i = 1:length(app.lyr_mat)
                    scale_factor = calc_total_orbital_contribution(app.lyr_cls{i}); 
                    scale_factor = scale_factor{1};
                    app.lyr_I_vs_hv{i} = scale_factor.*app.lyr_I_vs_hv{i};
                    app.lyr_Inorm_vs_hv{i} = scale_factor.*app.lyr_Inorm_vs_hv{i};
                    norm_val = norm_val + app.lyr_Inorm_vs_hv{i};
                end
                % -- Renormalizing the data
                for i = 1:length(app.lyr_mat); app.lyr_Inorm_vs_hv{i} = app.lyr_Inorm_vs_hv{i} ./ norm_val; end
            end
            % 3 : Emission Angle Dependence
            [app.lyr_I_vs_theta, app.lyr_Inorm_vs_theta]  = ...
                simul_pes_nlayer_intensity(...
                app.lyr_mat,  app.lyr_ele, app.lyr_cls, app.lyr_thick, app.lyr_density, app.lyr_exclude,...
                app.eph, app.theta_R, app.phi, app.P, app.formalism_xsect, app.formalism_imfp);
            if app.TotalOrbCont_CheckBox.Value == 1
                norm_val = zeros(size(app.lyr_Inorm_vs_theta{1}));
                % -- Scaling intensities by total orbital contribution
                for i = 1:length(app.lyr_mat)
                    scale_factor = calc_total_orbital_contribution(app.lyr_cls{i}); 
                    scale_factor = scale_factor{1};
                    app.lyr_I_vs_theta{i} = scale_factor.*app.lyr_I_vs_theta{i};
                    app.lyr_Inorm_vs_theta{i} = scale_factor.*app.lyr_Inorm_vs_theta{i};
                    norm_val = norm_val + app.lyr_Inorm_vs_theta{i};
                end
                % -- Renormalizing the data
                for i = 1:length(app.lyr_mat); app.lyr_Inorm_vs_theta{i} = app.lyr_Inorm_vs_theta{i} ./ norm_val; end
            end
        end

        % Function to update values on-the-fly
        function update_pes_model_on_the_fly(app)
            % 1 : Running updates
            update_single_dependents(app);
            % 2 : Write data
            write_app_data(app);
        end

        % Function to run the latest PES model
        function update_pes_model(app)
            % 1 : Running updates
            update_single_dependents(app);
            update_range_dependents(app);
            % 2 : Write data
            write_app_data(app);
            % 3 : Print output that its complete
            fprintf("%s: PES Model Complete! \n", datetime);
        end

        % Extract the data structure
        function write_app_data(app)
            app.myAppData = struct();
            % -- Defining the input arguments
            % --- Calculators & Formalisms 
            app.myAppData.formalism_imfp            = app.formalism_imfp;
            app.myAppData.formalism_xsect           = app.formalism_xsect;
            app.myAppData.tot_orb_cont              = app.tot_orb_cont;
            % --- Sample Stack
            app.myAppData.num_of_layers             = app.num_of_layers;
            app.myAppData.lyr_mat                   = app.lyr_mat;
            app.myAppData.lyr_ele                   = app.lyr_ele;
            app.myAppData.lyr_cls                   = app.lyr_cls;
            app.myAppData.lyr_thick                 = app.lyr_thick;
            app.myAppData.lyr_density               = app.lyr_density;
            app.myAppData.lyr_exclude               = app.lyr_exclude;
            app.myAppData.tab_layers                = app.tab_layers;
            app.myAppData.tab_bulk                  = app.tab_bulk;
            % --- Experimental Geometry
            % ---- Single values
            app.myAppData.eph                       = app.eph;
            app.myAppData.theta                     = app.theta;
            app.myAppData.phi                       = app.phi;
            app.myAppData.P                         = app.P;
            app.myAppData.lyr_I                     = app.lyr_I;
            app.myAppData.lyr_Inorm                 = app.lyr_Inorm;
            % ---- Photon Energy Dependence
            app.myAppData.eph_R                     = app.eph_R;
            app.myAppData.lyr_I_vs_hv               = app.lyr_I_vs_hv;
            app.myAppData.lyr_Inorm_vs_hv           = app.lyr_Inorm_vs_hv;
            % ---- Theta Dependence
            app.myAppData.theta_R                   = app.theta_R;
            app.myAppData.lyr_I_vs_theta            = app.lyr_I_vs_theta;
            app.myAppData.lyr_Inorm_vs_theta        = app.lyr_Inorm_vs_theta;
            % ---- Probing Depth Outputs
            app.myAppData.ke                        = app.ke;
            app.myAppData.info_depth                = app.info_depth;
        end

        %% Plot Functions
        % -- Plot the sample stack figure
        function plot_sample_stack(app)
            simul_pes_nlayer_sample(app.lyr_mat, app.lyr_thick);
            % - Shifting figure to the right of the app
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
        end

        % -- Plot the probing depth figure
        function plot_probing_depth(app)
            plot_result = 1;
            simul_pes_nlayer_info_depth(app.lyr_mat, app.lyr_thick, app.ke, app.theta, app.formalism_imfp, plot_result);
            % - Shifting figure to the right of the app
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
        end

        % -- Plot the photon energy dependence
        function plot_pes_simulations_vs_photon_energy(app)
            % - Initializing variables
            lwidth      = 2;
            Nlyrs       = length(app.lyr_Inorm_vs_hv);
            colorList   = read_pes_nlayer_color(Nlyrs);
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 750; 
            fig.Position(4) = 500;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plot of data
            nexttile(); hold on; grid on; grid minor;
            for i = 1:Nlyrs
                plot(app.eph_R, app.lyr_Inorm_vs_hv{i}, '.-', 'linewidth', lwidth, 'color', colorList{i}, 'markerfacecolor', colorList{i}); 
                scatter(app.eph, app.lyr_Inorm{i},...
                    'SizeData', 60, 'markeredgecolor', colorList{i}, 'markerfacecolor', colorList{i}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            end
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' Normalized Intensity ', 'FontWeight','bold');
            axis([app.x_lims, 0, 1.10]);
            text(0.02, 0.98, sprintf("theta = %.0f deg., phi = %.0f deg.", app.theta, app.phi),...
                'FontSize', 10, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            % - Formatting the axis
            lgnd_txt = cell(1,Nlyrs);
            for i = 1:Nlyrs
                if i == Nlyrs; lgnd_txt{i} = sprintf("%s-%s(%s)-%.0f", app.lyr_mat{i}, app.lyr_ele{i}, app.lyr_cls{i}, app.lyr_thick{i});
                else; lgnd_txt{i} = sprintf("%s-%s(%s)-%.1fnm", app.lyr_mat{i}, app.lyr_ele{i}, app.lyr_cls{i}, app.lyr_thick{i});
                end
            end
            legend(lgnd_txt, 'location', 'eastoutside', 'FontSize', 9);
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
            if strcmpi(app.formalism_xsect, "Scofield(1973)"); ax = gca; ax.XScale = 'log'; end
        end

        % -- Plot the theta dependence
        function plot_pes_simulations_vs_theta(app)
            % - Initializing variables
            lwidth      = 2;
            Nlyrs       = length(app.lyr_Inorm_vs_theta);
            colorList   = read_pes_nlayer_color(Nlyrs);
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 750; 
            fig.Position(4) = 500;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plot of data
            nexttile(); hold on; grid on; grid minor;
            for i = 1:Nlyrs
                plot(app.theta_R, app.lyr_Inorm_vs_theta{i}, '.-', 'linewidth', lwidth, 'color', colorList{i}, 'markerfacecolor', colorList{i}); 
                scatter(app.theta, app.lyr_Inorm{i},...
                    'SizeData', 60, 'markeredgecolor', colorList{i}, 'markerfacecolor', colorList{i}, 'MarkerFaceAlpha', 0.95, 'HandleVisibility','off');
            end
            xlabel(' Theta [deg.] ', 'FontWeight','bold');
            ylabel(' Normalized Intensity ', 'FontWeight','bold');
            axis([0,90, 0, 1.10]);
            text(0.02, 0.98, sprintf("hv = %.0f eV, phi = %.0f deg.", app.eph, app.phi),...
                'FontSize', 10, 'color', 'k', 'Units','normalized', 'HorizontalAlignment','left');
            % - Formatting the axis
            lgnd_txt = cell(1,Nlyrs);
            for i = 1:Nlyrs
                if i == Nlyrs; lgnd_txt{i} = sprintf("%s-%s(%s)-%.0f", app.lyr_mat{i}, app.lyr_ele{i}, app.lyr_cls{i}, app.lyr_thick{i});
                else; lgnd_txt{i} = sprintf("%s-%s(%s)-%.1fnm", app.lyr_mat{i}, app.lyr_ele{i}, app.lyr_cls{i}, app.lyr_thick{i});
                end
            end
            legend(lgnd_txt, 'location', 'eastoutside', 'FontSize', 9);
            yline(0, 'k-', 'LineWidth',1, 'HandleVisibility','off');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBaseX - N-Layer PES Intensity App (procopios.constantinou@psi.ch)';
            % 1 - Coloring the edit-fields that can be changed
            edit_color = [0.85 1 1];
            app.XSect_Formalism_DropDown.BackgroundColor            = edit_color;
            app.IMFP_Formalism_DropDown.BackgroundColor             = edit_color;
            app.PhotonEnergy_EditField.BackgroundColor              = edit_color;
            app.Geometry_theta_EditField.BackgroundColor            = edit_color;
            app.Geometry_phi_EditField.BackgroundColor              = edit_color;
            app.Geometry_P_EditField.BackgroundColor                = edit_color;
            app.ElectronKineticEnergy_EditField.BackgroundColor     = edit_color;
            add_color       = [0.8, 0.95, 0.8];
            remove_color    = [0.95, 0.8, 0.8];
            app.LayerAdd_Button.BackgroundColor         = add_color;
            app.LayerDelete_Button.BackgroundColor      = remove_color;
            app.LayerDeleteRowN_Button.BackgroundColor  = remove_color;
            % 2 - Default parameters upon startup
            % --- Experimental Geometry
            app.eph                 = app.PhotonEnergy_EditField.Value;
            app.theta               = app.Geometry_theta_EditField.Value;
            app.phi                 = app.Geometry_phi_EditField.Value;
            app.P                   = app.Geometry_P_EditField.Value;
            app.ke                  = app.ElectronKineticEnergy_EditField.Value;
            % -- IMFP parameters
            list_of_imfp_formalisms                     = define_imfp_formalisms(app);
            app.IMFP_Formalism_DropDown.Items           = list_of_imfp_formalisms;
            app.IMFP_Formalism_DropDown.Value           = app.IMFP_Formalism_DropDown.Items{1};
            list_of_imfp_formalisms_id                  = define_imfp_formalism_id(app);
            app.formalism_imfp                          = list_of_imfp_formalisms_id{1};
            % -- XSECT parameters
            list_of_xsect_formalisms                    = define_xsect_formalisms(app);
            app.XSect_Formalism_DropDown.Items          = list_of_xsect_formalisms;
            app.XSect_Formalism_DropDown.Value          = app.XSect_Formalism_DropDown.Items{1};
            list_of_xsect_formalisms_id                 = define_xsect_formalism_id(app);
            app.formalism_xsect                         = list_of_xsect_formalisms_id{1};
            % -- ORBITAL Contributions
            app.tot_orb_cont                            = app.TotalOrbCont_CheckBox.Value;
            % --- Material Stack
            materials   = define_materials(app);
            % ---- Initializing layer stack table
            default_layer_material              = 'Al2O3';
            core_levels                         = update_core_level_list(app, default_layer_material);
            app.Layer_UITable.ColumnName        = {'Layer','Material','Core-Level','Thickness(nm)','Density(atm/cc)', 'Exclude', 'Signal(%)'};
            app.Layer_UITable.ColumnFormat      = {'numeric', materials, core_levels, 'numeric','numeric','logical', 'numeric'};
            app.Layer_UITable.ColumnEditable    = [false,true,true,true,true,true,false];
            app.Layer_UITable.Data              = {1,default_layer_material,core_levels{3}, 5, mpd_calc_number_density(default_layer_material), false, 100};
            app.num_of_layers                   = size(app.Layer_UITable.Data,1);
            colorList                           = read_pes_nlayer_color(app.num_of_layers+1);
            for i = 1:app.num_of_layers
                temp_col = uistyle('BackgroundColor', colorList{i}); 
                addStyle(app.Layer_UITable, temp_col, 'cell', [i, 1]);
            end
            % ---- Initializing bulk table
            default_bulk_material               = 'Si';
            core_levels                         = update_core_level_list(app, default_bulk_material);
            % --- Initialising the table
            app.Bulk_UITable.ColumnName         = {'Layer','Material','Core-Level','Thickness(nm)','Density(atm/cc)', 'Exclude', 'Signal(%)'};
            app.Bulk_UITable.ColumnFormat       = {'numeric', materials, core_levels, 'numeric','numeric','logical', 'numeric'};
            app.Bulk_UITable.ColumnEditable     = [false,true,true,false,true,true,false];
            app.Bulk_UITable.Data               = {'Bulk', default_bulk_material, core_levels{3}, Inf, mpd_calc_number_density(default_bulk_material), false, 100};
            temp_col = uistyle('BackgroundColor', colorList{end}); 
            addStyle(app.Bulk_UITable, temp_col, 'cell', [1, 1]);
            % -- Data structure
            app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
            app.tab_bulk    = array2table(app.Bulk_UITable.Data, 'VariableNames', app.Bulk_UITable.ColumnName);
            % 3 - Running update
            update_pes_model(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run MatBaseX;
            open_MainMenu_to_left_of_App(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run MatBaseX_07_PES_NLayerSimulations;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 1080;
            screen_size(4) = 520;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('myAppData : '); disp(app.myAppData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'myAppData', app.myAppData);
        end

        % Value changed function: XSect_Formalism_DropDown
        function XSect_Formalism_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.XSect_Formalism_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the formalism selected
            list_of_formalisms_id = define_xsect_formalism_id(app);
            if strcmpi(value,app.XSect_Formalism_DropDown.Items{1});          value = list_of_formalisms_id(1);
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{2});      value = list_of_formalisms_id(2);
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{3});      value = list_of_formalisms_id(3);
            elseif strcmpi(value,app.XSect_Formalism_DropDown.Items{4});      value = list_of_formalisms_id(4);
            end
            % 3 - Assigning the output
            app.formalism_xsect = value;
            fprintf("--> xsect formalism: " + string(app.formalism_xsect) + "\n");
            % 4 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Value changed function: IMFP_Formalism_DropDown
        function IMFP_Formalism_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.IMFP_Formalism_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the formalism selected
            list_of_formalisms_id = define_imfp_formalism_id(app);
            if strcmpi(value,app.IMFP_Formalism_DropDown.Items{1});          value = list_of_formalisms_id(1);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{2});      value = list_of_formalisms_id(2);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{3});      value = list_of_formalisms_id(3);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{4});      value = list_of_formalisms_id(4);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{5});      value = list_of_formalisms_id(5);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{6});      value = list_of_formalisms_id(6);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{7});      value = list_of_formalisms_id(7);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{8});      value = list_of_formalisms_id(8);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{9});      value = list_of_formalisms_id(9);
            elseif strcmpi(value,app.IMFP_Formalism_DropDown.Items{10});     value = list_of_formalisms_id(10);
            end
            % 4 - Assigning the output
            app.formalism_imfp = value;
            fprintf("--> imfp formalism: " + string(app.formalism_imfp) + "\n");
            % 4 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Value changed function: TotalOrbCont_CheckBox
        function TotalOrbCont_CheckBoxValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.TotalOrbCont_CheckBox.Value;
            % 2 - Assigning the output
            app.tot_orb_cont = value;
            fprintf("--> total orbital contributions: " + string(app.tot_orb_cont) + "\n");
            % 3 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Value changed function: Geometry_theta_EditField
        function Geometry_theta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.Geometry_theta_EditField.Value;
            app.theta   = value;
            % 2 - Assigning the output
            fprintf("--> theta: " + string(app.theta) + " degree \n");
            % 3 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Value changed function: Geometry_phi_EditField
        function Geometry_phi_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.Geometry_phi_EditField.Value;
            app.phi     = value;
            % 2 - Assigning the output
            fprintf("--> phi: " + string(app.phi) + " degree \n");
            % 3 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Value changed function: Geometry_P_EditField
        function Geometry_P_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.Geometry_P_EditField.Value;
            app.P       = value;
            % 2 - Assigning the output
            fprintf("--> P: " + string(app.P) + "\n");
            % 3 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Value changed function: PhotonEnergy_EditField
        function PhotonEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PhotonEnergy_EditField.Value;
            % 2 - Validity check on the input value
            min_eph_val = find_min_eph(app);
            if isempty(value) || isnan(value) || value < min_eph_val
                value = ceil(min_eph_val + 10);
            end
            app.eph = value;
            app.PhotonEnergy_EditField.Value = app.eph;
            % 2 - Assigning the output
            fprintf("--> Photon Energy: " + string(app.eph) + " eV\n");
            % 3 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Value changed function: ElectronKineticEnergy_EditField
        function ElectronKineticEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value   = app.ElectronKineticEnergy_EditField.Value;
            app.ke  = value;
            % 2 - Assigning the output
            fprintf("--> Kinetic Energy: " + string(app.ke) + " eV\n");
            % 3 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Button pushed function: View_ProbingDepth_Button
        function View_ProbingDepth_ButtonPushed(app, event)
            plot_probing_depth(app);
        end

        % Button pushed function: Export_ProbingDepth_Button
        function Export_ProbingDepth_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = sprintf("id%s_NlayerModel_InfoDepth_%iL", string(yyyymmdd(datetime)), app.num_of_layers);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing data analysis
            [~, lyr_depth, lyr_Int0] = simul_pes_nlayer_info_depth(app.lyr_mat, app.lyr_thick, app.ke, app.theta, app.formalism_imfp);
            % 3 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("-- INPUTS -- \n");
            info = info + sprintf("Formalism (XSECT):\t%s\n", app.formalism_xsect);
            info = info + sprintf("Formalism (IMFP):\t%s\n", app.formalism_imfp);
            info = info + sprintf("Orbital contributions?:\t%i\n", app.tot_orb_cont);
            info = info + sprintf("eph:\t\t\t%.4e eV\n", app.eph);
            info = info + sprintf("theta:\t\t\t%.4e degree\n", app.theta);
            info = info + sprintf("phi:\t\t\t%.4e degree\n", app.phi);
            info = info + sprintf("P:\t\t\t%.4e\n", app.P);
            info = info + sprintf("ke:\t\t\t%.4e eV\n", app.ke);
            info = info + sprintf("info.depth:\t\t%.4e nm\n", app.info_depth);
            % Sample data
            T_Sample    = vertcat(app.tab_layers, app.tab_bulk);
            T_Data      = table(lyr_depth);
            for i = 1:length(lyr_Int0);  T_Data{:,i+1} = lyr_Int0{i}; end
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Sample, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 4 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: LayerAdd_Button
        function LayerAdd_ButtonPushed(app, event)
            % 1 - Adding one new layer to the table
            if app.num_of_layers ~= 15
                app.num_of_layers   = app.num_of_layers+1;
                material            = app.Layer_UITable.Data(end,2); material = material{1};
                clevel              = app.Layer_UITable.Data(end,3); clevel = clevel{1};
                thickness           = app.Layer_UITable.Data(end,4); thickness = thickness{1};
                density             = app.Layer_UITable.Data(end,5); density = density{1};
                exclusion           = app.Layer_UITable.Data(end,6); exclusion = exclusion{1};
                app.Layer_UITable.Data(end+1,:) = {app.num_of_layers, material, clevel, thickness, density, exclusion, []};
                % 2 - Assigning and printing the output
                app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
                % -- Updating the color cell
                colorList   = read_pes_nlayer_color(app.num_of_layers+1);
                temp_col = uistyle('BackgroundColor', colorList{app.num_of_layers}); 
                addStyle(app.Layer_UITable, temp_col, 'cell', [app.num_of_layers, 1]);
                fprintf("-- Layer Added: N = " + string(app.num_of_layers) + " \n");
                update_pes_model_on_the_fly(app);
            else; fprintf("-- Currently only supports 15 layers maximum...\n");
            end
        end

        % Button pushed function: LayerDelete_Button
        function LayerDelete_ButtonPushed(app, event)
            % 1 - Removing one layer from the table
            if app.num_of_layers ~= 1 
                app.num_of_layers               = app.num_of_layers - 1;
                app.Layer_UITable.Data(end,:)   = []; 
                app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
                fprintf("-- Layer Removed: N = " + string(app.num_of_layers + 1) + " \n");
                update_pes_model_on_the_fly(app);
            else; fprintf("-- Cannot remove uppermost layer...\n");
            end
        end

        % Button pushed function: LayerDeleteRowN_Button
        function LayerDeleteRowN_ButtonPushed(app, event)
            % - Removing one row from the UI table (defined index)
            if app.num_of_layers ~= 1 
                prompt      = {'Enter the Layer Number you want to delete:'};
                dlgtitle    = 'Delete Entry';
                dims        = [1 50];
                answer      = inputdlg(prompt,dlgtitle,dims);
                if ~isempty(answer) 
                    user_val = str2double(answer{1});
                    if user_val > app.num_of_layers || user_val < 1
                        fprintf("-> Must enter a Layer between 1 - " + string(app.num_of_layers) + "\n");
                    else
                        app.num_of_layers                   = app.num_of_layers - 1;
                        app.Layer_UITable.Data(user_val,:)  = []; 
                        for i = 1:app.num_of_layers; app.Layer_UITable.Data(i,1) = {i}; end
                        % -- Assigning and printing the output
                        app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
                        fprintf("-> Layer #" + string(user_val) + " Deleted \n");
                        % -- Ensure color is consistent
                        colorList   = read_pes_nlayer_color(app.num_of_layers+1);
                        for i = 1:app.num_of_layers
                            temp_col = uistyle('BackgroundColor', colorList{i}); 
                            addStyle(app.Layer_UITable, temp_col, 'cell', [i, 1]);
                        end
                        % -- Update Functions
                        update_pes_model_on_the_fly(app);
                    end
                end
            else; fprintf("-> At least one layer is required...\n");
            end
        end

        % Cell edit callback: Layer_UITable
        function Layer_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Update the core-levels to reflect the new material
            if indices(2) == 2
                materials       = define_materials(app);
                core_levels     = update_core_level_list(app, newData);
                density         = mpd_calc_number_density(newData);
                app.Layer_UITable.ColumnFormat          = {'numeric', materials, core_levels, 'numeric', 'numeric', 'logical', 'numeric'};
                app.Layer_UITable.Data{indices(1),3}    = core_levels{1};
                app.Layer_UITable.Data{indices(1),5}    = density;
            end
            % 3 - Assigning the output
            if indices(2) == 2;         layer_text = sprintf("Layer %i, Material = %s", indices(1), newData);
            elseif indices(2) == 3;     layer_text = sprintf("Layer %i, Core-Level = %s", indices(1), newData);
            elseif indices(2) == 4;     layer_text = sprintf("Layer %i, Thickness = %.2f nm", indices(1), newData);
            elseif indices(2) == 5;     layer_text = sprintf("Layer %i, Density = %.3e atoms/cc", indices(1), newData);
            elseif indices(2) == 6;     layer_text = sprintf("Layer %i, Exclude Layer Contribution = %i", indices(1), newData);
            end
            app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
            fprintf(layer_text + " \n");
            % 4 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Cell selection callback: Layer_UITable
        function Layer_UITableCellSelection(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            % 2 - Update the core-levels to reflect the new material
            if ~isempty(indices)
                if indices(2) == 3
                    material        = app.Layer_UITable.Data{indices(1),2};
                    core_levels     = update_core_level_list(app, material);
                    app.Layer_UITable.ColumnFormat{3}   = core_levels;
                end
            end
        end

        % Cell edit callback: Bulk_UITable
        function Bulk_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Update the core-levels to reflect the new material
            if indices(2) == 2
                materials       = define_materials(app);
                core_levels     = update_core_level_list(app, newData);
                density         = mpd_calc_number_density(newData);
                app.Bulk_UITable.ColumnFormat   = {'numeric', materials, core_levels, 'numeric', 'numeric', 'logical', 'numeric'};
                app.Bulk_UITable.Data{indices(1),3}     = core_levels{1};
                app.Bulk_UITable.Data{indices(1),5}    = density;
            end
            % 2 - Assigning the output
            if indices(2) == 2; layer_text = sprintf("Bulk, Material = %s", newData);
            elseif indices(2) == 3; layer_text = sprintf("Bulk, Core-Level = %s", newData);
            elseif indices(2) == 6; layer_text = sprintf("Bulk, Exclude Layer Contribution = %i", newData);
            end
            app.tab_bulk  = array2table(app.Bulk_UITable.Data, 'VariableNames', app.Bulk_UITable.ColumnName);
            fprintf(layer_text + " \n");
            % 4 - Update Functions
            update_pes_model_on_the_fly(app);
        end

        % Button pushed function: ViewSampleStack_Button
        function ViewSampleStack_ButtonPushed(app, event)
            plot_sample_stack(app);
        end

        % Button pushed function: RunPESModel_Button
        function RunPESModel_ButtonPushed(app, event)
            % 1 : Execute PES model
            update_pes_model(app);
            plot_pes_simulations_vs_theta(app);
            plot_pes_simulations_vs_photon_energy(app);
        end

        % Button pushed function: ViewPESModel_PhotonEnergy_Button
        function ViewPESModel_PhotonEnergy_ButtonPushed(app, event)
            % update_pes_model(app);
            plot_pes_simulations_vs_photon_energy(app);
        end

        % Button pushed function: ExportData_PhotonEnergy_Button
        function ExportData_PhotonEnergy_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = sprintf("id%s_NlayerModel_IntsVshv_%iL", string(yyyymmdd(datetime)), app.num_of_layers);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("-- INPUTS -- \n");
            info = info + sprintf("Formalism (XSECT):\t%s\n", app.formalism_xsect);
            info = info + sprintf("Formalism (IMFP):\t%s\n", app.formalism_imfp);
            info = info + sprintf("Orbital contributions?:\t%i\n", app.tot_orb_cont);
            info = info + sprintf("eph:\t\t\t%.4e eV\n", app.eph);
            info = info + sprintf("theta:\t\t\t%.4e degree\n", app.theta);
            info = info + sprintf("phi:\t\t\t%.4e degree\n", app.phi);
            info = info + sprintf("P:\t\t\t%.4e\n", app.P);
            info = info + sprintf("ke:\t\t\t%.4e eV\n", app.ke);
            info = info + sprintf("info.depth:\t\t%.4e nm\n", app.info_depth);
            % Sample data
            T_Sample    = vertcat(app.tab_layers, app.tab_bulk);
            T_Data      = table(app.eph_R);
            for i = 1:length(app.lyr_Inorm_vs_hv);  T_Data{:,i+1} = app.lyr_Inorm_vs_hv{i}; end
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            fprintf(fileID, '%s', sprintf("-- MATERIAL STACK -- \n"));
            writetable(T_Sample, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 4 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: ViewPESModel_Theta_Button
        function ViewPESModel_Theta_ButtonPushed(app, event)
            % update_pes_model(app);
            plot_pes_simulations_vs_theta(app);
        end

        % Button pushed function: ExportData_Theta_Button
        function ExportData_Theta_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = sprintf("id%s_NlayerModel_IntsVsTheta_%iL", string(yyyymmdd(datetime)), app.num_of_layers);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Sample metadata
            info = "";
            info = info + sprintf("-- INPUTS -- \n");
            info = info + sprintf("Formalism (XSECT):\t%s\n", app.formalism_xsect);
            info = info + sprintf("Formalism (IMFP):\t%s\n", app.formalism_imfp);
            info = info + sprintf("Orbital contributions?:\t%i\n", app.tot_orb_cont);
            info = info + sprintf("eph:\t\t\t%.4e eV\n", app.eph);
            info = info + sprintf("theta:\t\t\t%.4e degree\n", app.theta);
            info = info + sprintf("phi:\t\t\t%.4e degree\n", app.phi);
            info = info + sprintf("P:\t\t\t%.4e\n", app.P);
            info = info + sprintf("ke:\t\t\t%.4e eV\n", app.ke);
            info = info + sprintf("info.depth:\t\t%.4e nm\n", app.info_depth);
            % Sample data
            T_Sample    = vertcat(app.tab_layers, app.tab_bulk);
            T_Data      = table(app.theta_R);
            for i = 1:length(app.lyr_Inorm_vs_theta);  T_Data{:,i+1} = app.lyr_Inorm_vs_theta{i}; end
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            fprintf(fileID, '%s', sprintf("-- MATERIAL STACK -- \n"));
            writetable(T_Sample, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 4 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: SaveModel_Button
        function SaveModel_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.mat'};
            fname = sprintf("id%s_NlayerModel_%iL", string(yyyymmdd(datetime)), app.num_of_layers);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            structName          = char(save_filename(1:end-4));
            eval([structName ' = app.myAppData;']);     % Create the data structure dynamically
            % Saving the data as .mat
            eval(['save(''', save_fullfile, '.mat'', ''' structName ''', ''-v7.3'')']);
            disp('-> saved data : '); display(app.myAppData);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!');
        end

        % Button pushed function: LoadModel_Button
        function LoadModel_ButtonPushed(app, event)
            % 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.mat'}, 'Pick a *.mat data file...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % 2 - Loading in the parameters
            importedData  = load(string(PathName) + string(FileName));
            field_name = fieldnames(importedData);
            importedData  = importedData.(field_name{1});
            % 3 - Updating table of parameters
            % -- XSECT Formalism
            list_of_formalisms_id = define_xsect_formalism_id(app);
            idx = find(strcmpi(string(importedData.formalism_xsect), list_of_formalisms_id),1);
            app.XSect_Formalism_DropDown.Value = app.XSect_Formalism_DropDown.Items{idx};
            app.formalism_xsect      = list_of_formalisms_id{idx};
            % -- IMFP Formalism
            list_of_formalisms_id = define_imfp_formalism_id(app);
            idx = find(strcmpi(string(importedData.formalism_imfp), list_of_formalisms_id),1);
            app.IMFP_Formalism_DropDown.Value = app.IMFP_Formalism_DropDown.Items{idx};
            app.formalism_imfp      = list_of_formalisms_id{idx};
            % -- Orbital Contributions
            app.TotalOrbCont_CheckBox.Value             = importedData.tot_orb_cont;
            % -- Experimental Geometry
            app.PhotonEnergy_EditField.Value            = importedData.eph;
            app.Geometry_theta_EditField.Value          = importedData.theta;
            app.Geometry_phi_EditField.Value            = importedData.phi;
            app.Geometry_P_EditField.Value              = importedData.P;
            app.ElectronKineticEnergy_EditField.Value   = importedData.ke;
            % -- Loading in the tables
            app.Layer_UITable.Data      = table2array(importedData.tab_layers);
            app.Bulk_UITable.Data       = table2array(importedData.tab_bulk);
            % -- Update Table Colors
            app.num_of_layers = size(app.Layer_UITable.Data,1);
            colorList  = read_pes_nlayer_color(app.num_of_layers+1);
            for i = 1:app.num_of_layers
                temp_col = uistyle('BackgroundColor', colorList{i}); 
                addStyle(app.Layer_UITable, temp_col, 'cell', [i, 1]);
            end
            % 4 - Updating App parameters
            % -- Formalisms
            app.tot_orb_cont        = app.TotalOrbCont_CheckBox.Value;
            % --- Experimental Geometry
            app.eph                 = app.PhotonEnergy_EditField.Value;
            app.theta               = app.Geometry_theta_EditField.Value;
            app.phi                 = app.Geometry_phi_EditField.Value;
            app.P                   = app.Geometry_P_EditField.Value;
            app.ke                  = app.ElectronKineticEnergy_EditField.Value;
            % 5 - Update the app
            update_pes_model(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'PES : N-Layer Simulations of Photoelectron Intensity';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Explore the depths of material analysis and XPS with this robust tool. It empowers users to construct multi-layered models, each layer featuring a uniform and homogenous material composition and thickness. The app draws upon an extensive database for material characteristics and integrates various options for IMFP parameters, including Optical (NIST), Universal, TPP-2M, and S1 through S4. It also offers a selection of photoionization cross-sections and asymmetry parameters from renowned databases, such as the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018) and Cant (2022) databases. Utilizing the Beer-Lambert law, the tool accurately computes the PES intensities for each layer, modulated by the electron IMFP based on the specific experimental setup. Additionally, it can graphically represent the variations in photon energy and emission angles.'};
            app.Image.ImageSource = 'Icon_NLayerModeller01.jpeg';
            app.SaveModel_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SaveModel_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveModel_ButtonPushed, true);
            app.SaveModel_Button.Tooltip = {'Save the PES N-Layer Model as a *.mat file.'};
            app.SaveModel_Button.Text = {'Save Model... '; '(*.mat)'};
            app.LoadModel_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.LoadModel_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadModel_ButtonPushed, true);
            app.LoadModel_Button.Tooltip = {'Load in a previously saved PES N-Layer model (*.mat).'};
            app.LoadModel_Button.Text = {'Load Model...'; '(*.mat)'};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Params_Panel = uipanel(app.GridLayout2);
            app.Params_Panel.Tooltip = {'IMFP, Cross-Sections & Asymmety Formalisms Panel.'};
            app.Params_Panel.Title = 'IMFP, Cross-Sections & Asymmetry Formalisms';
            app.Params_GridLayout = uigridlayout(app.Params_Panel);
            app.XSectFormalismLabel = uilabel(app.Params_GridLayout);
            app.XSectFormalismLabel.Text = 'XSect Formalism:';
            app.XSect_Formalism_DropDown = uidropdown(app.Params_GridLayout);
            app.XSect_Formalism_DropDown.Items = {'Cant(2022) - 1500 to 10000 eV', 'Trzhaskovskaya(2018) - 1500 to 10000 eV', 'Yeh Lindau(1985) - 10 to 1500 eV', 'Scofield(1973) - 1 to 1500 keV'};
            app.XSect_Formalism_DropDown.ValueChangedFcn = createCallbackFcn(app, @XSect_Formalism_DropDownValueChanged, true);
            app.XSect_Formalism_DropDown.Tooltip = {'Select the formalism to be used to calculate the photoionisation cross-section and asymmetry parameter. For more information, see references:'; '[1] Scofield, J H. Theoretical photoionization cross sections from 1 to 1500 keV.. United States: N. p., 1973. Web. doi:10.2172/4545040.'; '[2] J.J. Yeh, I. Lindau. ATOMIC DATA AND NUCLEAR DATA TABLES 32, l-l 55 (1985). Web. doi:10.1016/0092-640X(85)90016-6'; '[3] M.B. Trzhaskovskaya, V.G. Yarzhemsky. Atomic Data and Nuclear Data Tables 119 (2018) 99–174. Web. doi:10.1016/j.adt.2017.04.003'; '[4] David J. H. Cant, Ben F. Spencer, Wendy R. Flavell, Alexander G. Shard. Surf Interface Anal. 2022; 54(4): 442-454. doi:10.1002/sia.7059'};
            app.XSect_Formalism_DropDown.Value = 'Cant(2022) - 1500 to 10000 eV';
            app.IMFPFormalismDropDownLabel = uilabel(app.Params_GridLayout);
            app.IMFPFormalismDropDownLabel.Text = 'IMFP Formalism:';
            app.IMFP_Formalism_DropDown = uidropdown(app.Params_GridLayout);
            app.IMFP_Formalism_DropDown.Items = {'Universal', 'Optical(NIST)', 'TPP-2M', 'TPP-2M(avg.)', 'S1', 'S2', 'S3', 'S4', 'JTP'};
            app.IMFP_Formalism_DropDown.ValueChangedFcn = createCallbackFcn(app, @IMFP_Formalism_DropDownValueChanged, true);
            app.IMFP_Formalism_DropDown.Tooltip = {'Select the formalism to be used to calculate the inelastic mean free path (IMFP) parameter. For more information, see references:'; '[1] M. P. Seah, Quantitative electron spectroscopy of surfaces A Standard Data Base for Electron Inelastic Mean Free Paths in Solids (1979)'; '[2] S. Tanuma, Calculations of Electron Inelastic Mean Free Paths. V. Data for 14 Organic Compounds over the 50-2000 eV Range (1994)'; '[3] S. Tanuma, Calculation of electron inelastic mean free paths (IMFPs) VII. Reliability of the TPP-2M IMFP predictive equation (2003)'; '[4] S. Tanuma, Calculations of electron inelasticmean free paths. IX. Data for 41 elemental solids over the 50 eV to 30 keV range (2011)'; '[5] M. P. Seah, An accurate and simple universal curve for the energy-dependent electron inelastic mean free path (2011)'};
            app.IMFP_Formalism_DropDown.Value = 'JTP';
            app.TotalOrbCont_CheckBox = uicheckbox(app.Params_GridLayout);
            app.TotalOrbCont_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TotalOrbCont_CheckBoxValueChanged, true);
            app.TotalOrbCont_CheckBox.Tooltip = {'Include total orbital contributions to the intensity determination.'};
            app.TotalOrbCont_CheckBox.Text = 'Calculate total orbital contributions?';
            app.Geometry_Panel = uipanel(app.GridLayout2);
            app.Geometry_Panel.Tooltip = {'Experimental Geometry Panel.'};
            app.Geometry_Panel.Title = 'Experimental Geometry';
            app.Geometry_GridLayout = uigridlayout(app.Geometry_Panel);
            app.PhotonEnergyeVEditFieldLabel = uilabel(app.Geometry_GridLayout);
            app.PhotonEnergyeVEditFieldLabel.Text = 'Photon Energy (eV):';
            app.PhotonEnergy_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.PhotonEnergy_EditField.Limits = [0 Inf];
            app.PhotonEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.PhotonEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @PhotonEnergy_EditFieldValueChanged, true);
            app.PhotonEnergy_EditField.Tooltip = {'Photon energy in eV.'};
            app.PhotonEnergy_EditField.Value = 2000;
            app.phidegLabel_4 = uilabel(app.Geometry_GridLayout);
            app.phidegLabel_4.Text = 'theta (degree):';
            app.Geometry_theta_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.Geometry_theta_EditField.Limits = [0 90];
            app.Geometry_theta_EditField.ValueDisplayFormat = '%.2f';
            app.Geometry_theta_EditField.ValueChangedFcn = createCallbackFcn(app, @Geometry_theta_EditFieldValueChanged, true);
            app.Geometry_theta_EditField.Tooltip = {'The polar angle (in degrees) between the photoelectron vector relative to electric field vector (i.e. at normal emission: LV (p-pol, E//MP) = 0, LH (s-pol, E⊥MP) = 90)'};
            app.phidegLabel_5 = uilabel(app.Geometry_GridLayout);
            app.phidegLabel_5.Text = 'phi (degree):';
            app.Geometry_phi_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.Geometry_phi_EditField.Limits = [0 180];
            app.Geometry_phi_EditField.ValueDisplayFormat = '%.2f';
            app.Geometry_phi_EditField.ValueChangedFcn = createCallbackFcn(app, @Geometry_phi_EditFieldValueChanged, true);
            app.Geometry_phi_EditField.Tooltip = {'The azimuthal angle (in degrees) between the photon momentum vector relative to the projection of the photoelectron vector on to the plane perpendicular to the electric field vector (i.e. normal emission = 0)'};
            app.PEditFieldLabel = uilabel(app.Geometry_GridLayout);
            app.PEditFieldLabel.Text = 'P:';
            app.Geometry_P_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.Geometry_P_EditField.Limits = [0 1];
            app.Geometry_P_EditField.ValueDisplayFormat = '%.2f';
            app.Geometry_P_EditField.ValueChangedFcn = createCallbackFcn(app, @Geometry_P_EditFieldValueChanged, true);
            app.Geometry_P_EditField.Tooltip = {'Scalar of degree of polarization, where 1 or 0 is equivalent to full linear polarization, and 0.5 is equivalent to unpolarized light.'};
            app.Geometry_P_EditField.Value = 1;
            app.ElectronKineticEnergyeVLabel = uilabel(app.Geometry_GridLayout);
            app.ElectronKineticEnergyeVLabel.Text = 'Electron Kin. Energy (eV):';
            app.ElectronKineticEnergy_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.ElectronKineticEnergy_EditField.Limits = [0 Inf];
            app.ElectronKineticEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.ElectronKineticEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @ElectronKineticEnergy_EditFieldValueChanged, true);
            app.ElectronKineticEnergy_EditField.Tooltip = {'Photoelectron Kinetic Energy (eV); used to calculate the probing depth.'};
            app.ElectronKineticEnergy_EditField.Value = 2000;
            app.ProbingDepthnmLabel = uilabel(app.Geometry_GridLayout);
            app.ProbingDepthnmLabel.Text = 'Info. Depth (nm):';
            app.ProbingDepth_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.ProbingDepth_EditField.Limits = [0 Inf];
            app.ProbingDepth_EditField.ValueDisplayFormat = '%.2f';
            app.ProbingDepth_EditField.Editable = 'off';
            app.ProbingDepth_EditField.Tooltip = {'Output Information Depth (nm).'};
            app.ProbingDepth_GridLayout = uigridlayout(app.Geometry_GridLayout);
            app.View_ProbingDepth_Button = uibutton(app.ProbingDepth_GridLayout, 'push');
            app.View_ProbingDepth_Button.ButtonPushedFcn = createCallbackFcn(app, @View_ProbingDepth_ButtonPushed, true);
            app.View_ProbingDepth_Button.Tooltip = {'View Figure of the Information Depth.'};
            app.View_ProbingDepth_Button.Text = 'View Info. Depth';
            app.Export_ProbingDepth_Button = uibutton(app.ProbingDepth_GridLayout, 'push');
            app.Export_ProbingDepth_Button.ButtonPushedFcn = createCallbackFcn(app, @Export_ProbingDepth_ButtonPushed, true);
            app.Export_ProbingDepth_Button.Tooltip = {'Export the Information Depth data as a text-file (.txt).'};
            app.Export_ProbingDepth_Button.Text = 'Export Info. Depth Data (.txt)';
            app.ExportSF_Panel = uipanel(app.GridLayout2);
            app.ExportSF_Panel.Tooltip = {'Run the PES Model Panel.'};
            app.ExportSF_Panel.Title = 'Run the PES Model';
            app.ExportModel_GridLayout = uigridlayout(app.ExportSF_Panel);
            app.RunPESModel_Button = uibutton(app.ExportModel_GridLayout, 'push');
            app.RunPESModel_Button.ButtonPushedFcn = createCallbackFcn(app, @RunPESModel_ButtonPushed, true);
            app.RunPESModel_Button.Tooltip = {'Run the N-Layer PES Model to calculate PES Intensities.'};
            app.RunPESModel_Button.Text = 'Run PES Model!';
            app.ViewPESModel_PhotonEnergy_Button = uibutton(app.ExportModel_GridLayout, 'push');
            app.ViewPESModel_PhotonEnergy_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewPESModel_PhotonEnergy_ButtonPushed, true);
            app.ViewPESModel_PhotonEnergy_Button.Tooltip = {'View PES intensities versus photon energy.'};
            app.ViewPESModel_PhotonEnergy_Button.Text = {'View PES Model vs Photon '; 'Energy'};
            app.ViewPESModel_Theta_Button = uibutton(app.ExportModel_GridLayout, 'push');
            app.ViewPESModel_Theta_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewPESModel_Theta_ButtonPushed, true);
            app.ViewPESModel_Theta_Button.Tooltip = {'View PES intensities versus emission angle.'};
            app.ViewPESModel_Theta_Button.Text = 'View PES Model vs Theta';
            app.ExportData_PhotonEnergy_Button = uibutton(app.ExportModel_GridLayout, 'push');
            app.ExportData_PhotonEnergy_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_PhotonEnergy_ButtonPushed, true);
            app.ExportData_PhotonEnergy_Button.Tooltip = {'Export data as a text-file (.txt) of the PES intensities versus photon energy.'};
            app.ExportData_PhotonEnergy_Button.Text = {'Export Data vs Photon '; 'Energy (.txt)'};
            app.ExportData_Theta_Button = uibutton(app.ExportModel_GridLayout, 'push');
            app.ExportData_Theta_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_Theta_ButtonPushed, true);
            app.ExportData_Theta_Button.Tooltip = {'Export data as a text-file (.txt) of the PES intensities versus emission angle.'};
            app.ExportData_Theta_Button.Text = 'Export Data vs Theta (.txt)';
            app.Model_Panel = uipanel(app.GridLayout);
            app.Model_Panel.Tooltip = {'Define N-Layer Sample Panel.'};
            app.Model_Panel.Title = 'Defining the n-layer model';
            app.NLayerStack_GridLayout = uigridlayout(app.Model_Panel);
            app.ViewSampleStack_Button = uibutton(app.NLayerStack_GridLayout, 'push');
            app.ViewSampleStack_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewSampleStack_ButtonPushed, true);
            app.ViewSampleStack_Button.Tooltip = {'View the current sample stack.'};
            app.ViewSampleStack_Button.Text = 'View Sample Stack';
            app.LayerAdd_Button = uibutton(app.NLayerStack_GridLayout, 'push');
            app.LayerAdd_Button.ButtonPushedFcn = createCallbackFcn(app, @LayerAdd_ButtonPushed, true);
            app.LayerAdd_Button.Tooltip = {'Add a new layer to the sample stack.'};
            app.LayerAdd_Button.Text = 'Add New Layer (+) ';
            app.LayerDelete_Button = uibutton(app.NLayerStack_GridLayout, 'push');
            app.LayerDelete_Button.ButtonPushedFcn = createCallbackFcn(app, @LayerDelete_ButtonPushed, true);
            app.LayerDelete_Button.Tooltip = {'Remove the last layer of the sample stack.'};
            app.LayerDelete_Button.Text = 'Remove Last Layer (-) ';
            app.LayerDeleteRowN_Button = uibutton(app.NLayerStack_GridLayout, 'push');
            app.LayerDeleteRowN_Button.ButtonPushedFcn = createCallbackFcn(app, @LayerDeleteRowN_ButtonPushed, true);
            app.LayerDeleteRowN_Button.Tooltip = {'Remove a specific layer from the sample stack.'};
            app.LayerDeleteRowN_Button.Text = 'Remove Specific Layer (-#)';
            app.Layer_UITable = uitable(app.NLayerStack_GridLayout);
            app.Layer_UITable.ColumnName = {'Layer'; 'Material'; 'Core-Level'; 'Thickness (nm)'; 'Density (atm/cc)'; 'Exclude'; 'Signal (%)'};
            app.Layer_UITable.RowName = {};
            app.Layer_UITable.CellEditCallback = createCallbackFcn(app, @Layer_UITableCellEdit, true);
            app.Layer_UITable.CellSelectionCallback = createCallbackFcn(app, @Layer_UITableCellSelection, true);
            app.Layer_UITable.Tooltip = {'Table the contains all the surface layer material properties. Layer 1 is the uppermost surface.'};
            app.Bulk_UITable = uitable(app.NLayerStack_GridLayout);
            app.Bulk_UITable.ColumnName = {'Layer'; 'Material'; 'Core-Level'; 'Thickness (nm)'; 'Density (atm/cc)'; 'Exclude'; 'Signal (%)'};
            app.Bulk_UITable.RowName = {};
            app.Bulk_UITable.CellEditCallback = createCallbackFcn(app, @Bulk_UITableCellEdit, true);
            app.Bulk_UITable.Tooltip = {'Table the contains all the bulk material properties.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatBaseX_07_PES_NLayerSimulations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end