classdef MatBaseX_08_PES_CurveFitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ModelParams_Panel               matlab.ui.container.Panel
        ModelParams_GridLayout          matlab.ui.container.GridLayout
        View_PESModelLive_Button        matlab.ui.control.Button
        View_PESModelSnap_Button        matlab.ui.control.Button
        Params_Export_Button            matlab.ui.control.Button
        Params_Import_Button            matlab.ui.control.Button
        DoF_EditField                   matlab.ui.control.NumericEditField
        NumberoffitparametersLabel      matlab.ui.control.Label
        FitModel_TabGroup               matlab.ui.container.TabGroup
        FitModel_Params_Tab             matlab.ui.container.Tab
        Params_GridLayout               matlab.ui.container.GridLayout
        Params_PeakDelete_Button        matlab.ui.control.Button
        Params_PeakDeleteN_Button       matlab.ui.control.Button
        Params_PeakAdd_Button           matlab.ui.control.Button
        Params_UITable                  matlab.ui.control.Table
        Params_AutoSort_Button          matlab.ui.control.Button
        FitModel_FixParams_Tab          matlab.ui.container.Tab
        FixParams_GridLayout            matlab.ui.container.GridLayout
        FixParams_UITable               matlab.ui.control.Table
        FitModel_DynCI_Tab              matlab.ui.container.Tab
        DynCI_GridLayout                matlab.ui.container.GridLayout
        DynCI_UITable                   matlab.ui.control.Table
        DynCI_CheckBox                  matlab.ui.control.CheckBox
        FitModel_StaticCI_Tab           matlab.ui.container.Tab
        StaticCI_GridLayout             matlab.ui.container.GridLayout
        StaticCI_UITable                matlab.ui.control.Table
        StaticCI_CheckBox               matlab.ui.control.CheckBox
        OptAlg_Panel                    matlab.ui.container.Panel
        OptAlg_GridLayout               matlab.ui.container.GridLayout
        View_PESFitSpectraWithBgrnd_Button  matlab.ui.control.Button
        View_PESFitAreas_Button         matlab.ui.control.Button
        View_PESFitSpectra_Button       matlab.ui.control.Button
        OptAlg_CHI_EditField            matlab.ui.control.NumericEditField
        MeasureofBestFitLabel_2         matlab.ui.control.Label
        OptAlg_ExportFit_Button         matlab.ui.control.Button
        OptAlg_SaveFit_Button           matlab.ui.control.Button
        OptAlg_RunOpt_Button            matlab.ui.control.Button
        OptAlg_SSR_EditField            matlab.ui.control.NumericEditField
        MeasureofBestFitLabel           matlab.ui.control.Label
        OptAlg_FinDiffRelStep_EditField  matlab.ui.control.NumericEditField
        NumberoffitparametersLabel_5    matlab.ui.control.Label
        OptAlg_FuncTol_EditField        matlab.ui.control.NumericEditField
        NumberoffitparametersLabel_4    matlab.ui.control.Label
        OptAlg_MaxFuncEval_EditField    matlab.ui.control.NumericEditField
        NumberoffitparametersLabel_2    matlab.ui.control.Label
        OptAlg_MaxIter_EditField        matlab.ui.control.NumericEditField
        NumberoffitparametersLabel_3    matlab.ui.control.Label
        OptAlg_Type_DropDown            matlab.ui.control.DropDown
        SolverTypeLabel                 matlab.ui.control.Label
        PES_Bgrnd_Panel                 matlab.ui.container.Panel
        PES_Bgrnd_GridLayout            matlab.ui.container.GridLayout
        AutoFitConstantOffset_CheckBox  matlab.ui.control.CheckBox
        PES_Bgrnd_ExportData_Button     matlab.ui.control.Button
        PES_Bgrnd_ViewRawWithBgrnd_Button  matlab.ui.control.Button
        PES_Bgrnd_TougArgs_Panel        matlab.ui.container.Panel
        PES_Bgrnd_TougArgs_GridLayout   matlab.ui.container.GridLayout
        PES_Bgrnd_Tou_D_EditField       matlab.ui.control.NumericEditField
        DParameterEditFieldLabel        matlab.ui.control.Label
        PES_Bgrnd_Tou_C_EditField       matlab.ui.control.NumericEditField
        CParameterEditFieldLabel        matlab.ui.control.Label
        PES_Bgrnd_Offset_EditField      matlab.ui.control.NumericEditField
        ConstantOffsetEditFieldLabel    matlab.ui.control.Label
        PES_Bgrnd_Win_EditField         matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        PES_Bgrnd_SOArgs_Panel          matlab.ui.container.Panel
        PES_Bgrnd_SOArgs_GridLayout     matlab.ui.container.GridLayout
        PES_Bgrnd_SO_Delta_EditField    matlab.ui.control.NumericEditField
        DeltaLabel                      matlab.ui.control.Label
        PES_Bgrnd_SO_Lambda_EditField   matlab.ui.control.NumericEditField
        LambdaLabel                     matlab.ui.control.Label
        PES_Bgrnd_RHS_EditField         matlab.ui.control.NumericEditField
        RHSEndEditFieldLabel            matlab.ui.control.Label
        PES_Bgrnd_LHS_EditField         matlab.ui.control.NumericEditField
        LHSStartEditFieldLabel          matlab.ui.control.Label
        PES_Bgrnd_PolyArgs_Panel        matlab.ui.container.Panel
        PES_Bgrnd_PolyArgs_GridLayout   matlab.ui.container.GridLayout
        PES_Bgrnd_PolyOrder_EditField   matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        PES_Bgrnd_Type_DropDown         matlab.ui.control.DropDown
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        ViewRawPES_Button               matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        RESET_Button                    matlab.ui.control.Button
        LOAD_Button                     matlab.ui.control.Button
        TRANSFER_Button                 matlab.ui.control.Button
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
        DataFile_Label                  matlab.ui.control.Label
        AppTitle                        matlab.ui.control.Label
        MAINMENU_GridLayout             matlab.ui.container.GridLayout
        MAINMENU_Button                 matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % 1 - Data Structure for storing all data & fits
        myData                  % Structure to store PES data
        myModel                 % Structure to store fitted PES data
        DoF                     % Degrees of freedom in the fits
        static_ci               % Checkbox value for static (=true) (or dynamic (=false)) confidence intervals
        data_parity             % Consistency check of the data parity (negative or positive x-axis?)
        % 2 - Background Properties
        bgrnd_type              % Background Type
        bgrnd_LHS               % LHS
        bgrnd_RHS               % RHS
        bgrnd_Win               % Window Size
        bgrnd_Offset            % Offset
        bgrnd_Order             % Order
        bgrnd_lambda            % Lambda
        bgrnd_delta             % Delta
        bgrnd_C                 % C
        bgrnd_D                 % D
        % 3 - PES Peak Properties
        num_of_peaks            % Total number of peaks
        tab_parameters          % Table the contains all Curve Model Parameters
        tab_dyn_ci              % Table the contains all Dynamic Confidence Intervals
        tab_static_ci           % Table the contains all Static Confidence Intervals
        tab_fix_params          % Table the contains all fixed parameters
        % 4 - Optimization Properties
        solver_Type             % Solver Type
        solver_MaxIter          % Maximum Number of Iterations
        solver_FuncTol          % Minimum Function Tolerance
        solver_MaxFuncEval      % MAximum Number of Function Evaluations
        solver_FinDiffRelStep   % Finite Difference Relative Step
    end
    
    % Private methods available to the app only
    methods (Access = private)
        %% Open Figure Locations 
        % Open Location of MainMenu App
        function open_MainMenu_to_left_of_App(app)
            figHandles      = findall(0, 'Type', 'figure', 'Visible', 'on');
            fig             = figure(figHandles(1));
            fig.Position(1) = app.UIFigure.Position(1);
            fig.Position(2) = app.UIFigure.Position(2);
        end
        %% General Functions
        % Define list of PES Background Types
        function bgrnd_types = define_pes_bgrnd_types(~)
            bgrnd_types = {...
                'None',...
                'L (Linear)',...
                'P (Polynomial)',...
                'S (Shirley)',...
                'SO (Shirley-Offset)',...
                'SS (Shirley-Smart)',...
                'T1 (Tougaard1)',...
                'T2 (Tougaard2)'};
        end

        % Find the PES Background Type used
        function list_of_pes_bgrnd_id = define_pes_bgrnd_id(~)
            list_of_pes_bgrnd_id   = ["None","L","P","S", "SO", "SS", "T1", "T2"];
        end

        % Extract all the possible optimization algorithms
        function optimization_types = define_optimization_types(~)
            optimization_types = {...
                'lsqcurvefit (Least Squares Fitting)',...
                'fmincon (Functional Minimization Fit)',...
                'simulannealbnd (Simulated Annealing Fitting)',...
                'fmincon (Functional Minimization Fit - Weighted Peaks)',...
                'simulannealbnd (Simulated Annealing Fitting - Weighted Peaks)'};
        end

        % Find the PES Background Type used
        function list_of_optimization_id = define_optimization_id(~)
            list_of_optimization_id   = ["lsqcurvefit","fmincon","simulannealbnd","fmincon-peaks", "simulannealbnd-peaks"];
        end

        % Extract all the possible PES Peak Types
        function peak_types = define_pes_peak_types(~)
            peak_types = {...
                'G (Gaussian)',...
                'L (Lorentizan)',...
                'DS (Doniach)',...
                'sGL (Voigt)',...
                'sGLA (Voigt)',...
                'pGL (Voigt)',...
                'pGLA (Voigt)'};
        end

        % Extract all the possible PES Peak Types
        function [BE, LSE, LSI] = find_core_level_props(app, label)
            % 1 - Initializing variables
            user_label      = string(label);
            % 2 - Extract all possible core-levels
            atom_be_table   = read_spectroscopy_element_data("be", []);
            list_of_clevels = strcat(atom_be_table.ATOM_SYMB, atom_be_table.SHELL);
            % 3 - Find whether the user label is a known core-level
            idx     = find(contains(list_of_clevels, user_label));
            if isempty(idx)
                BE = []; LSE = []; LSI = [];
            else
                clevel = ""; be = [];
                % --- For a SINGLET
                if isscalar(idx)
                    % -- Finding BE and LSE
                    be          = atom_be_table.BE_eV(idx);
                    BE          = (app.data_parity)*min(be(:));
                    LSE         = 0;
                    LSI         = 0;
                % --- For a DOUBLET
                elseif length(idx) == 2
                    % -- Finding BE and LSE
                    for i = 1:length(idx)
                        clevel(i)   = string(list_of_clevels(idx(i)));
                        be(i)       = atom_be_table.BE_eV(idx(i));
                    end
                    BE      = (app.data_parity)*min(be(:));
                    LSE     = -1*((app.data_parity)).*diff(be(:));
                    % -- Finding LSI
                    if find(contains(clevel(1), "p"));      LSI = 0.5;
                    elseif find(contains(clevel(1), "d"));  LSI = 0.66;
                    elseif find(contains(clevel(1), "f"));  LSI = 0.75;
                    else;                                   LSI = 0;
                    end
                else; BE = []; LSE = []; LSI = [];
                end
            end
        end

        %% Initialization Functions
        % Initialize UI elements
        function init_ui_on_start(app)
            % 1 - Initializing fields
            % -- Coloring the edit-fields that can be changed
            edit_color = [0.85 1 1];
            app.PES_Bgrnd_Type_DropDown.BackgroundColor                 = edit_color;
            app.PES_Bgrnd_LHS_EditField.BackgroundColor                 = edit_color;
            app.PES_Bgrnd_RHS_EditField.BackgroundColor                 = edit_color;
            app.PES_Bgrnd_Win_EditField.BackgroundColor                 = edit_color;
            app.PES_Bgrnd_Offset_EditField.BackgroundColor              = edit_color;
            app.OptAlg_Type_DropDown.BackgroundColor                    = edit_color;
            app.OptAlg_MaxIter_EditField.BackgroundColor                = edit_color;
            app.OptAlg_MaxFuncEval_EditField.BackgroundColor            = edit_color;
            app.OptAlg_FuncTol_EditField.BackgroundColor                = edit_color;
            app.OptAlg_FinDiffRelStep_EditField.BackgroundColor         = edit_color;
            add_color       = [0.8, 0.95, 0.8];
            remove_color    = [0.95, 0.8, 0.8];
            app.Params_PeakAdd_Button.BackgroundColor                   = add_color;
            app.Params_PeakDelete_Button.BackgroundColor                = remove_color;
            app.Params_PeakDeleteN_Button.BackgroundColor               = remove_color;
            misc_color    = [0.7, 0.7, 0.9];
            app.Params_AutoSort_Button.BackgroundColor                  = misc_color;
            % 2 - Disabling all data processing until data is loaded
            % --- General UI elements
            app.DataFile_Label.Text                         = '<<  select a raw *.txt file or processed *.mat data file to begin fitting';
            app.RESET_Button.Enable                         = 'off';
            app.ViewRawPES_Button.Enable                    = 'off';
            % --- Model Parameters panel
            app.ModelParams_Panel.Enable                    = 'off';
            % --- Background panel
            app.PES_Bgrnd_Panel.Enable                      = 'off';
            % --- Optimization panel
            app.OptAlg_Panel.Enable                         = 'off';
        end

        % Initialize background properties
        function init_pes_bgrnd_properties(app)
            init_idx = 2;
            bgrnd_types                         = define_pes_bgrnd_types(app);
            app.PES_Bgrnd_Type_DropDown.Items   = bgrnd_types;
            app.PES_Bgrnd_Type_DropDown.Value   = app.PES_Bgrnd_Type_DropDown.Items{init_idx};
            list_of_bgrnd_id                    = define_pes_bgrnd_id(app);
            app.bgrnd_type                      = list_of_bgrnd_id(init_idx);
            app.bgrnd_LHS                       = app.PES_Bgrnd_LHS_EditField.Value;
            app.bgrnd_RHS                       = app.PES_Bgrnd_RHS_EditField.Value;
            app.bgrnd_Win                       = app.PES_Bgrnd_Win_EditField.Value;
            app.bgrnd_Offset                    = app.PES_Bgrnd_Offset_EditField.Value;
            app.bgrnd_Order                     = app.PES_Bgrnd_PolyOrder_EditField.Value;
            app.bgrnd_lambda                    = app.PES_Bgrnd_SO_Lambda_EditField.Value;
            app.bgrnd_delta                     = app.PES_Bgrnd_SO_Delta_EditField.Value;
            app.bgrnd_C                         = app.PES_Bgrnd_Tou_C_EditField.Value;
            app.bgrnd_D                         = app.PES_Bgrnd_Tou_D_EditField.Value;
        end

        % Initialize optimization properties
        function init_optimization_properties(app)
            init_idx = 1;
            optimization_types                  = define_optimization_types(app);
            app.OptAlg_Type_DropDown.Items      = optimization_types;
            app.OptAlg_Type_DropDown.Value      = app.OptAlg_Type_DropDown.Items{init_idx};
            list_of_optimization_id             = define_optimization_id(app);
            app.solver_Type                     = list_of_optimization_id(init_idx);
            app.solver_MaxIter                  = app.OptAlg_MaxIter_EditField.Value;
            app.solver_FuncTol                  = app.OptAlg_FuncTol_EditField.Value;
            app.solver_MaxFuncEval              = app.OptAlg_MaxFuncEval_EditField.Value;
            app.solver_FinDiffRelStep           = app.OptAlg_FinDiffRelStep_EditField.Value;
        end

        % Initialize parameter table properties
        function init_parameters_properties(app)
            Si2p_BE     = -99.9;
            Si2p_INT    = 1;
            Si2p_FWHM   = 1;
            Si2p_MR     = 0.5;
            Si2p_LSE    = -0.605;
            Si2p_LSI    = 0.5;
            Si2p_LSW    = 0.0;
            Si2p_ASY    = 0.0;
            % 4.1 - Initialising the parameters
            app.num_of_peaks                        = 1;
            peak_types                              = define_pes_peak_types(app);
            app.Params_UITable.ColumnFormat         = {'numeric', 'char', peak_types, 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric'};
            app.Params_UITable.ColumnName           = {'Peak', 'Label', 'Type', 'BE', 'INT', 'FWHM', 'MR', 'LSE', 'LSI', 'LSW', 'ASY'};
            app.Params_UITable.ColumnEditable       = [false,true,true,true,true,true,true,true,true,true,true];
            app.Params_UITable.Data                 = {1,'Si2p',peak_types{5},Si2p_BE,Si2p_INT,Si2p_FWHM,Si2p_MR,Si2p_LSE,Si2p_LSI,Si2p_LSW,Si2p_ASY};
            app.tab_parameters                      = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
            % 4.2 - Initialising the static confidence intervals
            app.static_ci                           = false;
            app.StaticCI_UITable.ColumnFormat       = {'numeric', 'char', peak_types, [], [], [], [], [], [], [], []};
            app.StaticCI_UITable.ColumnName         = app.Params_UITable.ColumnName;
            app.StaticCI_UITable.ColumnEditable     = [false,false,false,true,true,true,true,true,true,true,true];
            app.StaticCI_UITable.Data               = {1,'Si2p',peak_types{5},'-102:96','0:2','0.1:2','0:1','-0.8:-0.5','0.4:0.6','0:0','0:0'};
            app.tab_static_ci                       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
            % 4.3 - Initialising the dynamic confidence intervals
            app.DynCI_UITable.ColumnFormat          = app.Params_UITable.ColumnFormat;
            app.DynCI_UITable.ColumnName            = app.Params_UITable.ColumnName;
            app.DynCI_UITable.ColumnEditable        = [false,false,false,true,true,true,true,true,true,true,true];
            app.DynCI_UITable.Data                  = {1,'Si2p',peak_types{5},0.50,0.50,0.50,0.50,0.25,0.25,0,0};
            app.tab_dyn_ci  = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            % 3.4 - Initialising the fixed parameters
            app.FixParams_UITable.ColumnFormat      = {'numeric', 'char', peak_types, 'logical', 'logical', 'logical', 'logical', 'logical', 'logical', 'logical', 'logical'};
            app.FixParams_UITable.ColumnName        = app.Params_UITable.ColumnName;
            app.FixParams_UITable.ColumnEditable    = [false,false,false,true,true,true,true,true,true,true,true];
            app.FixParams_UITable.Data              = {1,'Si2p',peak_types{5},false,false,false,false,false,false,false,false};
            app.tab_fix_params                      = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
            % -- Ensure color is consistent
            colorList   = read_line_colors(app.num_of_peaks+1);
            for i = 1:app.num_of_peaks
                temp_col = uistyle('BackgroundColor', colorList{i}); 
                addStyle(app.Params_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.StaticCI_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.DynCI_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.FixParams_UITable, temp_col, 'cell', [i, 1]);
            end
        end

        % Update the UI elements upon loading in the PES data
        function init_ui_on_load(app)
            % 1 - Updating handle text and gui elements
            % - 1.1 - Show the name, type and information of the ARPES data
            app.DataFile_Label.Text                         = app.myData.FileName;
            % - 1.2 - Activating UI Elements
            app.RESET_Button.Enable                         = 'on';
            app.ViewRawPES_Button.Enable                    = 'on';
            % --- Model Parameters panel
            app.ModelParams_Panel.Enable                    = 'on';
            % --- Background panel
            app.PES_Bgrnd_Panel.Enable                      = 'on';
            % --- Optimization panel
            app.OptAlg_Panel.Enable                         = 'on';
            % 2 - Running UI call-backs to ensure consistency
            if mean(app.myData.xdat) > 0; app.data_parity = 1;
            else; app.data_parity = -1;
            end
            % - If the original model does not lie within the Region Of Interest
            if app.PES_Bgrnd_LHS_EditField.Value < app.myData.xdat_lims(1) || app.PES_Bgrnd_RHS_EditField.Value > app.myData.xdat_lims(2)
                % - UI Background arguments
                app.PES_Bgrnd_LHS_EditField.Value       = ceil(app.myData.xdat_lims(1)); 
                app.bgrnd_LHS                           = app.PES_Bgrnd_LHS_EditField.Value;
                app.PES_Bgrnd_RHS_EditField.Value       = floor(app.myData.xdat_lims(2));
                app.bgrnd_RHS                           = app.PES_Bgrnd_RHS_EditField.Value;
                % - UI Parameters & Confidence arguments
                for i = 1:app.num_of_peaks
                    app.Params_UITable.Data{i,4}        = mean(app.myData.xdat(:));
                    app.Params_UITable.Data{i,5}        = max(app.myData.ydat(:));
                    app.DynCI_UITable.Data{i,5}         = 0.75*max(app.myData.ydat(:));
                    app.StaticCI_UITable.Data{i,4}      = char(sprintf("%.2f:%.2f", ceil(min(app.myData.xdat(:))), floor(max(app.myData.xdat(:)))));
                    app.StaticCI_UITable.Data{i,5}      = char(sprintf("%.1f:%.1f", 0, max(app.myData.ydat(:))));
                end
                % - Update UI Tables
                app.tab_parameters          = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
                app.tab_static_ci           = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                app.tab_dyn_ci              = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            end
            % - Choosing the type of confidence intervals to use
            if app.static_ci == true;       app.StaticCI_CheckBox.Value = true; app.StaticCI_CheckBoxValueChanged();
            else;                           app.DynCI_CheckBox.Value = true; app.DynCI_CheckBoxValueChanged();
            end
            % - Update dependents
            PES_Bgrnd_Type_DropDownValueChanged(app);
            update_single_dependents(app);
        end

        %% Update & Calculator Functions
        % Update the UI elements depending on the PES background Type
        function new_value = update_pes_bgrnd_args(app, value)
            % 1 - Finding the background type selected
            bgrnd_types         = define_pes_bgrnd_types(app);
            bgrnd_idx           = find(contains(string(bgrnd_types),value), 1);
            list_of_bgrnd_id    = define_pes_bgrnd_id(app);
            new_value           = list_of_bgrnd_id(bgrnd_idx);
            % 2 - Validity check on the background
            noedit_color    = [1 1 1];
            edit_color      = [0.85 1 1];
            Type = char(lower(new_value));
            switch Type
                case lower({'None','L','S','SS'})      
                    app.PES_Bgrnd_PolyArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_SOArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_TougArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_PolyOrder_EditField.BackgroundColor   = noedit_color;
                    app.PES_Bgrnd_SO_Lambda_EditField.BackgroundColor   = noedit_color;
                    app.PES_Bgrnd_SO_Delta_EditField.BackgroundColor    = noedit_color;
                    app.PES_Bgrnd_Tou_C_EditField.BackgroundColor       = noedit_color;
                    app.PES_Bgrnd_Tou_D_EditField.BackgroundColor       = noedit_color;

                case lower({'SO'})                    
                    app.PES_Bgrnd_PolyArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_SOArgs_Panel.Enable = 'on';
                    app.PES_Bgrnd_TougArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_PolyOrder_EditField.BackgroundColor   = noedit_color;
                    app.PES_Bgrnd_SO_Lambda_EditField.BackgroundColor   = edit_color;
                    app.PES_Bgrnd_SO_Delta_EditField.BackgroundColor    = edit_color;
                    app.PES_Bgrnd_Tou_C_EditField.BackgroundColor       = noedit_color;
                    app.PES_Bgrnd_Tou_D_EditField.BackgroundColor       = noedit_color;

                case lower({'P'})                    
                    app.PES_Bgrnd_PolyArgs_Panel.Enable = 'on';
                    app.PES_Bgrnd_SOArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_TougArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_PolyOrder_EditField.BackgroundColor   = edit_color;
                    app.PES_Bgrnd_SO_Lambda_EditField.BackgroundColor   = noedit_color;
                    app.PES_Bgrnd_SO_Delta_EditField.BackgroundColor    = noedit_color;
                    app.PES_Bgrnd_Tou_C_EditField.BackgroundColor       = noedit_color;
                    app.PES_Bgrnd_Tou_D_EditField.BackgroundColor       = noedit_color;

                case lower({'T1', 'T2'})                    
                    app.PES_Bgrnd_PolyArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_SOArgs_Panel.Enable = 'off';
                    app.PES_Bgrnd_TougArgs_Panel.Enable = 'on';
                    app.PES_Bgrnd_PolyOrder_EditField.BackgroundColor   = noedit_color;
                    app.PES_Bgrnd_SO_Lambda_EditField.BackgroundColor   = noedit_color;
                    app.PES_Bgrnd_SO_Delta_EditField.BackgroundColor    = noedit_color;
                    app.PES_Bgrnd_Tou_C_EditField.BackgroundColor       = edit_color;
                    app.PES_Bgrnd_Tou_D_EditField.BackgroundColor       = edit_color;
            end

        end

        % Loading in the PES data
        function load_in_pes_data(app, PathName, FileName)
            % 2 - If a *.txt file is loaded
            if FileName(end-3:end) == '.txt'
                % 2.1 - Load in the relevant variables
                data_table              = readtable(string(PathName) + string(FileName));
                app.myData.TimeStamp    = datetime;
                app.myData.PathName     = PathName;
                app.myData.FileName     = FileName;
                app.myData.Type         = "PES";
                app.myData.hv           = [];
                app.myData.thtM         = [];
                app.myData.sweeps       = size(data_table(:,2:end-1), 2);
                app.myData.ydat_sweeps  = table2array(data_table(:,2:end-1));
                app.myData.xdat         = round(table2array(data_table(:,1)),3);
                app.myData.ydat         = round(mean(table2array(data_table(:,end)),2),3);
                app.myData.xdat_lims    = round([min(app.myData.xdat(:)), max(app.myData.xdat(:))],3);
                app.myData.ydat_lims    = round([min(app.myData.ydat(:)), max(app.myData.ydat(:))],3);
                % 2.2 - Initialising the app to the data that has been loaded
                init_ui_on_load(app);
                % 2.3 - Plotting the raw PES data
                view_raw_pes_data(app);
            % 3 - If a *.mat file is loaded
            elseif FileName(end-3:end) == '.mat'
                % 3.1 - Load in the relevant variables
                dataStr         = load(string(PathName) + string(FileName));
                dataStr         = dataStr.dataStr;
                app.myData      = dataStr.myData;
                app.myData.TimeStamp    = datetime;
                app.myData.PathName     = PathName;
                app.myData.FileName     = FileName;
                app.myModel     = dataStr.myModel;
                % 3.2 - Initialising the app to the data that has been loaded
                init_ui_on_load(app);
                % 3.3 - Updating table of parameters
                app.num_of_peaks    = dataStr.num_of_peaks;
                % 3.4 - Initialising the parameters
                app.Params_UITable.Data     = table2array(dataStr.tab_parameters);
                app.tab_parameters              = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
                % 3.5 - Initialising the static confidence intervals
                app.StaticCI_UITable.Data       = table2array(dataStr.tab_static_ci);
                app.tab_static_ci               = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                % 3.6 - Initialising the dynamic confidence intervals
                app.DynCI_UITable.Data          = table2array(dataStr.tab_dyn_ci);
                app.tab_dyn_ci                  = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                % 3.7 - Initialising the fixed parameters
                app.FixParams_UITable.Data  = table2array(dataStr.tab_fix_params);
                app.tab_fix_params              = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
                % 3.8 - Choosing the type of confidence intervals to use
                app.static_ci                   = dataStr.static_ci;
                if app.static_ci == true;       app.StaticCI_CheckBox.Value = true; app.StaticCI_CheckBoxValueChanged();
                else;                           app.DynCI_CheckBox.Value = true; app.DynCI_CheckBoxValueChanged();
                end
                % 3.9 - Updating the background subtraction
                app.PES_Bgrnd_Type_DropDown.Value               = dataStr.tab_background.Type;
                app.PES_Bgrnd_LHS_EditField.Value               = dataStr.tab_background.LHS;
                app.PES_Bgrnd_RHS_EditField.Value               = dataStr.tab_background.RHS;
                app.PES_Bgrnd_Win_EditField.Value               = dataStr.tab_background.Window;
                app.PES_Bgrnd_Offset_EditField.Value            = dataStr.tab_background.Offset;
                app.PES_Bgrnd_PolyOrder_EditField.Value         = dataStr.tab_background.Order;
                app.PES_Bgrnd_SO_Lambda_EditField.Value         = dataStr.tab_background.Lambda;
                app.PES_Bgrnd_SO_Delta_EditField.Value          = dataStr.tab_background.Delta;
                app.PES_Bgrnd_Tou_C_EditField.Value             = dataStr.tab_background.C;
                app.PES_Bgrnd_Tou_D_EditField.Value             = dataStr.tab_background.D;
                app.PES_Bgrnd_Type_DropDownValueChanged(app);
                app.bgrnd_LHS                                   = app.PES_Bgrnd_LHS_EditField.Value;
                app.bgrnd_RHS                                   = app.PES_Bgrnd_RHS_EditField.Value;
                app.bgrnd_Win                                   = app.PES_Bgrnd_Win_EditField.Value;
                app.bgrnd_Offset                                = app.PES_Bgrnd_Offset_EditField.Value;
                app.bgrnd_Order                                 = app.PES_Bgrnd_PolyOrder_EditField.Value;
                app.bgrnd_lambda                                = app.PES_Bgrnd_SO_Lambda_EditField.Value;
                app.bgrnd_delta                                 = app.PES_Bgrnd_SO_Delta_EditField.Value;
                app.bgrnd_C                                     = app.PES_Bgrnd_Tou_C_EditField.Value;
                app.bgrnd_D                                     = app.PES_Bgrnd_Tou_D_EditField.Value;
                % 3.10 - Update degrees of freedom
                app.DoF                                         = calculate_degrees_of_freedom(app);
                app.DoF_EditField.Value                  = app.DoF;
                % 3.11 - Extract current PES Model & update best fit measure
                app.myModel                                   = extract_pes_model(app);
                app.OptAlg_SSR_EditField.Value                   = app.myModel.COST_CHISQ_NORM;
                % 3.12 - View the current PES Model
                view_pes_model_snap(app);
            end
        end
        
        % Extract the PES background
        function [X, D, B, DB, ROI, LHS, RHS] = extract_pes_bgrnd(app)
            % Extracting the data
            xdat = app.myData.xdat;
            ydat = app.myData.ydat;
            % - Extracting Start & End Point Indices
            % -- Start index points
            [~, StartIndx_lb]   = min(abs(xdat - app.bgrnd_LHS - app.bgrnd_Win));
            [~, StartIndx_ub]   = min(abs(xdat - app.bgrnd_LHS + app.bgrnd_Win));
            % -- End index points
            [~, EndIndx_lb]     = min(abs(xdat - app.bgrnd_RHS - app.bgrnd_Win));
            [~, EndIndx_ub]     = min(abs(xdat - app.bgrnd_RHS + app.bgrnd_Win));
            % - ROI indexes
            StartWin_indx      	= sort([StartIndx_lb, StartIndx_ub]);
            EndWin_indx     	= sort([EndIndx_lb, EndIndx_ub]);
            % - Extracting the data points of the background over the window size
            if iscolumn(xdat);  xdat_win      = [xdat(StartWin_indx(1):StartWin_indx(2));   xdat(EndWin_indx(1):EndWin_indx(2))];
            elseif isrow(xdat); xdat_win      = [xdat(StartWin_indx(1):StartWin_indx(2)),   xdat(EndWin_indx(1):EndWin_indx(2))];
            end
            if iscolumn(ydat);  ydat_win       = [ydat(StartWin_indx(1):StartWin_indx(2));   ydat(EndWin_indx(1):EndWin_indx(2))];
            elseif isrow(ydat); ydat_win      = [ydat(StartWin_indx(1):StartWin_indx(2)),   ydat(EndWin_indx(1):EndWin_indx(2))];
            end
            % - Background subtracting the data
            Type = char(lower(app.bgrnd_type));
            switch Type
                case lower({'None','L','S','SS'});      [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset);
                case lower({'SO'});                     [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_lambda, app.bgrnd_delta);
                case lower({'P'});                      [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_Order);
                case lower({'T1'});                     [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_C);
                case lower({'T2'});                     [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_C, app.bgrnd_D);
            end
            DB = D - B;
            % - Extracting area data
            ROI     = [app.bgrnd_LHS, app.bgrnd_RHS];
            LHS     = app.bgrnd_LHS + [-app.bgrnd_Win, app.bgrnd_Win];
            RHS     = app.bgrnd_RHS + [-app.bgrnd_Win, app.bgrnd_Win];  
        end
   
        % Calculate the Degrees of Freedom of the PES fit
        function DoF = calculate_degrees_of_freedom(app)
            val     = cell2mat(app.tab_parameters{:,4:end});
            lb = []; ub = [];
            % -- Extracting the lower- and upper-bound constraints
            if app.static_ci == true
                values = app.tab_static_ci{:,4:end};
                for i = 1:size(values, 1)
                    for j = 1:size(values, 2)
                        data = round(sort(str2double(split(values{i,j},':'))), 2);
                        lb(i,j) = data(1);
                        ub(i,j) = data(2);
                    end
                end
            else
                lb      = cell2mat(app.tab_parameters{:,4:end}) - cell2mat(app.tab_dyn_ci{:,4:end});
                ub      = cell2mat(app.tab_parameters{:,4:end}) + cell2mat(app.tab_dyn_ci{:,4:end});
            end
            % -- Identifying any fixed parameters
            fixed_params = cell2mat(app.tab_fix_params{:,4:end});
            lb(fixed_params~=0) = val(fixed_params~=0);
            ub(fixed_params~=0) = val(fixed_params~=0);
            % -- Extracting the degrees of freedom
            DoF = sum(sum(ub-lb~=0));
        end

        % Function that calculates all single dependent variables
        function update_single_dependents(app)
            if ~isempty(app.myData) || ~isempty(app.myModel)
                % 1 - Update degrees of freedom
                app.DoF                             = calculate_degrees_of_freedom(app);
                app.DoF_EditField.Value             = app.DoF;
                % 2 - Extract current PES Model & update best fit measures
                app.myModel                         = extract_pes_model(app);
                app.OptAlg_SSR_EditField.Value      = app.myModel.COST_R2;
                app.OptAlg_CHI_EditField.Value      = app.myModel.COST_CHISQ;
                % 3 - Live update of figure if necessary
                update_pes_model_live(app);
            end
        end

        %% PES Model Determination & Fitting Algorithms
        % Calculate the PES Model
        function myModel = extract_pes_model(app)
            % - 1 - Background subtraction of data
            xdat = app.myData.xdat;
            ydat = app.myData.ydat;
            % 1.1 - Extracting Start & End Point Indices
            % -- Start index points
            [~, StartIndx_lb]   = min(abs(xdat - app.bgrnd_LHS - app.bgrnd_Win));
            [~, StartIndx_ub]   = min(abs(xdat - app.bgrnd_LHS + app.bgrnd_Win));
            % -- End index points
            [~, EndIndx_lb]     = min(abs(xdat - app.bgrnd_RHS - app.bgrnd_Win));
            [~, EndIndx_ub]     = min(abs(xdat - app.bgrnd_RHS + app.bgrnd_Win));
            % - ROI indexes
            StartWin_indx      	= sort([StartIndx_lb, StartIndx_ub]);
            EndWin_indx     	= sort([EndIndx_lb, EndIndx_ub]);
            % - Extracting the data points of the background over the window size
            if iscolumn(xdat);  xdat_win      = [xdat(StartWin_indx(1):StartWin_indx(2));   xdat(EndWin_indx(1):EndWin_indx(2))];
            elseif isrow(xdat); xdat_win      = [xdat(StartWin_indx(1):StartWin_indx(2)),   xdat(EndWin_indx(1):EndWin_indx(2))];
            end
            if iscolumn(ydat); ydat_win       = [ydat(StartWin_indx(1):StartWin_indx(2));   ydat(EndWin_indx(1):EndWin_indx(2))];
            elseif isrow(ydat); ydat_win      = [ydat(StartWin_indx(1):StartWin_indx(2)),   ydat(EndWin_indx(1):EndWin_indx(2))];
            end
            % 1.2 - Background subtracting the data
            Type = char(lower(app.bgrnd_type));
            switch Type
                case lower({'None','L','S','SS'});      [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset);
                case lower({'SO'});                     [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_lambda, app.bgrnd_delta);
                case lower({'P'});                      [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_Order);
                case lower({'T1'});                     [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_C);
                case lower({'T2'});                     [X, D, B] = pes_bgrnd(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_C, app.bgrnd_D);
            end
            % 1.3 - Creating a table with the background parameters
            tab_background = table(string(app.PES_Bgrnd_Type_DropDown.Value));     tab_background.Properties.VariableNames{1} = 'Type';
            tab_background{:,2} = app.bgrnd_LHS;        tab_background.Properties.VariableNames{2} = 'LHS';
            tab_background{:,3} = app.bgrnd_RHS;        tab_background.Properties.VariableNames{3} = 'RHS';
            tab_background{:,4} = app.bgrnd_Win;        tab_background.Properties.VariableNames{4} = 'Window';
            tab_background{:,5} = app.bgrnd_Offset;     tab_background.Properties.VariableNames{5} = 'Offset';
            tab_background{:,6} = app.bgrnd_Order;      tab_background.Properties.VariableNames{6} = 'Order';
            tab_background{:,7} = app.bgrnd_lambda;     tab_background.Properties.VariableNames{7} = 'Lambda';
            tab_background{:,8} = app.bgrnd_delta;      tab_background.Properties.VariableNames{8} = 'Delta';
            tab_background{:,9} = app.bgrnd_C;          tab_background.Properties.VariableNames{9} = 'C';
            tab_background{:,10} = app.bgrnd_D;         tab_background.Properties.VariableNames{10} = 'D';
            % - 2 - Extracting all model data
            % 2.1 - Extracting model parameters
            Labels      = string(app.tab_parameters{:,2});
            cTYPE       = string(app.tab_parameters{:,3});
            BE          = cell2mat(app.tab_parameters{:,4});
            INT         = cell2mat(app.tab_parameters{:,5});
            FWHM        = cell2mat(app.tab_parameters{:,6});
            MR          = cell2mat(app.tab_parameters{:,7});
            LSE         = cell2mat(app.tab_parameters{:,8});
            LSI         = cell2mat(app.tab_parameters{:,9});
            LSW         = cell2mat(app.tab_parameters{:,10});
            ASY         = cell2mat(app.tab_parameters{:,11});
            % 2.2 - Extracting model confidence intervals
            % -- Extracting the lower- and upper-bound constraints
            if app.static_ci == true
                values = app.tab_static_ci{:,4:end};
                for i = 1:size(values, 1)
                    for j = 1:size(values, 2)
                        data = round(sort(str2double(split(values{i,j},':'))), 2);
                        lb(i,j) = data(1);
                        ub(i,j) = data(2);
                    end
                end
                BE_ci       = [lb(:,1), ub(:,1)];
                INT_ci      = [lb(:,2), ub(:,2)];
                FWHM_ci     = [lb(:,3), ub(:,3)];
                MR_ci       = [lb(:,4), ub(:,4)];
                LSE_ci      = [lb(:,5), ub(:,5)];
                LSI_ci      = [lb(:,6), ub(:,6)];
                LSW_ci      = [lb(:,7), ub(:,7)];
                ASY_ci      = [lb(:,8), ub(:,8)];
            else
                BE_ci       = BE    + cell2mat(app.tab_dyn_ci{:,4}).*[-1,1];
                INT_ci      = INT   + cell2mat(app.tab_dyn_ci{:,5}).*[-1,1];
                FWHM_ci     = FWHM  + cell2mat(app.tab_dyn_ci{:,6}).*[-1,1];
                MR_ci       = MR    + cell2mat(app.tab_dyn_ci{:,7}).*[-1,1];
                LSE_ci      = LSE   + cell2mat(app.tab_dyn_ci{:,8}).*[-1,1];
                LSI_ci      = LSI   + cell2mat(app.tab_dyn_ci{:,9}).*[-1,1];
                LSW_ci      = LSW   + cell2mat(app.tab_dyn_ci{:,10}).*[-1,1];
                ASY_ci      = ASY   + cell2mat(app.tab_dyn_ci{:,11}).*[-1,1];
            end
            % 2.3 - Checking for any fixed parameters
            for i = 1:app.num_of_peaks
                if app.tab_fix_params{i,4}{1} == true;  BE_ci(i,:) = BE(i).*[1,1]; end
                if app.tab_fix_params{i,5}{1} == true;  INT_ci(i,:) = INT(i).*[1,1]; end
                if app.tab_fix_params{i,6}{1} == true;  FWHM_ci(i,:) = FWHM(i).*[1,1]; end
                if app.tab_fix_params{i,7}{1} == true;  MR_ci(i,:) = MR(i).*[1,1]; end
                if app.tab_fix_params{i,8}{1} == true;  LSE_ci(i,:) = LSE(i).*[1,1]; end
                if app.tab_fix_params{i,9}{1} == true;  LSI_ci(i,:) = LSI(i).*[1,1]; end
                if app.tab_fix_params{i,10}{1} == true;  LSW_ci(i,:) = LSW(i).*[1,1]; end
                if app.tab_fix_params{i,11}{1} == true; ASY_ci(i,:) = ASY(i).*[1,1]; end
            end
            % 2.4 - Validity check on the confidence intervals to ensure they stay within physical values
            INT_ci(INT_ci<0) = 0;       % --- if INT < 0, then force it to 0
            FWHM_ci(FWHM_ci<0) = 0;     % --- if FWHM < 0, then make it 0
            MR_ci(MR_ci<0) = 0;         % --- if MR < 0, then make it 0
            MR_ci(MR_ci>1) = 1;         % --- if MR > 1, then make it 1
            LSI_ci(LSI_ci<0) = 0;       % --- if LSI < 0, then make it 0
            LSW_ci(LSW_ci<0) = 0;       % --- if LSW < 0, then make it 0
            ASY_ci(ASY_ci<-1) = -1;     % --- if ASY < -1, then make it -1
            ASY_ci(ASY_ci>1) = 1;       % --- if ASY > 1, then make it 1
            % -- Force MR and ASY to be zero for certain curve types
            peak_types = define_pes_peak_types(app);
            for i = 1:app.num_of_peaks
                % Gaussian & Lorentzian Condition
                if strcmpi(cTYPE(i), peak_types(1)) || strcmpi(cTYPE(i), peak_types(2))
                    MR(i)       = 0;
                    MR_ci(i,:)  = 0 .* MR_ci(i,:);
                    ASY(i)      = 0;
                    ASY_ci(i,:) = 0 .* ASY_ci(i,:);
                % Doniach-Sunjic Condition
                elseif strcmpi(cTYPE(i), peak_types(3))
                    MR(i)       = 0;
                    MR_ci(i,:)  = 0 .* MR_ci(i,:);
                    if ASY(i)<0; ASY(i) = 0; end
                    if ASY(i)>1; ASY(i) = 1; end
                    temp_ASY    = ASY_ci(i,:);
                    temp_ASY(temp_ASY<0) = 0;
                    temp_ASY(temp_ASY>1) = 1;
                    ASY_ci(i,:) = temp_ASY;
                end
            end
            % 2.5 - Extract the curve components for each peak
            M = zeros(size(X));
            cM = {};
            for i = 1:app.num_of_peaks
                % -- Extracting the component intensities
                cM{i} = pes_spec_int(X,...
                    app.tab_parameters{i,3}{1},...
                    app.tab_parameters{i,4}{1}, app.tab_parameters{i,5}{1}, app.tab_parameters{i,6}{1},...
                    app.tab_parameters{i,7}{1}, app.tab_parameters{i,8}{1}, app.tab_parameters{i,9}{1},...
                    app.tab_parameters{i,10}{1}, app.tab_parameters{i,11}{1});
                M       = M + cM{i};
                AREA(i) = trapz(X, cM{i});
            end
            M(isnan(M)) = 0;
            AREA0 = AREA ./ trapz(X, M);
            % 2.6 - Determination of the residuals and chi-squared
            R                   = M - (D - B);              % Residuals
            COST_R2             = sum(R.^2);                % Cost Function: Sum of Squared Residuals
            COST_CHISQ          = sum(R.^2 ./ abs(M));      % Cost Function: Chi-squared
            COST_CHISQ_NORM     = COST_CHISQ ./ (length(R) - 2);                            % Cost Function: Normalised Chi-squared
            COST_CHISQ_RED      = sum(R.^2 ./ abs(M)) ./ app.DoF ./ (length(R) - 2);        % Cost Function: Reduced Chi-squared
            % - 3 - Storing all the relevant data
            myModel = struct();
            % -- Storing the initial arguments
            myModel.tab_background      = tab_background;
            myModel.tab_parameters      = app.tab_parameters;
            myModel.static_ci           = app.static_ci;
            myModel.tab_static_ci       = app.tab_static_ci;
            myModel.tab_dyn_ci          = app.tab_dyn_ci;
            myModel.tab_fix_params      = app.tab_fix_params;
            myModel.num_of_peaks        = app.num_of_peaks;
            % -- Storing original data
            myModel.xdat_win            = xdat_win;
            myModel.ydat_win            = ydat_win;
            myModel.xdat                = xdat;
            myModel.ydat                = ydat;
            % -- Storing ROI data
            myModel.X                   = X;
            myModel.D 	                = D;
            myModel.B                   = B;
            myModel.DB 	                = D - B;
            myModel.M 	                = M;
            myModel.cM 	                = cM;
            % -- Extracting the RESIDUALS (R = D - (M + B))
            myModel.R 	                = R;
            myModel.COST_R2 	        = COST_R2;
            myModel.COST_CHISQ 	        = COST_CHISQ;
            myModel.COST_CHISQ_NORM     = COST_CHISQ_NORM;
            myModel.COST_CHISQ_RED 	    = COST_CHISQ_RED;
            % -- Storing the degrees of freedom
            myModel.DoF                 = app.DoF;
            % -- Storing all relevant confidence intervals
            myModel.BE_ci               = BE_ci;
            myModel.INT_ci              = INT_ci;
            myModel.FWHM_ci             = FWHM_ci;
            myModel.MR_ci               = MR_ci;
            myModel.LSE_ci              = LSE_ci;
            myModel.LSI_ci              = LSI_ci;
            myModel.LSW_ci              = LSW_ci;
            myModel.ASY_ci              = ASY_ci;
            % -- Storing all relevant parameters
            myModel.Label               = Labels;
            myModel.cTYPE               = cTYPE;
            myModel.BE                  = BE;
            myModel.INT                 = INT;
            myModel.FWHM                = FWHM;
            myModel.MR                  = MR;
            myModel.LSE                 = LSE;
            myModel.LSI                 = LSI;
            myModel.LSW                 = LSW;
            myModel.ASY                 = ASY;
            myModel.AREA                = abs(AREA');
            myModel.AREA0               = abs(AREA0');
            % -- Storing the parameters to be used for the fit function
            myModel.x0                  = [myModel.BE; myModel.INT; myModel.FWHM; myModel.MR; myModel.LSE; myModel.LSI; myModel.LSW; myModel.ASY];
            myModel.lb                  = [myModel.BE_ci(:,1); myModel.INT_ci(:,1); myModel.FWHM_ci(:,1); myModel.MR_ci(:,1); myModel.LSE_ci(:,1); myModel.LSI_ci(:,1); myModel.LSW_ci(:,1); myModel.ASY_ci(:,1)];
            myModel.ub                  = [myModel.BE_ci(:,2); myModel.INT_ci(:,2); myModel.FWHM_ci(:,2); myModel.MR_ci(:,2); myModel.LSE_ci(:,2); myModel.LSI_ci(:,2); myModel.LSW_ci(:,2); myModel.ASY_ci(:,2)];
        end

        % Run the Optimization algorithm
        function run_optimization(app)
            % 1 - Running the Optimization algorithm
            Type = char(lower(app.solver_Type));
            switch Type
                % 1.1 - LOCAL SOLVER: BOUNDED LEAST SQUARES FITTING METHOD FOR NON-LINEAR CURVE FITTING
                case lower({'lsqcurvefit (Least Squares Fitting)','lsqcurvefit','lsq','lsq'})
                    options = optimoptions(@lsqcurvefit,...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'MaxIterations', app.solver_MaxIter,...
                        'FinDiffRelStep', app.solver_FinDiffRelStep,...
                        'FinDiffType', 'central');
                    [params,meta_fits.rnorm,meta_fits.resid,meta_fits.exitflag,meta_fits.output,meta_fits.lambda,meta_fits.J] =...
                        lsqcurvefit(@(x,X) full_pes_function(app,x,app.myModel.X,app.myModel.cTYPE), app.myModel.x0, app.myModel.X, app.myModel.DB, app.myModel.lb, app.myModel.ub, options); 
                
                % 1.2 - LOCAL SOLVER: FIND MINIMUM OF BOUNDED MULTIVARIATE FUNCTION
                case lower({'fmincon (Functional Minimization Fit)','fmincon', 'fcon'})
                    % -- Defining the optimisation options for the simulated annealing method
                    options = optimoptions('fmincon',...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'MaxIterations', app.solver_MaxIter,...
                        'ConstraintTolerance', app.solver_FuncTol,...
                        'FinDiffRelStep', app.solver_FinDiffRelStep,...
                        'FinDiffType', 'central');
                    [params,meta_fits.fval,meta_fits.exitflag,meta_fits.output,meta_fits.lambda,meta_fits.grad,meta_fits.hessian] =...
                        fmincon(@(x) minimize_function(app,x,app.myModel.X,app.myModel.DB,app.myModel.cTYPE), app.myModel.x0, [], [], [], [], app.myModel.lb, app.myModel.ub, [], options);  
                
                % 1.3 - LOCAL SOLVER: FIND MINIMUM OF BOUNDED MULTIVARIATE FUNCTION (WEIGHTED AROUND PEAKS)
                case lower({'fmincon (Functional Minimization Fit - Weighted Peaks)','fmincon-peaks', 'fcon-peaks'})
                    % -- Defining the optimisation options for the simulated annealing method
                    options = optimoptions('fmincon',...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'MaxIterations', app.solver_MaxIter,...
                        'ConstraintTolerance', app.solver_FuncTol,...
                        'FinDiffRelStep', app.solver_FinDiffRelStep,...
                        'FinDiffType', 'central');
                    [params,meta_fits.fval,meta_fits.exitflag,meta_fits.output,meta_fits.lambda,meta_fits.grad,meta_fits.hessian] =...
                        fmincon(@(x) minimize_function_peak_weighted(app,x,app.myModel.X,app.myModel.DB,app.myModel.cTYPE), app.myModel.x0, [], [], [], [], app.myModel.lb, app.myModel.ub, [], options);  
                               
                % 1.4 - GLOBAL SOLVER: BOUNDED SIMULATED ANNEALING TO MINIMISE RESIDUALS
                case lower({'simulannealbnd (Simulated Annealing Fitting)','simulannealbnd'})
                    % -- Defining the optimisation options for the hybrid minimisation that takes place after convergence
                    hybridopts = optimoptions('fmincon',...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'ConstraintTolerance', app.solver_FuncTol);
                    % -- Defining the optimisation options for the simulated annealing method
                    TempFcn     = 'temperatureexp';
                    InitTemp    = 1e3;
                    ReannealInt = 1e2;
                    options = optimoptions(@simulannealbnd,...
                        'TemperatureFcn', TempFcn,...
                        'InitialTemperature', InitTemp,...
                        'ReannealInterval', ReannealInt,...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'MaxIterations', app.solver_MaxIter,...
                        'TolFun', app.solver_FuncTol,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'HybridFcn' , {'fmincon' , hybridopts});
                    [params,meta_fits.fval,meta_fits.exitflag,meta_fits.output] =...
                        simulannealbnd(@(x) minimize_function(app,x,app.myModel.X,app.myModel.DB,app.myModel.cTYPE), app.myModel.x0, app.myModel.lb, app.myModel.ub, options);
                
                % 1.4 - GLOBAL SOLVER: BOUNDED SIMULATED ANNEALING TO MINIMISE RESIDUALS (WEIGHTED AROUND PEAKS)
                case lower({'simulannealbnd (Simulated Annealing Fitting - Weighted Peaks)','simulannealbnd-peaks'})
                    % -- Defining the optimisation options for the hybrid minimisation that takes place after convergence
                    hybridopts = optimoptions('fmincon',...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'ConstraintTolerance', app.solver_FuncTol);
                    % -- Defining the optimisation options for the simulated annealing method
                    TempFcn     = 'temperatureexp';
                    InitTemp    = 1e3;
                    ReannealInt = 1e2;
                    options = optimoptions(@simulannealbnd,...
                        'TemperatureFcn', TempFcn,...
                        'InitialTemperature', InitTemp,...
                        'ReannealInterval', ReannealInt,...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'MaxIterations', app.solver_MaxIter,...
                        'TolFun', app.solver_FuncTol,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'HybridFcn' , {'fmincon' , hybridopts});
                    [params,meta_fits.fval,meta_fits.exitflag,meta_fits.output] =...
                        simulannealbnd(@(x) minimize_function_peak_weighted(app,x,app.myModel.X,app.myModel.DB,app.myModel.cTYPE), app.myModel.x0, app.myModel.lb, app.myModel.ub, options);

            end
            % 2 - Extracting the new best fit parameters
            params              = reshape(params,app.myModel.num_of_peaks,[]);
            params              = table2cell(array2table(params));
            new_tab_parameters  = app.myModel.tab_parameters;
            new_tab_parameters{:,4:end} = params;
            % 3 - Update the Parameter Values
            app.Params_UITable.Data         = table2array(new_tab_parameters);
            app.tab_parameters              = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
        end

        % Optimization Function - Minimization
        function MINFUN = minimize_function(app, x, X, DB, cType)
            M   = full_pes_function(app, x, X, cType);
            R   = DB - M;
            MINFUN = sum(R.^2);
        end

        % Optimization Function - Minimization
        function MINFUN = minimize_function_peak_weighted(app, x, X, DB, cType)
            M   = full_pes_function(app, x, X, cType);
            R   = DB - M;
            MINFUN = sum(R.^2 ./ abs(M));
        end

        % Optimization Function - Full PES Function
        function M = full_pes_function(~, x, xdat, cType)
            % - 1 - Extract the curve component for each state
            nStates = length(cType);
            comp_int = {};
            for i = 1:nStates
                % -- Extracting the arguments for the component curve
                pes_args    = x(i:nStates:end);
                % -- Extracting the component intensities
                comp_int{i} = pes_spec_int(xdat, cType(i),...
                    pes_args(1), pes_args(2), pes_args(3),...
                    pes_args(4), pes_args(5), pes_args(6),...
                    pes_args(7), pes_args(8));
            end
            % - 2 - Sum up all of the curve components to get the final model PES curve
            pes_int = zeros(size(xdat));
            for i = 1:nStates; pes_int = pes_int + comp_int{i}; end
            M = pes_int;
            M(isnan(M)) = 0;
            if size(M, 2) > 1; M = M'; end
        end

         % Run the Optimization algorithm
         function run_background_optimization(app)
            options = optimoptions(@lsqcurvefit,...
                'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                'FunctionTolerance', app.solver_FuncTol,...
                'StepTolerance', app.solver_FuncTol,...
                'OptimalityTolerance', app.solver_FuncTol,...
                'MaxIterations', app.solver_MaxIter,...
                'FinDiffRelStep', app.solver_FinDiffRelStep,...
                'FinDiffType', 'central');
            % Given: xdata, ydata, and a model without offset
            model0              = @(xdata) app.myModel.M;   % your fixed-shape model
            % 1-parameter model for lsqcurvefit: a(1) is the vertical offset
            model_with_offset   = @(a, xdata) model0(xdata) + a(1);
            % Initial guess for the offset
            a0 = app.PES_Bgrnd_Offset_EditField.Value;
            lb = 0;
            ub = max(app.myModel.M(:));
            % Fit
            a_opt = lsqcurvefit(model_with_offset, a0, app.myModel.X, app.myModel.M, lb, ub, options);
            % Best-fit offset and fitted curve
            c_best = round(a_opt(1),3);
            app.bgrnd_Offset = c_best;
            app.PES_Bgrnd_Offset_EditField.Value = app.bgrnd_Offset;
         end

        %% Plot Functions
        % Plot the raw PES data
        function view_raw_pes_data(app)
            % - Initializing variables
            dat_color = [0.1 0.1 0.9];
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 750; 
            fig.Position(4) = 500;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plot of data
            nexttile(); hold on;
            scatter(app.myData.xdat, app.myData.ydat,...
                'SizeData', 20, 'markeredgecolor', dat_color, 'markerfacecolor', dat_color, 'MarkerFaceAlpha', 0.50, 'HandleVisibility','off');
            plot(app.myData.xdat, app.myData.ydat, 'r-', 'linewidth', 1, 'color', dat_color);
            c = yline(0, 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            c.Annotation.LegendInformation.IconDisplayStyle = 'off';
            title(sprintf("%s", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 12);
            xlabel(' X ', 'fontweight', 'bold', 'FontSize', 11);
            ylabel(' Y ', 'fontweight', 'bold', 'FontSize', 11);
            axis([min(app.myData.xdat(:)), max(app.myData.xdat(:)), min(app.myData.ydat(:)), 1.10*max(app.myData.ydat(:))]);
        end

        % Plot the raw PES data with background subtraction
        function view_raw_pes_data_with_bgrnd(app)
            % - Extracting background data
            [X, D, B, DB, ROI, LHS, RHS] = extract_pes_bgrnd(app);
            % - Initializing color
            dat_color       = [0.1 0.1 0.9];
            bgrnd_color     = [0.9 0.1 0.1];
            final_color     = [0.2 0.2 0.2];
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 750; 
            fig.Position(4) = 500;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            % - Plot of data
            nexttile(); hold on;
            % -- Plotting the ROI, LHS and RHS analysis windows
            hMID    = patch([ROI(1), ROI(1), ROI(2), ROI(2), ROI(1)], [0, 1, 1, 0, 0].*2*max(app.myData.ydat(:)), [0.9 0.9 0.9], 'facealpha', 0.5, 'edgecolor', 'none');
            hLHS    = patch([LHS(1), LHS(1), LHS(2), LHS(2), LHS(1)], [0, 1, 1, 0, 0].*2*max(app.myData.ydat(:)), [0.2 0.7 0.2], 'facealpha', 0.2, 'edgecolor', 'none');
            hRHS    = patch([RHS(1), RHS(1), RHS(2), RHS(2), RHS(1)], [0, 1, 1, 0, 0].*2*max(app.myData.ydat(:)), [0.7 0.2 0.2], 'facealpha', 0.2, 'edgecolor', 'none');
            hLHS.Annotation.LegendInformation.IconDisplayStyle = 'off';
            hRHS.Annotation.LegendInformation.IconDisplayStyle = 'off';
            hMID.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % -- Plotting a vertical lines to show the ROI
            a = xline(ROI(1), 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            b = xline(ROI(2), 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            c = yline(0, 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            b.Annotation.LegendInformation.IconDisplayStyle = 'off';
            c.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % -- Plotting the data
            plot(app.myData.xdat, app.myData.ydat, 'b-', 'linewidth', 0.5, 'color', dat_color);
            plot(X, D, 'b-', 'linewidth', 2, 'color', dat_color);
            plot(X, B, 'r-', 'linewidth', 2, 'color', bgrnd_color);
            plot(X, DB, 'k-', 'linewidth', 2, 'color', final_color);
            % - Formatting the figure
            title(sprintf("%s", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 12);
            xlabel(' X ', 'fontweight', 'bold', 'FontSize', 11);
            ylabel(' Y ', 'fontweight', 'bold', 'FontSize', 11);
            legend({'Initial Data', 'ROI: Data', 'ROI: Background', 'ROI: Final'}, 'location', 'best', 'fontsize', 9);
            axLim_y = [D; DB; B];
            axLim_x = [mean(X(:)) - abs(0.65*range(X(:))); mean(X(:)) + abs(0.65*range(X(:)));];
            axis([axLim_x(1), axLim_x(2), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
        end

        % Plot a snapshot of the PES model
        function view_pes_model_snap(app)
            % - Initializing variables
            dat_color   = [1.0 0.2 0.2];
            colorList   = read_line_colors(app.num_of_peaks+1);
            % - Initialising the figure object
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; fig.Position(4) = 600;
            % -- Creating a tiled axis
            t = tiledlayout(4,1); t.TileSpacing = 'compact'; t.Padding = 'compact';
            nexttile([3,1]); hold on;

            % 1 - Plotting PES Model
            % 1.1 - Plotting the confidence intervals
            for i = 1:app.myModel.num_of_peaks
                % --- Plotting the primary peak uncertainties
                x_vals = [app.myModel.BE_ci(i,1)-0.02, app.myModel.BE_ci(i,1)-0.02, app.myModel.BE_ci(i,2)+0.02, app.myModel.BE_ci(i,2)+0.02, app.myModel.BE_ci(i,1)-0.02];
                y_vals = [app.myModel.INT_ci(i,1), app.myModel.INT_ci(i,2), app.myModel.INT_ci(i,2), app.myModel.INT_ci(i,1), app.myModel.INT_ci(i,1)];
                a = patch(x_vals, y_vals, colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0.5);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                % --- Adding text to identify each peak index
                text(max(x_vals), max(y_vals), string(i), 'interpreter', 'latex', 'fontsize', 14, 'color', 'k');
                % --- Plotting the spin-orbit split (SOS) peak uncertainties
                x_vals  = app.myModel.BE(i) + [app.myModel.LSE_ci(i,1), app.myModel.LSE_ci(i,1), app.myModel.LSE_ci(i,2), app.myModel.LSE_ci(i,2), app.myModel.LSE_ci(i,1)];
                y_vals  = [app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1), app.myModel.INT_ci(i,2)*app.myModel.LSI_ci(i,2), app.myModel.INT_ci(i,2)*app.myModel.LSI_ci(i,2), app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1), app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1)];
                a = patch(x_vals, y_vals, colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0.5);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end

            % 1.2 - Plotting all of the peak components
            for i = 1:app.myModel.num_of_peaks
                area(app.myModel.X, app.myModel.cM{i}, 'FaceColor', colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0);
                a = plot(app.myModel.X, app.myModel.cM{i}, 'k-', 'linewidth', 0.25);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.3 - Plotting the peak component energy locations
            for i = 1:app.myModel.num_of_peaks
                a = line([app.myModel.BE(i), app.myModel.BE(i)], [0, max(app.myModel.cM{i})],...
                    'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.4 - Plotting the final PES intensity spectrum
            a = plot(app.myModel.X, app.myModel.DB, 'r-', 'linewidth', 0.5, 'color', dat_color); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = scatter(app.myModel.X, app.myModel.DB, 40, dat_color, 'MarkerFaceColor', dat_color, 'MarkerFaceAlpha',0.6,'MarkerEdgeAlpha',0.6);
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            plot(app.myModel.X, app.myModel.M, 'k-', 'color', 'k', 'linewidth', 2.0);
            % 1.5 - Formatting the figure
            title(sprintf("%s", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 10);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            axLim_y = [0;app.myModel.DB; app.myModel.M];
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            lgnd_txt = {};
            for i = 1:app.myModel.num_of_peaks; lgnd_txt{i} = sprintf("Peak %i (%.1fpc)", i, 100*app.myModel.AREA0(i)); end
            lgnd_txt{end+1} = "Final";
            legend(lgnd_txt, 'location', 'best', 'fontsize', 8);
            
            % 2 - Plotting the residuals
            nexttile([1,1]); hold on;
            bar(app.myModel.X, app.myModel.R, 1, 'facecolor', [0 0 0]);
            ylabel('Resid.', 'FontWeight', 'bold', 'FontSize', 11);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            max_resid = max(abs(app.myModel.R(:)));
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), -max_resid, max_resid]);

        end

        % Live-view of the PES model that updates as variables are changed
        function view_pes_model_live(app)
            % - Initializing variables
            dat_color   = [1.0 0.2 0.2];
            colorList   = read_line_colors(app.num_of_peaks+1);
            % - Initialising the figure object
            figHandle = 3633;
            fig = findobj('Type', 'figure', 'Number', figHandle);
            if ~isempty(fig); close(figHandle); end
            % Check if the figure exists
            fig = figure(figHandle);
            fig.Name = 'PES Curve Fitting - Live Model Viewer';
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; fig.Position(4) = 600;
            % -- Creating a tiled axis
            t = tiledlayout(4,1); t.TileSpacing = 'compact'; t.Padding = 'compact';
            % 1 - Plotting PES Model
            nexttile([3,1]); hold on;
            Bgrnd       = app.myModel.B;
            basevalue   = min(app.myModel.D(:));
            % 1.1 - Plotting the confidence intervals
            for i = 1:app.myModel.num_of_peaks
                % --- Plotting the primary peak uncertainties
                x_vals = [app.myModel.BE_ci(i,1)-0.02, app.myModel.BE_ci(i,1)-0.02, app.myModel.BE_ci(i,2)+0.02, app.myModel.BE_ci(i,2)+0.02, app.myModel.BE_ci(i,1)-0.02];
                y_vals = [app.myModel.INT_ci(i,1), app.myModel.INT_ci(i,2), app.myModel.INT_ci(i,2), app.myModel.INT_ci(i,1), app.myModel.INT_ci(i,1)];
                a = patch(x_vals, y_vals, colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0.5);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                % --- Adding text to identify each peak index
                text(max(x_vals), max(y_vals), string(i), 'interpreter', 'latex', 'fontsize', 14, 'color', 'k');
                % --- Plotting the spin-orbit split (SOS) peak uncertainties
                x_vals  = app.myModel.BE(i) + [app.myModel.LSE_ci(i,1), app.myModel.LSE_ci(i,1), app.myModel.LSE_ci(i,2), app.myModel.LSE_ci(i,2), app.myModel.LSE_ci(i,1)];
                y_vals  = [app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1), app.myModel.INT_ci(i,2)*app.myModel.LSI_ci(i,2), app.myModel.INT_ci(i,2)*app.myModel.LSI_ci(i,2), app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1), app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1)];
                a = patch(x_vals, y_vals, colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0.5);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.2 - Plotting all of the peak components
            for i = 1:app.myModel.num_of_peaks
                patch([app.myModel.X; flipud(app.myModel.X)], [Bgrnd+app.myModel.cM{i}; flipud(Bgrnd)], colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0);
                % area(app.myModel.X, Bgrnd+app.myModel.cM{i}, 'FaceColor', colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0, 'basevalue', basevalue);
                a = plot(app.myModel.X, Bgrnd+app.myModel.cM{i}, 'k-', 'linewidth', 0.25);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.3 - Plotting the peak component energy locations
            for i = 1:app.myModel.num_of_peaks
                a = line([app.myModel.BE(i), app.myModel.BE(i)], [0, max(Bgrnd+app.myModel.cM{i})],...
                    'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.4 - Plotting the final PES intensity spectrum
            a = plot(app.myData.xdat, app.myModel.ydat, 'r-', 'linewidth', 0.5, 'color', dat_color); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = plot(app.myModel.X, app.myModel.D, 'r-', 'linewidth', 0.5, 'color', dat_color); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = scatter(app.myModel.X, app.myModel.D, 30, dat_color, 'MarkerFaceColor', dat_color, 'MarkerFaceAlpha',0.6,'MarkerEdgeAlpha',0.6);
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            plot(app.myModel.X, Bgrnd+app.myModel.M, 'k-', 'color', 'k', 'linewidth', 2.0);
            % 1.5 - Plotting the background
            % a = area(app.myModel.X, Bgrnd, 'FaceColor', 'r', 'FaceAlpha', 0.50, 'EdgeAlpha', 0, 'basevalue', basevalue);
            % a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = plot(app.myModel.X, Bgrnd, 'k--', 'linewidth', 1, 'color', 'k');
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % 1.6 - Formatting the figure
            title(sprintf("%s (Fit)", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 10);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            axLim_y = [app.myModel.D; Bgrnd+app.myModel.M; app.myModel.B];
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            lgnd_txt = {};
            for i = 1:app.myModel.num_of_peaks; lgnd_txt{i} = sprintf("Peak %i (%.1fpc)", i, 100*app.myModel.AREA0(i)); end
            lgnd_txt{end+1} = "Final";
            legend(lgnd_txt, 'location', 'best', 'fontsize', 8);
            % - 2 - Plotting the residuals
            nexttile([1,1]); hold on;
            bar(app.myModel.X, app.myModel.R, 1, 'facecolor', [0 0 0]);
            ylabel('Resid.', 'FontWeight', 'bold', 'FontSize', 11);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            max_resid = max(abs(app.myModel.R(:)));
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), -max_resid, max_resid]);
        end

        % Update function for the plot for the live-view PES model
        function update_pes_model_live(app)
            % - Initializing variables
            dat_color   = [1.0 0.2 0.2];
            colorList   = read_line_colors(app.num_of_peaks+1);
            figHandle   = 3633;
            % Check if the figure exists
            fig = findobj('Type', 'figure', 'Number', figHandle);
            % If the figure exists, then update
            if ~isempty(fig)
                figure(fig);
                ax = gca; [az, el] = view(ax);
                cla(); clf(); hold on;
                % Plot the model
                % -- Creating a tiled axis
                t = tiledlayout(4,1); t.TileSpacing = 'compact'; t.Padding = 'compact';
                % 1 - Plotting PES Model
                nexttile([3,1]); hold on;
                Bgrnd       = app.myModel.B;
                basevalue   = min(app.myModel.D(:));
                % 1.1 - Plotting the confidence intervals
                for i = 1:app.myModel.num_of_peaks
                    % --- Plotting the primary peak uncertainties
                    x_vals = [app.myModel.BE_ci(i,1)-0.02, app.myModel.BE_ci(i,1)-0.02, app.myModel.BE_ci(i,2)+0.02, app.myModel.BE_ci(i,2)+0.02, app.myModel.BE_ci(i,1)-0.02];
                    y_vals = [app.myModel.INT_ci(i,1), app.myModel.INT_ci(i,2), app.myModel.INT_ci(i,2), app.myModel.INT_ci(i,1), app.myModel.INT_ci(i,1)];
                    a = patch(x_vals, y_vals, colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0.5);
                    a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                    % --- Adding text to identify each peak index
                    text(max(x_vals), max(y_vals), string(i), 'interpreter', 'latex', 'fontsize', 14, 'color', 'k');
                    % --- Plotting the spin-orbit split (SOS) peak uncertainties
                    x_vals  = app.myModel.BE(i) + [app.myModel.LSE_ci(i,1), app.myModel.LSE_ci(i,1), app.myModel.LSE_ci(i,2), app.myModel.LSE_ci(i,2), app.myModel.LSE_ci(i,1)];
                    y_vals  = [app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1), app.myModel.INT_ci(i,2)*app.myModel.LSI_ci(i,2), app.myModel.INT_ci(i,2)*app.myModel.LSI_ci(i,2), app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1), app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1)];
                    a = patch(x_vals, y_vals, colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0.5);
                    a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                end
                % 1.2 - Plotting all of the peak components
                for i = 1:app.myModel.num_of_peaks
                    patch([app.myModel.X; flipud(app.myModel.X)], [Bgrnd+app.myModel.cM{i}; flipud(Bgrnd)], colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0);
                    % area(app.myModel.X, Bgrnd+app.myModel.cM{i}, 'FaceColor', colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0, 'basevalue', basevalue);
                    a = plot(app.myModel.X, Bgrnd+app.myModel.cM{i}, 'k-', 'linewidth', 0.25);
                    a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                end
                % 1.3 - Plotting the peak component energy locations
                for i = 1:app.myModel.num_of_peaks
                    a = line([app.myModel.BE(i), app.myModel.BE(i)], [0, max(Bgrnd+app.myModel.cM{i})],...
                        'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
                    a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                end
                % 1.4 - Plotting the final PES intensity spectrum
                a = plot(app.myData.xdat, app.myModel.ydat, 'r-', 'linewidth', 0.5, 'color', dat_color); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                a = plot(app.myModel.X, app.myModel.D, 'r-', 'linewidth', 0.5, 'color', dat_color); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                a = scatter(app.myModel.X, app.myModel.D, 30, dat_color, 'MarkerFaceColor', dat_color, 'MarkerFaceAlpha',0.6,'MarkerEdgeAlpha',0.6);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                plot(app.myModel.X, Bgrnd+app.myModel.M, 'k-', 'color', 'k', 'linewidth', 2.0);
                % 1.5 - Plotting the background
                % a = area(app.myModel.X, Bgrnd, 'FaceColor', 'r', 'FaceAlpha', 0.50, 'EdgeAlpha', 0, 'basevalue', basevalue);
                % a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                a = plot(app.myModel.X, Bgrnd, 'k--', 'linewidth', 1, 'color', 'k');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                % 1.6 - Formatting the figure
                title(sprintf("%s (Fit)", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 10);
                xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
                ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
                axLim_y = [app.myModel.D; Bgrnd+app.myModel.M; app.myModel.B];
                axis([min(app.myModel.X(:)), max(app.myModel.X(:)), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
                ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
                lgnd_txt = {};
                for i = 1:app.myModel.num_of_peaks; lgnd_txt{i} = sprintf("Peak %i (%.1fpc)", i, 100*app.myModel.AREA0(i)); end
                lgnd_txt{end+1} = "Final";
                legend(lgnd_txt, 'location', 'best', 'fontsize', 8);
                % - 2 - Plotting the residuals
                nexttile([1,1]); hold on;
                bar(app.myModel.X, app.myModel.R, 1, 'facecolor', [0 0 0]);
                ylabel('Resid.', 'FontWeight', 'bold', 'FontSize', 11);
                xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
                ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
                max_resid = max(abs(app.myModel.R(:)));
                axis([min(app.myModel.X(:)), max(app.myModel.X(:)), -max_resid, max_resid]);
            end
        end

        % Plot the final PES fit spectra
        function view_pes_fit_spectra(app)
            % - Initializing variables
            dat_color   = [1.0 0.2 0.2];
            colorList   = read_line_colors(app.num_of_peaks+1);
            % - Initialising the figure object
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; fig.Position(4) = 600;
            % -- Creating a tiled axis
            t = tiledlayout(4,1); t.TileSpacing = 'compact'; t.Padding = 'compact';
            nexttile([3,1]); hold on;
            % 1 - Plotting the PES Model Fit
            % 1.1 - Plotting all of the peak components
            for i = 1:app.myModel.num_of_peaks
                area(app.myModel.X, app.myModel.cM{i}, 'FaceColor', colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0);
                a = plot(app.myModel.X, app.myModel.cM{i}, 'k-', 'linewidth', 0.25);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.2 - Plotting the peak component energy locations
            for i = 1:app.myModel.num_of_peaks
                a = line([app.myModel.BE(i), app.myModel.BE(i)], [0, max(app.myModel.cM{i})],...
                    'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.3 - Plotting the final PES intensity spectrum
            a = plot(app.myModel.X, app.myModel.DB, 'r-', 'linewidth', 0.5, 'color', dat_color); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = scatter(app.myModel.X, app.myModel.DB, 40, dat_color, 'MarkerFaceColor', dat_color, 'MarkerFaceAlpha',0.6,'MarkerEdgeAlpha',0.6);
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            plot(app.myModel.X, app.myModel.M, 'k-', 'color', 'k', 'linewidth', 2.0);
            % 1.4 - Formatting the figure
            title(sprintf("%s (Fit)", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 10);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            axLim_y = [0;app.myModel.DB; app.myModel.M];
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            lgnd_txt = {};
            for i = 1:app.myModel.num_of_peaks; lgnd_txt{i} = sprintf("Peak %i (%.1fpc)", i, 100*app.myModel.AREA0(i)); end
            lgnd_txt{end+1} = "Final";
            legend(lgnd_txt, 'location', 'best', 'fontsize', 8);
            set(gca(), 'Layer','top');
            % - 2 - Plotting the residuals
            nexttile([1,1]); hold on;
            bar(app.myModel.X, app.myModel.R, 1, 'facecolor', [0 0 0]);
            ylabel('Resid.', 'FontWeight', 'bold', 'FontSize', 11);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            max_resid = max(abs(app.myModel.R(:)));
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), -max_resid, max_resid]);
            set(gca(), 'Layer','top');
        end

        % Plot the final PES fit spectra with background
        function view_pes_fit_spectra_with_background(app)
            % - Initializing variables
            dat_color   = [1.0 0.2 0.2];
            colorList   = read_line_colors(app.num_of_peaks+1);
            % - Initialising the figure object
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 600; fig.Position(4) = 600;
            % -- Creating a tiled axis
            t = tiledlayout(4,1); t.TileSpacing = 'compact'; t.Padding = 'compact';
            nexttile([3,1]); hold on;
            % 1 - Plotting the PES Model Fit
            Bgrnd       = app.myModel.B;
            basevalue   = min(app.myModel.D(:));
            % 1.1 - Plotting all of the peak components
            for i = 1:app.myModel.num_of_peaks
                patch([app.myModel.X; flipud(app.myModel.X)], [Bgrnd+app.myModel.cM{i}; flipud(Bgrnd)], colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0);
                % area(app.myModel.X, Bgrnd+app.myModel.cM{i}, 'FaceColor', colorList{i}, 'FaceAlpha', 0.85, 'EdgeAlpha', 0, 'basevalue', basevalue);
                a = plot(app.myModel.X, Bgrnd+app.myModel.cM{i}, 'k-', 'linewidth', 0.25);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.2 - Plotting the peak component energy locations
            for i = 1:app.myModel.num_of_peaks
                a = line([app.myModel.BE(i), app.myModel.BE(i)], [0, max(Bgrnd+app.myModel.cM{i})],...
                    'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.3 - Plotting the final PES intensity spectrum
            a = plot(app.myModel.X, app.myModel.D, 'r-', 'linewidth', 0.5, 'color', dat_color); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = scatter(app.myModel.X, app.myModel.D, 30, dat_color, 'MarkerFaceColor', dat_color, 'MarkerFaceAlpha',0.6,'MarkerEdgeAlpha',0.6);
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            plot(app.myModel.X, Bgrnd+app.myModel.M, 'k-', 'color', 'k', 'linewidth', 2.0);
            % 1.4 - Plotting the background
            % a = area(app.myModel.X, Bgrnd, 'FaceColor', 'r', 'FaceAlpha', 0.50, 'EdgeAlpha', 0, 'basevalue', basevalue);
            % a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = plot(app.myModel.X, Bgrnd, 'k--', 'linewidth', 1, 'color', 'k');
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % 1.5 - Formatting the figure
            title(sprintf("%s (Fit)", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 10);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            axLim_y = [app.myModel.D; Bgrnd+app.myModel.M; app.myModel.B];
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            lgnd_txt = {};
            for i = 1:app.myModel.num_of_peaks; lgnd_txt{i} = sprintf("Peak %i (%.1fpc)", i, 100*app.myModel.AREA0(i)); end
            lgnd_txt{end+1} = "Final";
            legend(lgnd_txt, 'location', 'best', 'fontsize', 8);
            set(gca(), 'Layer','top');
            % - 2 - Plotting the residuals
            nexttile([1,1]); hold on;
            bar(app.myModel.X, app.myModel.R, 1, 'facecolor', [0 0 0]);
            ylabel('Resid.', 'FontWeight', 'bold', 'FontSize', 11);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            max_resid = max(abs(app.myModel.R(:)));
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), -max_resid, max_resid]);
            set(gca(), 'Layer','top');
        end

        % Plot the final PES fit areas
        function view_pes_fit_areas(app)
            % - Initializing variables
            colorList   = read_line_colors(app.num_of_peaks+1);
            % - Initialising the figure object
            fig = figure(); 
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 500; fig.Position(4) = 500;
            % - Creating a tiled axis
            t = tiledlayout(1,1); t.TileSpacing = 'compact'; t.Padding = 'compact';
            nexttile([1,1]); hold on;
            % - Plotting the peak areas for each component
            for i = 1:app.myModel.num_of_peaks
                a = stem(app.myModel.BE(i), app.myModel.AREA(i), 'k-');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                scatter(app.myModel.BE(i), app.myModel.AREA(i),...
                    50, colorList{i}, 'MarkerFaceColor', colorList{i}, 'MarkerEdgeColor', 'k');
                text(app.myModel.BE(i), app.myModel.AREA(i), sprintf('  %.0f(%s)-%.1fpc', i, app.myModel.Label(i), 100*app.myModel.AREA0(i)),...
                    'Rotation',45, 'FontWeight','normal', 'FontSize',10, 'color', 'k'); 
            end
            % - Formatting the figure
            axis([min(app.myModel.BE(:))-1, max(app.myModel.BE(:))+1, 0, 1.4*max(app.myModel.AREA(:))]);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            title(sprintf("%s (Areas)", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 10);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Integrated Peak Area (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBaseX - PES Curve Fitter App (procopios.constantinou@psi.ch)';
            % - Initialise variables
            app.myData      = struct(); 
            app.myModel     = struct();
            app.data_parity = -1;
            % - Initializing UI elements
            init_ui_on_start(app);
            init_pes_bgrnd_properties(app);
            init_parameters_properties(app);
            init_optimization_properties(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run MatBaseX;
            open_MainMenu_to_left_of_App(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type', 'Figure');
            % Exclude those with 'MatBaseX' in the Name (title)
            matbaseFigs = findobj(hPlots, '-regexp', 'Name', 'MatBaseX');
            hPlotsToClose = setdiff(hPlots, matbaseFigs);
            % Close only the non-MatBaseX figures
            close(hPlotsToClose);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run MatBaseX_08_PESCurveFitter;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 1025;
            screen_size(4) = 630;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('.myData : '); disp(app.myData);
            disp('.myModel : '); disp(app.myModel);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'myData', app.myData);
            assignin('base', 'myModel', app.myModel);
        end

        % Button pushed function: LOAD_Button
        function LOAD_ButtonPushed(app, event)
            % 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.txt;*.mat'}, 'Pick a *.txt or *.mat data file...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            app.myData = struct(); app.myModel = struct();
            load_in_pes_data(app, PathName, FileName);
            % - Update color of tables
            colorList   = read_line_colors(app.num_of_peaks+1);
            for i = 1:app.num_of_peaks
                temp_col = uistyle('BackgroundColor', colorList{i}); 
                addStyle(app.Params_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.StaticCI_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.DynCI_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.FixParams_UITable, temp_col, 'cell', [i, 1]);
            end
            % - Update dependents
            update_single_dependents(app);
        end

        % Button pushed function: RESET_Button
        function RESET_ButtonPushed(app, event)
            % 1 - Re-load the raw data-file
            FileName = char(string(app.myData.FileName));
            PathName = char(string(app.myData.PathName));
            % 2 - Reset the app to the initial state
            runStartupFcn(app, @startupFcn);
            % 3 - Load in the file selected
            app.myData = struct(); app.myModel = struct();
            load_in_pes_data(app, PathName, FileName);
        end

        % Value changed function: PES_Bgrnd_Type_DropDown
        function PES_Bgrnd_Type_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_Type_DropDown.Value;
            value = string(value);
            % 2 - Updating the UI elements to reflect background selected
            new_value = update_pes_bgrnd_args(app, value);
            % 3 - Assigning the output
            app.bgrnd_type = new_value;
            fprintf("- Background Type: " + string(value) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_LHS_EditField
        function PES_Bgrnd_LHS_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_LHS_EditField.Value;
            % 2 - Validity check on the input value
            if value > app.bgrnd_RHS; value = app.bgrnd_RHS; end
            if value < min(app.myData.xdat(:)); value = min(app.myData.xdat(:)); end
            % 3 - Assigning the output
            app.bgrnd_LHS = value;
            app.PES_Bgrnd_LHS_EditField.Value = app.bgrnd_LHS;
            fprintf("- Background: LHS (Start) = " + string(app.bgrnd_LHS) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_RHS_EditField
        function PES_Bgrnd_RHS_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_RHS_EditField.Value;
            % 2 - Validity check on the input value
            if value < app.bgrnd_LHS; value = app.bgrnd_LHS; end
            if value > max(app.myData.xdat(:)); value = max(app.myData.xdat(:)); end
            % 3 - Assigning the output
            app.bgrnd_RHS = value;
            app.PES_Bgrnd_RHS_EditField.Value = app.bgrnd_RHS;
            fprintf("- Background: RHS (End) = " + string(app.bgrnd_RHS) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_Win_EditField
        function PES_Bgrnd_Win_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_Win_EditField.Value;
            % 2 - Validity check on the input value
            if value < 0; value = 0; end
            % 3 - Assigning the output
            app.bgrnd_Win = value;
            app.PES_Bgrnd_Win_EditField.Value = app.bgrnd_Win;
            fprintf("- Background: Window Size = " + string(app.bgrnd_Win) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_Offset_EditField
        function PES_Bgrnd_Offset_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_Offset_EditField.Value;
            % 3 - Assigning the output
            app.bgrnd_Offset = value;
            app.PES_Bgrnd_Offset_EditField.Value = app.bgrnd_Offset;
            fprintf("- Background: Constant Offset = " + string(app.bgrnd_Offset) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_PolyOrder_EditField
        function PES_Bgrnd_PolyOrder_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_PolyOrder_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.bgrnd_Order = value;
            app.PES_Bgrnd_PolyOrder_EditField.Value = app.bgrnd_Order;
            fprintf("- Background (Polynomial): Order = " + string(app.bgrnd_Order) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_SO_Lambda_EditField
        function PES_Bgrnd_SO_Lambda_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_SO_Lambda_EditField.Value;
            % 2 - Assigning the output
            app.bgrnd_lambda = value;
            app.PES_Bgrnd_SO_Lambda_EditField.Value = app.bgrnd_lambda;
            fprintf("- Background (Shirley-Offset): Lambda = " + string(app.bgrnd_lambda) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_SO_Delta_EditField
        function PES_Bgrnd_SO_Delta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_SO_Delta_EditField.Value;
            % 2 - Assigning the output
            app.bgrnd_delta = value;
            app.PES_Bgrnd_SO_Delta_EditField.Value = app.bgrnd_delta;
            fprintf("- Background (Shirley-Offset): Delta = " + string(app.bgrnd_delta) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_Tou_C_EditField
        function PES_Bgrnd_Tou_C_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_Tou_C_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 1643; end
            % 3 - Assigning the output
            app.bgrnd_C = value;
            app.PES_Bgrnd_Tou_C_EditField.Value = app.bgrnd_C;
            fprintf("- Background (Tougaard): C = " + string(app.bgrnd_C) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: PES_Bgrnd_Tou_D_EditField
        function PES_Bgrnd_Tou_D_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PES_Bgrnd_Tou_D_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 275; end
            % 3 - Assigning the output
            app.bgrnd_D = value;
            app.PES_Bgrnd_Tou_D_EditField.Value = app.bgrnd_D;
            fprintf("- Background (Tougaard): D = " + string(app.bgrnd_D) + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Button pushed function: ViewRawPES_Button
        function ViewRawPES_ButtonPushed(app, event)
            view_raw_pes_data(app);
        end

        % Button pushed function: PES_Bgrnd_ViewRawWithBgrnd_Button
        function PES_Bgrnd_ViewRawWithBgrnd_ButtonPushed(app, event)
            view_raw_pes_data_with_bgrnd(app);
        end

        % Button pushed function: PES_Bgrnd_ExportData_Button
        function PES_Bgrnd_ExportData_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = sprintf("%s_with_bgrnd_sub", app.myData.FileName(1:end-4));
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Metadata
            info = "";
            info = info + sprintf("-- INFO -- \n");
            info = info + sprintf("FileName:\t\t%s\n", app.myData.FileName);
            info = info + sprintf("Type:\t\t%s\n", app.PES_Bgrnd_Type_DropDown.Value);
            info = info + sprintf("LHS:\t\t%.3e eV\n", app.bgrnd_LHS);
            info = info + sprintf("RHS:\t\t%.3e eV\n", app.bgrnd_RHS);
            info = info + sprintf("Win:\t\t%.3e eV\n", app.bgrnd_Win);
            info = info + sprintf("Offset:\t\t%.3e eV\n", app.bgrnd_Offset);
            Type = char(lower(app.bgrnd_type));
            switch Type
                case lower({'SO'})           
                    info = info + sprintf("SO(Lambda):\t\t%.3f\n", app.bgrnd_lambda);
                    info = info + sprintf("SO(Delta):\t\t%.3f\n", app.bgrnd_delta);
                case lower({'P'})                    
                    info = info + sprintf("P(Order):\t\t%.0f\n", app.bgrnd_Order);
                case lower({'T1', 'T2'})      
                    info = info + sprintf("T(C):\t\t%.3e\n", app.bgrnd_C);
                    info = info + sprintf("T(D):\t\t%.3e\n", app.bgrnd_D);              
            end
            info = info + sprintf("\n-- TABLE OF DATA -- \n");
            % Data
            [X, D, B, DB, ~, ~, ~] = extract_pes_bgrnd(app);
            x_roi       = X;
            d_roi       = D;
            b_roi       = B;
            db_roi      = DB;
            T_Data = table(x_roi,d_roi,b_roi,db_roi);
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            close(wbar);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: Params_PeakAdd_Button
        function Params_PeakAdd_ButtonPushed(app, event)
            % - Adding one new peak to the UI tables
            peak_number    = app.Params_UITable.Data(end,1); peak_number = peak_number{1};
            if peak_number ~= 10
                peak_number     = peak_number+1;
                Label   = '';
                % -- Updating the Parameter Values UI Table
                Type    = app.Params_UITable.Data(end,3); Type = Type{1};
                BE      = app.Params_UITable.Data(end,4); BE = BE{1} - 1;
                INT     = app.Params_UITable.Data(end,5); INT = INT{1};
                FWHM    = app.Params_UITable.Data(end,6); FWHM = FWHM{1};
                MR      = app.Params_UITable.Data(end,7); MR = MR{1};
                LSE     = app.Params_UITable.Data(end,8); LSE = LSE{1};
                LSI     = app.Params_UITable.Data(end,9); LSI = LSI{1};
                LSW     = app.Params_UITable.Data(end,10); LSW = LSW{1};
                ASY     = app.Params_UITable.Data(end,11); ASY = ASY{1};
                app.Params_UITable.Data(end+1,:) = {peak_number, Label, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % -- Updating the Static Confidence Intervals UI Table
                Type    = app.StaticCI_UITable.Data(end,3); Type = Type{1};
                BE      = app.StaticCI_UITable.Data(end,4); BE = BE{1};
                INT     = app.StaticCI_UITable.Data(end,5); INT = INT{1};
                FWHM    = app.StaticCI_UITable.Data(end,6); FWHM = FWHM{1};
                MR      = app.StaticCI_UITable.Data(end,7); MR = MR{1};
                LSE     = app.StaticCI_UITable.Data(end,8); LSE = LSE{1};
                LSI     = app.StaticCI_UITable.Data(end,9); LSI = LSI{1};
                LSW     = app.StaticCI_UITable.Data(end,10); LSW = LSW{1};
                ASY     = app.StaticCI_UITable.Data(end,11); ASY = ASY{1};
                app.StaticCI_UITable.Data(end+1,:) = {peak_number, Label, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % -- Updating the Dynamic Confidence Intervals UI Table
                Type    = app.DynCI_UITable.Data(end,3); Type = Type{1};
                BE      = app.DynCI_UITable.Data(end,4); BE = BE{1};
                INT     = app.DynCI_UITable.Data(end,5); INT = INT{1};
                FWHM    = app.DynCI_UITable.Data(end,6); FWHM = FWHM{1};
                MR      = app.DynCI_UITable.Data(end,7); MR = MR{1};
                LSE     = app.DynCI_UITable.Data(end,8); LSE = LSE{1};
                LSI     = app.DynCI_UITable.Data(end,9); LSI = LSI{1};
                LSW     = app.DynCI_UITable.Data(end,10); LSW = LSW{1};
                ASY     = app.DynCI_UITable.Data(end,11); ASY = ASY{1};
                app.DynCI_UITable.Data(end+1,:) = {peak_number, Label, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % -- Updating the Fixed Parameters UI Table
                Type    = app.FixParams_UITable.Data(end,3); Type = Type{1};
                BE      = app.FixParams_UITable.Data(end,4); BE = BE{1};
                INT     = app.FixParams_UITable.Data(end,5); INT = INT{1};
                FWHM    = app.FixParams_UITable.Data(end,6); FWHM = FWHM{1};
                MR      = app.FixParams_UITable.Data(end,7); MR = MR{1};
                LSE     = app.FixParams_UITable.Data(end,8); LSE = LSE{1};
                LSI     = app.FixParams_UITable.Data(end,9); LSI = LSI{1};
                LSW     = app.FixParams_UITable.Data(end,10); LSW = LSW{1};
                ASY     = app.FixParams_UITable.Data(end,11); ASY = ASY{1};
                app.FixParams_UITable.Data(end+1,:) = {peak_number, Label, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % -- Assigning and printing the output
                app.num_of_peaks        = peak_number;
                app.tab_parameters      = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
                app.tab_static_ci       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                app.tab_dyn_ci          = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                app.tab_fix_params      = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
                fprintf("-- Peak Added: N = " + string(peak_number) + " \n");
                % -- Updating the color cell
                colorList   = read_line_colors(app.num_of_peaks+1);
                temp_col = uistyle('BackgroundColor', colorList{app.num_of_peaks}); 
                addStyle(app.Params_UITable, temp_col, 'cell', [app.num_of_peaks, 1]);
                addStyle(app.StaticCI_UITable, temp_col, 'cell', [app.num_of_peaks, 1]);
                addStyle(app.DynCI_UITable, temp_col, 'cell', [app.num_of_peaks, 1]);
                addStyle(app.FixParams_UITable, temp_col, 'cell', [app.num_of_peaks, 1]);
            else; fprintf("-- Currently only supports 10 peaks maximum...\n");
            end
            % - Update dependents
            update_single_dependents(app);
        end

        % Button pushed function: Params_PeakDelete_Button
        function Params_PeakDelete_ButtonPushed(app, event)
            % - Removing one peak from the UI tables
            peak_number    = app.Params_UITable.Data(end,1); peak_number = peak_number{1};
            if peak_number ~= 1 
                peak_number = peak_number - 1;
                % -- Updating the Parameter Values UI Table
                app.Params_UITable.Data(end,:) = []; 
                % -- Updating the Static Confidence Intervals UI Table
                app.StaticCI_UITable.Data(end,:) = [];
                % -- Updating the Dynamic Confidence Intervals UI Table
                app.DynCI_UITable.Data(end,:) = []; 
                % -- Updating the Fixed Parameters UI Table
                app.FixParams_UITable.Data(end,:) = [];
                % -- Assigning the output
                app.num_of_peaks        = peak_number;
                app.tab_parameters      = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
                app.tab_static_ci       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                app.tab_dyn_ci          = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                app.tab_fix_params      = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
                fprintf("-- Peak Removed: N = " + string(peak_number) + " \n");
            else; fprintf("-- Cannot remove first peak...\n");
            end
            % - Update dependents
            update_single_dependents(app);
        end

        % Button pushed function: Params_PeakDeleteN_Button
        function Params_PeakDeleteN_ButtonPushed(app, event)
            % - Removing one row from the UI table (defined index)
            if app.num_of_peaks ~= 1 
                prompt      = {'Enter the Peak Number you want to delete:'};
                dlgtitle    = 'Delete Entry';
                dims        = [1 50];
                answer      = inputdlg(prompt,dlgtitle,dims);
                if ~isempty(answer) 
                    user_val = str2double(answer{1});
                    if user_val > app.num_of_peaks || user_val < 1
                        fprintf("-> Must enter a Peak Number between 1 - " + string(app.num_of_peaks) + "\n");
                    else
                        app.num_of_peaks                    = app.num_of_peaks - 1;
                        % -- Updating the Parameter Values UI Table
                        app.Params_UITable.Data(user_val,:) = []; 
                        for i = 1:app.num_of_peaks; app.Params_UITable.Data(i,1) = {i}; end
                        % -- Updating the Static Confidence Intervals UI Table
                        app.StaticCI_UITable.Data(user_val,:) = [];
                        for i = 1:app.num_of_peaks; app.StaticCI_UITable.Data(i,1) = {i}; end
                        % -- Updating the Dynamic Confidence Intervals UI Table
                        app.DynCI_UITable.Data(user_val,:) = []; 
                        for i = 1:app.num_of_peaks; app.DynCI_UITable.Data(i,1) = {i}; end
                        % -- Updating the Fixed Parameters UI Table
                        app.FixParams_UITable.Data(user_val,:) = [];
                        for i = 1:app.num_of_peaks; app.FixParams_UITable.Data(i,1) = {i}; end
                        % -- Assigning the output
                        app.tab_parameters      = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
                        app.tab_static_ci       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                        app.tab_dyn_ci          = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                        app.tab_fix_params      = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
                        fprintf("-- Peak Removed: N = " + string(user_val) + " \n");
                        % -- Ensure color is consistent
                        colorList   = read_line_colors(app.num_of_peaks+1);
                        for i = 1:app.num_of_peaks
                            temp_col = uistyle('BackgroundColor', colorList{i}); 
                            addStyle(app.Params_UITable, temp_col, 'cell', [i, 1]);
                            addStyle(app.StaticCI_UITable, temp_col, 'cell', [i, 1]);
                            addStyle(app.DynCI_UITable, temp_col, 'cell', [i, 1]);
                            addStyle(app.FixParams_UITable, temp_col, 'cell', [i, 1]);
                        end
                    end
                else; fprintf("-> At least one peak is required...\n");
                end
            end
            % - Update dependents
            update_single_dependents(app);
        end

        % Cell edit callback: Params_UITable
        function Params_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            % -- Initializing variables
            peak_types = define_pes_peak_types(app);
            % -- Label
            if indices(2) == 2
                [BE, LSE, LSI] = find_core_level_props(app, newData);
                if ~isempty(BE); app.Params_UITable.Data{indices(1),4} = BE; end
                if ~isempty(LSE); app.Params_UITable.Data{indices(1),8} = LSE; end
                if ~isempty(LSI); app.Params_UITable.Data{indices(1),9} = LSI; end
                if ~isempty(LSI); app.Params_UITable.Data{indices(1),10} = 0; end
            % -- Type
            elseif indices(2) == 3
            % -- BE
            elseif indices(2) == 4
                % --- Validity Check on Input
                % -- Default value if empty
                if isempty(newData) || isnan(newData); newData = mean(app.myData.xdat(:)); end
                % -- If the BE is < min x value, pad it to the min value
                if newData < min(app.myData.xdat(:)); newData = min(app.myData.xdat(:)); end
                % -- If the BE is > max x value, pad it to the max value
                if newData > max(app.myData.xdat(:)); newData = max(app.myData.xdat(:)); end
            % -- INT
            elseif indices(2) == 5
                % --- Validity Check on Input
                % -- Default value if empty
                if isempty(newData) || isnan(newData); newData = max(app.myData.ydat(:)); end
                % -- If the INT is <0, pad it to 0
                if newData < 0; newData = 0; end
                % -- If the INT is > max y value, pad it to the max value
                if newData > max(app.myData.ydat(:)); newData = max(app.myData.ydat(:)); end
            % -- FWHM
            elseif indices(2) == 6
                % --- Validity Check on Input
                % -- Default value if empty
                if isempty(newData) || isnan(newData); newData = 0.50; end
                % -- If the FWHM is negative, pad it to zero
                if newData < 0; newData = 0; end
            % -- MR
            elseif indices(2) == 7
                % --- Validity Check on Input
                % -- Default value if empty
                if isempty(newData) || isnan(newData); newData = 0.50; end
                % -- If the MR is negative, pad it to zero
                if newData < 0; newData = 0; end
                % -- If the MR is >1, pad it to 1
                if newData > 1; newData = 1; end
                % -- Force MR to be zero for Gaussian, Lorentzian and DS curve-shapes
                if any(strcmpi(app.Params_UITable.Data{indices(1),2}, peak_types(1:3)))
                    newData = 0;
                end
            % -- LSE
            elseif indices(2) == 8
                % -- Default value if empty
                if isempty(newData) || isnan(newData); newData = 0.0; end
            % -- LSI
            elseif indices(2) == 9
                % --- Validity Check on Input
                % -- Default value if empty
                if isempty(newData) || isnan(newData); newData = 0.0; end
                % -- If the LSI is <0, pad it to 0
                if newData < 0; newData = 0; end
            % -- LSW
            elseif indices(2) == 10
                % --- Validity Check on Input
                % -- Default value if empty
                if isempty(newData) || isnan(newData); newData = 0.0; end
                % -- If the LSW is <0, pad it to 0
                if newData < 0; newData = 0; end
            % -- ASY
            elseif indices(2) == 11
                % --- Validity Check on Input
                % -- Default value if empty
                if isempty(newData) || isnan(newData); newData = 0.0; end
                % -- If the ASY is <-1, pad it to -1
                if newData < -1; newData = -1; end   
                % -- If the ASY is >1, pad it to 1
                if newData > 1; newData = 1; end
                % -- Force ASY to be zero for Gaussian, Lorentzian or between 0 and 1 for DS curve-shapes
                if any(strcmpi(app.Params_UITable.Data{indices(1),2}, peak_types(1:2)))
                    newData = 0;
                elseif any(strcmpi(app.Params_UITable.Data{indices(1),2}, peak_types(3)))
                    if newData < 0; newData = 0; end
                    if newData > 1; newData = 1; end
                end
            end
            % 3 - Assigning the output
            app.Params_UITable.Data{indices(1),indices(2)} = newData;
            app.tab_parameters  = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
            if indices(2) == 2;     peak_text = sprintf("Peak %i: Label = %s", indices(1), newData);
            elseif indices(2) == 3; peak_text = sprintf("Peak %i: Type = %s", indices(1), newData);
            elseif indices(2) == 4; peak_text = sprintf("Peak %i: BE = %.3f", indices(1), newData);
            elseif indices(2) == 5; peak_text = sprintf("Peak %i: INT = %.3f", indices(1), newData);
            elseif indices(2) == 6; peak_text = sprintf("Peak %i: FWHM = %.3f", indices(1), newData);
            elseif indices(2) == 7; peak_text = sprintf("Peak %i: MR = %.3f", indices(1), newData);
            elseif indices(2) == 8; peak_text = sprintf("Peak %i: LSE = %.3f", indices(1), newData);
            elseif indices(2) == 9; peak_text = sprintf("Peak %i: LSI = %.3f", indices(1), newData);
            elseif indices(2) == 10; peak_text = sprintf("Peak %i: LSE = %.3f", indices(1), newData);
            elseif indices(2) == 11; peak_text = sprintf("Peak %i: ASY = %.3f", indices(1), newData);
            end
            fprintf(peak_text + " \n");
            % 4 - If the curve type is changed, also change it for the other UI tables
            if indices(2) == 2 || indices(2) == 3
                app.StaticCI_UITable.Data{indices(1),indices(2)}    = newData;
                app.DynCI_UITable.Data{indices(1),indices(2)}       = newData;
                app.FixParams_UITable.Data{indices(1),indices(2)}   = newData;
                app.tab_static_ci       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                app.tab_dyn_ci          = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                app.tab_fix_params      = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
            end
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: DynCI_CheckBox
        function DynCI_CheckBoxValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.DynCI_CheckBox.Value;
            % 2 - Set global variables
            if value == true
                app.static_ci  = false;
                app.DynCI_UITable.Enable = 'on';
                app.StaticCI_CheckBox.Value = false;
                app.StaticCI_UITable.Enable = 'off';
                fprintf("- Dynamic Confidence Intervals" + "\n");
            else
                app.static_ci  = true;
                app.DynCI_UITable.Enable = 'off';
                app.StaticCI_CheckBox.Value = true;
                app.StaticCI_UITable.Enable = 'on';
                fprintf("- Static Confidence Intervals" + "\n");
            end
            % - Update dependents
            update_single_dependents(app);
        end

        % Cell edit callback: DynCI_UITable
        function DynCI_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            % -- BE
            if indices(2) == 4
                if isempty(newData) || isnan(newData); newData = 0.5; end
                if newData < 0; newData = 0.5; end
            % -- INT
            elseif indices(2) == 5
                if isempty(newData) || isnan(newData); newData = 0.75*max(app.myData.ydat(:)); end
                if newData < 0; newData = 0.75*max(app.myData.ydat(:)); end
            % -- FWHM
            elseif indices(2) == 6
                if isempty(newData) || isnan(newData); newData = 0.5; end
                if newData < 0; newData = 0.5; end
            % -- MR
            elseif indices(2) == 7
                if isempty(newData) || isnan(newData); newData = 0.5; end
                if newData < 0; newData = 0.5; end
            % -- LSE
            elseif indices(2) == 8
                if isempty(newData) || isnan(newData); newData = 0.1; end
                if newData < 0; newData = 0.1; end
            % -- LSI
            elseif indices(2) == 9
                if isempty(newData) || isnan(newData); newData = 0; end
                if newData < 0; newData = 0; end
            % -- LSW
            elseif indices(2) == 10
                if isempty(newData) || isnan(newData); newData = 0; end
                if newData < 0; newData = 0; end
            % -- ASY
            elseif indices(2) == 11
                if isempty(newData) || isnan(newData); newData = 0; end
                if newData < 0; newData = 0; end
            end
            % 3 - Assigning the output
            app.DynCI_UITable.Data{indices(1),indices(2)} = newData;
            app.tab_dyn_ci  = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            if indices(2) == 4; peak_text = sprintf("Peak %i (+/-): BE = %.3f", indices(1), newData);
            elseif indices(2) == 5; peak_text = sprintf("Peak %i (+/-): INT = %.3f", indices(1), newData);
            elseif indices(2) == 6; peak_text = sprintf("Peak %i (+/-): FWHM = %.3f", indices(1), newData);
            elseif indices(2) == 7; peak_text = sprintf("Peak %i (+/-): MR = %.3f", indices(1), newData);
            elseif indices(2) == 8; peak_text = sprintf("Peak %i (+/-): LSE = %.3f", indices(1), newData);
            elseif indices(2) == 9; peak_text = sprintf("Peak %i (+/-): LSI = %.3f", indices(1), newData);
            elseif indices(2) == 10; peak_text = sprintf("Peak %i (+/-): LSE = %.3f", indices(1), newData);
            elseif indices(2) == 11; peak_text = sprintf("Peak %i (+/-): ASY = %.3f", indices(1), newData);
            end
            fprintf(peak_text + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Value changed function: StaticCI_CheckBox
        function StaticCI_CheckBoxValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.StaticCI_CheckBox.Value;
            % 2 - Set global variables
            if value == true
                app.static_ci  = true;
                app.StaticCI_UITable.Enable = 'on';
                app.DynCI_CheckBox.Value = false;
                app.DynCI_UITable.Enable = 'off';
                fprintf("- Static Confidence Intervals" + "\n");
            else
                app.static_ci  = false;
                app.StaticCI_UITable.Enable = 'off';
                app.DynCI_CheckBox.Value = true;
                app.DynCI_UITable.Enable = 'on';
                fprintf("- Dynamic Confidence Intervals" + "\n");
            end
            % - Update dependents
            update_single_dependents(app);
        end

        % Cell edit callback: StaticCI_UITable
        function StaticCI_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            data_entry = round(sort(str2double(split(newData,':'))), 2);
            % -- If a conditional peak constraint is added
            if isnan(data_entry)
                newData         = erase(newData," ");
                indx1           = strfind(string(newData),'(');
                indx2           = strfind(string(newData),')');
                peak_index      = round(str2double(newData(indx1+2:indx2-1)), 0);
                peak_constraint = round(str2double(newData(indx2+1:end)), 2);
                data_entry      = app.Params_UITable.Data{peak_index,indices(2)} + peak_constraint;
            end
            % 2 - Finding the min/max values that are allowed
            % -- BE
            if indices(2) == 4
                minVal = min(app.myData.xdat(:)); maxVal = max(app.myData.xdat(:));
            end
            % -- INT
            if indices(2) == 5
                minVal = 0; maxVal = +Inf;
            end
            % -- FWHM
            if indices(2) == 6
                minVal = 0; maxVal = +Inf;
            end
            % -- MR
            if indices(2) == 7
                minVal = 0; maxVal = 1;
            end
            % -- LSE
            if indices(2) == 8
                minVal = -Inf; maxVal = +Inf;
            end
            % -- LSI
            if indices(2) == 9
                minVal = 0; maxVal = +Inf;
            end
            % -- LSW
            if indices(2) == 10
                minVal = 0; maxVal = +Inf;
            end
            % -- ASY
            if indices(2) == 11
                peak_types = define_pes_peak_types(app);
                % Gaussian & Lorentzian Condition
                if strcmpi(app.Params_UITable.Data{indices(1),2}, peak_types(1)) || strcmpi(app.Params_UITable.Data{indices(1),2}, peak_types(2))
                    minVal = 0; maxVal = 0;
                % Doniach-Sunjic Condition
                elseif strcmpi(app.Params_UITable.Data{indices(1),2}, peak_types(3))
                    minVal = 0; maxVal = 1;
                else
                    minVal = -1; maxVal = 1;
                end
            end
            % 3 - General Validity check on the input values
            % ---- If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % ---- If a single null entry is made, default to a window of +/-0.1
            elseif isscalar(data_entry) && data_entry(1) == 0
                data_entry(1) = 0;
                data_entry(2) = 0;
            % ---- If a single entry is made, default to a window of +/-0.1
            elseif isscalar(data_entry)
                temp_val = data_entry;
                data_entry(1) = temp_val - 0.1;
                data_entry(2) = temp_val + 0.1;
            % ---- If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
            end
            % -- Forcing the max / min limits if exceeded
            if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
            if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            data_entry  = sort(round([data_entry(1), data_entry(2)], 2));
            value       = string(data_entry(1) + ":" + data_entry(2)); 
            % 4 - Assigning the output
            app.StaticCI_UITable.Data{indices(1),indices(2)} = char(value);
            app.tab_static_ci  = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
            if indices(2) == 4; ci_text = sprintf("Peak %i (+/-): BE = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 5; ci_text = sprintf("Peak %i (+/-): INT = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 6; ci_text = sprintf("Peak %i (+/-): FWHM = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 7; ci_text = sprintf("Peak %i (+/-): MR = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 8; ci_text = sprintf("Peak %i (+/-): LSE = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 9; ci_text = sprintf("Peak %i (+/-): LSI = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 10; ci_text = sprintf("Peak %i (+/-): LSE = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 11; ci_text = sprintf("Peak %i (+/-): ASY = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            end
            fprintf(ci_text + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Cell edit callback: FixParams_UITable
        function FixParams_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Assigning the output
            app.FixParams_UITable.Data{indices(1),indices(2)} = newData;
            app.tab_fix_params  = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
            if indices(2) == 4; peak_text = sprintf("Peak %i (Fix): BE = %i", indices(1), newData);
            elseif indices(2) == 5; peak_text = sprintf("Peak %i (Fix): INT = %i", indices(1), newData);
            elseif indices(2) == 6; peak_text = sprintf("Peak %i (Fix): FWHM = %i", indices(1), newData);
            elseif indices(2) == 7; peak_text = sprintf("Peak %i (Fix): MR = %i", indices(1), newData);
            elseif indices(2) == 8; peak_text = sprintf("Peak %i (Fix): LSE = %i", indices(1), newData);
            elseif indices(2) == 9; peak_text = sprintf("Peak %i (Fix): LSI = %i", indices(1), newData);
            elseif indices(2) == 10; peak_text = sprintf("Peak %i (Fix): LSE = %i", indices(1), newData);
            elseif indices(2) == 11; peak_text = sprintf("Peak %i (Fix): ASY = %i", indices(1), newData);
            end
            fprintf(peak_text + " \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Button pushed function: Params_AutoSort_Button
        function Params_AutoSort_ButtonPushed(app, event)
            % 1 - Sort in ascending order of binding energies
            be_data             = cell2mat(app.Params_UITable.Data(:,4));
            [~, sort_idx]       = sort(be_data, 'descend'); 
            % 2 - Re-arranging rows
            app.Params_UITable.Data         = app.Params_UITable.Data(sort_idx,:);
            app.StaticCI_UITable.Data       = app.StaticCI_UITable.Data(sort_idx,:);
            app.DynCI_UITable.Data          = app.DynCI_UITable.Data(sort_idx,:);
            app.FixParams_UITable.Data      = app.FixParams_UITable.Data(sort_idx,:);
            % -- Updating the Peak Numbers
            for i = 1:app.num_of_peaks; app.Params_UITable.Data{i,1} = i; end
            for i = 1:app.num_of_peaks; app.StaticCI_UITable.Data{i,1} = i; end
            for i = 1:app.num_of_peaks; app.DynCI_UITable.Data{i,1} = i; end
            for i = 1:app.num_of_peaks; app.FixParams_UITable.Data{i,1} = i; end
            % -- Assigning the output
            app.tab_parameters      = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
            app.tab_static_ci       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
            app.tab_dyn_ci          = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            app.tab_fix_params      = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
            % -- Ensure color is consistent
            colorList   = read_line_colors(app.num_of_peaks+1);
            for i = 1:app.num_of_peaks
                temp_col = uistyle('BackgroundColor', colorList{i}); 
                addStyle(app.Params_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.StaticCI_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.DynCI_UITable, temp_col, 'cell', [i, 1]);
                addStyle(app.FixParams_UITable, temp_col, 'cell', [i, 1]);
            end
            fprintf("--> Auto-sorted parameter table \n");
            % - Update dependents
            update_single_dependents(app);
        end

        % Button pushed function: Params_Import_Button
        function Params_Import_ButtonPushed(app, event)
            % 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.mat'}, 'Pick a *.mat data file...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % 2 - Loading in the parameters
            importedParams              = load(string(PathName) + string(FileName));
            importedParams              = importedParams.dataStr;
            % 3 - Updating table of parameters
            app.num_of_peaks            = importedParams.num_of_peaks;
            % 3.1 - Initialising the parameters
            app.Params_UITable.Data     = table2array(importedParams.tab_parameters);
            app.tab_parameters          = array2table(app.Params_UITable.Data, 'VariableNames', app.Params_UITable.ColumnName);
            % 3.2 - Initialising the static confidence intervals
            app.StaticCI_UITable.Data   = table2array(importedParams.tab_static_ci);
            app.tab_static_ci           = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
            % 3.3 - Initialising the dynamic confidence intervals
            app.DynCI_UITable.Data      = table2array(importedParams.tab_dyn_ci);
            app.tab_dyn_ci              = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            % 3.4 - Initialising the fixed parameters
            app.FixParams_UITable.Data  = table2array(importedParams.tab_fix_params);
            app.tab_fix_params          = array2table(app.FixParams_UITable.Data, 'VariableNames', app.FixParams_UITable.ColumnName);
            % 3.5 - Choosing the type of confidence intervals to use
            app.static_ci               = importedParams.static_ci;
            if app.static_ci == true;       app.StaticCI_CheckBox.Value = true; app.StaticCI_CheckBoxValueChanged();
            else;                           app.DynCI_CheckBox.Value = true; app.DynCI_CheckBoxValueChanged();
            end
            % 4 - Updating the background subtraction
            app.PES_Bgrnd_Type_DropDown.Value               = importedParams.tab_background.Type;
            app.PES_Bgrnd_LHS_EditField.Value               = importedParams.tab_background.LHS;
            app.PES_Bgrnd_RHS_EditField.Value               = importedParams.tab_background.RHS;
            app.PES_Bgrnd_Win_EditField.Value               = importedParams.tab_background.Window;
            app.PES_Bgrnd_Offset_EditField.Value            = importedParams.tab_background.Offset;
            app.PES_Bgrnd_PolyOrder_EditField.Value         = importedParams.tab_background.Order;
            app.PES_Bgrnd_SO_Lambda_EditField.Value         = importedParams.tab_background.Lambda;
            app.PES_Bgrnd_SO_Delta_EditField.Value          = importedParams.tab_background.Delta;
            app.PES_Bgrnd_Tou_C_EditField.Value             = importedParams.tab_background.C;
            app.PES_Bgrnd_Tou_D_EditField.Value             = importedParams.tab_background.D;
            app.PES_Bgrnd_Type_DropDownValueChanged(app);
            app.bgrnd_LHS           = app.PES_Bgrnd_LHS_EditField.Value;
            app.bgrnd_RHS           = app.PES_Bgrnd_RHS_EditField.Value;
            app.bgrnd_Win           = app.PES_Bgrnd_Win_EditField.Value;
            app.bgrnd_Offset        = app.PES_Bgrnd_Offset_EditField.Value;
            app.bgrnd_Order         = app.PES_Bgrnd_PolyOrder_EditField.Value;
            app.bgrnd_lambda        = app.PES_Bgrnd_SO_Lambda_EditField.Value;
            app.bgrnd_delta         = app.PES_Bgrnd_SO_Delta_EditField.Value;
            app.bgrnd_C             = app.PES_Bgrnd_Tou_C_EditField.Value;
            app.bgrnd_D             = app.PES_Bgrnd_Tou_D_EditField.Value;
            % - Update dependents
            update_single_dependents(app);
        end

        % Button pushed function: Params_Export_Button
        function Params_Export_ButtonPushed(app, event)
            % 1 - Extract current PES Model & update best fit measure
            app.myModel = extract_pes_model(app);
            app.OptAlg_SSR_EditField.Value = app.myModel.COST_CHISQ_NORM;
            % 2 - User defined FileName and Path for the processed data
            fname = "Params" + string(yyyymmdd(datetime)) + "_" + sprintf("C%i", app.num_of_peaks); filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 3 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStr = struct();
            dataStr.FileName        = save_filename;
            dataStr.TimeStamp       = datetime;
            dataStr.num_of_peaks    = app.myModel.num_of_peaks;
            dataStr.tab_background  = app.myModel.tab_background;
            dataStr.tab_parameters  = app.myModel.tab_parameters;
            dataStr.static_ci       = app.myModel.static_ci;
            dataStr.tab_static_ci   = app.myModel.tab_static_ci;
            dataStr.tab_dyn_ci      = app.myModel.tab_dyn_ci;
            dataStr.tab_fix_params  = app.myModel.tab_fix_params;
            save(char(save_fullfile), 'dataStr', '-v7.3');
            disp('-> saved data : '); display(dataStr);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: View_PESModelSnap_Button
        function View_PESModelSnap_ButtonPushed(app, event)
            update_single_dependents(app);
            view_pes_model_snap(app);
        end

        % Button pushed function: View_PESModelLive_Button
        function View_PESModelLive_ButtonPushed(app, event)
            update_single_dependents(app);
            view_pes_model_live(app);
        end

        % Value changed function: OptAlg_Type_DropDown
        function OptAlg_Type_DropDownValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.OptAlg_Type_DropDown.Value;
            value = string(value);
            % 2 - Assigning string to the background type
            if strcmpi(value,"lsqcurvefit (Least Squares Fitting)");                          value = "lsqcurvefit";
            elseif strcmpi(value,"fmincon (Functional Minimization Fit)");                    value = "fmincon";
            elseif strcmpi(value,"simulannealbnd (Simulated Annealing Fitting)");             value = "simulannealbnd";
            elseif strcmpi(value,"fmincon (Functional Minimization Fit - Weighted Peaks)");        value = "fmincon-peaks";
            elseif strcmpi(value,"simulannealbnd (Simulated Annealing Fitting - Weighted Peaks)"); value = "simulannealbnd-peaks";
            end
            % 3 - Assigning the output
            app.solver_Type = value;
            fprintf("- Solver Type: " + string(app.solver_Type) + " \n");
        end

        % Value changed function: OptAlg_MaxIter_EditField
        function OptAlg_MaxIter_EditFieldValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.OptAlg_MaxIter_EditField.Value;
            value = round(value,0);
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.solver_MaxIter = value;
            app.OptAlg_MaxIter_EditField.Value = app.solver_MaxIter;
            fprintf("- Solver: Max. Iterations = " + string(app.solver_MaxIter) + " \n");
        end

        % Value changed function: OptAlg_FuncTol_EditField
        function OptAlg_FuncTol_EditFieldValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.OptAlg_FuncTol_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.solver_FuncTol = value;
            app.OptAlg_FuncTol_EditField.Value = app.solver_FuncTol;
            fprintf("- Solver: Function Tolerance = " + string(app.solver_FuncTol) + " \n");
        end

        % Value changed function: OptAlg_MaxFuncEval_EditField
        function OptAlg_MaxFuncEval_EditFieldValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.OptAlg_MaxFuncEval_EditField.Value;
            value = round(value,0);
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.solver_MaxFuncEval = value;
            app.OptAlg_MaxFuncEval_EditField.Value = app.solver_MaxFuncEval;
            fprintf("- Solver: Max. Function Evaluations = " + string(app.solver_MaxFuncEval) + " \n");
        end

        % Value changed function: OptAlg_FinDiffRelStep_EditField
        function OptAlg_FinDiffRelStep_EditFieldValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.OptAlg_FinDiffRelStep_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.solver_FinDiffRelStep = value;
            app.OptAlg_FinDiffRelStep_EditField.Value = app.solver_FinDiffRelStep;
            fprintf("- Solver: Finite Difference Relative Step = " + string(app.solver_FinDiffRelStep) + " \n");
        end

        % Button pushed function: OptAlg_RunOpt_Button
        function OptAlg_RunOpt_ButtonPushed(app, event)
            update_single_dependents(app);
            wbar = waitbar(0.5,'Running Curve Fit Optimization...'); 
            run_optimization(app);
            if app.AutoFitConstantOffset_CheckBox.Value == true
                run_optimization(app); update_single_dependents(app);
                run_background_optimization(app); update_single_dependents(app);
                run_optimization(app); update_single_dependents(app);
            else
                run_optimization(app); update_single_dependents(app);
            end
            close(wbar);
            % Check if live-view figure exists, otherwise make a plot
            figHandle   = 3633;
            fig = findobj('Type', 'figure', 'Number', figHandle);
            if isempty(fig); view_pes_model_snap(app); end
        end

        % Button pushed function: View_PESFitSpectra_Button
        function View_PESFitSpectra_ButtonPushed(app, event)
            view_pes_fit_spectra(app);
        end

        % Button pushed function: View_PESFitSpectraWithBgrnd_Button
        function View_PESFitSpectraWithBgrnd_ButtonPushed(app, event)
            view_pes_fit_spectra_with_background(app);
        end

        % Button pushed function: View_PESFitAreas_Button
        function View_PESFitAreas_ButtonPushed(app, event)
            view_pes_fit_areas(app);
        end

        % Button pushed function: OptAlg_SaveFit_Button
        function OptAlg_SaveFit_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            fname = "Fit" + string(yyyymmdd(datetime)) + "_" + sprintf("C%i", app.num_of_peaks) + "_" + app.myData.FileName(1:end-4);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 3 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStr = struct();
            dataStr.FileName        = save_filename;
            dataStr.TimeStamp       = datetime;
            dataStr.num_of_peaks    = app.myModel.num_of_peaks;
            dataStr.tab_background  = app.myModel.tab_background;
            dataStr.tab_parameters  = app.myModel.tab_parameters;
            dataStr.static_ci       = app.myModel.static_ci;
            dataStr.tab_static_ci   = app.myModel.tab_static_ci;
            dataStr.tab_dyn_ci      = app.myModel.tab_dyn_ci;
            dataStr.tab_fix_params  = app.myModel.tab_fix_params;
            dataStr.myData          = app.myData;
            dataStr.myModel         = app.myModel;
            dataStr.SSR             = app.myModel.COST_R2;
            dataStr.ChiSq           = app.myModel.COST_CHISQ;
            save(char(save_fullfile), 'dataStr', '-v7.3');
            % 4 - Executing the saving of a png
            view_pes_fit_spectra_with_background(app); 
            saveas(gcf,save_fullfile(1:end-4), 'fig');
            saveas(gcf,save_fullfile(1:end-4), 'png');
            disp('-> saved data : '); display(dataStr);
            % 5 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: OptAlg_ExportFit_Button
        function OptAlg_ExportFit_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = "Fit" + string(yyyymmdd(datetime)) + "_" + sprintf("C%i", app.num_of_peaks) + "_" + app.myData.FileName(1:end-4);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % Metadata
            info = "";
            info = info + sprintf("-- INFO -- \n");
            info = info + sprintf("FileName:\t%s\n", app.myData.FileName);
            info = info + sprintf("--- Background Subtraction --- \n");
            info = info + sprintf("Type:\t\t%s\n", app.PES_Bgrnd_Type_DropDown.Value);
            info = info + sprintf("LHS:\t\t%.3e eV\n", app.bgrnd_LHS);
            info = info + sprintf("RHS:\t\t%.3e eV\n", app.bgrnd_RHS);
            info = info + sprintf("Win:\t\t%.3e eV\n", app.bgrnd_Win);
            info = info + sprintf("Offset:\t\t%.3e eV\n", app.bgrnd_Offset);
            Type = char(lower(app.bgrnd_type));
            switch Type
                case lower({'SO'})           
                    info = info + sprintf("SO(Lambda):\t\t%.3f\n", app.bgrnd_lambda);
                    info = info + sprintf("SO(Delta):\t\t%.3f\n", app.bgrnd_delta);
                case lower({'P'})                    
                    info = info + sprintf("P(Order):\t\t%.0f\n", app.bgrnd_Order);
                case lower({'T1', 'T2'})      
                    info = info + sprintf("T(C):\t\t%.3e\n", app.bgrnd_C);
                    info = info + sprintf("T(D):\t\t%.3e\n", app.bgrnd_D);              
            end
            info = info + sprintf("--- Optimisation Algorithm --- \n");
            info = info + sprintf("SSR:\t\t%.3e\n", app.myModel.COST_R2);
            info = info + sprintf("Chi-Squared:\t%.3e\n", app.myModel.COST_CHISQ);
            info = info + sprintf("--- Peak Areas --- \n");
            for i = 1:app.myModel.num_of_peaks
                info = info + sprintf(" cM_%.0f(%s):\t%.4e\n", i, app.myModel.Label(i), app.myModel.AREA(i));
            end
            info = info + sprintf("\n-- TABLE OF DATA -- \n");
            % Data
            X               = app.myModel.X;
            Y_Raw           = app.myModel.D;
            Y_Bgr           = app.myModel.B;
            Y_BgrSubDat     = app.myModel.DB;
            M               = app.myModel.M;
            cM              = [];
            for i = 1:app.myModel.num_of_peaks
                cM(:,i) = app.myModel.cM{i};
            end
            R       = app.myModel.R;
            T_Data = table(X,Y_Raw,Y_Bgr,Y_BgrSubDat,M,cM,R);
            % Open the file for writing
            fileID = fopen(save_fullfile, 'w');
            fprintf(fileID, '%s', info);
            writetable(T_Data, save_fullfile, 'WriteMode', 'append', 'Delimiter', '\t', 'WriteVariableNames', true);
            fclose(fileID);
            % 4 - Executing the saving of a png
            view_pes_fit_spectra_with_background(app); 
            saveas(gcf,save_fullfile(1:end-4), 'fig');
            saveas(gcf,save_fullfile(1:end-4), 'png');
            % 5 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Value changed function: AutoFitConstantOffset_CheckBox
        function AutoFitConstantOffset_CheckBoxValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.AutoFitConstantOffset_CheckBox.Value;
            if value == true;   fprintf("- Constant Background: Fitted & Optimized \n");
            else;               fprintf("- Constant Background: Not-Fitted \n");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'PES : Curve Fitting Photoelectron Spectroscopy Data';
            app.DataFile_Label = uilabel(app.GridLayout);
            app.DataFile_Label.Tooltip = {'Loaded Filename'};
            app.DataFile_Label.Text = '<<  select a raw ''*.txt'' file or processed ''*.mat'' data file to begin fitting';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.LOAD_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.LOAD_Button.ButtonPushedFcn = createCallbackFcn(app, @LOAD_ButtonPushed, true);
            app.LOAD_Button.Tooltip = {'Load in PES data. Either a raw *.txt file or a previously saved *.mat file.'};
            app.LOAD_Button.Text = {'Load Data...'; '(.txt / .mat)'};
            app.RESET_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESET_Button.ButtonPushedFcn = createCallbackFcn(app, @RESET_ButtonPushed, true);
            app.RESET_Button.Tooltip = {'Revert the PES Curve Fitting to the Initial State upon loading the data.'};
            app.RESET_Button.Text = {'Revert to '; 'Initial State'};
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Explore the depths of material analysis and XPS with this robust tool. It empowers users to construct multi-layered models, each layer featuring a uniform and homogenous material composition and thickness. The app draws upon an extensive database for material characteristics and integrates various options for IMFP parameters, including Optical (NIST), Universal, TPP-2M, and S1 through S4. It also offers a selection of photoionization cross-sections and asymmetry parameters from renowned databases, such as the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018) and Cant (2022) databases. Utilizing the Beer-Lambert law, the tool accurately computes the PES intensities for each layer, modulated by the electron IMFP based on the specific experimental setup. Additionally, it can graphically represent the variations in photon energy and emission angles.'};
            app.Image.ImageSource = 'Icon_PESModelFitter.jpeg';
            app.ViewRawPES_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.ViewRawPES_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewRawPES_ButtonPushed, true);
            app.ViewRawPES_Button.Tooltip = {'View the raw PES data.'};
            app.ViewRawPES_Button.Text = {'View Raw '; 'PES Data'};
            app.PES_Bgrnd_Panel = uipanel(app.GridLayout);
            app.PES_Bgrnd_Panel.Tooltip = {'Background Subtraction Panel'};
            app.PES_Bgrnd_Panel.Title = 'Background Subtraction';
            app.PES_Bgrnd_GridLayout = uigridlayout(app.PES_Bgrnd_Panel);
            app.PES_Bgrnd_Type_DropDown = uidropdown(app.PES_Bgrnd_GridLayout);
            app.PES_Bgrnd_Type_DropDown.Items = {'L - (Linear)', 'P - (Polynomial)', 'S - (Shirley)', 'SO - (Shirley-Offset)', 'SS - (Shirley-Smart)', 'T1 - (Tougaard1)', 'T2 - (Tougaard2)'};
            app.PES_Bgrnd_Type_DropDown.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_Type_DropDownValueChanged, true);
            app.PES_Bgrnd_Type_DropDown.Tooltip = {'Background Subtraction Type'};
            app.PES_Bgrnd_Type_DropDown.Value = 'L - (Linear)';
            app.PES_Bgrnd_PolyArgs_Panel = uipanel(app.PES_Bgrnd_GridLayout);
            app.PES_Bgrnd_PolyArgs_Panel.Tooltip = {'Polynomial Arguments Panel'};
            app.PES_Bgrnd_PolyArgs_Panel.Title = 'Polynomial Arguments';
            app.PES_Bgrnd_PolyArgs_GridLayout = uigridlayout(app.PES_Bgrnd_PolyArgs_Panel);
            app.OrderEditFieldLabel = uilabel(app.PES_Bgrnd_PolyArgs_GridLayout);
            app.OrderEditFieldLabel.Text = 'Order:';
            app.PES_Bgrnd_PolyOrder_EditField = uieditfield(app.PES_Bgrnd_PolyArgs_GridLayout, 'numeric');
            app.PES_Bgrnd_PolyOrder_EditField.Limits = [0 Inf];
            app.PES_Bgrnd_PolyOrder_EditField.RoundFractionalValues = 'on';
            app.PES_Bgrnd_PolyOrder_EditField.ValueDisplayFormat = '%.0f';
            app.PES_Bgrnd_PolyOrder_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_PolyOrder_EditFieldValueChanged, true);
            app.PES_Bgrnd_PolyOrder_EditField.Tooltip = {'Scalar of the Polynomial Order '};
            app.PES_Bgrnd_PolyOrder_EditField.Value = 3;
            app.LHSStartEditFieldLabel = uilabel(app.PES_Bgrnd_GridLayout);
            app.LHSStartEditFieldLabel.Text = 'LHS (Start):';
            app.PES_Bgrnd_LHS_EditField = uieditfield(app.PES_Bgrnd_GridLayout, 'numeric');
            app.PES_Bgrnd_LHS_EditField.ValueDisplayFormat = '%.3f';
            app.PES_Bgrnd_LHS_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_LHS_EditFieldValueChanged, true);
            app.PES_Bgrnd_LHS_EditField.Tooltip = {'LHS Starting Point of the background subtraction region of interest'};
            app.PES_Bgrnd_LHS_EditField.Value = -5;
            app.RHSEndEditFieldLabel = uilabel(app.PES_Bgrnd_GridLayout);
            app.RHSEndEditFieldLabel.Text = 'RHS (End):';
            app.PES_Bgrnd_RHS_EditField = uieditfield(app.PES_Bgrnd_GridLayout, 'numeric');
            app.PES_Bgrnd_RHS_EditField.ValueDisplayFormat = '%.3f';
            app.PES_Bgrnd_RHS_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_RHS_EditFieldValueChanged, true);
            app.PES_Bgrnd_RHS_EditField.Tooltip = {'RHS End Point of the background subtraction region of interest'};
            app.PES_Bgrnd_RHS_EditField.Value = 5;
            app.PES_Bgrnd_SOArgs_Panel = uipanel(app.PES_Bgrnd_GridLayout);
            app.PES_Bgrnd_SOArgs_Panel.Tooltip = {'Shirley-Offset Arguments Panel'};
            app.PES_Bgrnd_SOArgs_Panel.Title = 'Shirley-Offset Arguments';
            app.PES_Bgrnd_SOArgs_GridLayout = uigridlayout(app.PES_Bgrnd_SOArgs_Panel);
            app.LambdaLabel = uilabel(app.PES_Bgrnd_SOArgs_GridLayout);
            app.LambdaLabel.Text = 'Lambda:';
            app.PES_Bgrnd_SO_Lambda_EditField = uieditfield(app.PES_Bgrnd_SOArgs_GridLayout, 'numeric');
            app.PES_Bgrnd_SO_Lambda_EditField.Limits = [0 1];
            app.PES_Bgrnd_SO_Lambda_EditField.ValueDisplayFormat = '%.3f';
            app.PES_Bgrnd_SO_Lambda_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_SO_Lambda_EditFieldValueChanged, true);
            app.PES_Bgrnd_SO_Lambda_EditField.Tooltip = {'Scalar for the Shirley-Offset mixing ratio: lambda = 0 is Pure Shirley; lambda = 1 is Pure Linear.'};
            app.PES_Bgrnd_SO_Lambda_EditField.Value = 0.5;
            app.DeltaLabel = uilabel(app.PES_Bgrnd_SOArgs_GridLayout);
            app.DeltaLabel.Text = 'Delta:';
            app.PES_Bgrnd_SO_Delta_EditField = uieditfield(app.PES_Bgrnd_SOArgs_GridLayout, 'numeric');
            app.PES_Bgrnd_SO_Delta_EditField.ValueDisplayFormat = '%.3f';
            app.PES_Bgrnd_SO_Delta_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_SO_Delta_EditFieldValueChanged, true);
            app.PES_Bgrnd_SO_Delta_EditField.Tooltip = {'Scalar for the Shirley curve offset along the x-axis'};
            app.WindowSizeEditFieldLabel = uilabel(app.PES_Bgrnd_GridLayout);
            app.WindowSizeEditFieldLabel.Text = 'Window Size:';
            app.PES_Bgrnd_Win_EditField = uieditfield(app.PES_Bgrnd_GridLayout, 'numeric');
            app.PES_Bgrnd_Win_EditField.Limits = [0 Inf];
            app.PES_Bgrnd_Win_EditField.ValueDisplayFormat = '%.3f';
            app.PES_Bgrnd_Win_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_Win_EditFieldValueChanged, true);
            app.PES_Bgrnd_Win_EditField.Tooltip = {'Averaging Window Size around the start/end position'};
            app.PES_Bgrnd_Win_EditField.Value = 0.5;
            app.ConstantOffsetEditFieldLabel = uilabel(app.PES_Bgrnd_GridLayout);
            app.ConstantOffsetEditFieldLabel.Text = 'Constant Offset:';
            app.PES_Bgrnd_Offset_EditField = uieditfield(app.PES_Bgrnd_GridLayout, 'numeric');
            app.PES_Bgrnd_Offset_EditField.ValueDisplayFormat = '%.3f';
            app.PES_Bgrnd_Offset_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_Offset_EditFieldValueChanged, true);
            app.PES_Bgrnd_Offset_EditField.Tooltip = {'Constant Offset added to the background subtraction'};
            app.PES_Bgrnd_TougArgs_Panel = uipanel(app.PES_Bgrnd_GridLayout);
            app.PES_Bgrnd_TougArgs_Panel.Tooltip = {'Tougaard Arguments Panel'};
            app.PES_Bgrnd_TougArgs_Panel.Title = 'Tougaard Arguments';
            app.PES_Bgrnd_TougArgs_GridLayout = uigridlayout(app.PES_Bgrnd_TougArgs_Panel);
            app.CParameterEditFieldLabel = uilabel(app.PES_Bgrnd_TougArgs_GridLayout);
            app.CParameterEditFieldLabel.Text = 'C-Parameter:';
            app.PES_Bgrnd_Tou_C_EditField = uieditfield(app.PES_Bgrnd_TougArgs_GridLayout, 'numeric');
            app.PES_Bgrnd_Tou_C_EditField.Limits = [0 Inf];
            app.PES_Bgrnd_Tou_C_EditField.RoundFractionalValues = 'on';
            app.PES_Bgrnd_Tou_C_EditField.ValueDisplayFormat = '%.0f';
            app.PES_Bgrnd_Tou_C_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_Tou_C_EditFieldValueChanged, true);
            app.PES_Bgrnd_Tou_C_EditField.Tooltip = {'Scalar of C-parameter in the Tougaard background'};
            app.PES_Bgrnd_Tou_C_EditField.Value = 1643;
            app.DParameterEditFieldLabel = uilabel(app.PES_Bgrnd_TougArgs_GridLayout);
            app.DParameterEditFieldLabel.Text = 'D-Parameter:';
            app.PES_Bgrnd_Tou_D_EditField = uieditfield(app.PES_Bgrnd_TougArgs_GridLayout, 'numeric');
            app.PES_Bgrnd_Tou_D_EditField.Limits = [0 Inf];
            app.PES_Bgrnd_Tou_D_EditField.RoundFractionalValues = 'on';
            app.PES_Bgrnd_Tou_D_EditField.ValueDisplayFormat = '%.0f';
            app.PES_Bgrnd_Tou_D_EditField.ValueChangedFcn = createCallbackFcn(app, @PES_Bgrnd_Tou_D_EditFieldValueChanged, true);
            app.PES_Bgrnd_Tou_D_EditField.Tooltip = {'Scalar of D-parameter in the Tougaard background'};
            app.PES_Bgrnd_Tou_D_EditField.Value = 275;
            app.PES_Bgrnd_ViewRawWithBgrnd_Button = uibutton(app.PES_Bgrnd_GridLayout, 'push');
            app.PES_Bgrnd_ViewRawWithBgrnd_Button.ButtonPushedFcn = createCallbackFcn(app, @PES_Bgrnd_ViewRawWithBgrnd_ButtonPushed, true);
            app.PES_Bgrnd_ViewRawWithBgrnd_Button.Tooltip = {'View the raw PES data, along with the background subtraction being shown.'};
            app.PES_Bgrnd_ViewRawWithBgrnd_Button.Text = {'View Bgrnd Sub. '; 'PES Data'};
            app.PES_Bgrnd_ExportData_Button = uibutton(app.PES_Bgrnd_GridLayout, 'push');
            app.PES_Bgrnd_ExportData_Button.ButtonPushedFcn = createCallbackFcn(app, @PES_Bgrnd_ExportData_ButtonPushed, true);
            app.PES_Bgrnd_ExportData_Button.Tooltip = {'Export data as a text-file (.txt); the background subtracted PES data is exported.'};
            app.PES_Bgrnd_ExportData_Button.Text = 'Export Bgrnd Sub. PES Data (*.txt)';
            app.AutoFitConstantOffset_CheckBox = uicheckbox(app.PES_Bgrnd_GridLayout);
            app.AutoFitConstantOffset_CheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoFitConstantOffset_CheckBoxValueChanged, true);
            app.AutoFitConstantOffset_CheckBox.Text = 'Auto Fit Constant Offset?';
            app.OptAlg_Panel = uipanel(app.GridLayout);
            app.OptAlg_Panel.Tooltip = {'Optimization Panel'};
            app.OptAlg_Panel.Title = 'Optimization Algorithms';
            app.OptAlg_GridLayout = uigridlayout(app.OptAlg_Panel);
            app.SolverTypeLabel = uilabel(app.OptAlg_GridLayout);
            app.SolverTypeLabel.Text = 'Solver Type:';
            app.OptAlg_Type_DropDown = uidropdown(app.OptAlg_GridLayout);
            app.OptAlg_Type_DropDown.Items = {'lsqcurvefit - (Least Squares Fitting)', 'fmincon - (Functional Minimization Fit)', 'simulannealbnd - (Simulated Annealing Fitting)'};
            app.OptAlg_Type_DropDown.ValueChangedFcn = createCallbackFcn(app, @OptAlg_Type_DropDownValueChanged, true);
            app.OptAlg_Type_DropDown.Tooltip = {'Solver Type'};
            app.OptAlg_Type_DropDown.Value = 'lsqcurvefit - (Least Squares Fitting)';
            app.NumberoffitparametersLabel_3 = uilabel(app.OptAlg_GridLayout);
            app.NumberoffitparametersLabel_3.Text = 'Max. Iterations:';
            app.OptAlg_MaxIter_EditField = uieditfield(app.OptAlg_GridLayout, 'numeric');
            app.OptAlg_MaxIter_EditField.Limits = [0 Inf];
            app.OptAlg_MaxIter_EditField.ValueDisplayFormat = '%.0f';
            app.OptAlg_MaxIter_EditField.ValueChangedFcn = createCallbackFcn(app, @OptAlg_MaxIter_EditFieldValueChanged, true);
            app.OptAlg_MaxIter_EditField.Tooltip = {'Maximum Number of Solver Iterations'};
            app.OptAlg_MaxIter_EditField.Value = 5000;
            app.NumberoffitparametersLabel_2 = uilabel(app.OptAlg_GridLayout);
            app.NumberoffitparametersLabel_2.Text = 'Max. Func. Eval.:';
            app.OptAlg_MaxFuncEval_EditField = uieditfield(app.OptAlg_GridLayout, 'numeric');
            app.OptAlg_MaxFuncEval_EditField.Limits = [0 Inf];
            app.OptAlg_MaxFuncEval_EditField.ValueDisplayFormat = '%.0f';
            app.OptAlg_MaxFuncEval_EditField.ValueChangedFcn = createCallbackFcn(app, @OptAlg_MaxFuncEval_EditFieldValueChanged, true);
            app.OptAlg_MaxFuncEval_EditField.Tooltip = {'Maximum Number of Function Evaluations for the Solver'};
            app.OptAlg_MaxFuncEval_EditField.Value = 100000;
            app.NumberoffitparametersLabel_4 = uilabel(app.OptAlg_GridLayout);
            app.NumberoffitparametersLabel_4.Text = 'Function Tol.:';
            app.OptAlg_FuncTol_EditField = uieditfield(app.OptAlg_GridLayout, 'numeric');
            app.OptAlg_FuncTol_EditField.Limits = [0 Inf];
            app.OptAlg_FuncTol_EditField.ValueDisplayFormat = '%.1e';
            app.OptAlg_FuncTol_EditField.ValueChangedFcn = createCallbackFcn(app, @OptAlg_FuncTol_EditFieldValueChanged, true);
            app.OptAlg_FuncTol_EditField.Tooltip = {'Solver Function Tolerance'};
            app.OptAlg_FuncTol_EditField.Value = 1e-07;
            app.NumberoffitparametersLabel_5 = uilabel(app.OptAlg_GridLayout);
            app.NumberoffitparametersLabel_5.Text = 'Fin. Diff. Rel. Step:';
            app.OptAlg_FinDiffRelStep_EditField = uieditfield(app.OptAlg_GridLayout, 'numeric');
            app.OptAlg_FinDiffRelStep_EditField.Limits = [0 Inf];
            app.OptAlg_FinDiffRelStep_EditField.ValueDisplayFormat = '%.1e';
            app.OptAlg_FinDiffRelStep_EditField.ValueChangedFcn = createCallbackFcn(app, @OptAlg_FinDiffRelStep_EditFieldValueChanged, true);
            app.OptAlg_FinDiffRelStep_EditField.Tooltip = {'Finite Difference Relative Step Size for the Solver'};
            app.OptAlg_FinDiffRelStep_EditField.Value = 1e-05;
            app.MeasureofBestFitLabel = uilabel(app.OptAlg_GridLayout);
            app.MeasureofBestFitLabel.Text = 'SSR:';
            app.OptAlg_SSR_EditField = uieditfield(app.OptAlg_GridLayout, 'numeric');
            app.OptAlg_SSR_EditField.ValueDisplayFormat = '%.3e';
            app.OptAlg_SSR_EditField.Editable = 'off';
            app.OptAlg_SSR_EditField.Tooltip = {'Sum of the Squared Residuals. The smaller the value the better the fit.'};
            app.OptAlg_RunOpt_Button = uibutton(app.OptAlg_GridLayout, 'push');
            app.OptAlg_RunOpt_Button.ButtonPushedFcn = createCallbackFcn(app, @OptAlg_RunOpt_ButtonPushed, true);
            app.OptAlg_RunOpt_Button.Tooltip = {'Run the Curve Fit Optimization Algorithm'};
            app.OptAlg_RunOpt_Button.Text = 'Run Curve Fit Optimization!';
            app.OptAlg_SaveFit_Button = uibutton(app.OptAlg_GridLayout, 'push');
            app.OptAlg_SaveFit_Button.ButtonPushedFcn = createCallbackFcn(app, @OptAlg_SaveFit_ButtonPushed, true);
            app.OptAlg_SaveFit_Button.Tooltip = {'Save the current PES model'};
            app.OptAlg_SaveFit_Button.Text = 'Save PES Model (*.mat)';
            app.OptAlg_ExportFit_Button = uibutton(app.OptAlg_GridLayout, 'push');
            app.OptAlg_ExportFit_Button.ButtonPushedFcn = createCallbackFcn(app, @OptAlg_ExportFit_ButtonPushed, true);
            app.OptAlg_ExportFit_Button.Tooltip = {'Export the current PES model'};
            app.OptAlg_ExportFit_Button.Text = 'Export PES Model (*.txt)';
            app.MeasureofBestFitLabel_2 = uilabel(app.OptAlg_GridLayout);
            app.MeasureofBestFitLabel_2.Text = 'Chi-Squared:';
            app.OptAlg_CHI_EditField = uieditfield(app.OptAlg_GridLayout, 'numeric');
            app.OptAlg_CHI_EditField.ValueDisplayFormat = '%.3e';
            app.OptAlg_CHI_EditField.Editable = 'off';
            app.OptAlg_CHI_EditField.Tooltip = {'Chi-squared value of the data & model. Smaller the value the better the fit.'};
            app.View_PESFitSpectra_Button = uibutton(app.OptAlg_GridLayout, 'push');
            app.View_PESFitSpectra_Button.ButtonPushedFcn = createCallbackFcn(app, @View_PESFitSpectra_ButtonPushed, true);
            app.View_PESFitSpectra_Button.Tooltip = {'View the PES Fit (Spectrum)'};
            app.View_PESFitSpectra_Button.Text = 'View PES Fit - Spectra';
            app.View_PESFitAreas_Button = uibutton(app.OptAlg_GridLayout, 'push');
            app.View_PESFitAreas_Button.ButtonPushedFcn = createCallbackFcn(app, @View_PESFitAreas_ButtonPushed, true);
            app.View_PESFitAreas_Button.Tooltip = {'View the PES Fit (Peak Areas)'};
            app.View_PESFitAreas_Button.Text = 'View PES Fit - Areas';
            app.View_PESFitSpectraWithBgrnd_Button = uibutton(app.OptAlg_GridLayout, 'push');
            app.View_PESFitSpectraWithBgrnd_Button.ButtonPushedFcn = createCallbackFcn(app, @View_PESFitSpectraWithBgrnd_ButtonPushed, true);
            app.View_PESFitSpectraWithBgrnd_Button.Tooltip = {'View the PES Fit (Spectrum)'};
            app.View_PESFitSpectraWithBgrnd_Button.Text = 'View PES Fit - Spectra with Bgrnd';
            app.ModelParams_Panel = uipanel(app.GridLayout);
            app.ModelParams_Panel.Title = 'Model Parameter Values & Constraints';
            app.ModelParams_GridLayout = uigridlayout(app.ModelParams_Panel);
            app.FitModel_TabGroup = uitabgroup(app.ModelParams_GridLayout);
            app.FitModel_Params_Tab = uitab(app.FitModel_TabGroup);
            app.FitModel_Params_Tab.Tooltip = {'Get (or set) the parameter values'};
            app.FitModel_Params_Tab.Title = 'Parameter Values';
            app.Params_GridLayout = uigridlayout(app.FitModel_Params_Tab);
            app.Params_GridLayout.Tooltip = {'Parameter Values Tab'};
            app.Params_AutoSort_Button = uibutton(app.Params_GridLayout, 'push');
            app.Params_AutoSort_Button.ButtonPushedFcn = createCallbackFcn(app, @Params_AutoSort_ButtonPushed, true);
            app.Params_AutoSort_Button.Tooltip = {'Autosort each curve in descending order of binding energies.'};
            app.Params_AutoSort_Button.Text = '@';
            app.Params_UITable = uitable(app.Params_GridLayout);
            app.Params_UITable.ColumnName = {'Peak'; 'Label'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.Params_UITable.RowName = {};
            app.Params_UITable.CellEditCallback = createCallbackFcn(app, @Params_UITableCellEdit, true);
            app.Params_UITable.Tooltip = {'Table of all the parameter values for the PES model'};
            app.Params_PeakAdd_Button = uibutton(app.Params_GridLayout, 'push');
            app.Params_PeakAdd_Button.ButtonPushedFcn = createCallbackFcn(app, @Params_PeakAdd_ButtonPushed, true);
            app.Params_PeakAdd_Button.Tooltip = {'Add a new peak'};
            app.Params_PeakAdd_Button.Text = '+';
            app.Params_PeakDeleteN_Button = uibutton(app.Params_GridLayout, 'push');
            app.Params_PeakDeleteN_Button.ButtonPushedFcn = createCallbackFcn(app, @Params_PeakDeleteN_ButtonPushed, true);
            app.Params_PeakDeleteN_Button.Tooltip = {'Remove the N''th peak'};
            app.Params_PeakDeleteN_Button.Text = '-#';
            app.Params_PeakDelete_Button = uibutton(app.Params_GridLayout, 'push');
            app.Params_PeakDelete_Button.ButtonPushedFcn = createCallbackFcn(app, @Params_PeakDelete_ButtonPushed, true);
            app.Params_PeakDelete_Button.Tooltip = {'Remove the last peak'};
            app.Params_PeakDelete_Button.Text = '-';
            app.FitModel_FixParams_Tab = uitab(app.FitModel_TabGroup);
            app.FitModel_FixParams_Tab.Tooltip = {'Specify which parameter values to fix'};
            app.FitModel_FixParams_Tab.Title = 'Fix Parameters';
            app.FixParams_GridLayout = uigridlayout(app.FitModel_FixParams_Tab);
            app.FixParams_GridLayout.Tooltip = {'Fixed Parameters Tab'};
            app.FixParams_UITable = uitable(app.FixParams_GridLayout);
            app.FixParams_UITable.ColumnName = {'Peak'; 'Label'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.FixParams_UITable.RowName = {};
            app.FixParams_UITable.CellEditCallback = createCallbackFcn(app, @FixParams_UITableCellEdit, true);
            app.FixParams_UITable.Tooltip = {'Table of all the fixed parameters'};
            app.FitModel_DynCI_Tab = uitab(app.FitModel_TabGroup);
            app.FitModel_DynCI_Tab.Tooltip = {'Dynamic Confidence Intervals based on a given window size'};
            app.FitModel_DynCI_Tab.Title = 'Dynamic Confidence Intervals (+/-)';
            app.DynCI_GridLayout = uigridlayout(app.FitModel_DynCI_Tab);
            app.DynCI_GridLayout.Tooltip = {'Confidence Intervals Tab'};
            app.DynCI_CheckBox = uicheckbox(app.DynCI_GridLayout);
            app.DynCI_CheckBox.ValueChangedFcn = createCallbackFcn(app, @DynCI_CheckBoxValueChanged, true);
            app.DynCI_CheckBox.Text = '';
            app.DynCI_CheckBox.Value = true;
            app.DynCI_UITable = uitable(app.DynCI_GridLayout);
            app.DynCI_UITable.ColumnName = {'Peak'; 'Label'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.DynCI_UITable.RowName = {};
            app.DynCI_UITable.CellEditCallback = createCallbackFcn(app, @DynCI_UITableCellEdit, true);
            app.DynCI_UITable.Tooltip = {'Table of all the confidence intervals'};
            app.FitModel_StaticCI_Tab = uitab(app.FitModel_TabGroup);
            app.FitModel_StaticCI_Tab.Tooltip = {'Static Confidence Intervals based on a given range of allowed values'};
            app.FitModel_StaticCI_Tab.Title = 'Static Confidence Intervals (lb:ub)';
            app.StaticCI_GridLayout = uigridlayout(app.FitModel_StaticCI_Tab);
            app.StaticCI_GridLayout.Tooltip = {'Confidence Intervals Tab'};
            app.StaticCI_CheckBox = uicheckbox(app.StaticCI_GridLayout);
            app.StaticCI_CheckBox.ValueChangedFcn = createCallbackFcn(app, @StaticCI_CheckBoxValueChanged, true);
            app.StaticCI_CheckBox.Text = '';
            app.StaticCI_UITable = uitable(app.StaticCI_GridLayout);
            app.StaticCI_UITable.ColumnName = {'Peak'; 'Label'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.StaticCI_UITable.RowName = {};
            app.StaticCI_UITable.CellEditCallback = createCallbackFcn(app, @StaticCI_UITableCellEdit, true);
            app.StaticCI_UITable.Tooltip = {'Table of all the confidence intervals'};
            app.NumberoffitparametersLabel = uilabel(app.ModelParams_GridLayout);
            app.NumberoffitparametersLabel.Text = 'DoF:';
            app.DoF_EditField = uieditfield(app.ModelParams_GridLayout, 'numeric');
            app.DoF_EditField.Limits = [0 Inf];
            app.DoF_EditField.ValueDisplayFormat = '%.0f';
            app.DoF_EditField.Editable = 'off';
            app.DoF_EditField.Tooltip = {'Degrees of Freedom of the fits (i.e. total number of fit parameters)'};
            app.Params_Import_Button = uibutton(app.ModelParams_GridLayout, 'push');
            app.Params_Import_Button.ButtonPushedFcn = createCallbackFcn(app, @Params_Import_ButtonPushed, true);
            app.Params_Import_Button.Tooltip = {'Import all the parameter arguments (Background, Parameters, Confidence Intervals & Fix Parameters)'};
            app.Params_Import_Button.Text = 'Import Parameters (*.mat)';
            app.Params_Export_Button = uibutton(app.ModelParams_GridLayout, 'push');
            app.Params_Export_Button.ButtonPushedFcn = createCallbackFcn(app, @Params_Export_ButtonPushed, true);
            app.Params_Export_Button.Tooltip = {'Save all the parameter arguments (Background, Parameters, Confidence Intervals & Fix Parameters)'};
            app.Params_Export_Button.Text = 'Save Parameters (*.mat)';
            app.View_PESModelSnap_Button = uibutton(app.ModelParams_GridLayout, 'push');
            app.View_PESModelSnap_Button.ButtonPushedFcn = createCallbackFcn(app, @View_PESModelSnap_ButtonPushed, true);
            app.View_PESModelSnap_Button.Tooltip = {'View a snapshot of the current PES model.'};
            app.View_PESModelSnap_Button.Text = 'View Model Snapshot';
            app.View_PESModelLive_Button = uibutton(app.ModelParams_GridLayout, 'push');
            app.View_PESModelLive_Button.ButtonPushedFcn = createCallbackFcn(app, @View_PESModelLive_ButtonPushed, true);
            app.View_PESModelLive_Button.Tooltip = {'Open a persistant figure that updates in real time as the parameter table is changed.'};
            app.View_PESModelLive_Button.Text = 'Live Model Builder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatBaseX_08_PES_CurveFitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end